classdef PBB84analysis_V1p2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        LDTab                 matlab.ui.container.Tab
        Coordinate            matlab.ui.container.ButtonGroup
        LDLogButton           matlab.ui.control.RadioButton
        LDLinearButton        matlab.ui.control.RadioButton
        Panel_11              matlab.ui.container.Panel
        LD_Cov_Fre            matlab.ui.control.NumericEditField
        Label_24              matlab.ui.control.Label
        LD_Fremeasured        matlab.ui.control.NumericEditField
        Label_23              matlab.ui.control.Label
        GHzLabel_5            matlab.ui.control.Label
        GHzLabel_4            matlab.ui.control.Label
        LD_Slider             matlab.ui.control.Slider
        SliderLabel_3         matlab.ui.control.Label
        Panel_10              matlab.ui.container.Panel
        LD20dB                matlab.ui.control.NumericEditField
        dBLabel_2             matlab.ui.control.Label
        LD3dB                 matlab.ui.control.NumericEditField
        dBLabel               matlab.ui.control.Label
        psLabel_20            matlab.ui.control.Label
        psLabel_19            matlab.ui.control.Label
        Panel_9               matlab.ui.container.Panel
        LDpeakmin             matlab.ui.control.NumericEditField
        SLabel_3              matlab.ui.control.Label
        binsLabel_5           matlab.ui.control.Label
        LD_MINPEAKDISTANCE    matlab.ui.control.NumericEditField
        Label_25              matlab.ui.control.Label
        LD_dataneme           matlab.ui.control.EditField
        Label_19              matlab.ui.control.Label
        LDButton              matlab.ui.control.Button
        GHzLabel_3            matlab.ui.control.Label
        LD_Fre                matlab.ui.control.NumericEditField
        Label_17              matlab.ui.control.Label
        UIAxes7               matlab.ui.control.UIAxes
        SDVTab                matlab.ui.container.Tab
        Coordinate_2          matlab.ui.container.ButtonGroup
        SDVLogButton          matlab.ui.control.RadioButton
        SDVLinearButton       matlab.ui.control.RadioButton
        SDV_Slider            matlab.ui.control.Slider
        SliderLabel_2         matlab.ui.control.Label
        Panel_3               matlab.ui.container.Panel
        Cov_V                 matlab.ui.control.NumericEditField
        VLabel                matlab.ui.control.Label
        Cov_D                 matlab.ui.control.NumericEditField
        DLabel                matlab.ui.control.Label
        Cov_S                 matlab.ui.control.NumericEditField
        SLabel                matlab.ui.control.Label
        Panel_2               matlab.ui.container.Panel
        S10dB                 matlab.ui.control.NumericEditField
        S10dBLabel            matlab.ui.control.Label
        S0p46dB               matlab.ui.control.NumericEditField
        S046dBLabel           matlab.ui.control.Label
        psLabel_22            matlab.ui.control.Label
        psLabel_21            matlab.ui.control.Label
        V20dB                 matlab.ui.control.NumericEditField
        V20dBLabel            matlab.ui.control.Label
        V3dB                  matlab.ui.control.NumericEditField
        V3dBLabel             matlab.ui.control.Label
        D20dB                 matlab.ui.control.NumericEditField
        D20dBLabel            matlab.ui.control.Label
        D3dB                  matlab.ui.control.NumericEditField
        D3dBLabel             matlab.ui.control.Label
        S20dB                 matlab.ui.control.NumericEditField
        S20dBLabel            matlab.ui.control.Label
        S3dB                  matlab.ui.control.NumericEditField
        S3dBLabel             matlab.ui.control.Label
        psLabel_6             matlab.ui.control.Label
        psLabel_5             matlab.ui.control.Label
        psLabel_4             matlab.ui.control.Label
        psLabel_3             matlab.ui.control.Label
        psLabel_2             matlab.ui.control.Label
        psLabel               matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        Vpeakmax              matlab.ui.control.NumericEditField
        VLabel_3              matlab.ui.control.Label
        Speakmin              matlab.ui.control.NumericEditField
        SLabel_2              matlab.ui.control.Label
        SDV_MINPEAKDISTANCE   matlab.ui.control.NumericEditField
        Label_21              matlab.ui.control.Label
        binsLabel_3           matlab.ui.control.Label
        SDV_dataname          matlab.ui.control.EditField
        Label_20              matlab.ui.control.Label
        binsLabel             matlab.ui.control.Label
        SDVButton             matlab.ui.control.Button
        GHzLabel              matlab.ui.control.Label
        SDV_Binsnum           matlab.ui.control.NumericEditField
        SDV_Fre               matlab.ui.control.NumericEditField
        Label_3               matlab.ui.control.Label
        DropDown              matlab.ui.control.DropDown
        Label_2               matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
        PNRLTab               matlab.ui.container.Tab
        Coordinate_3          matlab.ui.container.ButtonGroup
        PNRLLogButton         matlab.ui.control.RadioButton
        LDLinearButton_2      matlab.ui.control.RadioButton
        Panel_12              matlab.ui.container.Panel
        RLpeakextratio        matlab.ui.control.NumericEditField
        RLLabel_2             matlab.ui.control.Label
        dBLabel_9             matlab.ui.control.Label
        PNpeakextratio        matlab.ui.control.NumericEditField
        PNLabel_2             matlab.ui.control.Label
        dBLabel_8             matlab.ui.control.Label
        PDL                   matlab.ui.control.NumericEditField
        PDLLabel              matlab.ui.control.Label
        dBLabel_7             matlab.ui.control.Label
        PNthreextratio        matlab.ui.control.NumericEditField
        PNLabel               matlab.ui.control.Label
        RLthreextratio        matlab.ui.control.NumericEditField
        RLLabel               matlab.ui.control.Label
        dBLabel_5             matlab.ui.control.Label
        dBLabel_6             matlab.ui.control.Label
        peakextratio          matlab.ui.control.NumericEditField
        Label_29              matlab.ui.control.Label
        dBLabel_3             matlab.ui.control.Label
        threextratio          matlab.ui.control.NumericEditField
        Label_27              matlab.ui.control.Label
        dBLabel_4             matlab.ui.control.Label
        peakerrorrate         matlab.ui.control.NumericEditField
        Label_9               matlab.ui.control.Label
        threerrorrate         matlab.ui.control.NumericEditField
        Label_8               matlab.ui.control.Label
        Label_11              matlab.ui.control.Label
        Label_10              matlab.ui.control.Label
        PNRL_Slider           matlab.ui.control.Slider
        SliderLabel           matlab.ui.control.Label
        Panel_7               matlab.ui.container.Panel
        Label_7               matlab.ui.control.Label
        Label_6               matlab.ui.control.Label
        Rstablemin            matlab.ui.control.NumericEditField
        Lstablemin            matlab.ui.control.NumericEditField
        Nstablemin            matlab.ui.control.NumericEditField
        Pstablemin            matlab.ui.control.NumericEditField
        Rstablemax            matlab.ui.control.NumericEditField
        RLabel                matlab.ui.control.Label
        Lstablemax            matlab.ui.control.NumericEditField
        LLabel                matlab.ui.control.Label
        Nstablemax            matlab.ui.control.NumericEditField
        NLabel                matlab.ui.control.Label
        Pstablemax            matlab.ui.control.NumericEditField
        PLabel                matlab.ui.control.Label
        Panel_6               matlab.ui.container.Panel
        L20dB                 matlab.ui.control.NumericEditField
        L20dBLabel            matlab.ui.control.Label
        L3dB                  matlab.ui.control.NumericEditField
        L3dBLabel             matlab.ui.control.Label
        R20dB                 matlab.ui.control.NumericEditField
        R20dBLabel            matlab.ui.control.Label
        R3dB                  matlab.ui.control.NumericEditField
        R3dBLabel             matlab.ui.control.Label
        psLabel_16            matlab.ui.control.Label
        psLabel_15            matlab.ui.control.Label
        N20dB                 matlab.ui.control.NumericEditField
        N20dBLabel            matlab.ui.control.Label
        N3dB                  matlab.ui.control.NumericEditField
        N3dBLabel             matlab.ui.control.Label
        P20dB                 matlab.ui.control.NumericEditField
        P20dBLabel            matlab.ui.control.Label
        P3dB                  matlab.ui.control.NumericEditField
        P3dBLabel             matlab.ui.control.Label
        psLabel_14            matlab.ui.control.Label
        psLabel_13            matlab.ui.control.Label
        psLabel_12            matlab.ui.control.Label
        psLabel_11            matlab.ui.control.Label
        psLabel_10            matlab.ui.control.Label
        psLabel_9             matlab.ui.control.Label
        Panel_4               matlab.ui.container.Panel
        ch4eff                matlab.ui.control.NumericEditField
        ch4Label              matlab.ui.control.Label
        ch3eff                matlab.ui.control.NumericEditField
        ch3Label              matlab.ui.control.Label
        ch2eff                matlab.ui.control.NumericEditField
        ch2Label              matlab.ui.control.Label
        ch1eff                matlab.ui.control.NumericEditField
        ch1Label              matlab.ui.control.Label
        PNRL_MINPEAKDISTANCE  matlab.ui.control.NumericEditField
        Label_22              matlab.ui.control.Label
        binsLabel_4           matlab.ui.control.Label
        ch4DropDownLabel_2    matlab.ui.control.Label
        ch2DropDownLabel_2    matlab.ui.control.Label
        PNRL_dataname         matlab.ui.control.EditField
        Label_12              matlab.ui.control.Label
        PNRLButton            matlab.ui.control.Button
        Lpeakmin              matlab.ui.control.NumericEditField
        LLabel_3              matlab.ui.control.Label
        Lpeakmax              matlab.ui.control.NumericEditField
        LLabel_2              matlab.ui.control.Label
        Rpeakmin              matlab.ui.control.NumericEditField
        RLabel_5              matlab.ui.control.Label
        Rpeakmax              matlab.ui.control.NumericEditField
        RLabel_4              matlab.ui.control.Label
        Npeakmin              matlab.ui.control.NumericEditField
        NLabel_3              matlab.ui.control.Label
        Npeakmax              matlab.ui.control.NumericEditField
        NLabel_2              matlab.ui.control.Label
        Ppeakmin              matlab.ui.control.NumericEditField
        PLabel_3              matlab.ui.control.Label
        Ppeakmax              matlab.ui.control.NumericEditField
        PLabel_2              matlab.ui.control.Label
        psLabel_18            matlab.ui.control.Label
        ch4delay              matlab.ui.control.NumericEditField
        psLabel_17            matlab.ui.control.Label
        ch2delay              matlab.ui.control.NumericEditField
        binsLabel_2           matlab.ui.control.Label
        GHzLabel_2            matlab.ui.control.Label
        PNRL_Binsnum          matlab.ui.control.NumericEditField
        PNRL_Fre              matlab.ui.control.NumericEditField
        Label_5               matlab.ui.control.Label
        DropDown_2            matlab.ui.control.DropDown
        DropDown_2Label       matlab.ui.control.Label
        UIAxes2_L             matlab.ui.control.UIAxes
        UIAxes2_R             matlab.ui.control.UIAxes
        UIAxes2_P             matlab.ui.control.UIAxes
        UIAxes2_N             matlab.ui.control.UIAxes
        Tab                   matlab.ui.container.Tab
        UIAxes5_2             matlab.ui.control.UIAxes
        UIAxes5               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        fileLD
        fileSDV
        filech1 
        filech2 
        filech3 
        filech4
        LD_filename
        SDV_filename % Description
        ch1_filename
        ch2_filename
        ch3_filename
        ch4_filename
        LD_T
        SDV_T
        PNRL_T
        ppeakmax
        ppeakmin
        npeakmax
        npeakmin
        lpeakmax
        lpeakmin
        rpeakmax
        rpeakmin
        thre_term = '手动输入'
        four_term = '手动输入'
    end
    
    methods (Access = public)        

        function [signal,signal_loc,Fremeasured,Cov_Fre,signal_3dBwidth,signal_20dBwidth] = LD_analysis(app)
            LD_timebin = app.fileLD(1,2)-app.fileLD(1,1); %ps
            time0 = app.fileLD(1,:);
            counts0 = app.fileLD(2,:);
            [pks0,locs0] = findpeaks(counts0,'MINPEAKHEIGHT',1,'MINPEAKDISTANCE',app.LD_MINPEAKDISTANCE.Value);  %数据寻峰

            %% 信号态寻峰
            threshold_s =app.LDpeakmin.Value;
            signal = pks0(find(pks0>threshold_s));
            signal_loc_temp = find(pks0>threshold_s);
            signal_loc = locs0(signal_loc_temp);
            
            %% 频率计算
            Frelist = zeros(1,length(signal_loc)-1);
            Cov_Fre = 0;
            for i = 1:length(Frelist)
                Frelist(i) = 10^12/((signal_loc(i+1)-signal_loc(i))*LD_timebin)*10^(-9);
            end
            Fremeasured = sum(Frelist)/length(Frelist);
            for i = 1:length(Frelist)
                Cov_Fre = Cov_Fre+(Frelist(i)-Fremeasured)^2;
            end
            Cov_Fre = sqrt(Cov_Fre/length(Frelist));
            
            %% 3dB宽度计算(仅强度态)
            signal_3dB = signal/2;
            signal_3dBwidth = zeros(1,length(signal)-2);
            for i = 1:1:length(signal_3dBwidth)
                halfpulse_count = round(app.LD_T/LD_timebin/2);
                leftrange = counts0(signal_loc(i+1)-halfpulse_count:signal_loc(i+1));
                rightrange = counts0(signal_loc(i+1):signal_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-signal_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange-signal_3dB(i+1)));
                signal_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*LD_timebin;
            end
            signal_3dBwidth = mean(signal_3dBwidth);        
            
            %% 20dB宽度计算(仅强度态)
            signal_20dB = signal/100;
            signal_20dBwidth = zeros(1,length(signal)-2);
            for i = 1:1:length(signal_20dBwidth)
                halfpulse_count = round(app.LD_T/LD_timebin/2);
                leftrange = counts0(signal_loc(i+1)-halfpulse_count:signal_loc(i+1));
                rightrange = counts0(signal_loc(i+1):signal_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-signal_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange-signal_20dB(i+1)));
                signal_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*LD_timebin;
            end
            signal_20dBwidth = mean(signal_20dBwidth);            
        
        end
        
        function LD_plot(app,signal,signal_loc)
            delete(allchild(app.UIAxes7));   
            LD_timebin = app.fileLD(1,2)-app.fileLD(1,1); %ps
            time0 = app.fileLD(1,:);
            counts0 = app.fileLD(2,:);

            plot(app.UIAxes7,time0,counts0,time0(signal_loc),signal,'or'); % 真作图\
            if app.LDLinearButton.Value
                set(app.UIAxes7,'YScale','linear');
            else
                set(app.UIAxes7,'YScale','log');
            end
            xlim(app.UIAxes7,[1,app.LD_Slider.Value]);
%             ylim(app.UIAxes7,[1,100000]);
            grid(app.UIAxes7,'on');
            xlabel(app.UIAxes7,'time/ps','fontsize',18);
            ylabel(app.UIAxes7,'counts','fontsize',18);
            title(app.UIAxes7,'LD');
        end
        
        function [SDV_timebin, ...
                  signal, signal_loc, Cov_S, signal_3dBwidth, signal_20dBwidth, signal_0p46dBwidth, signal_10dBwidth, ...
                  decoy, decoy_loc, Cov_D, decoy_3dBwidth, decoy_20dBwidth, ER_SD, ER_SD_int, ...
                  vaccum, vaccum_loc, Cov_V, vaccum_3dBwidth, vaccum_20dBwidth, ER_SV, ER_SV_int, ...
                  signal_sum, decoy_sum, vaccum_sum, all_sum ...
                 ] = SDV_analysis(app,bins)
            SDV_timebin = app.fileSDV(1,2)-app.fileSDV(1,1); %ps
            time0 = app.fileSDV(1,:);
            counts0 = app.fileSDV(2,:);
            [pks0,locs0] = findpeaks(counts0,'MINPEAKHEIGHT',1,'MINPEAKDISTANCE',app.SDV_MINPEAKDISTANCE.Value);  %数据寻峰
            
            %% 信号态寻峰
            threshold_s = app.Speakmin.Value;
            signal = pks0(find(pks0>threshold_s));
            signal_loc_temp = find(pks0>threshold_s);
            signal_loc = locs0(signal_loc_temp);
            
            %% 真空态寻峰
            threshold_v = app.Vpeakmax.Value;
            vaccum = pks0(find(pks0<threshold_v));
            vaccum_loc_temp = find(pks0<threshold_v);
            vaccum_loc = locs0(vaccum_loc_temp);
            
            %% 诱骗态寻峰
            decoy1 = pks0(find(pks0<threshold_s));
            decoy1_loc_temp = find(pks0<threshold_s);
            decoy1_loc = locs0(decoy1_loc_temp);
            decoy = decoy1(find(decoy1>threshold_v));
            decoy_loc_temp1 = find(decoy1>threshold_v);
            decoy_loc = decoy1_loc(decoy_loc_temp1);
            
            signal_ave = mean(signal);
            decoy_ave = mean(decoy);
            vaccum_ave = mean(vaccum);
            ER_SV = 10*log10(signal_ave/vaccum_ave);   
            ER_SD = 10*log10(signal_ave/decoy_ave);  
                
            signal_int = zeros(1,length(signal)-2);
            vaccum_int = zeros(1,length(vaccum)-2);
            decoy_int = zeros(1,length(decoy)-2);
            
            for i = 1:1:length(signal_int)
                signal_int(i) = sum(counts0(signal_loc(i+1)-bins/2:signal_loc(i+1)+bins/2));
            end
            for j = 1:1:length(vaccum_int)
                vaccum_int(j) = sum(counts0(vaccum_loc(j+1)-bins/2:vaccum_loc(j+1)+bins/2));
            end
            for k = 1:1:length(decoy_int)
                decoy_int(k) = sum(counts0(decoy_loc(k+1)-bins/2:decoy_loc(k+1)+bins/2));
            end
            signal_sum = sum(signal_int);
            decoy_sum = sum(decoy_int);
            vaccum_sum = sum(vaccum_int);
            all_sum = signal_sum + decoy_sum + vaccum_sum;
            signal_int_ave = mean(signal_int);
            vaccum_int_ave = mean(vaccum_int);
            decoy_int_ave = mean(decoy_int);
            ER_SV_int = 10*log10(signal_int_ave/vaccum_int_ave);
            ER_SD_int = 10*log10(signal_int_ave/decoy_int_ave);
            
            %% 变异系数
            Cov_S = sqrt(var(signal))/mean(signal);
            Cov_D = sqrt(var(decoy))/mean(decoy);
            Cov_V = sqrt(var(vaccum))/mean(vaccum);
            
            %% 3dB宽度计算(仅强度态)
            signal_3dB = signal/2;
            signal_3dBwidth = zeros(1,length(signal)-2);
            for i = 1:1:length(signal_3dBwidth)
                halfpulse_count = round(app.SDV_T/SDV_timebin/2);
                leftrange = counts0(signal_loc(i+1)-halfpulse_count:signal_loc(i+1));
                rightrange = counts0(signal_loc(i+1):signal_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-signal_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange-signal_3dB(i+1)));
                signal_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*SDV_timebin;
            end
            signal_3dBwidth = mean(signal_3dBwidth);
            
            %% 20dB宽度计算(仅强度态)
            signal_20dB = signal/100;
            signal_20dBwidth = zeros(1,length(signal)-2);
            for i = 1:1:length(signal_20dBwidth)
                halfpulse_count = round(app.SDV_T/SDV_timebin/2);
                leftrange = counts0(signal_loc(i+1)-halfpulse_count:signal_loc(i+1));
                rightrange = counts0(signal_loc(i+1):signal_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-signal_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange-signal_20dB(i+1)));
                signal_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*SDV_timebin;
            end
            signal_20dBwidth = mean(signal_20dBwidth);
            
            %% 0.46dB宽度计算(仅强度态)
            signal_0p46dB = signal*0.9;
            signal_0p46dBwidth = zeros(1,length(signal)-2);
            for i = 1:1:length(signal_0p46dBwidth)
                halfpulse_count = round(app.SDV_T/SDV_timebin/2);
                leftrange = counts0(signal_loc(i+1)-halfpulse_count:signal_loc(i+1));
                rightrange = counts0(signal_loc(i+1):signal_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-signal_0p46dB(i+1)));
                [~, rightindex] = min(abs(rightrange-signal_0p46dB(i+1)));
                signal_0p46dBwidth(i) = (halfpulse_count-leftindex+rightindex)*SDV_timebin;
            end
            signal_0p46dBwidth = mean(signal_0p46dBwidth);
            
            %% 21dB宽度计算(仅强度态)
            signal_10dB = signal/10;
            signal_10dBwidth = zeros(1,length(signal)-2);
            for i = 1:1:length(signal_10dBwidth)
                halfpulse_count = round(app.SDV_T/SDV_timebin/2);
                leftrange = counts0(signal_loc(i+1)-halfpulse_count:signal_loc(i+1));
                rightrange = counts0(signal_loc(i+1):signal_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-signal_10dB(i+1)));
                [~, rightindex] = min(abs(rightrange-signal_10dB(i+1)));
                signal_10dBwidth(i) = (halfpulse_count-leftindex+rightindex)*SDV_timebin;
            end
            signal_10dBwidth = mean(signal_10dBwidth);
            
            %% 3dB宽度计算(仅诱骗态)
            decoy_3dB = decoy/2;
            decoy_3dBwidth = zeros(1,length(decoy)-2);
            for i = 1:1:length(decoy_3dBwidth)
                halfpulse_count = round(app.SDV_T/SDV_timebin/2);
                leftrange = counts0(decoy_loc(i+1)-halfpulse_count:decoy_loc(i+1));
                rightrange = counts0(decoy_loc(i+1):decoy_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-decoy_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange-decoy_3dB(i+1)));
                decoy_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*SDV_timebin;
            end
            decoy_3dBwidth = mean(decoy_3dBwidth);
            
            %% 20dB宽度计算(仅诱骗态)
            decoy_20dB = decoy/100;
            decoy_20dBwidth = zeros(1,length(decoy)-2);
            for i = 1:1:length(decoy_20dBwidth)
                halfpulse_count = round(app.SDV_T/SDV_timebin/2);
                leftrange = counts0(decoy_loc(i+1)-halfpulse_count:decoy_loc(i+1));
                rightrange = counts0(decoy_loc(i+1):decoy_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-decoy_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange-decoy_20dB(i+1)));
                decoy_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*SDV_timebin;
            end
            decoy_20dBwidth = mean(decoy_20dBwidth);
            
            %% 3dB宽度计算(仅真空态)
            vaccum_3dB = vaccum/2;
            vaccum_3dBwidth = zeros(1,length(vaccum)-2);
            for i = 1:1:length(vaccum_3dBwidth)
                halfpulse_count = round(app.SDV_T/SDV_timebin/2);
                leftrange = counts0(vaccum_loc(i+1)-halfpulse_count:vaccum_loc(i+1));
                rightrange = counts0(vaccum_loc(i+1):vaccum_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-vaccum_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange-vaccum_3dB(i+1)));
                vaccum_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*SDV_timebin;
            end
            vaccum_3dBwidth = mean(vaccum_3dBwidth);
            
            %% 20dB宽度计算(仅真空态)
            vaccum_20dB = vaccum/100;
            vaccum_20dBwidth = zeros(1,length(vaccum)-2);
            for i = 1:1:length(vaccum_20dBwidth)
                halfpulse_count = round(app.SDV_T/SDV_timebin/2);
                leftrange = counts0(vaccum_loc(i+1)-halfpulse_count:vaccum_loc(i+1));
                rightrange = counts0(vaccum_loc(i+1):vaccum_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-vaccum_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange-vaccum_20dB(i+1)));
                vaccum_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*SDV_timebin;
            end
            vaccum_20dBwidth = mean(vaccum_20dBwidth);
        end
        
        function SDV_plot(app,signal, signal_loc, ...
                  decoy, decoy_loc, ER_SD, ER_SD_int, ...
                  vaccum, vaccum_loc, ER_SV, ER_SV_int ...
                   )
            delete(allchild(app.UIAxes));   
            SDV_timebin = app.fileSDV(1,2)-app.fileSDV(1,1); %ps
            time0 = app.fileSDV(1,:);
            counts0 = app.fileSDV(2,:);
            
            dB1 = strcat('ave ER of S&V peaks = ',num2str(ER_SV),'dB');
            dB2 = strcat('ave ER of S&V pulses = ',num2str(ER_SV_int),'dB');
            dB3 = strcat('ave ER of S&D peaks = ',num2str(ER_SD),'dB');
            dB4 = strcat('ave ER of S&D pulses = ',num2str(ER_SD_int),'dB');
            plot(app.UIAxes,time0,counts0,time0(signal_loc),signal,'or',time0(vaccum_loc),vaccum,'sm',time0(decoy_loc),decoy,'+b'); % 真空态信号态诱骗态作图
            if app.SDVLinearButton.Value
                set(app.UIAxes,'YScale','linear');
                ylim(app.UIAxes,[1,max(counts0)+100]);
                text(app.UIAxes,1,max(counts0)+80,dB1);
                text(app.UIAxes,30000*app.SDV_Slider.Value/50000,max(counts0)+80,dB2);
                text(app.UIAxes,1,max(counts0)+50,dB3);
                text(app.UIAxes,30000*app.SDV_Slider.Value/50000,max(counts0)+50,dB4);
            else
                set(app.UIAxes,'YScale','log');
                ylim(app.UIAxes,[1,100000]);
                text(app.UIAxes,1,80000,dB1);
                text(app.UIAxes,30000*app.SDV_Slider.Value/50000,80000,dB2);
                text(app.UIAxes,1,50000,dB3);
                text(app.UIAxes,30000*app.SDV_Slider.Value/50000,50000,dB4);
            end
            xlim(app.UIAxes,[1,app.SDV_Slider.Value]);
%             ylim(app.UIAxes,[1,100000]);
            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'time/ps','fontsize',18);
            ylabel(app.UIAxes,'counts','fontsize',18);
            title(app.UIAxes,'AM Rand');
        end

        function  [binslist,width,errorate,signal_sumratio_list,decoy_sumratio_list,vaccum_sumratio_list,all_sumratio_list] ...
            = SDV_binwidth_analysis(app,SDV_timebin)
            binslist = 0:round(app.SDV_T/2/SDV_timebin);
            width = binslist*SDV_timebin;
            errorate = zeros(1,length(binslist));
            signal_sum_list = zeros(1,length(binslist));
            decoy_sum_list = zeros(1,length(binslist));
            vaccum_sum_list = zeros(1,length(binslist));
            all_sum_list = zeros(1,length(binslist));
            signal_sumratio_list = zeros(1,length(binslist));
            decoy_sumratio_list = zeros(1,length(binslist));
            vaccum_sumratio_list = zeros(1,length(binslist));
            all_sumratio_list = zeros(1,length(binslist));             
            for i = 1:length(binslist)
                [ SDV_timebin, ...
                  signal, signal_loc, Cov_S, signal_3dBwidth, signal_20dBwidth, signal_0p46dBwidth, signal_10dBwidth, ...
                  decoy, decoy_loc, Cov_D, decoy_3dBwidth, decoy_20dBwidth, ER_SD, ER_SD_int, ...
                  vaccum, vaccum_loc, Cov_V, vaccum_3dBwidth, vaccum_20dBwidth, ER_SV, ER_SV_int, ...
                  signal_sum, decoy_sum, vaccum_sum, all_sum ...
                 ] = SDV_analysis(app,binslist(i));
                errorate(i) = 1/10^(ER_SV_int/10)*100;
                signal_sum_list(i) = signal_sum;
                decoy_sum_list(i) = decoy_sum;
                vaccum_sum_list(i) = vaccum_sum;
                all_sum_list(i) = all_sum;
            end
            for i = 1:length(binslist)
                signal_sumratio_list(i) = signal_sum_list(i)/signal_sum_list(length(binslist))*100;
                decoy_sumratio_list(i) = decoy_sum_list(i)/decoy_sum_list(length(binslist))*100;
                vaccum_sumratio_list(i) = vaccum_sum_list(i)/vaccum_sum_list(length(binslist))*100;
                all_sumratio_list(i) = all_sum_list(i)/all_sum_list(length(binslist))*100;
            end
        end
         
        function SDV_binwidth_analysis_plot(app, width, errorate, signal_sumratio_list, decoy_sumratio_list, vaccum_sumratio_list, all_sumratio_list)          
            delete(allchild(app.UIAxes5_2)); 
            yyaxis(app.UIAxes5_2,"left");
            % 左侧Y轴（Extinction Ratio）
            p1 = plot(app.UIAxes5_2,width, errorate, '-*');
            ylabel(app.UIAxes5_2,'Extinction Ratio (%)', 'fontsize', 18, 'FontName', 'Arial');
            
            yyaxis(app.UIAxes5_2,"right");
            % 右侧Y轴（Photon Number Ratio）
            p2 = plot(app.UIAxes5_2,width,signal_sumratio_list,'c-*');
            hold(app.UIAxes5_2,'on');
            p3 = plot(app.UIAxes5_2,width,decoy_sumratio_list,'g-*');
            hold(app.UIAxes5_2,'on');
            p4 = plot(app.UIAxes5_2,width,vaccum_sumratio_list,'m-*');
            hold(app.UIAxes5_2,'on');
            p5 = plot(app.UIAxes5_2,width,all_sumratio_list,'-*');
            ylabel(app.UIAxes5_2,'Photon Number Ratio (%)', 'fontsize', 18, 'FontName', 'Arial');
            
            p1.LineWidth = 1;
            p2.LineWidth = 1;
            p3.LineWidth = 1;
            p4.LineWidth = 1;
            p5.LineWidth = 1;
            
            % 添加图例
            lgd = legend(app.UIAxes5_2,'Extinction Ratio', 'Signal Sum Ratio', 'Decoy Sum Ratio', 'Vaccum Sum Ratio', 'All Sum Ratio','Location', 'southeast');
            lgd.Box = 'off';
            
            % 显示网格
            grid(app.UIAxes5_2,'on');
            
            % 设置坐标轴标签和标题
            xlabel(app.UIAxes5_2,'Bin Width (ps)', 'fontsize', 18, 'FontName', 'Arial');
            title(app.UIAxes5_2,'SDV Bin Width Analysis', 'fontsize', 21, 'FontName', 'Arial');
        end

        
        function [PNRL_timebin, ...
            P_PC1_ch1, locs1_P, P_PC1_ch2, locs2_P, ER_P, ER_P_int, P_PC1_ch1_3dBwidth, P_PC1_ch1_20dBwidth, Pstablemax, Pstablemin, ...
            N_PC1_ch1, locs1_N, N_PC1_ch2, locs2_N, ER_N, ER_N_int, N_PC1_ch2_3dBwidth, N_PC1_ch2_20dBwidth, Nstablemax, Nstablemin, ...
            R_PC2_ch3, locs1_R, R_PC2_ch4, locs2_R, ER_R, ER_R_int, R_PC2_ch3_3dBwidth, R_PC2_ch3_20dBwidth, Rstablemax, Rstablemin, ...
            L_PC2_ch3, locs1_L, L_PC2_ch4, locs2_L, ER_L, ER_L_int, L_PC2_ch4_3dBwidth, L_PC2_ch4_20dBwidth, Lstablemax, Lstablemin, ...
            peakerrorrate, threerrorrate, peakextratio, threextratio, PNpeakextratio, PNthreextratio, RLpeakextratio, RLthreextratio, ...
            P_sum, N_sum, R_sum, L_sum, all_sum, ...
            P_sum_fullwidth, N_sum_fullwidth, R_sum_fullwidth, L_sum_fullwidth, PDL ...
            ] = PNRL_analysis(app,bins) 
            
            % 无用的值
            P_sum_fullwidth = 1;
            N_sum_fullwidth = 1;
            R_sum_fullwidth = 1;
            L_sum_fullwidth = 1;
            
            PNRL_timebin = app.filech1(1,2)-app.filech1(1,1); %ps    
            time1 = app.filech1(1,:);
            counts1 = app.filech1(2,:);
            time2 = app.filech2(1,:);
            counts2 = app.filech2(2,:);
            time3 = app.filech3(1,:);
            counts3 = app.filech3(2,:);
            time4 = app.filech4(1,:);
            counts4 = app.filech4(2,:);
            
            [pks1,locs1] = findpeaks(counts1,'MINPEAKHEIGHT',3,'MINPEAKDISTANCE',app.PNRL_MINPEAKDISTANCE.Value);  %PC1 ch1通道数据寻峰
            [pks2,locs2] = findpeaks(counts2,'MINPEAKHEIGHT',3,'MINPEAKDISTANCE',app.PNRL_MINPEAKDISTANCE.Value);  %PC1 ch2通道数据寻峰
            [pks3,locs3] = findpeaks(counts3,'MINPEAKHEIGHT',3,'MINPEAKDISTANCE',app.PNRL_MINPEAKDISTANCE.Value);  %PC2 ch3通道数据寻峰
            [pks4,locs4] = findpeaks(counts4,'MINPEAKHEIGHT',3,'MINPEAKDISTANCE',app.PNRL_MINPEAKDISTANCE.Value);  %PC2 ch4通道数据寻峰      
            
            % P在PC1 ch1 下
            thr_P_PC1_ch1 = app.Ppeakmax.Value;                       % P态在PC1下ch1通道的阈值
            locs1_P_temp = find(pks1>thr_P_PC1_ch1);     % P态在寻峰序列中的位置       PC1下ch1
            P_PC1_ch1 = pks1(locs1_P_temp);              % P态峰值强度                PC1下ch1
            locs1_P = locs1(locs1_P_temp);               % P态在时间序列中的位置      PC1下ch1
            
            % P在PC1 ch2 下
            thr_P_PC1_ch2 = app.Ppeakmin.Value;                       % P态在PC1下ch2通道的阈值
            locs2_P_temp = find(pks2<thr_P_PC1_ch2);     % P态在寻峰序列中的位置       PC1下ch2
            P_PC1_ch2 = pks2(locs2_P_temp);              % P态峰值强度                PC1下ch2
            locs2_P = locs2(locs2_P_temp);               % P态在时间序列中的位置      PC1下ch2
            
            % P态峰值比
            P_max_ave = mean(P_PC1_ch1)/app.ch1eff.Value;    % 超导效率归一化
            P_min_ave = mean(P_PC1_ch2)/app.ch2eff.Value;
            ER_P = 10*log10(P_max_ave/P_min_ave);          
            
            % 门宽积分比和全宽积分
            P_PC1_ch1_int = zeros(1,length(P_PC1_ch1)-2); %  P态在PC1 ch1通道中脉冲半高宽积分
%             P_PC1_ch1_int_fullwidth = zeros(1,length(P_PC1_ch1)-2);
            for i = 1:length(P_PC1_ch1_int)
                P_PC1_ch1_int(i) = sum(counts1(locs1_P(i+1)-bins/2:locs1_P(i+1)+bins/2));   
%                 P_PC1_ch1_int_fullwidth(i) = sum(counts1(locs1_P(i+1)-round(app.PNRL_T/2/PNRL_timebin):locs1_P(i+1)+round(app.PNRL_T/2/PNRL_timebin)));  
            end
            P_PC1_ch2_int = zeros(1,length(P_PC1_ch2)-2); %  P态在PC1 ch2通道中脉冲半高宽积分
%             P_PC1_ch2_int_fullwidth = zeros(1,length(P_PC1_ch2)-2);
            for i = 1:length(P_PC1_ch2_int)
                P_PC1_ch2_int(i) = sum(counts2(locs2_P(i+1)-bins/2:locs2_P(i+1)+bins/2));
%                 P_PC1_ch2_int_fullwidth(i) = sum(counts2(locs2_P(i+1)-round(app.PNRL_T/2/PNRL_timebin):locs2_P(i+1)+round(app.PNRL_T/2/PNRL_timebin)));
            end
            P_PC1_ch1_int_ave = mean(P_PC1_ch1_int)/app.ch1eff.Value;    % 超导效率归一化
            P_PC1_ch2_int_ave = mean(P_PC1_ch2_int)/app.ch2eff.Value;
%             P_PC1_ch1_int_fullwidth_ave = mean(P_PC1_ch1_int_fullwidth)/app.ch1eff.Value;
%             P_PC1_ch2_int_fullwidth_ave = mean(P_PC1_ch2_int_fullwidth)/app.ch2eff.Value;
            ER_P_int = 10*log10(P_PC1_ch1_int_ave/P_PC1_ch2_int_ave);
            
            %% 3dB宽度计算(仅P态)
            P_PC1_ch1_3dB = P_PC1_ch1/2;
            P_PC1_ch1_3dBwidth = zeros(1,length(P_PC1_ch1)-2);
            halfpulse_count = round(app.PNRL_T/PNRL_timebin/2);
            for i = 1:1:length(P_PC1_ch1_3dBwidth)
                leftrange = counts1(locs1_P(i+1)-halfpulse_count:locs1_P(i+1));
                rightrange = counts1(locs1_P(i+1):locs1_P(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- P_PC1_ch1_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange- P_PC1_ch1_3dB(i+1)));
                P_PC1_ch1_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            P_PC1_ch1_3dBwidth = mean(P_PC1_ch1_3dBwidth);
            
            %% 20dB宽度计算(仅P态)
            P_PC1_ch1_20dB = P_PC1_ch1/100;
            P_PC1_ch1_20dBwidth = zeros(1,length(P_PC1_ch1)-2);
            halfpulse_count = round(app.PNRL_T/PNRL_timebin/2);
            for i = 1:1:length(P_PC1_ch1_20dBwidth)
                leftrange = counts1(locs1_P(i+1)-halfpulse_count:locs1_P(i+1));
                rightrange = counts1(locs1_P(i+1):locs1_P(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- P_PC1_ch1_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange- P_PC1_ch1_20dB(i+1)));
                P_PC1_ch1_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            P_PC1_ch1_20dBwidth = mean(P_PC1_ch1_20dBwidth);
            
            Pstablemax = sqrt(var(P_PC1_ch1/app.ch1eff.Value))/P_max_ave;
            Pstablemin = sqrt(var(P_PC1_ch2/app.ch2eff.Value))/P_min_ave;
            P_sum = P_PC1_ch1_int_ave+P_PC1_ch2_int_ave;
%             P_sum_fullwidth = P_PC1_ch1_int_fullwidth_ave+P_PC1_ch2_int_fullwidth_ave;     
            
            % N在PC1 ch1 下
            thr_N_PC1_ch1 = app.Npeakmin.Value;                         % N态在PC1下ch1通道的阈值
            locs1_N_temp = find(pks1<thr_N_PC1_ch1);     % N态在寻峰序列中的位置       PC1下ch1
            N_PC1_ch1 = pks1(locs1_N_temp);              % N态峰值强度                PC1下ch1
            locs1_N = locs1(locs1_N_temp);               % N态在时间序列中的位置       PC1下ch1
            
            % N在PC1 ch2 下
            thr_N_PC1_ch2 = app.Npeakmax.Value;                        % N态在PC1下ch2通道的阈值
            locs2_N_temp = find(pks2>thr_N_PC1_ch2);     % N态在寻峰序列中的位置       PC1下ch2
            N_PC1_ch2 = pks2(locs2_N_temp);              % N态峰值强度                PC1下ch2
            locs2_N = locs2(locs2_N_temp);               % N态在时间序列中的位置      PC1下ch2
            
            % N态峰值比
            N_max_ave = mean(N_PC1_ch2)/app.ch2eff.Value;    % 超导效率归一化
            N_min_ave = mean(N_PC1_ch1)/app.ch1eff.Value;
            ER_N = 10*log10(N_max_ave/N_min_ave);       
            
            % 门宽积分比和全宽积分
            N_PC1_ch1_int = zeros(1,length(N_PC1_ch1)-2); %  N态在PC1 ch1通道中脉冲半高宽积分
%             N_PC1_ch1_int_fullwidth = zeros(1,length(N_PC1_ch1)-2);
            for i = 1:length(N_PC1_ch1_int)
                N_PC1_ch1_int(i) = sum(counts1(locs1_N(i+1)-bins/2:locs1_N(i+1)+bins/2));
%                 N_PC1_ch1_int_fullwidth(i) = sum(counts1(locs1_N(i+1)-round(app.PNRL_T/2/PNRL_timebin):locs1_N(i+1)+round(app.PNRL_T/2/PNRL_timebin)));
            end
            N_PC1_ch2_int = zeros(1,length(N_PC1_ch2)-2); %  N态在PC1 ch2通道中脉冲半高宽积分
%             N_PC1_ch2_int_fullwidth = zeros(1,length(N_PC1_ch2)-2);
            for i = 1:length(N_PC1_ch2_int)
                N_PC1_ch2_int(i) = sum(counts2(locs2_N(i+1)-bins/2:locs2_N(i+1)+bins/2));
%                 N_PC1_ch2_int_fullwidth(i) = sum(counts2(locs2_N(i+1)-round(app.PNRL_T/2/PNRL_timebin):locs2_N(i+1)+round(app.PNRL_T/2/PNRL_timebin)));
            end
            N_PC1_ch1_int_ave = mean(N_PC1_ch1_int)/app.ch1eff.Value;    % 超导效率归一化
            N_PC1_ch2_int_ave = mean(N_PC1_ch2_int)/app.ch2eff.Value;
%             N_PC1_ch1_int_fullwidth_ave = mean(N_PC1_ch1_int_fullwidth)/app.ch1eff.Value; 
%             N_PC1_ch2_int_fullwidth_ave = mean(N_PC1_ch2_int_fullwidth)/app.ch2eff.Value;
            ER_N_int = 10*log10(N_PC1_ch2_int_ave/N_PC1_ch1_int_ave);
            
            %% 3dB宽度计算(仅N态)
            N_PC1_ch2_3dB = N_PC1_ch2/2;
            N_PC1_ch2_3dBwidth = zeros(1,length(N_PC1_ch2)-2);
            halfpulse_count = round(app.PNRL_T/PNRL_timebin/2);
            for i = 1:1:length(N_PC1_ch2_3dBwidth)
                leftrange = counts2(locs2_N(i+1)-halfpulse_count:locs2_N(i+1));
                rightrange = counts2(locs2_N(i+1):locs2_N(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- N_PC1_ch2_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange- N_PC1_ch2_3dB(i+1)));
                N_PC1_ch2_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            N_PC1_ch2_3dBwidth = mean(N_PC1_ch2_3dBwidth);
            
            %% 20dB宽度计算(仅N态)
            N_PC1_ch2_20dB = N_PC1_ch2/100;
            N_PC1_ch2_20dBwidth = zeros(1,length(N_PC1_ch2)-2);
            halfpulse_count = round(app.PNRL_T/PNRL_timebin/2);
            for i = 1:1:length(N_PC1_ch2_20dBwidth)
                leftrange = counts2(locs2_N(i+1)-halfpulse_count:locs2_N(i+1));
                rightrange = counts2(locs2_N(i+1):locs2_N(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- N_PC1_ch2_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange- N_PC1_ch2_20dB(i+1)));
                N_PC1_ch2_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            N_PC1_ch2_20dBwidth = mean(N_PC1_ch2_20dBwidth);
            
            Nstablemax = sqrt(var(N_PC1_ch2/app.ch2eff.Value))/N_max_ave;
            Nstablemin = sqrt(var(N_PC1_ch1/app.ch1eff.Value))/N_min_ave;
            N_sum = N_PC1_ch1_int_ave+N_PC1_ch2_int_ave;
%             N_sum_fullwidth = N_PC1_ch1_int_fullwidth_ave+N_PC1_ch2_int_fullwidth_ave;
            
            % R在PC2 ch3 下
            thr_R_PC2_ch3 = app.Rpeakmax.Value;                       % R态在PC2下ch3通道的阈值
            locs1_R_temp = find(pks3>thr_R_PC2_ch3);     % R态在寻峰序列中的位置       PC2下ch3
            R_PC2_ch3 = pks3(locs1_R_temp);              % R态峰值强度                PC2下ch3
            locs1_R = locs3(locs1_R_temp);               % R态在时间序列中的位置      PC2下ch3
            
            % R在PC2 ch4 下
            thr_R_PC2_ch4 = app.Rpeakmin.Value;                       % R态在PC2下ch4通道的阈值
            locs2_R_temp = find(pks4<thr_R_PC2_ch4);     % R态在寻峰序列中的位置       PC2下ch4
            R_PC2_ch4 = pks4(locs2_R_temp);              % R态峰值强度                PC2下ch4
            locs2_R = locs4(locs2_R_temp);               % R态在时间序列中的位置      PC2下ch4
            
            % R态峰值比
            R_max_ave = mean(R_PC2_ch3)/app.ch3eff.Value;    % 超导效率归一化
            R_min_ave = mean(R_PC2_ch4)/app.ch4eff.Value;
            ER_R = 10*log10(R_max_ave/R_min_ave);          
            
            % 门宽积分比和全宽积分
            R_PC2_ch3_int = zeros(1,length(R_PC2_ch3)-2); %  R态在PC2 ch3通道中脉冲半高宽积分
%             R_PC2_ch3_int_fullwidth = zeros(1,length(R_PC2_ch3)-2);
            for i = 1:length(R_PC2_ch3_int)
                R_PC2_ch3_int(i) = sum(counts3(locs1_R(i+1)-bins/2:locs1_R(i+1)+bins/2));
%                 R_PC2_ch3_int_fullwidth(i) = sum(counts3(locs1_R(i+1)-round(app.PNRL_T/2/PNRL_timebin):locs1_R(i+1)+round(app.PNRL_T/2/PNRL_timebin)));
            end
            R_PC2_ch4_int = zeros(1,length(R_PC2_ch4)-2); %  R态在PC2 ch4通道中脉冲半高宽积分
%             R_PC2_ch4_int_fullwidth = zeros(1,length(R_PC2_ch4)-2);
            for i = 1:length(R_PC2_ch4_int)
                R_PC2_ch4_int(i) = sum(counts4(locs2_R(i+1)-bins/2:locs2_R(i+1)+bins/2));
%                 R_PC2_ch4_int_fullwidth(i) = sum(counts4(locs2_R(i+1)-round(app.PNRL_T/2/PNRL_timebin):locs2_R(i+1)+round(app.PNRL_T/2/PNRL_timebin)));
            end
            R_PC2_ch3_int_ave = mean(R_PC2_ch3_int)/app.ch3eff.Value;    % 超导效率归一化
            R_PC2_ch4_int_ave = mean(R_PC2_ch4_int)/app.ch4eff.Value;
%             R_PC2_ch3_int_fullwidth_ave = mean(R_PC2_ch3_int_fullwidth)/app.ch3eff.Value;   
%             R_PC2_ch4_int_fullwidth_ave = mean(R_PC2_ch4_int_fullwidth)/app.ch4eff.Value;
            ER_R_int = 10*log10(R_PC2_ch3_int_ave/R_PC2_ch4_int_ave);
            
            %% 3dB宽度计算(仅R态)
            R_PC2_ch3_3dB = R_PC2_ch3/2;
            R_PC2_ch3_3dBwidth = zeros(1,length(R_PC2_ch3)-2);
            halfpulse_count = round(app.PNRL_T/PNRL_timebin/2);
            for i = 1:1:length(R_PC2_ch3_3dBwidth)
                leftrange = counts3(locs1_R(i+1)-halfpulse_count:locs1_R(i+1));
                rightrange = counts3(locs1_R(i+1):locs1_R(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- R_PC2_ch3_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange- R_PC2_ch3_3dB(i+1)));
                R_PC2_ch3_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            R_PC2_ch3_3dBwidth = mean(R_PC2_ch3_3dBwidth);
            
            %% 20dB宽度计算(仅R态)
            R_PC2_ch3_20dB = R_PC2_ch3/100;
            R_PC2_ch3_20dBwidth = zeros(1,length(R_PC2_ch3)-2);
            halfpulse_count = round(app.PNRL_T/PNRL_timebin/2);
            for i = 1:1:length(R_PC2_ch3_20dBwidth)
                leftrange = counts3(locs1_R(i+1)-halfpulse_count:locs1_R(i+1));
                rightrange = counts3(locs1_R(i+1):locs1_R(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- R_PC2_ch3_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange- R_PC2_ch3_20dB(i+1)));
                R_PC2_ch3_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            R_PC2_ch3_20dBwidth = mean(R_PC2_ch3_20dBwidth);     
            
            Rstablemax = sqrt(var(R_PC2_ch3/app.ch3eff.Value))/R_max_ave;
            Rstablemin = sqrt(var(R_PC2_ch4/app.ch4eff.Value))/R_min_ave;
            R_sum = R_PC2_ch3_int_ave+R_PC2_ch4_int_ave;
%             R_sum_fullwidth = R_PC2_ch3_int_fullwidth_ave+R_PC2_ch4_int_fullwidth_ave;
            
            % L在PC2 ch3 下
            thr_L_PC2_ch3 = app.Lpeakmin.Value;                         % L态在PC2下ch3通道的阈值
            locs1_L_temp = find(pks3<thr_L_PC2_ch3);     % L态在寻峰序列中的位置       PC2下ch3
            L_PC2_ch3 = pks3(locs1_L_temp);              % L态峰值强度                PC2下ch3
            locs1_L = locs3(locs1_L_temp);               % L态在时间序列中的位置       PC2下ch3
            
            % L在PC2 ch4 下
            thr_L_PC2_ch4 = app.Lpeakmax.Value;                        % L态在PC2下ch4通道的阈值
            locs2_L_temp = find(pks4>thr_L_PC2_ch4);     % L态在寻峰序列中的位置       PC2下ch4
            L_PC2_ch4 = pks4(locs2_L_temp);              % L态峰值强度                PC2下ch4
            locs2_L = locs4(locs2_L_temp);               % L态在时间序列中的位置      PC2下ch4
            
            % L态峰值比
            L_max_ave = mean(L_PC2_ch4)/app.ch4eff.Value;    % 超导效率归一化
            L_min_ave = mean(L_PC2_ch3)/app.ch3eff.Value;
            ER_L = 10*log10(L_max_ave/L_min_ave);       
            
            % 门宽积分比和全宽积分
            L_PC2_ch3_int = zeros(1,length(L_PC2_ch3)-2); %  L态在PC2 ch3通道中脉冲半高宽积分
%             L_PC2_ch3_int_fullwidth = zeros(1,length(L_PC2_ch3)-2);
            for i = 1:length(L_PC2_ch3_int)
                L_PC2_ch3_int(i) = sum(counts3(locs1_L(i+1)-bins/2:locs1_L(i+1)+bins/2));
%                 L_PC2_ch3_int_fullwidth(i) = sum(counts3(locs1_L(i+1)-round(app.PNRL_T/2/PNRL_timebin):locs1_L(i+1)+round(app.PNRL_T/2/PNRL_timebin)));
            end
            L_PC2_ch4_int = zeros(1,length(L_PC2_ch4)-2); %  L态在PC2 ch4通道中脉冲半高宽积分
%             L_PC2_ch4_int_fullwidth = zeros(1,length(L_PC2_ch4)-2);
            for i = 1:length(L_PC2_ch4_int)
                L_PC2_ch4_int(i) = sum(counts4(locs2_L(i+1)-bins/2:locs2_L(i+1)+bins/2));
%                 L_PC2_ch4_int_fullwidth(i) = sum(counts4(locs2_L(i+1)-round(app.PNRL_T/2/PNRL_timebin):locs2_L(i+1)+round(app.PNRL_T/2/PNRL_timebin)));
            end
            L_PC2_ch3_int_ave = mean(L_PC2_ch3_int)/app.ch3eff.Value;    % 超导效率归一化
            L_PC2_ch4_int_ave = mean(L_PC2_ch4_int)/app.ch4eff.Value;
%             L_PC2_ch3_int_fullwidth_ave = mean(L_PC2_ch3_int_fullwidth)/app.ch3eff.Value;
%             L_PC2_ch4_int_fullwidth_ave = mean(L_PC2_ch4_int_fullwidth)/app.ch4eff.Value;
            ER_L_int = 10*log10(L_PC2_ch4_int_ave/L_PC2_ch3_int_ave);
            
            %% 3dB宽度计算(仅L态)
            L_PC2_ch4_3dB = L_PC2_ch4/2;
            L_PC2_ch4_3dBwidth = zeros(1,length(L_PC2_ch4)-2);
            halfpulse_count = round(app.PNRL_T/PNRL_timebin/2);
            for i = 1:1:length(L_PC2_ch4_3dBwidth)
                leftrange = counts4(locs2_L(i+1)-halfpulse_count:locs2_L(i+1));
                rightrange = counts4(locs2_L(i+1):locs2_L(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- L_PC2_ch4_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange- L_PC2_ch4_3dB(i+1)));
                L_PC2_ch4_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            L_PC2_ch4_3dBwidth = mean(L_PC2_ch4_3dBwidth);
            
            %% 20dB宽度计算(仅L态)
            L_PC2_ch4_20dB = L_PC2_ch4/100;
            L_PC2_ch4_20dBwidth = zeros(1,length(L_PC2_ch4)-2);
            halfpulse_count = round(app.PNRL_T/PNRL_timebin/2);
            for i = 1:1:length(L_PC2_ch4_20dBwidth)
                leftrange = counts4(locs2_L(i+1)-halfpulse_count:locs2_L(i+1));
                rightrange = counts4(locs2_L(i+1):locs2_L(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- L_PC2_ch4_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange- L_PC2_ch4_20dB(i+1)));
                L_PC2_ch4_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            L_PC2_ch4_20dBwidth = mean(L_PC2_ch4_20dBwidth);
            
            Lstablemax= sqrt(var(L_PC2_ch4/app.ch4eff.Value))/L_max_ave;
            Lstablemin = sqrt(var(L_PC2_ch3/app.ch3eff.Value))/L_min_ave;
            L_sum = L_PC2_ch3_int_ave+L_PC2_ch4_int_ave;
%             L_sum_fullwidth = L_PC2_ch3_int_fullwidth_ave+L_PC2_ch4_int_fullwidth_ave;
            
            peakerrorrate = (1/10^(ER_P/10)+1/10^(ER_N/10)+1/10^(ER_L/10)+1/10^(ER_R/10))/4*100;
            peakextratio = 10*log10(1/(peakerrorrate/100));
            threerrorrate = (1/10^(ER_P_int/10)+1/10^(ER_N_int/10)+1/10^(ER_L_int/10)+1/10^(ER_R_int/10))/4*100; 
            threextratio = 10*log10(1/(threerrorrate/100)); 
            PNpeakextratio = 10*log10(1/((1/10^(ER_P/10)+1/10^(ER_N/10))/2));
            PNthreextratio = 10*log10(1/((1/10^(ER_P_int/10)+1/10^(ER_N_int/10))/2));
            RLpeakextratio = 10*log10(1/((1/10^(ER_R/10)+1/10^(ER_L/10))/2));
            RLthreextratio = 10*log10(1/((1/10^(ER_R_int/10)+1/10^(ER_L_int/10))/2));
            
            all_sum = P_sum+N_sum+R_sum+L_sum;
            max_power = max([P_sum, N_sum, R_sum, L_sum]);
            min_power = min([P_sum, N_sum, R_sum, L_sum]);
            
            PDL = 10*log10(max_power/min_power);
        end
        
        function PNLR_plot(app, ...
                           P_PC1_ch1, locs1_P, P_PC1_ch2, locs2_P, ER_P, ER_P_int, ...
                           N_PC1_ch1, locs1_N, N_PC1_ch2, locs2_N, ER_N, ER_N_int, ...
                           L_PC2_ch3, locs1_L, L_PC2_ch4, locs2_L, ER_L, ER_L_int, ...
                           R_PC2_ch3, locs1_R, R_PC2_ch4, locs2_R, ER_R, ER_R_int ...
                           )
            delete(allchild(app.UIAxes2_P));          
            delete(allchild(app.UIAxes2_N));  
            delete(allchild(app.UIAxes2_R));  
            delete(allchild(app.UIAxes2_L));  
            
            ch2_delay = app.ch2delay.Value;
            ch4_delay = app.ch4delay.Value;
            
            PNRL_timebin = app.filech1(1,2)-app.filech1(1,1); %ps    
            time1 = app.filech1(1,:);
            counts1 = app.filech1(2,:);
            time2 = app.filech2(1,:);
            counts2 = app.filech2(2,:);
            time3 = app.filech3(1,:);
            counts3 = app.filech3(2,:);
            time4 = app.filech4(1,:);
            counts4 = app.filech4(2,:);
            
            %% P态作图
            dB1 = strcat('ave ER of peaks = ',num2str(ER_P),'dB');
            dB2 = strcat('ave ER of pulses = ',num2str(ER_P_int),'dB');
            H = plot(app.UIAxes2_P,time1,counts1,time1(locs1_P),P_PC1_ch1,'or',time2-ch2_delay,counts2,time2(locs2_P)-ch2_delay,P_PC1_ch2,'sk');
            xlim(app.UIAxes2_P,[0,app.PNRL_Slider.Value]);
%             ylim(app.UIAxes2_P,[1,100000]);
            grid(app.UIAxes2_P,'on');
            hP = legend(H([2 4]),'P in ch1','P in ch2');
            set(hP,'Orientation','horizon');
            set(hP,'FontName','Times New Roman','FontSize',18,'FontWeight','normal');
            xlabel(app.UIAxes2_P,'time/ps','fontsize',14,'FontName','Arial');
            ylabel(app.UIAxes2_P,'counts','fontsize',14,'FontName','Arial');
            title(app.UIAxes2_P,'P state');
            text(app.UIAxes2_P,5,15000,dB1);
            text(app.UIAxes2_P,30000*app.PNRL_Slider.Value/50000,15000,dB2);
            if app.LDLinearButton_2.Value
                set(app.UIAxes2_P,'YScale','linear');
            else
                set(app.UIAxes2_P,'YScale','log');
            end
            
            %% N态作图
            dB1 = strcat('ave ER of peaks = ',num2str(ER_N),'dB');
            dB2 = strcat('ave ER of pulses = ',num2str(ER_N_int),'dB');
            H = plot(app.UIAxes2_N,time1,counts1,time1(locs1_N),N_PC1_ch1,'or',time2-ch2_delay,counts2,time2(locs2_N)-ch2_delay,N_PC1_ch2,'sk');
            xlim(app.UIAxes2_N,[0,app.PNRL_Slider.Value]);
%             ylim(app.UIAxes2_N,[1,100000]);
            grid(app.UIAxes2_N,'on');
            hP = legend(H([4 2]),'N in ch2','N in ch1');
            set(hP,'Orientation','horizon');
            set(hP,'FontName','Times New Roman','FontSize',18,'FontWeight','normal');
            xlabel(app.UIAxes2_N,'time/ps','fontsize',14,'FontName','Arial');
            ylabel(app.UIAxes2_N,'counts','fontsize',14,'FontName','Arial');
            title(app.UIAxes2_N,'N state');
            text(app.UIAxes2_N,5,15000,dB1);
            text(app.UIAxes2_N,30000*app.PNRL_Slider.Value/50000,15000,dB2);
            if app.LDLinearButton_2.Value
                set(app.UIAxes2_N,'YScale','linear');
            else
                set(app.UIAxes2_N,'YScale','log');
            end
            
            %% R态作图
            dB1 = strcat('ave ER of peaks = ',num2str(ER_R),'dB');
            dB2 = strcat('ave ER of pulses = ',num2str(ER_R_int),'dB');
            H = plot(app.UIAxes2_R,time3,counts3,time3(locs1_R),R_PC2_ch3,'or',time4-ch4_delay,counts4,time4(locs2_R)-ch4_delay,R_PC2_ch4,'sk');
            xlim(app.UIAxes2_R,[0,app.PNRL_Slider.Value]);
%             ylim(app.UIAxes2_R,[1,100000]);
            grid(app.UIAxes2_R,'on');
            hP = legend(H([2 4]),'R in ch3','R in ch4');
            set(hP,'Orientation','horizon');
            set(hP,'FontName','Times New Roman','FontSize',18,'FontWeight','normal');
            xlabel(app.UIAxes2_R,'time/ps','fontsize',14,'FontName','Arial');
            ylabel(app.UIAxes2_R,'counts','fontsize',14,'FontName','Arial');
            title(app.UIAxes2_R,'R state');
            text(app.UIAxes2_R,5,15000,dB1);
            text(app.UIAxes2_R,30000*app.PNRL_Slider.Value/50000,15000,dB2);
            if app.LDLinearButton_2.Value
                set(app.UIAxes2_R,'YScale','linear');
            else
                set(app.UIAxes2_R,'YScale','log');
            end
            
            %% L态作图
            dB1 = strcat('ave ER of peaks = ',num2str(ER_L),'dB');
            dB2 = strcat('ave ER of pulses = ',num2str(ER_L_int),'dB');
            H = plot(app.UIAxes2_L,time3,counts3,time3(locs1_L),L_PC2_ch3,'or',time4-ch4_delay,counts4,time4(locs2_L)-ch4_delay,L_PC2_ch4,'sk');
            xlim(app.UIAxes2_L,[0,app.PNRL_Slider.Value]);
%             ylim(app.UIAxes2_L,[1,100000]);
            grid(app.UIAxes2_L,'on');
            hP = legend(H([4 2]),'L in ch4','L in ch3');
            set(hP,'Orientation','horizon');
            set(hP,'FontName','Times New Roman','FontSize',18,'FontWeight','normal');
            xlabel(app.UIAxes2_L,'time/ps','fontsize',14,'FontName','Arial');
            ylabel(app.UIAxes2_L,'counts','fontsize',14,'FontName','Arial');
            title(app.UIAxes2_L,'L state');
            text(app.UIAxes2_L,5,15000,dB1);
            text(app.UIAxes2_L,30000*app.PNRL_Slider.Value/50000,15000,dB2);   
            if app.LDLinearButton_2.Value
                set(app.UIAxes2_L,'YScale','linear');
            else
                set(app.UIAxes2_L,'YScale','log');
            end
               
    %             %% 重叠
    %             semilogy(app.UIAxes6,time1(locs1_P),P_PC1_ch1,'or',time2(locs2_N)-ch2_delay,N_PC1_ch2,'sr', ...
    %                 time3(locs1_L),L_PC2_ch3,'xr',time4(locs2_R)-ch4_delay,R_PC2_ch4,'+r');
    %             xlim(app.UIAxes6,[0,50000]);
    %             ylim(app.UIAxes6,[1,100000]);
    %             grid(app.UIAxes6,'on');
            
         end

        function  [binslist,width,errorate_P,errorate_N,errorate_L,errorate_R,errorate_ave, ...
                P_sumratio_list,N_sumratio_list,R_sumratio_list,L_sumratio_list,all_sumratio_list]=PNLR_binwidth_analysis(app,PNRL_timebin)
            binslist = 0:round(app.PNRL_T/2/PNRL_timebin);
            width = binslist*PNRL_timebin;
            errorate_P = zeros(1,length(binslist));
            errorate_N = zeros(1,length(binslist));
            errorate_L = zeros(1,length(binslist));
            errorate_R = zeros(1,length(binslist));
            errorate_ave = zeros(1,length(binslist));
            P_sum_list = zeros(1,length(binslist));
            N_sum_list = zeros(1,length(binslist));
            R_sum_list = zeros(1,length(binslist));
            L_sum_list = zeros(1,length(binslist));
            all_sum_list = zeros(1,length(binslist));
            P_sumratio_list = zeros(1,length(binslist));
            N_sumratio_list = zeros(1,length(binslist));
            R_sumratio_list = zeros(1,length(binslist));
            L_sumratio_list = zeros(1,length(binslist));
            all_sumratio_list = zeros(1,length(binslist));             
            for i = 1:length(binslist)
                [PNRL_timebin, ...
                P_PC1_ch1, locs1_P, P_PC1_ch2, locs2_P, ER_P, ER_P_int, P_PC1_ch1_3dBwidth, P_PC1_ch1_20dBwidth, Pstablemax, Pstablemin, ...
                N_PC1_ch1, locs1_N, N_PC1_ch2, locs2_N, ER_N, ER_N_int, N_PC1_ch2_3dBwidth, N_PC1_ch2_20dBwidth, Nstablemax, Nstablemin, ...
                R_PC2_ch3, locs1_R, R_PC2_ch4, locs2_R, ER_R, ER_R_int, R_PC2_ch3_3dBwidth, R_PC2_ch3_20dBwidth, Rstablemax, Rstablemin, ...
                L_PC2_ch3, locs1_L, L_PC2_ch4, locs2_L, ER_L, ER_L_int, L_PC2_ch4_3dBwidth, L_PC2_ch4_20dBwidth, Lstablemax, Lstablemin, ...
                peakerrorrate, threerrorrate, peakextratio, threextratio, PNpeakextratio, PNthreextratio, RLpeakextratio, RLthreextratio, ...
                P_sum, N_sum, R_sum, L_sum, all_sum, ...
                P_sum_fullwidth, N_sum_fullwidth, R_sum_fullwidth, L_sum_fullwidth, PDL ...
                ] = PNRL_analysis(app,binslist(i));
                errorate_P(i) = 1/10^(ER_P_int/10)*100;
                errorate_N(i) = 1/10^(ER_N_int/10)*100;
                errorate_L(i) = 1/10^(ER_L_int/10)*100;
                errorate_R(i) = 1/10^(ER_R_int/10)*100;
                errorate_ave(i) = (errorate_P(i)+errorate_N(i)+errorate_L(i)+errorate_R(i))/4;
                P_sum_list(i) = P_sum;
                N_sum_list(i) = N_sum;
                R_sum_list(i) = R_sum;
                L_sum_list(i) = L_sum;
                all_sum_list(i) = all_sum;
            end
            for i = 1:length(binslist)
                P_sumratio_list(i) = P_sum_list(i)/P_sum_list(length(binslist))*100;
                N_sumratio_list(i) = N_sum_list(i)/N_sum_list(length(binslist))*100;
                R_sumratio_list(i) = R_sum_list(i)/R_sum_list(length(binslist))*100;
                L_sumratio_list(i) = L_sum_list(i)/L_sum_list(length(binslist))*100;
                all_sumratio_list(i) = all_sum_list(i)/all_sum_list(length(binslist))*100;
            end
        end
        
        function PNLR_binwidth_analysis_plot(app,width,errorate_P,errorate_N,errorate_L,errorate_R,errorate_ave, ...
                P_sumratio_list,N_sumratio_list,R_sumratio_list,L_sumratio_list,all_sumratio_list)
            delete(allchild(app.UIAxes5));  
            yyaxis(app.UIAxes5,"left");
            % 左侧Y轴（Extinction Ratio）
            p1 = plot(app.UIAxes5,width,errorate_P,'c-*');
            hold(app.UIAxes5,'on');
            p2 = plot(app.UIAxes5,width,errorate_N,'g-*');
            hold(app.UIAxes5,'on');
            p3 = plot(app.UIAxes5,width,errorate_R,'m-*');
            hold(app.UIAxes5,'on');
            p4 = plot(app.UIAxes5,width,errorate_L,'k-*');
            hold(app.UIAxes5,'on');
            p5 = plot(app.UIAxes5,width,errorate_ave,'-*');
            hold(app.UIAxes5,'on');
            ylabel(app.UIAxes5,'Errorate (%)','fontsize',18,'FontName','Arial');
            
            yyaxis(app.UIAxes5,"right");
            % 右侧Y轴（Photon Number Ratio）
            p6 = plot(app.UIAxes5,width,P_sumratio_list,'c-*');
            hold(app.UIAxes5,'on');
            p7 = plot(app.UIAxes5,width,N_sumratio_list,'g-*');
            hold(app.UIAxes5,'on');
            p8 = plot(app.UIAxes5,width,R_sumratio_list,'m-*');
            hold(app.UIAxes5,'on');
            p9 = plot(app.UIAxes5,width,L_sumratio_list,'k-*');
            hold(app.UIAxes5,'on');
            p10 = plot(app.UIAxes5,width,all_sumratio_list,'-*');
            ylabel(app.UIAxes5,'Photon Number Ratio (%)', 'fontsize', 18, 'FontName', 'Arial');
            
            p1.LineWidth = 1;
            p2.LineWidth = 1;
            p3.LineWidth = 1;
            p4.LineWidth = 1;
            p5.LineWidth = 1;
            p6.LineWidth = 1;
            p7.LineWidth = 1;
            p8.LineWidth = 1;
            p9.LineWidth = 1;
            p10.LineWidth = 1;
            
            % 添加图例
            lgd = legend(app.UIAxes5,'P ER', 'N ER', 'R ER', 'L ER', 'ave ER','P Sum Ratio', 'N Sum Ratio', 'R Sum Ratio', 'L Sum Ratio', 'all Sum Ratio','Location', 'southeast');
            lgd.Box = 'off';
            
            % 显示网格
            grid(app.UIAxes5,'on');
            
            % 设置坐标轴标签和标题
            xlabel(app.UIAxes5,'Bin Width (ps)', 'fontsize', 18, 'FontName', 'Arial');
            title(app.UIAxes5,'PNRL Bin Width Analysis', 'fontsize', 21, 'FontName', 'Arial'); 
        end
        
        
        function LDImportfile(app)
            app.fileLD = importdata(app.LD_filename);  % 导入TimeTagger数据
        end
        
        function SDVImportfile(app)
            app.fileSDV = importdata(app.SDV_filename);  % 导入TimeTagger数据
        end
        
        function PNRLImportfile(app)
            app.filech1 = importdata(app.ch1_filename);  % 导入TimeTagger数据
            app.filech2 = importdata(app.ch2_filename);
            app.filech3 = importdata(app.ch3_filename);
            app.filech4 = importdata(app.ch4_filename);   
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SDV_Fre
        function SDV_FreValueChanged(app, event)
            freq = app.SDV_Fre.Value;
            app.SDV_T = 1000/freq; %ps
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.thre_term = app.DropDown.Value;
        end

        % Button pushed function: SDVButton
        function SDVButtonPushed(app, event)
            SDVImportfile(app);
            if strcmp(app.thre_term,'手动输入') == 1
                bins = app.SDV_Binsnum.Value;
            elseif strcmp(app.thre_term,'自动最优') == 1
                SDV_timebin = app.fileSDV(1,2)-app.fileSDV(1,1); %ps
                [binslist,width,errorate,signal_sumratio_list,decoy_sumratio_list,vaccum_sumratio_list,all_sumratio_list]= SDV_binwidth_analysis(app,SDV_timebin);
                SDV_binwidth_analysis_plot(app, width, errorate, signal_sumratio_list, decoy_sumratio_list, vaccum_sumratio_list, all_sumratio_list);
                [~,bin_pos] = min(errorate);
                bins =  binslist(bin_pos);
                app.SDV_Binsnum.Value = bins;
            end
            [SDV_timebin, ...
             signal, signal_loc, Cov_S, signal_3dBwidth, signal_20dBwidth, signal_0p46dBwidth, signal_10dBwidth, ...
             decoy, decoy_loc, Cov_D, decoy_3dBwidth, decoy_20dBwidth, ER_SD, ER_SD_int, ...
             vaccum, vaccum_loc, Cov_V, vaccum_3dBwidth, vaccum_20dBwidth, ER_SV, ER_SV_int, ...
             signal_sum, decoy_sum, vaccum_sum, all_sum ...
             ] = SDV_analysis(app,bins);
            
            %% 宽度
            app.S3dB.Value = signal_3dBwidth;
            app.S20dB.Value = signal_20dBwidth;
            app.D3dB.Value = decoy_3dBwidth;
            app.D20dB.Value = decoy_20dBwidth;
            app.V3dB.Value = vaccum_3dBwidth;
            app.V20dB.Value = vaccum_20dBwidth;  
            app.S0p46dB.Value = signal_0p46dBwidth;
            app.S10dB.Value = signal_10dBwidth;
            
            %% 变异系数
            app.Cov_S.Value = sqrt(var(signal))/mean(signal);
            app.Cov_D.Value = sqrt(var(decoy))/mean(decoy);
            app.Cov_V.Value = sqrt(var(vaccum))/mean(vaccum);
            
            SDV_plot(app,signal, signal_loc, ...
                  decoy, decoy_loc, ER_SD, ER_SD_int, ...
                  vaccum, vaccum_loc, ER_SV, ER_SV_int ...
                   );
        end

        % Value changed function: PNRL_dataname
        function PNRL_datanameValueChanged2(app, event)
            value = app.PNRL_dataname.Value;
            app.ch1_filename = ['ch1-' value '.csv'];
            app.ch2_filename = ['ch2-' value '.csv'];
            app.ch3_filename = ['ch3-' value '.csv'];
            app.ch4_filename = ['ch4-' value '.csv'];            
        end

        % Value changed function: PNRL_Fre
        function PNRL_FreValueChanged(app, event)
            freq = app.PNRL_Fre.Value;
            app.PNRL_T = 1000/freq; %ps            
        end

        % Button pushed function: PNRLButton
        function PNRLButtonPushed(app, event)
            PNRLImportfile(app);
            if strcmp(app.four_term,'手动输入') == 1
                bins = app.PNRL_Binsnum.Value;
            elseif strcmp(app.four_term,'自动最优') == 1
                PNRL_timebin = app.filech1(1,2)-app.filech1(1,1); %ps   
                [binslist,width,errorate_P,errorate_N,errorate_L,errorate_R,errorate_ave, ...
                P_sumratio_list,N_sumratio_list,R_sumratio_list,L_sumratio_list,all_sumratio_list]=PNLR_binwidth_analysis(app,PNRL_timebin);
                PNLR_binwidth_analysis_plot(app,width,errorate_P,errorate_N,errorate_L,errorate_R,errorate_ave, ...
                P_sumratio_list,N_sumratio_list,R_sumratio_list,L_sumratio_list,all_sumratio_list);
                [~,bin_pos] = min(errorate_ave);
                bins =  binslist(bin_pos);
                app.PNRL_Binsnum.Value = bins;
            end
            [PNRL_timebin, ...
            P_PC1_ch1, locs1_P, P_PC1_ch2, locs2_P, ER_P, ER_P_int, P_PC1_ch1_3dBwidth, P_PC1_ch1_20dBwidth, Pstablemax, Pstablemin, ...
            N_PC1_ch1, locs1_N, N_PC1_ch2, locs2_N, ER_N, ER_N_int, N_PC1_ch2_3dBwidth, N_PC1_ch2_20dBwidth, Nstablemax, Nstablemin, ...
            R_PC2_ch3, locs1_R, R_PC2_ch4, locs2_R, ER_R, ER_R_int, R_PC2_ch3_3dBwidth, R_PC2_ch3_20dBwidth, Rstablemax, Rstablemin, ...
            L_PC2_ch3, locs1_L, L_PC2_ch4, locs2_L, ER_L, ER_L_int, L_PC2_ch4_3dBwidth, L_PC2_ch4_20dBwidth, Lstablemax, Lstablemin, ...
            peakerrorrate, threerrorrate, peakextratio, threextratio, PNpeakextratio, PNthreextratio, RLpeakextratio, RLthreextratio, ...
            P_sum, N_sum, R_sum, L_sum, all_sum, ...
            P_sum_fullwidth, N_sum_fullwidth, R_sum_fullwidth, L_sum_fullwidth, PDL ...
            ] = PNRL_analysis(app,bins); 
            
            app.P3dB.Value = P_PC1_ch1_3dBwidth;
            app.P20dB.Value = P_PC1_ch1_20dBwidth;
            app.Pstablemax.Value = Pstablemax;
            app.Pstablemin.Value = Pstablemin;
            
            app.N3dB.Value = N_PC1_ch2_3dBwidth;
            app.N20dB.Value = N_PC1_ch2_20dBwidth;
            app.Nstablemax.Value = Nstablemax;
            app.Nstablemin.Value = Nstablemin;
            
            app.R3dB.Value = R_PC2_ch3_3dBwidth;
            app.R20dB.Value = R_PC2_ch3_20dBwidth;
            app.Rstablemax.Value = Rstablemax;
            app.Rstablemin.Value = Rstablemin;
            
            app.L3dB.Value = L_PC2_ch4_3dBwidth;
            app.L20dB.Value = L_PC2_ch4_20dBwidth;
            app.Lstablemax.Value = Lstablemax;
            app.Lstablemin.Value = Lstablemin; 
            
            app.peakerrorrate.Value = peakerrorrate;
            app.threerrorrate.Value = threerrorrate;
            app.peakextratio.Value = peakextratio;
            app.threextratio.Value = threextratio;
            app.PNpeakextratio.Value = PNpeakextratio;
            app.PNthreextratio.Value = PNthreextratio;
            app.RLpeakextratio.Value = RLpeakextratio;
            app.RLthreextratio.Value = RLthreextratio;
            app.PDL.Value = PDL;
            
            PNLR_plot(app, ...
                       P_PC1_ch1, locs1_P, P_PC1_ch2, locs2_P, ER_P, ER_P_int, ...
                       N_PC1_ch1, locs1_N, N_PC1_ch2, locs2_N, ER_N, ER_N_int, ...
                       L_PC2_ch3, locs1_L, L_PC2_ch4, locs2_L, ER_L, ER_L_int, ...
                       R_PC2_ch3, locs1_R, R_PC2_ch4, locs2_R, ER_R, ER_R_int ...
                       );
        end

        % Value changed function: Ppeakmax
        function PpeakmaxValueChanged(app, event)
            app.Ppeakmax.Value = event.Value;
        end

        % Value changed function: Ppeakmin
        function PpeakminValueChanged(app, event)
            app.Ppeakmin.Value = event.Value;            
        end

        % Value changed function: Npeakmax
        function NpeakmaxValueChanged(app, event)
            app.npeakmax.Value = event.Value;           
        end

        % Value changed function: Npeakmin
        function NpeakminValueChanged(app, event)
            app.npeakmin.Value = event.Value;            
        end

        % Value changed function: Rpeakmax
        function RpeakmaxValueChanged(app, event)
            app.lpeakmax.Value = event.Value;
        end

        % Value changed function: Rpeakmin
        function RpeakminValueChanged(app, event)
            app.lpeakmin.Value = event.Value;
        end

        % Value changed function: Lpeakmax
        function LpeakmaxValueChanged(app, event)
            app.rpeakmax.Value = event.Value;
        end

        % Value changed function: Lpeakmin
        function LpeakminValueChanged(app, event)
            app.rpeakmin.Value = event.Value;
        end

        % Value changed function: PNRL_Binsnum
        function PNRL_BinsnumValueChanged(app, event)
            app.PNRL_Binsnum.Value = event.Value;           
        end

        % Value changed function: ch2delay
        function ch2delayValueChanged(app, event)
            app.ch2delay.Value = event.Value;      
        end

        % Value changed function: ch4delay
        function ch4delayValueChanged(app, event)
            app.ch4delay.Value = event.Value;
            
        end

        % Callback function
        function PDL_LOSSValueChanged(app, event)
            app.PDL_LOSS.Value = event.Value;
        end

        % Callback function
        function PDL_KValueChanged(app, event)
            app.PDL_K.Value = event.Value;
        end

        % Value changed function: LD_Fre
        function LD_FreValueChanged(app, event)
            freq = app.LD_Fre.Value;
            app.LD_T = 1000/freq; %ps                
        end

        % Button pushed function: LDButton
        function LDButtonPushed(app, event)
            LDImportfile(app);
            [signal,signal_loc,Fremeasured,Cov_Fre,signal_3dBwidth,signal_20dBwidth] = LD_analysis(app);
            app.LD3dB.Value = signal_3dBwidth;
            app.LD20dB.Value = signal_20dBwidth;
            app.LD_Fremeasured.Value = Fremeasured;
            app.LD_Cov_Fre.Value = Cov_Fre;
            LD_plot(app,signal,signal_loc);
        end

        % Value changed function: LD_dataneme
        function LD_datanemeValueChanged(app, event)
            value = app.LD_dataneme.Value;
            app.LD_filename = ['LD-' value '.csv'];            
        end

        % Value changed function: PNRL_Slider
        function PNRL_SliderValueChanged(app, event)
            value = app.PNRL_Slider.Value;
        end

        % Callback function
        function Button_7Pushed(app, event)
            cla (app.UIAxes5_3,'reset')
            PNLR_binwidth_photonnum_plot(app);
        end

        % Value changed function: SDV_Slider
        function SDV_SliderValueChanged(app, event)
            value = app.SDV_Slider.Value;
        end

        % Value changed function: SDV_dataname
        function SDV_datanameValueChanged(app, event)
            value = app.SDV_dataname.Value;
            app.SDV_filename = ['SDV-' value '.csv'];
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            app.four_term = app.DropDown_2.Value;
        end

        % Value changed function: LD_Slider
        function LD_SliderValueChanged(app, event)
            value = app.LD_Slider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LDTab = uitab(app.TabGroup);
            app.LDTab.Title = 'LD脉冲宽度';
            app.UIAxes7 = uiaxes(app.LDTab);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            app.Panel_9 = uipanel(app.LDTab);
            app.Panel_9.Title = '用户参数输入';
            app.Label_17 = uilabel(app.Panel_9);
            app.Label_17.Text = '频率';
            app.LD_Fre = uieditfield(app.Panel_9, 'numeric');
            app.LD_Fre.ValueChangedFcn = createCallbackFcn(app, @LD_FreValueChanged, true);
            app.GHzLabel_3 = uilabel(app.Panel_9);
            app.GHzLabel_3.Text = 'GHz';
            app.LDButton = uibutton(app.Panel_9, 'push');
            app.LDButton.ButtonPushedFcn = createCallbackFcn(app, @LDButtonPushed, true);
            app.LDButton.Text = '确定';
            app.Label_19 = uilabel(app.Panel_9);
            app.Label_19.Text = '数据名称';
            app.LD_dataneme = uieditfield(app.Panel_9, 'text');
            app.LD_dataneme.ValueChangedFcn = createCallbackFcn(app, @LD_datanemeValueChanged, true);
            app.Label_25 = uilabel(app.Panel_9);
            app.Label_25.Text = '寻峰间距';
            app.LD_MINPEAKDISTANCE = uieditfield(app.Panel_9, 'numeric');
            app.binsLabel_5 = uilabel(app.Panel_9);
            app.binsLabel_5.Text = 'bins';
            app.SLabel_3 = uilabel(app.Panel_9);
            app.SLabel_3.Text = 'S态阈值';
            app.LDpeakmin = uieditfield(app.Panel_9, 'numeric');
            app.LDpeakmin.Value = 1000;
            app.Panel_10 = uipanel(app.LDTab);
            app.Panel_10.Title = '宽度';
            app.psLabel_19 = uilabel(app.Panel_10);
            app.psLabel_19.Text = 'ps';
            app.psLabel_20 = uilabel(app.Panel_10);
            app.psLabel_20.Text = 'ps';
            app.dBLabel = uilabel(app.Panel_10);
            app.dBLabel.Text = '3dB宽度';
            app.LD3dB = uieditfield(app.Panel_10, 'numeric');
            app.dBLabel_2 = uilabel(app.Panel_10);
            app.dBLabel_2.Text = '20dB宽度';
            app.LD20dB = uieditfield(app.Panel_10, 'numeric');
            app.SliderLabel_3 = uilabel(app.LDTab);
            app.SliderLabel_3.Text = 'Slider';
            app.LD_Slider = uislider(app.LDTab);
            app.LD_Slider.Limits = [0 50000];
            app.LD_Slider.ValueChangedFcn = createCallbackFcn(app, @LD_SliderValueChanged, true);
            app.LD_Slider.Value = 50000;
            app.Panel_11 = uipanel(app.LDTab);
            app.Panel_11.Title = '频率';
            app.GHzLabel_4 = uilabel(app.Panel_11);
            app.GHzLabel_4.Text = 'GHz';
            app.GHzLabel_5 = uilabel(app.Panel_11);
            app.GHzLabel_5.Text = 'GHz';
            app.Label_23 = uilabel(app.Panel_11);
            app.Label_23.Text = '测量频率';
            app.LD_Fremeasured = uieditfield(app.Panel_11, 'numeric');
            app.Label_24 = uilabel(app.Panel_11);
            app.Label_24.Text = '频率标准差';
            app.LD_Cov_Fre = uieditfield(app.Panel_11, 'numeric');
            app.Coordinate = uibuttongroup(app.LDTab);
            app.LDLinearButton = uiradiobutton(app.Coordinate);
            app.LDLinearButton.Text = 'Linear';
            app.LDLinearButton.Value = true;
            app.LDLogButton = uiradiobutton(app.Coordinate);
            app.LDLogButton.Text = 'Log';
            app.SDVTab = uitab(app.TabGroup);
            app.SDVTab.Title = 'SDV数据处理';
            app.UIAxes = uiaxes(app.SDVTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Panel = uipanel(app.SDVTab);
            app.Panel.Title = '用户参数输入';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '门宽';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'手动输入', '自动最优'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '手动输入';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '频率';
            app.SDV_Fre = uieditfield(app.Panel, 'numeric');
            app.SDV_Fre.ValueChangedFcn = createCallbackFcn(app, @SDV_FreValueChanged, true);
            app.SDV_Binsnum = uieditfield(app.Panel, 'numeric');
            app.GHzLabel = uilabel(app.Panel);
            app.GHzLabel.Text = 'GHz';
            app.SDVButton = uibutton(app.Panel, 'push');
            app.SDVButton.ButtonPushedFcn = createCallbackFcn(app, @SDVButtonPushed, true);
            app.SDVButton.Text = '确定';
            app.binsLabel = uilabel(app.Panel);
            app.binsLabel.Text = 'bins';
            app.Label_20 = uilabel(app.Panel);
            app.Label_20.Text = '数据名称';
            app.SDV_dataname = uieditfield(app.Panel, 'text');
            app.SDV_dataname.ValueChangedFcn = createCallbackFcn(app, @SDV_datanameValueChanged, true);
            app.binsLabel_3 = uilabel(app.Panel);
            app.binsLabel_3.Text = 'bins';
            app.Label_21 = uilabel(app.Panel);
            app.Label_21.Text = '寻峰间距';
            app.SDV_MINPEAKDISTANCE = uieditfield(app.Panel, 'numeric');
            app.SLabel_2 = uilabel(app.Panel);
            app.SLabel_2.Text = 'S态阈值';
            app.Speakmin = uieditfield(app.Panel, 'numeric');
            app.Speakmin.Value = 1000;
            app.VLabel_3 = uilabel(app.Panel);
            app.VLabel_3.Text = 'V态阈值';
            app.Vpeakmax = uieditfield(app.Panel, 'numeric');
            app.Vpeakmax.Value = 200;
            app.Panel_2 = uipanel(app.SDVTab);
            app.Panel_2.Title = '宽度';
            app.psLabel = uilabel(app.Panel_2);
            app.psLabel.Text = 'ps';
            app.psLabel_2 = uilabel(app.Panel_2);
            app.psLabel_2.Text = 'ps';
            app.psLabel_3 = uilabel(app.Panel_2);
            app.psLabel_3.Text = 'ps';
            app.psLabel_4 = uilabel(app.Panel_2);
            app.psLabel_4.Text = 'ps';
            app.psLabel_5 = uilabel(app.Panel_2);
            app.psLabel_5.Text = 'ps';
            app.psLabel_6 = uilabel(app.Panel_2);
            app.psLabel_6.Text = 'ps';
            app.S3dBLabel = uilabel(app.Panel_2);
            app.S3dBLabel.Text = 'S态3dB宽度';
            app.S3dB = uieditfield(app.Panel_2, 'numeric');
            app.S20dBLabel = uilabel(app.Panel_2);
            app.S20dBLabel.Text = 'S态20dB宽度';
            app.S20dB = uieditfield(app.Panel_2, 'numeric');
            app.D3dBLabel = uilabel(app.Panel_2);
            app.D3dBLabel.Text = 'D态3dB宽度';
            app.D3dB = uieditfield(app.Panel_2, 'numeric');
            app.D20dBLabel = uilabel(app.Panel_2);
            app.D20dBLabel.Text = 'D态20dB宽度';
            app.D20dB = uieditfield(app.Panel_2, 'numeric');
            app.V3dBLabel = uilabel(app.Panel_2);
            app.V3dBLabel.Text = 'V态3dB宽度';
            app.V3dB = uieditfield(app.Panel_2, 'numeric');
            app.V20dBLabel = uilabel(app.Panel_2);
            app.V20dBLabel.Text = 'V态20dB宽度';
            app.V20dB = uieditfield(app.Panel_2, 'numeric');
            app.psLabel_21 = uilabel(app.Panel_2);
            app.psLabel_21.Text = 'ps';
            app.psLabel_22 = uilabel(app.Panel_2);
            app.psLabel_22.Text = 'ps';
            app.S046dBLabel = uilabel(app.Panel_2);
            app.S046dBLabel.Text = 'S态0.46dB宽度';
            app.S0p46dB = uieditfield(app.Panel_2, 'numeric');
            app.S10dBLabel = uilabel(app.Panel_2);
            app.S10dBLabel.Text = 'S态10dB宽度';
            app.S10dB = uieditfield(app.Panel_2, 'numeric');
            app.Panel_3 = uipanel(app.SDVTab);
            app.Panel_3.Title = '峰值稳定性';
            app.SLabel = uilabel(app.Panel_3);
            app.SLabel.Text = 'S态变异系数';
            app.Cov_S = uieditfield(app.Panel_3, 'numeric');
            app.DLabel = uilabel(app.Panel_3);
            app.DLabel.Text = 'D态变异系数';
            app.Cov_D = uieditfield(app.Panel_3, 'numeric');
            app.VLabel = uilabel(app.Panel_3);
            app.VLabel.Text = 'V态变异系数';
            app.Cov_V = uieditfield(app.Panel_3, 'numeric');
            app.SliderLabel_2 = uilabel(app.SDVTab);
            app.SliderLabel_2.Text = 'Slider';
            app.SDV_Slider = uislider(app.SDVTab);
            app.SDV_Slider.Limits = [0 50000];
            app.SDV_Slider.ValueChangedFcn = createCallbackFcn(app, @SDV_SliderValueChanged, true);
            app.SDV_Slider.Value = 50000;
            app.Coordinate_2 = uibuttongroup(app.SDVTab);
            app.SDVLinearButton = uiradiobutton(app.Coordinate_2);
            app.SDVLinearButton.Text = 'Linear';
            app.SDVLinearButton.Value = true;
            app.SDVLogButton = uiradiobutton(app.Coordinate_2);
            app.SDVLogButton.Text = 'Log';
            app.PNRLTab = uitab(app.TabGroup);
            app.PNRLTab.Title = 'PNRL数据处理';
            app.UIAxes2_N = uiaxes(app.PNRLTab);
            title(app.UIAxes2_N, 'Title')
            xlabel(app.UIAxes2_N, 'X')
            ylabel(app.UIAxes2_N, 'Y')
            app.UIAxes2_P = uiaxes(app.PNRLTab);
            title(app.UIAxes2_P, 'Title')
            xlabel(app.UIAxes2_P, 'X')
            ylabel(app.UIAxes2_P, 'Y')
            app.UIAxes2_R = uiaxes(app.PNRLTab);
            title(app.UIAxes2_R, 'Title')
            xlabel(app.UIAxes2_R, 'X')
            ylabel(app.UIAxes2_R, 'Y')
            app.UIAxes2_L = uiaxes(app.PNRLTab);
            title(app.UIAxes2_L, 'Title')
            xlabel(app.UIAxes2_L, 'X')
            ylabel(app.UIAxes2_L, 'Y')
            app.Panel_4 = uipanel(app.PNRLTab);
            app.Panel_4.Title = '用户参数输入';
            app.DropDown_2Label = uilabel(app.Panel_4);
            app.DropDown_2Label.Text = '门宽';
            app.DropDown_2 = uidropdown(app.Panel_4);
            app.DropDown_2.Items = {'手动输入', '自动最优'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '手动输入';
            app.Label_5 = uilabel(app.Panel_4);
            app.Label_5.Text = '频率';
            app.PNRL_Fre = uieditfield(app.Panel_4, 'numeric');
            app.PNRL_Fre.ValueChangedFcn = createCallbackFcn(app, @PNRL_FreValueChanged, true);
            app.PNRL_Binsnum = uieditfield(app.Panel_4, 'numeric');
            app.PNRL_Binsnum.ValueChangedFcn = createCallbackFcn(app, @PNRL_BinsnumValueChanged, true);
            app.PNRL_Binsnum.Value = 20;
            app.GHzLabel_2 = uilabel(app.Panel_4);
            app.GHzLabel_2.Text = 'GHz';
            app.binsLabel_2 = uilabel(app.Panel_4);
            app.binsLabel_2.Text = 'bins';
            app.ch2delay = uieditfield(app.Panel_4, 'numeric');
            app.ch2delay.ValueChangedFcn = createCallbackFcn(app, @ch2delayValueChanged, true);
            app.psLabel_17 = uilabel(app.Panel_4);
            app.psLabel_17.Text = 'ps';
            app.ch4delay = uieditfield(app.Panel_4, 'numeric');
            app.ch4delay.ValueChangedFcn = createCallbackFcn(app, @ch4delayValueChanged, true);
            app.psLabel_18 = uilabel(app.Panel_4);
            app.psLabel_18.Text = 'ps';
            app.PLabel_2 = uilabel(app.Panel_4);
            app.PLabel_2.Text = 'P态大阈值';
            app.Ppeakmax = uieditfield(app.Panel_4, 'numeric');
            app.Ppeakmax.ValueChangedFcn = createCallbackFcn(app, @PpeakmaxValueChanged, true);
            app.Ppeakmax.Value = 2000;
            app.PLabel_3 = uilabel(app.Panel_4);
            app.PLabel_3.Text = 'P态小阈值';
            app.Ppeakmin = uieditfield(app.Panel_4, 'numeric');
            app.Ppeakmin.ValueChangedFcn = createCallbackFcn(app, @PpeakminValueChanged, true);
            app.Ppeakmin.Value = 200;
            app.NLabel_2 = uilabel(app.Panel_4);
            app.NLabel_2.Text = 'N态大阈值';
            app.Npeakmax = uieditfield(app.Panel_4, 'numeric');
            app.Npeakmax.ValueChangedFcn = createCallbackFcn(app, @NpeakmaxValueChanged, true);
            app.Npeakmax.Value = 2000;
            app.NLabel_3 = uilabel(app.Panel_4);
            app.NLabel_3.Text = 'N态小阈值';
            app.Npeakmin = uieditfield(app.Panel_4, 'numeric');
            app.Npeakmin.ValueChangedFcn = createCallbackFcn(app, @NpeakminValueChanged, true);
            app.Npeakmin.Value = 200;
            app.RLabel_4 = uilabel(app.Panel_4);
            app.RLabel_4.Text = 'R态大阈值';
            app.Rpeakmax = uieditfield(app.Panel_4, 'numeric');
            app.Rpeakmax.ValueChangedFcn = createCallbackFcn(app, @RpeakmaxValueChanged, true);
            app.Rpeakmax.Value = 2000;
            app.RLabel_5 = uilabel(app.Panel_4);
            app.RLabel_5.Text = 'R态小阈值';
            app.Rpeakmin = uieditfield(app.Panel_4, 'numeric');
            app.Rpeakmin.ValueChangedFcn = createCallbackFcn(app, @RpeakminValueChanged, true);
            app.Rpeakmin.Value = 200;
            app.LLabel_2 = uilabel(app.Panel_4);
            app.LLabel_2.Text = 'L态大阈值';
            app.Lpeakmax = uieditfield(app.Panel_4, 'numeric');
            app.Lpeakmax.ValueChangedFcn = createCallbackFcn(app, @LpeakmaxValueChanged, true);
            app.Lpeakmax.Value = 2000;
            app.LLabel_3 = uilabel(app.Panel_4);
            app.LLabel_3.Text = 'L态小阈值';
            app.Lpeakmin = uieditfield(app.Panel_4, 'numeric');
            app.Lpeakmin.ValueChangedFcn = createCallbackFcn(app, @LpeakminValueChanged, true);
            app.Lpeakmin.Value = 200;
            app.PNRLButton = uibutton(app.Panel_4, 'push');
            app.PNRLButton.ButtonPushedFcn = createCallbackFcn(app, @PNRLButtonPushed, true);
            app.PNRLButton.Text = '确定';
            app.Label_12 = uilabel(app.Panel_4);
            app.Label_12.Text = '数据名称';
            app.PNRL_dataname = uieditfield(app.Panel_4, 'text');
            app.PNRL_dataname.ValueChangedFcn = createCallbackFcn(app, @PNRL_datanameValueChanged2, true);
            app.ch2DropDownLabel_2 = uilabel(app.Panel_4);
            app.ch2DropDownLabel_2.Text = 'ch2延时';
            app.ch4DropDownLabel_2 = uilabel(app.Panel_4);
            app.ch4DropDownLabel_2.Text = 'ch4延时';
            app.binsLabel_4 = uilabel(app.Panel_4);
            app.binsLabel_4.Text = 'bins';
            app.Label_22 = uilabel(app.Panel_4);
            app.Label_22.Text = '寻峰间距';
            app.PNRL_MINPEAKDISTANCE = uieditfield(app.Panel_4, 'numeric');
            app.ch1Label = uilabel(app.Panel_4);
            app.ch1Label.Text = 'ch1效率';
            app.ch1eff = uieditfield(app.Panel_4, 'numeric');
            app.ch1eff.Value = 1;
            app.ch2Label = uilabel(app.Panel_4);
            app.ch2Label.Text = 'ch2效率';
            app.ch2eff = uieditfield(app.Panel_4, 'numeric');
            app.ch2eff.Value = 1;
            app.ch3Label = uilabel(app.Panel_4);
            app.ch3Label.Text = 'ch3效率';
            app.ch3eff = uieditfield(app.Panel_4, 'numeric');
            app.ch3eff.Value = 1;
            app.ch4Label = uilabel(app.Panel_4);
            app.ch4Label.Text = 'ch4效率';
            app.ch4eff = uieditfield(app.Panel_4, 'numeric');
            app.ch4eff.Value = 1;
            app.Panel_6 = uipanel(app.PNRLTab);
            app.Panel_6.Title = '宽度';
            app.psLabel_9 = uilabel(app.Panel_6);
            app.psLabel_9.Text = 'ps';
            app.psLabel_10 = uilabel(app.Panel_6);
            app.psLabel_10.Text = 'ps';
            app.psLabel_11 = uilabel(app.Panel_6);
            app.psLabel_11.Text = 'ps';
            app.psLabel_12 = uilabel(app.Panel_6);
            app.psLabel_12.Text = 'ps';
            app.psLabel_13 = uilabel(app.Panel_6);
            app.psLabel_13.Text = 'ps';
            app.psLabel_14 = uilabel(app.Panel_6);
            app.psLabel_14.Text = 'ps';
            app.P3dBLabel = uilabel(app.Panel_6);
            app.P3dBLabel.Text = 'P态3dB宽度';
            app.P3dB = uieditfield(app.Panel_6, 'numeric');
            app.P20dBLabel = uilabel(app.Panel_6);
            app.P20dBLabel.Text = 'P态20dB宽度';
            app.P20dB = uieditfield(app.Panel_6, 'numeric');
            app.N3dBLabel = uilabel(app.Panel_6);
            app.N3dBLabel.Text = 'N态3dB宽度';
            app.N3dB = uieditfield(app.Panel_6, 'numeric');
            app.N20dBLabel = uilabel(app.Panel_6);
            app.N20dBLabel.Text = 'N态20dB宽度';
            app.N20dB = uieditfield(app.Panel_6, 'numeric');
            app.psLabel_15 = uilabel(app.Panel_6);
            app.psLabel_15.Text = 'ps';
            app.psLabel_16 = uilabel(app.Panel_6);
            app.psLabel_16.Text = 'ps';
            app.R3dBLabel = uilabel(app.Panel_6);
            app.R3dBLabel.Text = 'R态3dB宽度';
            app.R3dB = uieditfield(app.Panel_6, 'numeric');
            app.R20dBLabel = uilabel(app.Panel_6);
            app.R20dBLabel.Text = 'R态20dB宽度';
            app.R20dB = uieditfield(app.Panel_6, 'numeric');
            app.L3dBLabel = uilabel(app.Panel_6);
            app.L3dBLabel.Text = 'L态3dB宽度';
            app.L3dB = uieditfield(app.Panel_6, 'numeric');
            app.L20dBLabel = uilabel(app.Panel_6);
            app.L20dBLabel.Text = 'L态20dB宽度';
            app.L20dB = uieditfield(app.Panel_6, 'numeric');
            app.Panel_7 = uipanel(app.PNRLTab);
            app.Panel_7.Title = '峰值稳定性';
            app.PLabel = uilabel(app.Panel_7);
            app.PLabel.Text = 'P态变异系数';
            app.Pstablemax = uieditfield(app.Panel_7, 'numeric');
            app.NLabel = uilabel(app.Panel_7);
            app.NLabel.Text = 'N态变异系数';
            app.Nstablemax = uieditfield(app.Panel_7, 'numeric');
            app.LLabel = uilabel(app.Panel_7);
            app.LLabel.Text = 'L态变异系数';
            app.Lstablemax = uieditfield(app.Panel_7, 'numeric');
            app.RLabel = uilabel(app.Panel_7);
            app.RLabel.Text = 'R态变异系数';
            app.Rstablemax = uieditfield(app.Panel_7, 'numeric');
            app.Pstablemin = uieditfield(app.Panel_7, 'numeric');
            app.Nstablemin = uieditfield(app.Panel_7, 'numeric');
            app.Lstablemin = uieditfield(app.Panel_7, 'numeric');
            app.Rstablemin = uieditfield(app.Panel_7, 'numeric');
            app.Label_6 = uilabel(app.Panel_7);
            app.Label_6.Text = '主通道';
            app.Label_7 = uilabel(app.Panel_7);
            app.Label_7.Text = '次通道';
            app.SliderLabel = uilabel(app.PNRLTab);
            app.SliderLabel.Text = 'Slider';
            app.PNRL_Slider = uislider(app.PNRLTab);
            app.PNRL_Slider.Limits = [0 50000];
            app.PNRL_Slider.ValueChangedFcn = createCallbackFcn(app, @PNRL_SliderValueChanged, true);
            app.PNRL_Slider.Value = 50000;
            app.Panel_12 = uipanel(app.PNRLTab);
            app.Panel_12.Title = '主要指标';
            app.Label_10 = uilabel(app.Panel_12);
            app.Label_10.Text = '%';
            app.Label_11 = uilabel(app.Panel_12);
            app.Label_11.Text = '%';
            app.Label_8 = uilabel(app.Panel_12);
            app.Label_8.Text = '门宽误码率';
            app.threerrorrate = uieditfield(app.Panel_12, 'numeric');
            app.Label_9 = uilabel(app.Panel_12);
            app.Label_9.Text = '峰值误码率';
            app.peakerrorrate = uieditfield(app.Panel_12, 'numeric');
            app.dBLabel_4 = uilabel(app.Panel_12);
            app.dBLabel_4.Text = 'dB';
            app.Label_27 = uilabel(app.Panel_12);
            app.Label_27.Text = '门宽对比度';
            app.threextratio = uieditfield(app.Panel_12, 'numeric');
            app.dBLabel_3 = uilabel(app.Panel_12);
            app.dBLabel_3.Text = 'dB';
            app.Label_29 = uilabel(app.Panel_12);
            app.Label_29.Text = '峰值对比度';
            app.peakextratio = uieditfield(app.Panel_12, 'numeric');
            app.dBLabel_6 = uilabel(app.Panel_12);
            app.dBLabel_6.Text = 'dB';
            app.dBLabel_5 = uilabel(app.Panel_12);
            app.dBLabel_5.Text = 'dB';
            app.RLLabel = uilabel(app.Panel_12);
            app.RLLabel.Text = 'RL门宽对比度';
            app.RLthreextratio = uieditfield(app.Panel_12, 'numeric');
            app.PNLabel = uilabel(app.Panel_12);
            app.PNLabel.Text = 'PN门宽对比度';
            app.PNthreextratio = uieditfield(app.Panel_12, 'numeric');
            app.dBLabel_7 = uilabel(app.Panel_12);
            app.dBLabel_7.Text = 'dB';
            app.PDLLabel = uilabel(app.Panel_12);
            app.PDLLabel.Text = '门宽内PDL';
            app.PDL = uieditfield(app.Panel_12, 'numeric');
            app.dBLabel_8 = uilabel(app.Panel_12);
            app.dBLabel_8.Text = 'dB';
            app.PNLabel_2 = uilabel(app.Panel_12);
            app.PNLabel_2.Text = 'PN峰值对比度';
            app.PNpeakextratio = uieditfield(app.Panel_12, 'numeric');
            app.dBLabel_9 = uilabel(app.Panel_12);
            app.dBLabel_9.Text = 'dB';
            app.RLLabel_2 = uilabel(app.Panel_12);
            app.RLLabel_2.Text = 'RL峰值对比度';
            app.RLpeakextratio = uieditfield(app.Panel_12, 'numeric');
            app.Coordinate_3 = uibuttongroup(app.PNRLTab);
            app.LDLinearButton_2 = uiradiobutton(app.Coordinate_3);
            app.LDLinearButton_2.Text = 'Linear';
            app.LDLinearButton_2.Value = true;
            app.PNRLLogButton = uiradiobutton(app.Coordinate_3);
            app.PNRLLogButton.Text = 'Log';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '门宽分析';
            app.UIAxes5 = uiaxes(app.Tab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.UIAxes5_2 = uiaxes(app.Tab);
            title(app.UIAxes5_2, 'Title')
            xlabel(app.UIAxes5_2, 'X')
            ylabel(app.UIAxes5_2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PBB84analysis_V1p2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end