classdef PBB84analysis_V2p4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        LDTab                      matlab.ui.container.Tab
        UIAxes7                    matlab.ui.control.UIAxes
        Panel_9                    matlab.ui.container.Panel
        Label_17                   matlab.ui.control.Label
        LD_Fre                     matlab.ui.control.NumericEditField
        GHzLabel_3                 matlab.ui.control.Label
        LDButton                   matlab.ui.control.Button
        Label_19                   matlab.ui.control.Label
        LD_dataneme                matlab.ui.control.EditField
        Label_25                   matlab.ui.control.Label
        LD_MINPEAKDISTANCE         matlab.ui.control.NumericEditField
        binsLabel_5                matlab.ui.control.Label
        SLabel_3                   matlab.ui.control.Label
        LDpeakmin                  matlab.ui.control.NumericEditField
        Panel_10                   matlab.ui.container.Panel
        psLabel_19                 matlab.ui.control.Label
        psLabel_20                 matlab.ui.control.Label
        dBLabel                    matlab.ui.control.Label
        LD3dB                      matlab.ui.control.NumericEditField
        dBLabel_2                  matlab.ui.control.Label
        LD20dB                     matlab.ui.control.NumericEditField
        SliderLabel_3              matlab.ui.control.Label
        LD_Slider                  matlab.ui.control.Slider
        Panel_11                   matlab.ui.container.Panel
        GHzLabel_4                 matlab.ui.control.Label
        GHzLabel_5                 matlab.ui.control.Label
        Label_23                   matlab.ui.control.Label
        LD_Fremeasured             matlab.ui.control.NumericEditField
        Label_24                   matlab.ui.control.Label
        LD_Cov_Fre                 matlab.ui.control.NumericEditField
        Coordinate                 matlab.ui.container.ButtonGroup
        LDLinearButton             matlab.ui.control.RadioButton
        LDLogButton                matlab.ui.control.RadioButton
        SDVTab                     matlab.ui.container.Tab
        UIAxes                     matlab.ui.control.UIAxes
        Panel                      matlab.ui.container.Panel
        Label_2                    matlab.ui.control.Label
        DropDown                   matlab.ui.control.DropDown
        Label_3                    matlab.ui.control.Label
        SDV_Fre                    matlab.ui.control.NumericEditField
        SDV_Binsnum                matlab.ui.control.NumericEditField
        GHzLabel                   matlab.ui.control.Label
        SDVButton                  matlab.ui.control.Button
        binsLabel                  matlab.ui.control.Label
        Label_20                   matlab.ui.control.Label
        SDV_dataname               matlab.ui.control.EditField
        binsLabel_3                matlab.ui.control.Label
        Label_21                   matlab.ui.control.Label
        SDV_MINPEAKDISTANCE        matlab.ui.control.NumericEditField
        SLabel_2                   matlab.ui.control.Label
        Speakmin                   matlab.ui.control.NumericEditField
        VLabel_3                   matlab.ui.control.Label
        Vpeakmax                   matlab.ui.control.NumericEditField
        Panel_2                    matlab.ui.container.Panel
        psLabel                    matlab.ui.control.Label
        psLabel_2                  matlab.ui.control.Label
        psLabel_3                  matlab.ui.control.Label
        psLabel_4                  matlab.ui.control.Label
        psLabel_5                  matlab.ui.control.Label
        psLabel_6                  matlab.ui.control.Label
        S3dBLabel                  matlab.ui.control.Label
        S3dB                       matlab.ui.control.NumericEditField
        S20dBLabel                 matlab.ui.control.Label
        S20dB                      matlab.ui.control.NumericEditField
        D3dBLabel                  matlab.ui.control.Label
        D3dB                       matlab.ui.control.NumericEditField
        D20dBLabel                 matlab.ui.control.Label
        D20dB                      matlab.ui.control.NumericEditField
        V3dBLabel                  matlab.ui.control.Label
        V3dB                       matlab.ui.control.NumericEditField
        V20dBLabel                 matlab.ui.control.Label
        V20dB                      matlab.ui.control.NumericEditField
        psLabel_21                 matlab.ui.control.Label
        psLabel_22                 matlab.ui.control.Label
        S046dBLabel                matlab.ui.control.Label
        S0p46dB                    matlab.ui.control.NumericEditField
        S10dBLabel                 matlab.ui.control.Label
        S10dB                      matlab.ui.control.NumericEditField
        Panel_3                    matlab.ui.container.Panel
        SLabel                     matlab.ui.control.Label
        Cov_S                      matlab.ui.control.NumericEditField
        DLabel                     matlab.ui.control.Label
        Cov_D                      matlab.ui.control.NumericEditField
        VLabel                     matlab.ui.control.Label
        Cov_V                      matlab.ui.control.NumericEditField
        SliderLabel_2              matlab.ui.control.Label
        SDV_Slider                 matlab.ui.control.Slider
        Coordinate_2               matlab.ui.container.ButtonGroup
        SDVLinearButton            matlab.ui.control.RadioButton
        SDVLogButton               matlab.ui.control.RadioButton
        PNRLTab                    matlab.ui.container.Tab
        UIAxes2_N                  matlab.ui.control.UIAxes
        UIAxes2_P                  matlab.ui.control.UIAxes
        UIAxes2_R                  matlab.ui.control.UIAxes
        UIAxes2_L                  matlab.ui.control.UIAxes
        Panel_4                    matlab.ui.container.Panel
        DropDown_2Label            matlab.ui.control.Label
        DropDown_2                 matlab.ui.control.DropDown
        Label_5                    matlab.ui.control.Label
        PNRL_Fre                   matlab.ui.control.NumericEditField
        PNRL_Binsnum               matlab.ui.control.NumericEditField
        GHzLabel_2                 matlab.ui.control.Label
        binsLabel_2                matlab.ui.control.Label
        ch2delay                   matlab.ui.control.NumericEditField
        psLabel_17                 matlab.ui.control.Label
        ch4delay                   matlab.ui.control.NumericEditField
        psLabel_18                 matlab.ui.control.Label
        PLabel_2                   matlab.ui.control.Label
        Ppeakmax                   matlab.ui.control.NumericEditField
        PLabel_3                   matlab.ui.control.Label
        Ppeakmin                   matlab.ui.control.NumericEditField
        NLabel_2                   matlab.ui.control.Label
        Npeakmax                   matlab.ui.control.NumericEditField
        NLabel_3                   matlab.ui.control.Label
        Npeakmin                   matlab.ui.control.NumericEditField
        RLabel_4                   matlab.ui.control.Label
        Rpeakmax                   matlab.ui.control.NumericEditField
        RLabel_5                   matlab.ui.control.Label
        Rpeakmin                   matlab.ui.control.NumericEditField
        LLabel_2                   matlab.ui.control.Label
        Lpeakmax                   matlab.ui.control.NumericEditField
        LLabel_3                   matlab.ui.control.Label
        Lpeakmin                   matlab.ui.control.NumericEditField
        PNRLButton                 matlab.ui.control.Button
        Label_12                   matlab.ui.control.Label
        PNRL_dataname              matlab.ui.control.EditField
        ch2DropDownLabel_2         matlab.ui.control.Label
        ch4DropDownLabel_2         matlab.ui.control.Label
        binsLabel_4                matlab.ui.control.Label
        Label_22                   matlab.ui.control.Label
        PNRL_MINPEAKDISTANCE       matlab.ui.control.NumericEditField
        ch1Label                   matlab.ui.control.Label
        ch1eff                     matlab.ui.control.NumericEditField
        ch2Label                   matlab.ui.control.Label
        ch2eff                     matlab.ui.control.NumericEditField
        ch3Label                   matlab.ui.control.Label
        ch3eff                     matlab.ui.control.NumericEditField
        ch4Label                   matlab.ui.control.Label
        ch4eff                     matlab.ui.control.NumericEditField
        Panel_6                    matlab.ui.container.Panel
        psLabel_9                  matlab.ui.control.Label
        psLabel_10                 matlab.ui.control.Label
        psLabel_11                 matlab.ui.control.Label
        psLabel_12                 matlab.ui.control.Label
        psLabel_13                 matlab.ui.control.Label
        psLabel_14                 matlab.ui.control.Label
        P3dBLabel                  matlab.ui.control.Label
        P3dB                       matlab.ui.control.NumericEditField
        P20dBLabel                 matlab.ui.control.Label
        P20dB                      matlab.ui.control.NumericEditField
        N3dBLabel                  matlab.ui.control.Label
        N3dB                       matlab.ui.control.NumericEditField
        N20dBLabel                 matlab.ui.control.Label
        N20dB                      matlab.ui.control.NumericEditField
        psLabel_15                 matlab.ui.control.Label
        psLabel_16                 matlab.ui.control.Label
        R3dBLabel                  matlab.ui.control.Label
        R3dB                       matlab.ui.control.NumericEditField
        R20dBLabel                 matlab.ui.control.Label
        R20dB                      matlab.ui.control.NumericEditField
        L3dBLabel                  matlab.ui.control.Label
        L3dB                       matlab.ui.control.NumericEditField
        L20dBLabel                 matlab.ui.control.Label
        L20dB                      matlab.ui.control.NumericEditField
        Panel_7                    matlab.ui.container.Panel
        PLabel                     matlab.ui.control.Label
        Pstablemax                 matlab.ui.control.NumericEditField
        NLabel                     matlab.ui.control.Label
        Nstablemax                 matlab.ui.control.NumericEditField
        LLabel                     matlab.ui.control.Label
        Lstablemax                 matlab.ui.control.NumericEditField
        RLabel                     matlab.ui.control.Label
        Rstablemax                 matlab.ui.control.NumericEditField
        Pstablemin                 matlab.ui.control.NumericEditField
        Nstablemin                 matlab.ui.control.NumericEditField
        Lstablemin                 matlab.ui.control.NumericEditField
        Rstablemin                 matlab.ui.control.NumericEditField
        Label_6                    matlab.ui.control.Label
        Label_7                    matlab.ui.control.Label
        SliderLabel                matlab.ui.control.Label
        PNRL_Slider                matlab.ui.control.Slider
        Panel_12                   matlab.ui.container.Panel
        Label_10                   matlab.ui.control.Label
        Label_11                   matlab.ui.control.Label
        Label_8                    matlab.ui.control.Label
        threerrorrate              matlab.ui.control.NumericEditField
        Label_9                    matlab.ui.control.Label
        peakerrorrate              matlab.ui.control.NumericEditField
        dBLabel_4                  matlab.ui.control.Label
        Label_27                   matlab.ui.control.Label
        threextratio               matlab.ui.control.NumericEditField
        dBLabel_3                  matlab.ui.control.Label
        Label_29                   matlab.ui.control.Label
        peakextratio               matlab.ui.control.NumericEditField
        dBLabel_6                  matlab.ui.control.Label
        dBLabel_5                  matlab.ui.control.Label
        RLLabel                    matlab.ui.control.Label
        RLthreextratio             matlab.ui.control.NumericEditField
        PNLabel                    matlab.ui.control.Label
        PNthreextratio             matlab.ui.control.NumericEditField
        dBLabel_7                  matlab.ui.control.Label
        PDLLabel                   matlab.ui.control.Label
        PDL                        matlab.ui.control.NumericEditField
        dBLabel_8                  matlab.ui.control.Label
        PNLabel_2                  matlab.ui.control.Label
        PNpeakextratio             matlab.ui.control.NumericEditField
        dBLabel_9                  matlab.ui.control.Label
        RLLabel_2                  matlab.ui.control.Label
        RLpeakextratio             matlab.ui.control.NumericEditField
        Coordinate_3               matlab.ui.container.ButtonGroup
        PNRLLinearButton           matlab.ui.control.RadioButton
        PNRLLogButton              matlab.ui.control.RadioButton
        PNRLTab_2                  matlab.ui.container.Tab
        UIAxes8                    matlab.ui.control.UIAxes
        UIAxes8_2                  matlab.ui.control.UIAxes
        Tab                        matlab.ui.container.Tab
        UIAxes5                    matlab.ui.control.UIAxes
        UIAxes5_2                  matlab.ui.control.UIAxes
        SDVPNRL1Tab                matlab.ui.container.Tab
        UIAxes4_SDV_PN             matlab.ui.control.UIAxes
        UIAxes4_SDV_RL             matlab.ui.control.UIAxes
        Panel_13                   matlab.ui.container.Panel
        Label_30                   matlab.ui.control.Label
        SDVPNRL_Fre                matlab.ui.control.NumericEditField
        GHzLabel_6                 matlab.ui.control.Label
        binsLabel_6                matlab.ui.control.Label
        SDVPNRLButton              matlab.ui.control.Button
        Label_31                   matlab.ui.control.Label
        SDVPNRL_dataname           matlab.ui.control.EditField
        binsLabel_7                matlab.ui.control.Label
        Label_32                   matlab.ui.control.Label
        SDVPNRL_MINPEAKDISTANCE    matlab.ui.control.NumericEditField
        ch1Label_2                 matlab.ui.control.Label
        SDVPNRL_ch1eff             matlab.ui.control.NumericEditField
        ch2Label_2                 matlab.ui.control.Label
        SDVPNRL_ch2eff             matlab.ui.control.NumericEditField
        ch3Label_2                 matlab.ui.control.Label
        SDVPNRL_ch3eff             matlab.ui.control.NumericEditField
        ch4Label_2                 matlab.ui.control.Label
        SDVPNRL_ch4eff             matlab.ui.control.NumericEditField
        Label_33                   matlab.ui.control.Label
        SDVPNRL_Binsnum            matlab.ui.control.NumericEditField
        SDVPNRL_P_thre             matlab.ui.control.NumericEditField
        ch2DropDownLabel_4         matlab.ui.control.Label
        SDVPNRL_N_thre             matlab.ui.control.NumericEditField
        ch2DropDownLabel_5         matlab.ui.control.Label
        SDVPNRL_R_thre             matlab.ui.control.NumericEditField
        ch2DropDownLabel_6         matlab.ui.control.Label
        SDVPNRL_L_thre             matlab.ui.control.NumericEditField
        ch2DropDownLabel_7         matlab.ui.control.Label
        dBLabel_10                 matlab.ui.control.Label
        dBLabel_11                 matlab.ui.control.Label
        dBLabel_12                 matlab.ui.control.Label
        dBLabel_13                 matlab.ui.control.Label
        ZSLabel                    matlab.ui.control.Label
        Speakmin_Z                 matlab.ui.control.NumericEditField
        XSLabel                    matlab.ui.control.Label
        Speakmin_X                 matlab.ui.control.NumericEditField
        ZVLabel                    matlab.ui.control.Label
        Vpeakmax_Z                 matlab.ui.control.NumericEditField
        XVLabel                    matlab.ui.control.Label
        Vpeakmax_X                 matlab.ui.control.NumericEditField
        SDVPNRL_ch2delay           matlab.ui.control.NumericEditField
        SDVPNRL_ch4delay           matlab.ui.control.NumericEditField
        ch2DropDownLabel_8         matlab.ui.control.Label
        ch4DropDownLabel_3         matlab.ui.control.Label
        psLabel_23                 matlab.ui.control.Label
        psLabel_24                 matlab.ui.control.Label
        SDVPNRLSaveButton          matlab.ui.control.Button
        Panel_15                   matlab.ui.container.Panel
        SLabel_4                   matlab.ui.control.Label
        SDVPN_Cov_S                matlab.ui.control.NumericEditField
        DLabel_2                   matlab.ui.control.Label
        SDVPN_Cov_D                matlab.ui.control.NumericEditField
        VLabel_4                   matlab.ui.control.Label
        SDVPN_Cov_V                matlab.ui.control.NumericEditField
        SDVRL_Cov_S                matlab.ui.control.NumericEditField
        SDVRL_Cov_D                matlab.ui.control.NumericEditField
        SDVRL_Cov_V                matlab.ui.control.NumericEditField
        ZLabel                     matlab.ui.control.Label
        XLabel                     matlab.ui.control.Label
        SliderLabel_4              matlab.ui.control.Label
        SDVPNRL_Slider_SDV         matlab.ui.control.Slider
        Coordinate_5               matlab.ui.container.ButtonGroup
        SDVPNRL_LinearButton_SDV   matlab.ui.control.RadioButton
        SDVPNRL_LogButton_SDV      matlab.ui.control.RadioButton
        Panel_16                   matlab.ui.container.Panel
        SLabel_5                   matlab.ui.control.Label
        SDVPNRL_SP_num             matlab.ui.control.NumericEditField
        DLabel_3                   matlab.ui.control.Label
        SDVPNRL_DP_num             matlab.ui.control.NumericEditField
        SDVPNRL_SP_cov             matlab.ui.control.NumericEditField
        SDVPNRL_DP_cov             matlab.ui.control.NumericEditField
        PLabel_4                   matlab.ui.control.Label
        NLabel_4                   matlab.ui.control.Label
        SDVPNRL_SN_num             matlab.ui.control.NumericEditField
        SDVPNRL_DN_num             matlab.ui.control.NumericEditField
        SDVPNRL_SN_cov             matlab.ui.control.NumericEditField
        SDVPNRL_DN_cov             matlab.ui.control.NumericEditField
        Label_34                   matlab.ui.control.Label
        Label_35                   matlab.ui.control.Label
        Label_36                   matlab.ui.control.Label
        Label_37                   matlab.ui.control.Label
        SDVPNRL_SR_cov             matlab.ui.control.NumericEditField
        SDVPNRL_DR_cov             matlab.ui.control.NumericEditField
        RLabel_6                   matlab.ui.control.Label
        LLabel_4                   matlab.ui.control.Label
        SDVPNRL_SL_num             matlab.ui.control.NumericEditField
        SDVPNRL_DL_num             matlab.ui.control.NumericEditField
        SDVPNRL_SL_cov             matlab.ui.control.NumericEditField
        SDVPNRL_DL_cov             matlab.ui.control.NumericEditField
        Label_38                   matlab.ui.control.Label
        Label_39                   matlab.ui.control.Label
        Label_40                   matlab.ui.control.Label
        Label_41                   matlab.ui.control.Label
        SLabel_6                   matlab.ui.control.Label
        SDVPNRL_SR_num             matlab.ui.control.NumericEditField
        DLabel_4                   matlab.ui.control.Label
        SDVPNRL_DR_num             matlab.ui.control.NumericEditField
        SPanel                     matlab.ui.container.Panel
        Label_42                   matlab.ui.control.Label
        Label_43                   matlab.ui.control.Label
        Label_44                   matlab.ui.control.Label
        SDVPNRL_threerrorrate      matlab.ui.control.NumericEditField
        Label_45                   matlab.ui.control.Label
        SDVPNRL_peakerrorrate      matlab.ui.control.NumericEditField
        dBLabel_14                 matlab.ui.control.Label
        Label_46                   matlab.ui.control.Label
        SDVPNRL_threextratio       matlab.ui.control.NumericEditField
        dBLabel_15                 matlab.ui.control.Label
        Label_47                   matlab.ui.control.Label
        SDVPNRL_peakextratio       matlab.ui.control.NumericEditField
        dBLabel_16                 matlab.ui.control.Label
        dBLabel_17                 matlab.ui.control.Label
        dBLabel_19                 matlab.ui.control.Label
        dBLabel_20                 matlab.ui.control.Label
        RLLabel_3                  matlab.ui.control.Label
        SDVPNRL_RLthreextratio     matlab.ui.control.NumericEditField
        PNLabel_3                  matlab.ui.control.Label
        SDVPNRL_PNthreextratio     matlab.ui.control.NumericEditField
        PNLabel_4                  matlab.ui.control.Label
        SDVPNRL_PNpeakextratio     matlab.ui.control.NumericEditField
        RLLabel_4                  matlab.ui.control.Label
        SDVPNRL_RLpeakextratio     matlab.ui.control.NumericEditField
        SDVPNRL2Tab                matlab.ui.container.Tab
        UIAxes3_N                  matlab.ui.control.UIAxes
        UIAxes3_P                  matlab.ui.control.UIAxes
        UIAxes3_R                  matlab.ui.control.UIAxes
        UIAxes3_L                  matlab.ui.control.UIAxes
        SDVPNRL_Slider_PNRL        matlab.ui.control.Slider
        Coordinate_4               matlab.ui.container.ButtonGroup
        SDVPNRL_LinearButton_PNRL  matlab.ui.control.RadioButton
        SDVPNRL_LogButton_PNRL     matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        fileLD
        fileSDV
        filech1 
        filech2 
        filech3 
        filech4
        file_IP_ch1
        file_IP_ch2
        file_IP_ch3
        file_IP_ch4

        LD_signal_data 
        LD_signal_loc_data 


        SDV_filename % Description
        SDV_signal_data      % 存储SDV分析的signal数据
        SDV_signal_loc       % 存储SDV分析的signal位置
        SDV_decoy_data       % 存储SDV分析的decoy数据
        SDV_decoy_loc        % 存储SDV分析的decoy位置
        SDV_vaccum_data      % 存储SDV分析的vaccum数据
        SDV_vaccum_loc       % 存储SDV分析的vaccum位置
        SDV_ER_SD            % 存储ER_SD数据
        SDV_ER_SD_int        % 存储ER_SD_int数据
        SDV_ER_SV            % 存储ER_SV数据
        SDV_ER_SV_int        % 存储ER_SV_int数据
        SDV_time_data        % 存储time0数据
        SDV_counts_data      % 存储counts0数据

        PNRL_time1_data      % 存储time1数据
        PNRL_counts1_data    % 存储counts1数据
        PNRL_time2_data      % 存储time2数据
        PNRL_counts2_data    % 存储counts2数据
        PNRL_time3_data      % 存储time3数据
        PNRL_counts3_data    % 存储counts3数据
        PNRL_time4_data      % 存储time4数据
        PNRL_counts4_data    % 存储counts4数据
        
        % P态数据
        PNRL_P_PC1_ch1       % 存储P态ch1峰值数据
        PNRL_locs1_P         % 存储P态ch1位置
        PNRL_P_PC1_ch2       % 存储P态ch2峰值数据
        PNRL_locs2_P         % 存储P态ch2位置
        PNRL_ER_P            % 存储P态ER峰值
        PNRL_ER_P_int        % 存储P态ER脉冲
        
        % N态数据
        PNRL_N_PC1_ch1       % 存储N态ch1峰值数据
        PNRL_locs1_N         % 存储N态ch1位置
        PNRL_N_PC1_ch2       % 存储N态ch2峰值数据
        PNRL_locs2_N         % 存储N态ch2位置
        PNRL_ER_N            % 存储N态ER峰值
        PNRL_ER_N_int        % 存储N态ER脉冲
        
        % R态数据
        PNRL_R_PC2_ch3       % 存储R态ch3峰值数据
        PNRL_locs1_R         % 存储R态ch3位置
        PNRL_R_PC2_ch4       % 存储R态ch4峰值数据
        PNRL_locs2_R         % 存储R态ch4位置
        PNRL_ER_R            % 存储R态ER峰值
        PNRL_ER_R_int        % 存储R态ER脉冲
        
        % L态数据
        PNRL_L_PC2_ch3       % 存储L态ch3峰值数据
        PNRL_locs1_L         % 存储L态ch3位置
        PNRL_L_PC2_ch4       % 存储L态ch4峰值数据
        PNRL_locs2_L         % 存储L态ch4位置
        PNRL_ER_L            % 存储L态ER峰值
        PNRL_ER_L_int        % 存储L态ER脉冲        

        % SDVPNRL原始数据
        SDVPNRL_time1_data
        SDVPNRL_counts1_data
        SDVPNRL_time2_data
        SDVPNRL_counts2_data
        SDVPNRL_time3_data
        SDVPNRL_counts3_data
        SDVPNRL_time4_data
        SDVPNRL_counts4_data
        
        % SDVPNRL处理后的数据
        SDVPNRL_time12_data
        SDVPNRL_counts12_data
        SDVPNRL_time34_data
        SDVPNRL_counts34_data
        
        % SDV_PN分析结果
        SDVPNRL_signal_PN_data
        SDVPNRL_signal_loc_PN
        SDVPNRL_decoy_PN_data
        SDVPNRL_decoy_loc_PN
        SDVPNRL_vaccum_PN_data
        SDVPNRL_vaccum_loc_PN
        SDVPNRL_ER_SD_PN
        SDVPNRL_ER_SD_int_PN
        SDVPNRL_ER_SV_PN
        SDVPNRL_ER_SV_int_PN
        
        % SDV_RL分析结果
        SDVPNRL_signal_RL_data
        SDVPNRL_signal_loc_RL
        SDVPNRL_decoy_RL_data
        SDVPNRL_decoy_loc_RL
        SDVPNRL_vaccum_RL_data
        SDVPNRL_vaccum_loc_RL
        SDVPNRL_ER_SD_RL
        SDVPNRL_ER_SD_int_RL
        SDVPNRL_ER_SV_RL
        SDVPNRL_ER_SV_int_RL
        
        % PNRL分析结果
        SDVPNRL_P_PC1_ch1
        SDVPNRL_locs1_P
        SDVPNRL_P_PC1_ch2
        SDVPNRL_locs2_P
        SDVPNRL_ER_P
        SDVPNRL_ER_P_int
        
        SDVPNRL_N_PC1_ch1
        SDVPNRL_locs1_N
        SDVPNRL_N_PC1_ch2
        SDVPNRL_locs2_N
        SDVPNRL_ER_N
        SDVPNRL_ER_N_int
        
        SDVPNRL_R_PC2_ch3
        SDVPNRL_locs1_R
        SDVPNRL_R_PC2_ch4
        SDVPNRL_locs2_R
        SDVPNRL_ER_R
        SDVPNRL_ER_R_int
        
        SDVPNRL_L_PC2_ch3
        SDVPNRL_locs1_L
        SDVPNRL_L_PC2_ch4
        SDVPNRL_locs2_L
        SDVPNRL_ER_L
        SDVPNRL_ER_L_int

              
        LD_filename
        ch1_filename
        ch2_filename
        ch3_filename
        ch4_filename
        IP_ch1_filename
        IP_ch2_filename
        IP_ch3_filename
        IP_ch4_filename
        LD_T
        SDV_T
        PNRL_T
        SDVPNRL_T
        ppeakmax
        ppeakmin
        npeakmax
        npeakmin
        lpeakmax
        lpeakmin
        rpeakmax
        rpeakmin
        thre_term = '手动输入'
        four_term = '手动输入'
    end
    
    methods (Access = public)        

        function [signal,signal_loc,Fremeasured,Cov_Fre,signal_3dBwidth,signal_20dBwidth] = LD_analysis(app)
            LD_timebin = app.fileLD(1,2)-app.fileLD(1,1); %ps
            time0 = app.fileLD(1,:);
            counts0 = app.fileLD(2,:);
            [pks0,locs0] = findpeaks(counts0,'MINPEAKHEIGHT',1,'MINPEAKDISTANCE',app.LD_MINPEAKDISTANCE.Value);  %数据寻峰

            %% 信号态寻峰
            threshold_s =app.LDpeakmin.Value;
            signal = pks0(find(pks0>threshold_s));
            signal_loc_temp = find(pks0>threshold_s);
            signal_loc = locs0(signal_loc_temp);
            
            %% 频率计算
            Frelist = zeros(1,length(signal_loc)-1);
            Cov_Fre = 0;
            for i = 1:length(Frelist)
                Frelist(i) = 10^12/((signal_loc(i+1)-signal_loc(i))*LD_timebin)*10^(-9);
            end
            Fremeasured = sum(Frelist)/length(Frelist);
            for i = 1:length(Frelist)
                Cov_Fre = Cov_Fre+(Frelist(i)-Fremeasured)^2;
            end
            Cov_Fre = sqrt(Cov_Fre/length(Frelist));
            
            %% 3dB宽度计算(仅强度态)
            signal_3dB = signal/2;
            signal_3dBwidth = zeros(1,length(signal)-2);
            for i = 1:1:length(signal_3dBwidth)
                halfpulse_count = round(app.LD_T/LD_timebin/2);
                leftrange = counts0(signal_loc(i+1)-halfpulse_count:signal_loc(i+1));
                rightrange = counts0(signal_loc(i+1):signal_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-signal_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange-signal_3dB(i+1)));
                signal_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*LD_timebin;
            end
            signal_3dBwidth = mean(signal_3dBwidth);        
            
            %% 20dB宽度计算(仅强度态)
            signal_20dB = signal/100;
            signal_20dBwidth = zeros(1,length(signal)-2);
            for i = 1:1:length(signal_20dBwidth)
                halfpulse_count = round(app.LD_T/LD_timebin/2);
                leftrange = counts0(signal_loc(i+1)-halfpulse_count:signal_loc(i+1));
                rightrange = counts0(signal_loc(i+1):signal_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-signal_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange-signal_20dB(i+1)));
                signal_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*LD_timebin;
            end
            signal_20dBwidth = mean(signal_20dBwidth);            
        
        end
        
        function LD_plot(app, signal, signal_loc)
            delete(allchild(app.UIAxes7));   
            LD_timebin = app.fileLD(1,2)-app.fileLD(1,1); %ps
            time0 = app.fileLD(1,:);
            counts0 = app.fileLD(2,:);
        
            plot(app.UIAxes7, time0, counts0, time0(signal_loc), signal, 'or');
            
            % 从按钮组获取选中的按钮
            selectedButton = app.Coordinate.SelectedObject;
            
            % 根据选中的按钮设置坐标轴类型
            if strcmp(selectedButton.Text, 'Linear')
                set(app.UIAxes7, 'YScale', 'linear');
            else % 如果是'Log'
                set(app.UIAxes7, 'YScale', 'log');
            end
            
            xlim(app.UIAxes7, [1, app.LD_Slider.Value]);
            grid(app.UIAxes7, 'on');
            xlabel(app.UIAxes7, 'time/ps', 'fontsize', 18);
            ylabel(app.UIAxes7, 'counts', 'fontsize', 18);
            title(app.UIAxes7, 'LD');
        end
        
        function [signal, signal_loc, Cov_S, signal_3dBwidth, signal_20dBwidth, signal_0p46dBwidth, signal_10dBwidth, ...
                  decoy, decoy_loc, Cov_D, decoy_3dBwidth, decoy_20dBwidth, ER_SD, ER_SD_int, ...
                  vaccum, vaccum_loc, Cov_V, vaccum_3dBwidth, vaccum_20dBwidth, ER_SV, ER_SV_int, ...
                  signal_sum, decoy_sum, vaccum_sum, all_sum ...
                 ] = SDV_analysis(app,Speakmin,Vpeakmax,T,bins,time0,counts0,SDV_timebin,MINPEAKDISTANCE)
            [pks0,locs0] = findpeaks(counts0,'MINPEAKHEIGHT',1,'MINPEAKDISTANCE',MINPEAKDISTANCE);  %数据寻峰
            
            %% 信号态寻峰
            threshold_s = Speakmin;
            signal = pks0(find(pks0>threshold_s));
            signal_loc_temp = find(pks0>threshold_s);
            signal_loc = locs0(signal_loc_temp);
            
            %% 真空态寻峰
            threshold_v = Vpeakmax;
            vaccum = pks0(find(pks0<threshold_v));
            vaccum_loc_temp = find(pks0<threshold_v);
            vaccum_loc = locs0(vaccum_loc_temp);
            
            %% 诱骗态寻峰
            decoy1 = pks0(find(pks0<threshold_s));
            decoy1_loc_temp = find(pks0<threshold_s);
            decoy1_loc = locs0(decoy1_loc_temp);
            decoy = decoy1(find(decoy1>threshold_v));
            decoy_loc_temp1 = find(decoy1>threshold_v);
            decoy_loc = decoy1_loc(decoy_loc_temp1);
            
            signal_ave = mean(signal);
            decoy_ave = mean(decoy);
            vaccum_ave = mean(vaccum);
            ER_SV = 10*log10(signal_ave/vaccum_ave);   
            ER_SD = 10*log10(signal_ave/decoy_ave);  
                
            signal_int = zeros(1,length(signal)-2);
            vaccum_int = zeros(1,length(vaccum)-2);
            decoy_int = zeros(1,length(decoy)-2);
            
            for i = 1:1:length(signal_int)
                signal_int(i) = sum(counts0(signal_loc(i+1)-bins/2:signal_loc(i+1)+bins/2));
            end
            for j = 1:1:length(vaccum_int)
                vaccum_int(j) = sum(counts0(vaccum_loc(j+1)-bins/2:vaccum_loc(j+1)+bins/2));
            end
            for k = 1:1:length(decoy_int)
                decoy_int(k) = sum(counts0(decoy_loc(k+1)-bins/2:decoy_loc(k+1)+bins/2));
            end
            signal_sum = sum(signal_int);
            decoy_sum = sum(decoy_int);
            vaccum_sum = sum(vaccum_int);
            all_sum = signal_sum + decoy_sum + vaccum_sum;
            signal_int_ave = mean(signal_int);
            vaccum_int_ave = mean(vaccum_int);
            decoy_int_ave = mean(decoy_int);
            ER_SV_int = 10*log10(signal_int_ave/vaccum_int_ave);
            ER_SD_int = 10*log10(signal_int_ave/decoy_int_ave);
            
            %% 变异系数
            Cov_S = sqrt(var(signal))/mean(signal);
            Cov_D = sqrt(var(decoy))/mean(decoy);
            Cov_V = sqrt(var(vaccum))/mean(vaccum);
            
            %% 3dB宽度计算(仅强度态)
            signal_3dB = signal/2;
            signal_3dBwidth = zeros(1,length(signal)-2);
            for i = 1:1:length(signal_3dBwidth)
                halfpulse_count = round(T/SDV_timebin/2);
                leftrange = counts0(signal_loc(i+1)-halfpulse_count:signal_loc(i+1));
                rightrange = counts0(signal_loc(i+1):signal_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-signal_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange-signal_3dB(i+1)));
                signal_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*SDV_timebin;
            end
            signal_3dBwidth = mean(signal_3dBwidth);
            
            %% 20dB宽度计算(仅强度态)
            signal_20dB = signal/100;
            signal_20dBwidth = zeros(1,length(signal)-2);
            for i = 1:1:length(signal_20dBwidth)
                halfpulse_count = round(T/SDV_timebin/2);
                leftrange = counts0(signal_loc(i+1)-halfpulse_count:signal_loc(i+1));
                rightrange = counts0(signal_loc(i+1):signal_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-signal_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange-signal_20dB(i+1)));
                signal_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*SDV_timebin;
            end
            signal_20dBwidth = mean(signal_20dBwidth);
            
            %% 0.46dB宽度计算(仅强度态)
            signal_0p46dB = signal*0.9;
            signal_0p46dBwidth = zeros(1,length(signal)-2);
            for i = 1:1:length(signal_0p46dBwidth)
                halfpulse_count = round(T/SDV_timebin/2);
                leftrange = counts0(signal_loc(i+1)-halfpulse_count:signal_loc(i+1));
                rightrange = counts0(signal_loc(i+1):signal_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-signal_0p46dB(i+1)));
                [~, rightindex] = min(abs(rightrange-signal_0p46dB(i+1)));
                signal_0p46dBwidth(i) = (halfpulse_count-leftindex+rightindex)*SDV_timebin;
            end
            signal_0p46dBwidth = mean(signal_0p46dBwidth);
            
            %% 21dB宽度计算(仅强度态)
            signal_10dB = signal/10;
            signal_10dBwidth = zeros(1,length(signal)-2);
            for i = 1:1:length(signal_10dBwidth)
                halfpulse_count = round(T/SDV_timebin/2);
                leftrange = counts0(signal_loc(i+1)-halfpulse_count:signal_loc(i+1));
                rightrange = counts0(signal_loc(i+1):signal_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-signal_10dB(i+1)));
                [~, rightindex] = min(abs(rightrange-signal_10dB(i+1)));
                signal_10dBwidth(i) = (halfpulse_count-leftindex+rightindex)*SDV_timebin;
            end
            signal_10dBwidth = mean(signal_10dBwidth);
            
            %% 3dB宽度计算(仅诱骗态)
            decoy_3dB = decoy/2;
            decoy_3dBwidth = zeros(1,length(decoy)-2);
            for i = 1:1:length(decoy_3dBwidth)
                halfpulse_count = round(T/SDV_timebin/2);
                leftrange = counts0(decoy_loc(i+1)-halfpulse_count:decoy_loc(i+1));
                rightrange = counts0(decoy_loc(i+1):decoy_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-decoy_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange-decoy_3dB(i+1)));
                decoy_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*SDV_timebin;
            end
            decoy_3dBwidth = mean(decoy_3dBwidth);
            
            %% 20dB宽度计算(仅诱骗态)
            decoy_20dB = decoy/100;
            decoy_20dBwidth = zeros(1,length(decoy)-2);
            for i = 1:1:length(decoy_20dBwidth)
                halfpulse_count = round(T/SDV_timebin/2);
                leftrange = counts0(decoy_loc(i+1)-halfpulse_count:decoy_loc(i+1));
                rightrange = counts0(decoy_loc(i+1):decoy_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-decoy_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange-decoy_20dB(i+1)));
                decoy_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*SDV_timebin;
            end
            decoy_20dBwidth = mean(decoy_20dBwidth);
            
            %% 3dB宽度计算(仅真空态)
            vaccum_3dB = vaccum/2;
            vaccum_3dBwidth = zeros(1,length(vaccum)-2);
            for i = 1:1:length(vaccum_3dBwidth)
                halfpulse_count = round(T/SDV_timebin/2);
                leftrange = counts0(vaccum_loc(i+1)-halfpulse_count:vaccum_loc(i+1));
                rightrange = counts0(vaccum_loc(i+1):vaccum_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-vaccum_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange-vaccum_3dB(i+1)));
                vaccum_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*SDV_timebin;
            end
            vaccum_3dBwidth = mean(vaccum_3dBwidth);
            
            %% 20dB宽度计算(仅真空态)
            vaccum_20dB = vaccum/100;
            vaccum_20dBwidth = zeros(1,length(vaccum)-2);
            for i = 1:1:length(vaccum_20dBwidth)
                halfpulse_count = round(T/SDV_timebin/2);
                leftrange = counts0(vaccum_loc(i+1)-halfpulse_count:vaccum_loc(i+1));
                rightrange = counts0(vaccum_loc(i+1):vaccum_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-vaccum_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange-vaccum_20dB(i+1)));
                vaccum_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*SDV_timebin;
            end
            vaccum_20dBwidth = mean(vaccum_20dBwidth);
        end
        
        function SDV_plot(app, fig, time0, counts0, signal, signal_loc, ...
                          decoy, decoy_loc, ER_SD, ER_SD_int, ...
                          vaccum, vaccum_loc, ER_SV, ER_SV_int,  ...
                          slider, button, figtitle)
            delete(allchild(fig));   
            
            dB1 = strcat('ave ER of S&V peaks = ',num2str(ER_SV),'dB');
            dB2 = strcat('ave ER of S&V pulses = ',num2str(ER_SV_int),'dB');
            dB3 = strcat('ave ER of S&D peaks = ',num2str(ER_SD),'dB');
            dB4 = strcat('ave ER of S&D pulses = ',num2str(ER_SD_int),'dB');
            plot(fig,time0,counts0,time0(signal_loc),signal,'or',time0(vaccum_loc),vaccum,'sm',time0(decoy_loc),decoy,'+b'); % 真空态信号态诱骗态作图
            if button
                set(fig,'YScale','linear');
                ylim(fig, 'auto');
            else
                set(fig,'YScale','log');
                ylim(fig,[1,100000]);
                text(fig,1,80000,dB1);
                text(fig,30000*slider/50000,80000,dB2);
                text(fig,1,50000,dB3);
                text(fig,30000*slider/50000,50000,dB4);
            end
            xlim(fig,[1,slider]);
            grid(fig,'on');
            xlabel(fig,'time/ps','fontsize',18);
            ylabel(fig,'counts','fontsize',18);
            title(fig, figtitle);
        end

        function  [binslist,width,errorate,signal_sumratio_list,decoy_sumratio_list,vaccum_sumratio_list,all_sumratio_list] ...
                  = SDV_binwidth_analysis(app,T,SDV_timebin,time0,counts0)
            binslist = 0:round(T/2/SDV_timebin);
            width = binslist*SDV_timebin;
            errorate = zeros(1,length(binslist));
            signal_sum_list = zeros(1,length(binslist));
            decoy_sum_list = zeros(1,length(binslist));
            vaccum_sum_list = zeros(1,length(binslist));
            all_sum_list = zeros(1,length(binslist));
            signal_sumratio_list = zeros(1,length(binslist));
            decoy_sumratio_list = zeros(1,length(binslist));
            vaccum_sumratio_list = zeros(1,length(binslist));
            all_sumratio_list = zeros(1,length(binslist));             
            for i = 1:length(binslist)
                [signal, signal_loc, Cov_S, signal_3dBwidth, signal_20dBwidth, signal_0p46dBwidth, signal_10dBwidth, ...
                 decoy, decoy_loc, Cov_D, decoy_3dBwidth, decoy_20dBwidth, ER_SD, ER_SD_int, ...
                 vaccum, vaccum_loc, Cov_V, vaccum_3dBwidth, vaccum_20dBwidth, ER_SV, ER_SV_int, ...
                 signal_sum, decoy_sum, vaccum_sum, all_sum ...
                 ] = SDV_analysis(app,app.Speakmin.Value,app.Vpeakmax.Value,app.SDV_T,binslist(i),time0,counts0,SDV_timebin,app.SDV_MINPEAKDISTANCE.Value);
                errorate(i) = 1/10^(ER_SV_int/10)*100;
                signal_sum_list(i) = signal_sum;
                decoy_sum_list(i) = decoy_sum;
                vaccum_sum_list(i) = vaccum_sum;
                all_sum_list(i) = all_sum;
            end
            for i = 1:length(binslist)
                signal_sumratio_list(i) = signal_sum_list(i)/signal_sum_list(length(binslist))*100;
                decoy_sumratio_list(i) = decoy_sum_list(i)/decoy_sum_list(length(binslist))*100;
                vaccum_sumratio_list(i) = vaccum_sum_list(i)/vaccum_sum_list(length(binslist))*100;
                all_sumratio_list(i) = all_sum_list(i)/all_sum_list(length(binslist))*100;
            end
        end
         
        function SDV_binwidth_analysis_plot(app, width, errorate, signal_sumratio_list, decoy_sumratio_list, vaccum_sumratio_list, all_sumratio_list)          
            delete(allchild(app.UIAxes5_2)); 
            yyaxis(app.UIAxes5_2,"left");
            % 左侧Y轴（Extinction Ratio）
            p1 = plot(app.UIAxes5_2,width, errorate, '-*');
            ylabel(app.UIAxes5_2,'Extinction Ratio (%)', 'fontsize', 18, 'FontName', 'Arial');
            
            yyaxis(app.UIAxes5_2,"right");
            % 右侧Y轴（Photon Number Ratio）
            p2 = plot(app.UIAxes5_2,width,signal_sumratio_list,'c-*');
            hold(app.UIAxes5_2,'on');
            p3 = plot(app.UIAxes5_2,width,decoy_sumratio_list,'g-*');
            hold(app.UIAxes5_2,'on');
            p4 = plot(app.UIAxes5_2,width,vaccum_sumratio_list,'m-*');
            hold(app.UIAxes5_2,'on');
            p5 = plot(app.UIAxes5_2,width,all_sumratio_list,'-*');
            ylabel(app.UIAxes5_2,'Photon Number Ratio (%)', 'fontsize', 18, 'FontName', 'Arial');
            
            p1.LineWidth = 1;
            p2.LineWidth = 1;
            p3.LineWidth = 1;
            p4.LineWidth = 1;
            p5.LineWidth = 1;
            
            % 添加图例
            lgd = legend(app.UIAxes5_2,'Extinction Ratio', 'Signal Sum Ratio', 'Decoy Sum Ratio', 'Vaccum Sum Ratio', 'All Sum Ratio','Location', 'southeast');
            lgd.Box = 'off';
            
            % 显示网格
            grid(app.UIAxes5_2,'on');
            
            % 设置坐标轴标签和标题
            xlabel(app.UIAxes5_2,'Bin Width (ps)', 'fontsize', 18, 'FontName', 'Arial');
            title(app.UIAxes5_2,'SDV Bin Width Analysis', 'fontsize', 21, 'FontName', 'Arial');
        end

        
        function [PNRL_timebin, ...
                  P_PC1_ch1, locs1_P, P_PC1_ch2, locs2_P, ER_P, ER_P_int, P_PC1_ch1_3dBwidth, P_PC1_ch1_20dBwidth, Pstablemax, Pstablemin, ...
                  N_PC1_ch1, locs1_N, N_PC1_ch2, locs2_N, ER_N, ER_N_int, N_PC1_ch2_3dBwidth, N_PC1_ch2_20dBwidth, Nstablemax, Nstablemin, ...
                  R_PC2_ch3, locs1_R, R_PC2_ch4, locs2_R, ER_R, ER_R_int, R_PC2_ch3_3dBwidth, R_PC2_ch3_20dBwidth, Rstablemax, Rstablemin, ...
                  L_PC2_ch3, locs1_L, L_PC2_ch4, locs2_L, ER_L, ER_L_int, L_PC2_ch4_3dBwidth, L_PC2_ch4_20dBwidth, Lstablemax, Lstablemin, ...
                  peakerrorrate, threerrorrate, peakextratio, threextratio, PNpeakextratio, PNthreextratio, RLpeakextratio, RLthreextratio, ...
                  P_sum, N_sum, R_sum, L_sum, all_sum, PDL, theta_rad_CH12, theta_rad_CH34 ...
                 ] = PNRL_analysis(app,Ppeakmax,Ppeakmin,Npeakmax,Npeakmin,Rpeakmax,Rpeakmin,Lpeakmax,Lpeakmin, ...
                                    T,bins,time1,counts1,time2,counts2,time3,counts3,time4,counts4,PNRL_timebin,MINPEAKDISTANCE) 
            [pks1,locs1] = findpeaks(counts1,'MINPEAKHEIGHT',0,'MINPEAKDISTANCE',MINPEAKDISTANCE);  %PC1 ch1通道数据寻峰
            [pks2,locs2] = findpeaks(counts2,'MINPEAKHEIGHT',0,'MINPEAKDISTANCE',MINPEAKDISTANCE);  %PC1 ch2通道数据寻峰
            [pks3,locs3] = findpeaks(counts3,'MINPEAKHEIGHT',0,'MINPEAKDISTANCE',MINPEAKDISTANCE);  %PC2 ch3通道数据寻峰
            [pks4,locs4] = findpeaks(counts4,'MINPEAKHEIGHT',0,'MINPEAKDISTANCE',MINPEAKDISTANCE);  %PC2 ch4通道数据寻峰      
            
            % P在PC1 ch1 下
            thr_P_PC1_ch1 = Ppeakmax;          % P态在PC1下ch1通道的阈值
            locs1_P_temp = find(pks1>thr_P_PC1_ch1);     % P态在寻峰序列中的位置       PC1下ch1
            P_PC1_ch1 = pks1(locs1_P_temp);              % P态峰值强度                PC1下ch1
            locs1_P = locs1(locs1_P_temp);               % P态在时间序列中的位置      PC1下ch1
            
            % P在PC1 ch2 下
            thr_P_PC1_ch2 = Ppeakmin;          % P态在PC1下ch2通道的阈值
            locs2_P_temp = find(pks2<thr_P_PC1_ch2);     % P态在寻峰序列中的位置       PC1下ch2
            P_PC1_ch2 = pks2(locs2_P_temp);              % P态峰值强度                PC1下ch2
            locs2_P = locs2(locs2_P_temp);               % P态在时间序列中的位置      PC1下ch2

            % P态峰值比
            P_max_ave = mean(P_PC1_ch1);    % 超导效率归一化
            P_min_ave = mean(P_PC1_ch2);
            ER_P = 10*log10(P_max_ave/P_min_ave);    
            
            
            %% PRNL投影处理
            ch2_delay = app.ch2delay.Value;
            ch4_delay = app.ch4delay.Value;
            if ch2_delay >= 0
                counts2_delay = counts2(1+floor(ch2_delay/PNRL_timebin):end);
                counts1_delay = counts1;
            else
                counts1_delay = counts1(1+floor(-ch2_delay/PNRL_timebin):end);
                counts2_delay = counts2;
            end
            
            length12 = min(length(counts1_delay),length(counts2_delay));
            counts1_delay = counts1_delay(1:length12);
            counts2_delay = counts2_delay(1:length12);
    
            if ch4_delay >= 0
                counts4_delay = counts4(1+floor(ch4_delay/PNRL_timebin):end);
                counts3_delay = counts3;
            else
                counts3_delay = counts3(1+floor(-ch4_delay/PNRL_timebin):end);
                counts4_delay = counts4;
            end
            
            length34 = min(length(counts3_delay),length(counts4_delay));
            counts3_delay = counts3_delay(1:length34);
            counts4_delay = counts4_delay(1:length34);
            
            [~,locs1_delay] = findpeaks(counts1_delay,'MINPEAKHEIGHT',3,'MINPEAKDISTANCE',MINPEAKDISTANCE);  %PC1 ch1通道数据寻峰
            [~,locs3_delay] = findpeaks(counts3_delay,'MINPEAKHEIGHT',3,'MINPEAKDISTANCE',MINPEAKDISTANCE);  %PC2 ch3通道数据寻峰
            
            ch1_int = zeros(1,length(locs1_delay)-4); % 
            ch2_int = zeros(1,length(locs1_delay)-4); % 
            ch3_int = zeros(1,length(locs3_delay)-4); % 
            ch4_int = zeros(1,length(locs3_delay)-4); % 
            for i = 1:length(ch1_int)
                ch1_int(i) = sum(counts1_delay(locs1_delay(i+1)-bins/2:locs1_delay(i+1)+bins/2));   
                ch2_int(i) = sum(counts2_delay(locs1_delay(i+1)-bins/2:locs1_delay(i+1)+bins/2)); 
            end
            
            for i = 1:length(ch3_int)
                ch3_int(i) = sum(counts3_delay(locs3_delay(i+1)-bins/2:locs3_delay(i+1)+bins/2));   
                ch4_int(i) = sum(counts4_delay(locs3_delay(i+1)-bins/2:locs3_delay(i+1)+bins/2)); 
            end
            counts_CH12_ratio = ch1_int./ch2_int;
            counts_CH34_ratio = ch3_int./ch4_int;
            
            theta_rad_CH12 = zeros(size(counts_CH12_ratio));
            theta_rad_CH34 = zeros(size(counts_CH34_ratio));

            for i = 1:length(counts_CH12_ratio)
                theta_rad_CH12(i) = 2 * atan(1/sqrt(abs(counts_CH12_ratio(i))));
            end
            
            for i = 1:length(counts_CH34_ratio)
                theta_rad_CH34(i) = 2 * atan(1/sqrt(abs(counts_CH34_ratio(i))));
            end            
            
            % 门宽积分比和全宽积分
            P_PC1_ch1_int = zeros(1,length(P_PC1_ch1)-2); %  P态在PC1 ch1通道中脉冲半高宽积分
            for i = 1:length(P_PC1_ch1_int)
                P_PC1_ch1_int(i) = sum(counts1(locs1_P(i+1)-bins/2:locs1_P(i+1)+bins/2));   
            end
            P_PC1_ch2_int = zeros(1,length(P_PC1_ch2)-2); %  P态在PC1 ch2通道中脉冲半高宽积分
            for i = 1:length(P_PC1_ch2_int)
                P_PC1_ch2_int(i) = sum(counts2(locs2_P(i+1)-bins/2:locs2_P(i+1)+bins/2));
            end
            P_PC1_ch1_int_ave = mean(P_PC1_ch1_int);    % 超导效率归一化
            P_PC1_ch2_int_ave = mean(P_PC1_ch2_int);
            ER_P_int = 10*log10(P_PC1_ch1_int_ave/P_PC1_ch2_int_ave);
            
            %% 3dB宽度计算(仅P态)
            P_PC1_ch1_3dB = P_PC1_ch1/2;
            P_PC1_ch1_3dBwidth = zeros(1,length(P_PC1_ch1)-2);
            halfpulse_count = round(T/PNRL_timebin/2);
            for i = 1:1:length(P_PC1_ch1_3dBwidth)
                leftrange = counts1(locs1_P(i+1)-halfpulse_count:locs1_P(i+1));
                rightrange = counts1(locs1_P(i+1):locs1_P(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- P_PC1_ch1_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange- P_PC1_ch1_3dB(i+1)));
                P_PC1_ch1_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            P_PC1_ch1_3dBwidth = mean(P_PC1_ch1_3dBwidth);
            
            %% 20dB宽度计算(仅P态)
            P_PC1_ch1_20dB = P_PC1_ch1/100;
            P_PC1_ch1_20dBwidth = zeros(1,length(P_PC1_ch1)-2);
            halfpulse_count = round(T/PNRL_timebin/2);
            for i = 1:1:length(P_PC1_ch1_20dBwidth)
                leftrange = counts1(locs1_P(i+1)-halfpulse_count:locs1_P(i+1));
                rightrange = counts1(locs1_P(i+1):locs1_P(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- P_PC1_ch1_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange- P_PC1_ch1_20dB(i+1)));
                P_PC1_ch1_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            P_PC1_ch1_20dBwidth = mean(P_PC1_ch1_20dBwidth);
            
            Pstablemax = sqrt(var(P_PC1_ch1))/P_max_ave;
            Pstablemin = sqrt(var(P_PC1_ch2))/P_min_ave;
            P_sum = P_PC1_ch1_int_ave+P_PC1_ch2_int_ave;
            
            % N在PC1 ch1 下
            thr_N_PC1_ch1 = Npeakmin;          % N态在PC1下ch1通道的阈值
            locs1_N_temp = find(pks1<thr_N_PC1_ch1);     % N态在寻峰序列中的位置       PC1下ch1
            N_PC1_ch1 = pks1(locs1_N_temp);              % N态峰值强度                PC1下ch1
            locs1_N = locs1(locs1_N_temp);               % N态在时间序列中的位置       PC1下ch1
            
            % N在PC1 ch2 下
            thr_N_PC1_ch2 = Npeakmax;          % N态在PC1下ch2通道的阈值
            locs2_N_temp = find(pks2>thr_N_PC1_ch2);     % N态在寻峰序列中的位置       PC1下ch2
            N_PC1_ch2 = pks2(locs2_N_temp);              % N态峰值强度                PC1下ch2
            locs2_N = locs2(locs2_N_temp);               % N态在时间序列中的位置      PC1下ch2
            
            % N态峰值比
            N_max_ave = mean(N_PC1_ch2);    % 超导效率归一化
            N_min_ave = mean(N_PC1_ch1);
            ER_N = 10*log10(N_max_ave/N_min_ave);       
            
            % 门宽积分比和全宽积分
            N_PC1_ch1_int = zeros(1,length(N_PC1_ch1)-2); %  N态在PC1 ch1通道中脉冲半高宽积分
            for i = 1:length(N_PC1_ch1_int)
                N_PC1_ch1_int(i) = sum(counts1(locs1_N(i+1)-bins/2:locs1_N(i+1)+bins/2));
            end
            N_PC1_ch2_int = zeros(1,length(N_PC1_ch2)-2); %  N态在PC1 ch2通道中脉冲半高宽积分
            for i = 1:length(N_PC1_ch2_int)
                N_PC1_ch2_int(i) = sum(counts2(locs2_N(i+1)-bins/2:locs2_N(i+1)+bins/2));
            end
            N_PC1_ch1_int_ave = mean(N_PC1_ch1_int);    % 超导效率归一化
            N_PC1_ch2_int_ave = mean(N_PC1_ch2_int);
            ER_N_int = 10*log10(N_PC1_ch2_int_ave/N_PC1_ch1_int_ave);
            
            %% 3dB宽度计算(仅N态)
            N_PC1_ch2_3dB = N_PC1_ch2/2;
            N_PC1_ch2_3dBwidth = zeros(1,length(N_PC1_ch2)-2);
            halfpulse_count = round(T/PNRL_timebin/2);
            for i = 1:1:length(N_PC1_ch2_3dBwidth)
                leftrange = counts2(locs2_N(i+1)-halfpulse_count:locs2_N(i+1));
                rightrange = counts2(locs2_N(i+1):locs2_N(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- N_PC1_ch2_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange- N_PC1_ch2_3dB(i+1)));
                N_PC1_ch2_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            N_PC1_ch2_3dBwidth = mean(N_PC1_ch2_3dBwidth);
            
            %% 20dB宽度计算(仅N态)
            N_PC1_ch2_20dB = N_PC1_ch2/100;
            N_PC1_ch2_20dBwidth = zeros(1,length(N_PC1_ch2)-2);
            halfpulse_count = round(T/PNRL_timebin/2);
            for i = 1:1:length(N_PC1_ch2_20dBwidth)
                leftrange = counts2(locs2_N(i+1)-halfpulse_count:locs2_N(i+1));
                rightrange = counts2(locs2_N(i+1):locs2_N(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- N_PC1_ch2_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange- N_PC1_ch2_20dB(i+1)));
                N_PC1_ch2_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            N_PC1_ch2_20dBwidth = mean(N_PC1_ch2_20dBwidth);
            
            Nstablemax = sqrt(var(N_PC1_ch2))/N_max_ave;
            Nstablemin = sqrt(var(N_PC1_ch1))/N_min_ave;
            N_sum = N_PC1_ch1_int_ave+N_PC1_ch2_int_ave;
            
            % R在PC2 ch3 下
            thr_R_PC2_ch3 = Rpeakmax;          % R态在PC2下ch3通道的阈值
            locs1_R_temp = find(pks3>thr_R_PC2_ch3);     % R态在寻峰序列中的位置       PC2下ch3
            R_PC2_ch3 = pks3(locs1_R_temp);              % R态峰值强度                PC2下ch3
            locs1_R = locs3(locs1_R_temp);               % R态在时间序列中的位置      PC2下ch3
            
            % R在PC2 ch4 下
            thr_R_PC2_ch4 = Rpeakmin;          % R态在PC2下ch4通道的阈值
            locs2_R_temp = find(pks4<thr_R_PC2_ch4);     % R态在寻峰序列中的位置       PC2下ch4
            R_PC2_ch4 = pks4(locs2_R_temp);              % R态峰值强度                PC2下ch4
            locs2_R = locs4(locs2_R_temp);               % R态在时间序列中的位置      PC2下ch4
            
            % R态峰值比
            R_max_ave = mean(R_PC2_ch3);    % 超导效率归一化
            R_min_ave = mean(R_PC2_ch4);
            ER_R = 10*log10(R_max_ave/R_min_ave);          
            
            % 门宽积分比和全宽积分
            R_PC2_ch3_int = zeros(1,length(R_PC2_ch3)-2); %  R态在PC2 ch3通道中脉冲半高宽积分
            for i = 1:length(R_PC2_ch3_int)
                R_PC2_ch3_int(i) = sum(counts3(locs1_R(i+1)-bins/2:locs1_R(i+1)+bins/2));
            end
            R_PC2_ch4_int = zeros(1,length(R_PC2_ch4)-2); %  R态在PC2 ch4通道中脉冲半高宽积分
            for i = 1:length(R_PC2_ch4_int)
                R_PC2_ch4_int(i) = sum(counts4(locs2_R(i+1)-bins/2:locs2_R(i+1)+bins/2));
            end
            R_PC2_ch3_int_ave = mean(R_PC2_ch3_int);    % 超导效率归一化
            R_PC2_ch4_int_ave = mean(R_PC2_ch4_int);
            ER_R_int = 10*log10(R_PC2_ch3_int_ave/R_PC2_ch4_int_ave);
            
            %% 3dB宽度计算(仅R态)
            R_PC2_ch3_3dB = R_PC2_ch3/2;
            R_PC2_ch3_3dBwidth = zeros(1,length(R_PC2_ch3)-2);
            halfpulse_count = round(T/PNRL_timebin/2);
            for i = 1:1:length(R_PC2_ch3_3dBwidth)
                leftrange = counts3(locs1_R(i+1)-halfpulse_count:locs1_R(i+1));
                rightrange = counts3(locs1_R(i+1):locs1_R(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- R_PC2_ch3_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange- R_PC2_ch3_3dB(i+1)));
                R_PC2_ch3_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            R_PC2_ch3_3dBwidth = mean(R_PC2_ch3_3dBwidth);
            
            %% 20dB宽度计算(仅R态)
            R_PC2_ch3_20dB = R_PC2_ch3/100;
            R_PC2_ch3_20dBwidth = zeros(1,length(R_PC2_ch3)-2);
            halfpulse_count = round(T/PNRL_timebin/2);
            for i = 1:1:length(R_PC2_ch3_20dBwidth)
                leftrange = counts3(locs1_R(i+1)-halfpulse_count:locs1_R(i+1));
                rightrange = counts3(locs1_R(i+1):locs1_R(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- R_PC2_ch3_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange- R_PC2_ch3_20dB(i+1)));
                R_PC2_ch3_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            R_PC2_ch3_20dBwidth = mean(R_PC2_ch3_20dBwidth);     
            
            Rstablemax = sqrt(var(R_PC2_ch3))/R_max_ave;
            Rstablemin = sqrt(var(R_PC2_ch4))/R_min_ave;
            R_sum = R_PC2_ch3_int_ave+R_PC2_ch4_int_ave;
            
            % L在PC2 ch3 下
            thr_L_PC2_ch3 = Lpeakmin;          % L态在PC2下ch3通道的阈值
            locs1_L_temp = find(pks3<thr_L_PC2_ch3);     % L态在寻峰序列中的位置       PC2下ch3
            L_PC2_ch3 = pks3(locs1_L_temp);              % L态峰值强度                PC2下ch3
            locs1_L = locs3(locs1_L_temp);               % L态在时间序列中的位置       PC2下ch3
            
            % L在PC2 ch4 下
            thr_L_PC2_ch4 = Lpeakmax;          % L态在PC2下ch4通道的阈值
            locs2_L_temp = find(pks4>thr_L_PC2_ch4);     % L态在寻峰序列中的位置       PC2下ch4
            L_PC2_ch4 = pks4(locs2_L_temp);              % L态峰值强度                PC2下ch4
            locs2_L = locs4(locs2_L_temp);               % L态在时间序列中的位置      PC2下ch4
            
            % L态峰值比
            L_max_ave = mean(L_PC2_ch4);    % 超导效率归一化
            L_min_ave = mean(L_PC2_ch3);
            ER_L = 10*log10(L_max_ave/L_min_ave);       
            
            % 门宽积分比和全宽积分
            L_PC2_ch3_int = zeros(1,length(L_PC2_ch3)-2); %  L态在PC2 ch3通道中脉冲半高宽积分
            for i = 1:length(L_PC2_ch3_int)
                L_PC2_ch3_int(i) = sum(counts3(locs1_L(i+1)-bins/2:locs1_L(i+1)+bins/2));
            end
            L_PC2_ch4_int = zeros(1,length(L_PC2_ch4)-2); %  L态在PC2 ch4通道中脉冲半高宽积分
            for i = 1:length(L_PC2_ch4_int)
                L_PC2_ch4_int(i) = sum(counts4(locs2_L(i+1)-bins/2:locs2_L(i+1)+bins/2));
            end
            L_PC2_ch3_int_ave = mean(L_PC2_ch3_int);    % 超导效率归一化
            L_PC2_ch4_int_ave = mean(L_PC2_ch4_int);
            ER_L_int = 10*log10(L_PC2_ch4_int_ave/L_PC2_ch3_int_ave);
            
            %% 3dB宽度计算(仅L态)
            L_PC2_ch4_3dB = L_PC2_ch4/2;
            L_PC2_ch4_3dBwidth = zeros(1,length(L_PC2_ch4)-2);
            halfpulse_count = round(T/PNRL_timebin/2);
            for i = 1:1:length(L_PC2_ch4_3dBwidth)
                leftrange = counts4(locs2_L(i+1)-halfpulse_count:locs2_L(i+1));
                rightrange = counts4(locs2_L(i+1):locs2_L(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- L_PC2_ch4_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange- L_PC2_ch4_3dB(i+1)));
                L_PC2_ch4_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            L_PC2_ch4_3dBwidth = mean(L_PC2_ch4_3dBwidth);
            
            %% 20dB宽度计算(仅L态)
            L_PC2_ch4_20dB = L_PC2_ch4/100;
            L_PC2_ch4_20dBwidth = zeros(1,length(L_PC2_ch4)-2);
            halfpulse_count = round(T/PNRL_timebin/2);
            for i = 1:1:length(L_PC2_ch4_20dBwidth)
                leftrange = counts4(locs2_L(i+1)-halfpulse_count:locs2_L(i+1));
                rightrange = counts4(locs2_L(i+1):locs2_L(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- L_PC2_ch4_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange- L_PC2_ch4_20dB(i+1)));
                L_PC2_ch4_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            L_PC2_ch4_20dBwidth = mean(L_PC2_ch4_20dBwidth);
            
            Lstablemax= sqrt(var(L_PC2_ch4))/L_max_ave;
            Lstablemin = sqrt(var(L_PC2_ch3))/L_min_ave;
            L_sum = L_PC2_ch3_int_ave+L_PC2_ch4_int_ave;
            
            peakerrorrate = (1/10^(ER_P/10)+1/10^(ER_N/10)+1/10^(ER_L/10)+1/10^(ER_R/10))/4*100;
            peakextratio = 10*log10(1/(peakerrorrate/100));
            threerrorrate = (1/10^(ER_P_int/10)+1/10^(ER_N_int/10)+1/10^(ER_L_int/10)+1/10^(ER_R_int/10))/4*100; 
            threextratio = 10*log10(1/(threerrorrate/100)); 
            PNpeakextratio = 10*log10(1/((1/10^(ER_P/10)+1/10^(ER_N/10))/2));
            PNthreextratio = 10*log10(1/((1/10^(ER_P_int/10)+1/10^(ER_N_int/10))/2));
            RLpeakextratio = 10*log10(1/((1/10^(ER_R/10)+1/10^(ER_L/10))/2));
            RLthreextratio = 10*log10(1/((1/10^(ER_R_int/10)+1/10^(ER_L_int/10))/2));
            
            all_sum = P_sum+N_sum+R_sum+L_sum;
            max_power = max([P_sum, N_sum, R_sum, L_sum]);
            min_power = min([P_sum, N_sum, R_sum, L_sum]);
            
            PDL = 10*log10(max_power/min_power);
        end
        
        function PNRL_plot(app,fig_P,fig_N,fig_R,fig_L, ...
                   time1,counts1,time2,counts2,time3,counts3,time4,counts4, ...
                   P_PC1_ch1, locs1_P, P_PC1_ch2, locs2_P, ER_P, ER_P_int, ...
                   N_PC1_ch1, locs1_N, N_PC1_ch2, locs2_N, ER_N, ER_N_int, ...
                   R_PC2_ch3, locs1_R, R_PC2_ch4, locs2_R, ER_R, ER_R_int, ...
                   L_PC2_ch3, locs1_L, L_PC2_ch4, locs2_L, ER_L, ER_L_int, ...
                   slider, button, title_P, title_N, title_R, title_L)
            delete(allchild(fig_P));          
            delete(allchild(fig_N));  
            delete(allchild(fig_R));  
            delete(allchild(fig_L));  
            
            ch2_delay = app.ch2delay.Value;
            ch4_delay = app.ch4delay.Value;
            
           %% P态作图
            dB1 = strcat('ave ER of peaks = ',num2str(ER_P),'dB');
            dB2 = strcat('ave ER of pulses = ',num2str(ER_P_int),'dB');
            H = plot(fig_P,time1,counts1,time1(locs1_P),P_PC1_ch1,'or',time2-ch2_delay,counts2,time2(locs2_P)-ch2_delay,P_PC1_ch2,'sk');
            xlim(fig_P,[0,slider]);
            grid(fig_P,'on');
            hP = legend(H([2 4]),'P in ch1','P in ch2');
            set(hP,'Orientation','horizon');
            set(hP,'FontName','Times New Roman','FontSize',14,'FontWeight','normal');
            set(hP, 'Color', 'none');
            set(hP, 'Box', 'off'); 
            xlabel(fig_P,'time/ps','fontsize',14,'FontName','Arial');
            ylabel(fig_P,'counts','fontsize',14,'FontName','Arial');
            title(fig_P,title_P);
            if button
                set(fig_P,'YScale','linear');
                ylim(fig_P, 'auto');
            else
                text(fig_P,5,100000,dB1);
                text(fig_P,30000*slider/50000,100000,dB2);
                set(fig_P,'YScale','log');
                ylim(fig_P,[1,100000]);
            end
            
           %% N态作图
            dB1 = strcat('ave ER of peaks = ',num2str(ER_N),'dB');
            dB2 = strcat('ave ER of pulses = ',num2str(ER_N_int),'dB');
            H = plot(fig_N,time1,counts1,time1(locs1_N),N_PC1_ch1,'or',time2-ch2_delay,counts2,time2(locs2_N)-ch2_delay,N_PC1_ch2,'sk');
            xlim(fig_N,[0,slider]);
            grid(fig_N,'on');
            hP = legend(H([4 2]),'N in ch2','N in ch1');
            set(hP,'Orientation','horizon');
            set(hP,'FontName','Times New Roman','FontSize',14,'FontWeight','normal');
            set(hP, 'Color', 'none');
            set(hP, 'Box', 'off'); 
            xlabel(fig_N,'time/ps','fontsize',14,'FontName','Arial');
            ylabel(fig_N,'counts','fontsize',14,'FontName','Arial');
            title(fig_N,title_N);
            if button
                set(fig_N,'YScale','linear');
                ylim(fig_N, 'auto');
            else
                text(fig_N,5,100000,dB1);
                text(fig_N,30000*slider/50000,100000,dB2);                
                set(fig_N,'YScale','log');
                ylim(fig_N,[1,100000]);
            end
            
           %% R态作图
            dB1 = strcat('ave ER of peaks = ',num2str(ER_R),'dB');
            dB2 = strcat('ave ER of pulses = ',num2str(ER_R_int),'dB');
            H = plot(fig_R,time3,counts3,time3(locs1_R),R_PC2_ch3,'or',time4-ch4_delay,counts4,time4(locs2_R)-ch4_delay,R_PC2_ch4,'sk');
            xlim(fig_R,[0,slider]);
            grid(fig_R,'on');
            hP = legend(H([2 4]),'R in ch3','R in ch4');
            set(hP,'Orientation','horizon');
            set(hP,'FontName','Times New Roman','FontSize',14,'FontWeight','normal');
            set(hP, 'Color', 'none');
            set(hP, 'Box', 'off'); 
            xlabel(fig_R,'time/ps','fontsize',14,'FontName','Arial');
            ylabel(fig_R,'counts','fontsize',14,'FontName','Arial');
            title(fig_R,title_R);
            if button
                set(fig_R,'YScale','linear');
                ylim(fig_R, 'auto');
            else
                set(fig_R,'YScale','log');
                text(fig_R,5,100000,dB1);
                text(fig_R,30000*slider/50000,100000,dB2);
                ylim(fig_R,[1,100000]);
            end
            
           %% L态作图
            dB1 = strcat('ave ER of peaks = ',num2str(ER_L),'dB');
            dB2 = strcat('ave ER of pulses = ',num2str(ER_L_int),'dB');
            H = plot(fig_L,time3,counts3,time3(locs1_L),L_PC2_ch3,'or',time4-ch4_delay,counts4,time4(locs2_L)-ch4_delay,L_PC2_ch4,'sk');
            xlim(fig_L,[0,slider]);
            grid(fig_L,'on');
            hP = legend(H([4 2]),'L in ch4','L in ch3');
            set(hP,'Orientation','horizon');
            set(hP,'FontName','Times New Roman','FontSize',14,'FontWeight','normal');
            set(hP, 'Color', 'none');
            set(hP, 'Box', 'off'); 
            xlabel(fig_L,'time/ps','fontsize',14,'FontName','Arial');
            ylabel(fig_L,'counts','fontsize',14,'FontName','Arial');
            title(fig_L,title_L);   
            if button
                set(fig_L,'YScale','linear');
                ylim(fig_L, 'auto');
            else
                set(fig_L,'YScale','log');
                text(fig_L,5,100000,dB1);
                text(fig_L,30000*slider/50000,100000,dB2);
                ylim(fig_L,[1,100000]);
            end           
        end

        function  [binslist,width,errorate_P,errorate_N,errorate_L,errorate_R,errorate_ave, ...
                P_sumratio_list,N_sumratio_list,R_sumratio_list,L_sumratio_list,all_sumratio_list]=PNRL_binwidth_analysis(app,T,PNRL_timebin,time1,counts1,time2,counts2,time3,counts3,time4,counts4)
            binslist = 0:round(T/2/PNRL_timebin);
            width = binslist*PNRL_timebin;
            errorate_P = zeros(1,length(binslist));
            errorate_N = zeros(1,length(binslist));
            errorate_L = zeros(1,length(binslist));
            errorate_R = zeros(1,length(binslist));
            errorate_ave = zeros(1,length(binslist));
            P_sum_list = zeros(1,length(binslist));
            N_sum_list = zeros(1,length(binslist));
            R_sum_list = zeros(1,length(binslist));
            L_sum_list = zeros(1,length(binslist));
            all_sum_list = zeros(1,length(binslist));
            P_sumratio_list = zeros(1,length(binslist));
            N_sumratio_list = zeros(1,length(binslist));
            R_sumratio_list = zeros(1,length(binslist));
            L_sumratio_list = zeros(1,length(binslist));
            all_sumratio_list = zeros(1,length(binslist));             
            for i = 1:length(binslist)
                [PNRL_timebin, ...
                P_PC1_ch1, locs1_P, P_PC1_ch2, locs2_P, ER_P, ER_P_int, P_PC1_ch1_3dBwidth, P_PC1_ch1_20dBwidth, Pstablemax, Pstablemin, ...
                N_PC1_ch1, locs1_N, N_PC1_ch2, locs2_N, ER_N, ER_N_int, N_PC1_ch2_3dBwidth, N_PC1_ch2_20dBwidth, Nstablemax, Nstablemin, ...
                R_PC2_ch3, locs1_R, R_PC2_ch4, locs2_R, ER_R, ER_R_int, R_PC2_ch3_3dBwidth, R_PC2_ch3_20dBwidth, Rstablemax, Rstablemin, ...
                L_PC2_ch3, locs1_L, L_PC2_ch4, locs2_L, ER_L, ER_L_int, L_PC2_ch4_3dBwidth, L_PC2_ch4_20dBwidth, Lstablemax, Lstablemin, ...
                peakerrorrate, threerrorrate, peakextratio, threextratio, PNpeakextratio, PNthreextratio, RLpeakextratio, RLthreextratio, ...
                P_sum, N_sum, R_sum, L_sum, all_sum, PDL, theta_rad_CH12, theta_rad_CH34 ...
                ] = PNRL_analysis(app,app.Ppeakmax.Value,app.Ppeakmin.Value,app.Npeakmax.Value,app.Npeakmin.Value,app.Rpeakmax.Value,app.Rpeakmin.Value,app.Lpeakmax.Value,app.Lpeakmin.Value, ...
                                    app.PNRL_T,binslist(i),time1,counts1,time2,counts2,time3,counts3,time4,counts4,PNRL_timebin,app.PNRL_MINPEAKDISTANCE.Value); 
                errorate_P(i) = 1/10^(ER_P_int/10)*100;
                errorate_N(i) = 1/10^(ER_N_int/10)*100;
                errorate_L(i) = 1/10^(ER_L_int/10)*100;
                errorate_R(i) = 1/10^(ER_R_int/10)*100;
                errorate_ave(i) = (errorate_P(i)+errorate_N(i)+errorate_L(i)+errorate_R(i))/4;
                P_sum_list(i) = P_sum;
                N_sum_list(i) = N_sum;
                R_sum_list(i) = R_sum;
                L_sum_list(i) = L_sum;
                all_sum_list(i) = all_sum;
            end
            for i = 1:length(binslist)
                P_sumratio_list(i) = P_sum_list(i)/P_sum_list(length(binslist))*100;
                N_sumratio_list(i) = N_sum_list(i)/N_sum_list(length(binslist))*100;
                R_sumratio_list(i) = R_sum_list(i)/R_sum_list(length(binslist))*100;
                L_sumratio_list(i) = L_sum_list(i)/L_sum_list(length(binslist))*100;
                all_sumratio_list(i) = all_sum_list(i)/all_sum_list(length(binslist))*100;
            end
        end
        
        function PNRL_binwidth_analysis_plot(app,width,errorate_P,errorate_N,errorate_L,errorate_R,errorate_ave, ...
                P_sumratio_list,N_sumratio_list,R_sumratio_list,L_sumratio_list,all_sumratio_list)
            delete(allchild(app.UIAxes5));  
            yyaxis(app.UIAxes5,"left");
            % 左侧Y轴（Extinction Ratio）
            p1 = plot(app.UIAxes5,width,errorate_P,'c-*');
            hold(app.UIAxes5,'on');
            p2 = plot(app.UIAxes5,width,errorate_N,'g-*');
            hold(app.UIAxes5,'on');
            p3 = plot(app.UIAxes5,width,errorate_R,'m-*');
            hold(app.UIAxes5,'on');
            p4 = plot(app.UIAxes5,width,errorate_L,'k-*');
            hold(app.UIAxes5,'on');
            p5 = plot(app.UIAxes5,width,errorate_ave,'-*');
            hold(app.UIAxes5,'on');
            ylabel(app.UIAxes5,'Errorate (%)','fontsize',18,'FontName','Arial');
            
            yyaxis(app.UIAxes5,"right");
            % 右侧Y轴（Photon Number Ratio）
            p6 = plot(app.UIAxes5,width,P_sumratio_list,'c-*');
            hold(app.UIAxes5,'on');
            p7 = plot(app.UIAxes5,width,N_sumratio_list,'g-*');
            hold(app.UIAxes5,'on');
            p8 = plot(app.UIAxes5,width,R_sumratio_list,'m-*');
            hold(app.UIAxes5,'on');
            p9 = plot(app.UIAxes5,width,L_sumratio_list,'k-*');
            hold(app.UIAxes5,'on');
            p10 = plot(app.UIAxes5,width,all_sumratio_list,'-*');
            ylabel(app.UIAxes5,'Photon Number Ratio (%)', 'fontsize', 18, 'FontName', 'Arial');
            
            p1.LineWidth = 1;
            p2.LineWidth = 1;
            p3.LineWidth = 1;
            p4.LineWidth = 1;
            p5.LineWidth = 1;
            p6.LineWidth = 1;
            p7.LineWidth = 1;
            p8.LineWidth = 1;
            p9.LineWidth = 1;
            p10.LineWidth = 1;
            
            % 添加图例
            lgd = legend(app.UIAxes5,'P ER', 'N ER', 'R ER', 'L ER', 'ave ER','P Sum Ratio', 'N Sum Ratio', 'R Sum Ratio', 'L Sum Ratio', 'all Sum Ratio','Location', 'southeast');
            lgd.Box = 'off';
            
            % 显示网格
            grid(app.UIAxes5,'on');
            
            % 设置坐标轴标签和标题
            xlabel(app.UIAxes5,'Bin Width (ps)', 'fontsize', 18, 'FontName', 'Arial');
            title(app.UIAxes5,'PNRL Bin Width Analysis', 'fontsize', 21, 'FontName', 'Arial'); 
        end
        
        function [time1,time2,time3,time4,counts1,counts2,counts3,counts4,time12,counts12,time34,counts34] = ...
                        SDVPNRL_delayamend(app,time1,counts1,time2,counts2,time3,counts3,time4,counts4,SDVPNRL_timebin)
            
            % ========== 1. 峰值检测 ==========
            min_peak_height = 3;
            [~, locs1] = findpeaks(counts1, 'MINPEAKHEIGHT', min_peak_height, ...
                                  'MINPEAKDISTANCE', app.SDVPNRL_MINPEAKDISTANCE.Value);
            [~, locs2] = findpeaks(counts2, 'MINPEAKHEIGHT', min_peak_height, ...
                                  'MINPEAKDISTANCE', app.SDVPNRL_MINPEAKDISTANCE.Value);
            [~, locs3] = findpeaks(counts3, 'MINPEAKHEIGHT', min_peak_height, ...
                                  'MINPEAKDISTANCE', app.SDVPNRL_MINPEAKDISTANCE.Value);
            [~, locs4] = findpeaks(counts4, 'MINPEAKHEIGHT', min_peak_height, ...
                                  'MINPEAKDISTANCE', app.SDVPNRL_MINPEAKDISTANCE.Value);
            
            % ========== 2. 寻找最优延迟（新算法）==========
            % 通道1和2的最优延迟
            CH12_locs_diff = find_best_delay(app, locs1, locs2, app.SDVPNRL_T, SDVPNRL_timebin);
            
            % 通道3和4的最优延迟
            CH34_locs_diff = find_best_delay(app, locs3, locs4, app.SDVPNRL_T, SDVPNRL_timebin);
            
            % ========== 3. 应用时间对齐 ==========
            if CH12_locs_diff > 0
                time1 = time1(1+CH12_locs_diff:end);
                counts1 = counts1(1+CH12_locs_diff:end);
                time2 = time2(1:length(time1));
                counts2 = counts2(1:length(counts1));
            elseif CH12_locs_diff < 0
                time2 = time2(1-CH12_locs_diff:end);
                counts2 = counts2(1-CH12_locs_diff:end);
                time1 = time1(1:length(time2));
                counts1 = counts1(1:length(counts2));
            end
            
            if CH34_locs_diff > 0
                time3 = time3(1+CH34_locs_diff:end);
                counts3 = counts3(1+CH34_locs_diff:end);
                time4 = time4(1:length(time3));
                counts4 = counts4(1:length(counts3));
            elseif CH34_locs_diff < 0
                time4 = time4(1-CH34_locs_diff:end);
                counts4 = counts4(1-CH34_locs_diff:end);
                time3 = time3(1:length(time4));
                counts3 = counts3(1:length(counts4));
            end
            
            % ========== 4. 通道合并 ==========
            time12 = time1;
            counts12 = counts1 + counts2;
            time34 = time3;
            counts34 = counts3 + counts4;
        end
        
        function best_delay = find_best_delay(app, locs1, locs2, T, timebin)
            % 寻找使两个通道峰值对齐的最优延迟
            if length(locs1) < 3 || length(locs2) < 3
                best_delay = 0;
                return;
            end
            
            % 1. 计算所有可能的延迟候选值
            % 只取前N个峰值进行计算以提高效率
            N = min(50, min(length(locs1), length(locs2)));
            locs1_sub = locs1(1:N);
            locs2_sub = locs2(1:N);
            
            % 计算每对峰值的延迟
            delays = zeros(N, 1);
            for i = 1:N
                delays(i) = locs1_sub(i) - locs2_sub(i);
            end
            
            % 2. 使用直方图找到最集中的延迟值
            max_delay_range = round(T/timebin);  % 最大搜索范围（一个周期内）
            delay_bins = (-max_delay_range:max_delay_range)';
            
            % 计算每个延迟值附近有多少个峰值对
            match_counts = zeros(length(delay_bins), 1);
            tolerance = round(0.01 * mean(diff(locs1_sub)));  % 容差设为平均峰间距的1%
            
            for i = 1:length(delay_bins)
                target_delay = delay_bins(i);
                % 统计有多少个延迟值在目标延迟±容差范围内
                match_counts(i) = sum(abs(delays - target_delay) <= tolerance);
            end
            
            % 3. 找到匹配最多的延迟
            [max_matches, max_idx] = max(match_counts);
            
            if max_matches >= max(3, N*0.3)  % 至少3个匹配，或30%的峰值匹配
                best_delay_raw = delay_bins(max_idx);
                
                % 4. 周期校正
                if abs(best_delay_raw*timebin) < T/2
                    best_delay = best_delay_raw;
                elseif best_delay_raw > 0
                    best_delay = round((best_delay_raw*timebin - T)/timebin);
                else
                    best_delay = round((best_delay_raw*timebin + T)/timebin);
                end
            else
                best_delay = 0;  % 没有足够匹配，不对齐
            end
        end
        
        function LDImportfile(app)
            app.fileLD = readmatrix(app.LD_filename);  % 导入TimeTagger数据
        end
        
        function SDVImportfile(app)
            app.fileSDV = readmatrix(app.SDV_filename);  % 导入TimeTagger数据
        end
        
        function PNRLImportfile(app)
            app.filech1 = readmatrix(app.ch1_filename);  % 导入TimeTagger数据
            app.filech2 = readmatrix(app.ch2_filename);
            app.filech3 = readmatrix(app.ch3_filename);
            app.filech4 = readmatrix(app.ch4_filename);   
        end
        
        function SDVPNRLImportfile(app)
            app.file_IP_ch1 = readmatrix(app.IP_ch1_filename);  % 导入TimeTagger数据
            app.file_IP_ch2 = readmatrix(app.IP_ch2_filename);
            app.file_IP_ch3 = readmatrix(app.IP_ch3_filename);
            app.file_IP_ch4 = readmatrix(app.IP_ch4_filename);   
        end
        
        % 定义一个简单的辅助函数
        function color = get_color_SDVPNRL_SDV(app, value, a, b, c)
            % value: 要检查的值
            % a, b, c: 三个数列
            % 返回颜色：value在a中->黑色，在b中->蓝色，在c中->红色，都不在->绿色
            colors = lines(7);
            if ismember(value, a)
                color = colors(1,:);  % 黑色
            elseif ismember(value, b)
                color = colors(2,:);  % 蓝色
            elseif ismember(value, c)
                color = colors(3,:);  % 红色
            else
                color = colors(4,:);  % 绿色
            end
        end   
        
        function common = find_close_elements(app, a, b, m)
            % 排序两个数组
            a_sorted = sort(a(:));
            b_sorted = sort(b(:));
            
            common = [];
            i = 1;  % a数组的指针
            j = 1;  % b数组的指针
            n_a = length(a_sorted);
            n_b = length(b_sorted);
            
            while i <= n_a && j <= n_b
                diff_val = a_sorted(i) - b_sorted(j);
                
                if abs(diff_val) <= m
                    % 找到匹配元素
                    common = [common; a_sorted(i)];
                    
                    % 移动两个指针，寻找下一个匹配
                    i = i + 1;
                    j = j + 1;
                elseif diff_val > m
                    % a[i] 太大，移动 b 指针
                    j = j + 1;
                else
                    % a[i] 太小，移动 a 指针
                    i = i + 1;
                end
            end
            
            % 去重（由于已排序，unique更快）
            common = unique(common);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            app.UIFigure.Position = get(0,'screensize');
        end

        % Value changed function: SDV_Fre
        function SDV_FreValueChanged(app, event)
            freq = app.SDV_Fre.Value;
            app.SDV_T = 1000/freq; %ps
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.thre_term = app.DropDown.Value;
        end

        % Button pushed function: SDVButton
        function SDVButtonPushed(app, event)
            SDVImportfile(app);
            SDV_timebin = app.fileSDV(1,2)-app.fileSDV(1,1); %ps
            time0 = app.fileSDV(1,:);
            counts0 = app.fileSDV(2,:);

            % 存储原始数据
            app.SDV_time_data = time0;
            app.SDV_counts_data = counts0;
            
            if strcmp(app.thre_term,'手动输入') == 1
                bins = app.SDV_Binsnum.Value;
            elseif strcmp(app.thre_term,'自动最优') == 1
                [binslist,width,errorate,signal_sumratio_list,decoy_sumratio_list,vaccum_sumratio_list,all_sumratio_list]= SDV_binwidth_analysis(app,app.SDV_T,SDV_timebin,time0,counts0);
                SDV_binwidth_analysis_plot(app, width, errorate, signal_sumratio_list, decoy_sumratio_list, vaccum_sumratio_list, all_sumratio_list);
                [~,bin_pos] = min(errorate);
                bins =  binslist(bin_pos);
                app.SDV_Binsnum.Value = bins;
            end
            [signal, signal_loc, Cov_S, signal_3dBwidth, signal_20dBwidth, signal_0p46dBwidth, signal_10dBwidth, ...
             decoy, decoy_loc, Cov_D, decoy_3dBwidth, decoy_20dBwidth, ER_SD, ER_SD_int, ...
             vaccum, vaccum_loc, Cov_V, vaccum_3dBwidth, vaccum_20dBwidth, ER_SV, ER_SV_int, ...
             signal_sum, decoy_sum, vaccum_sum, all_sum ...
             ] = SDV_analysis(app,app.Speakmin.Value,app.Vpeakmax.Value,app.SDV_T,bins,time0,counts0,SDV_timebin,app.SDV_MINPEAKDISTANCE.Value);
            
            %% 存储分析结果
            app.SDV_signal_data = signal;
            app.SDV_signal_loc = signal_loc;
            app.SDV_decoy_data = decoy;
            app.SDV_decoy_loc = decoy_loc;
            app.SDV_vaccum_data = vaccum;
            app.SDV_vaccum_loc = vaccum_loc;
            app.SDV_ER_SD = ER_SD;
            app.SDV_ER_SD_int = ER_SD_int;
            app.SDV_ER_SV = ER_SV;
            app.SDV_ER_SV_int = ER_SV_int;

            %% 宽度
            app.S3dB.Value = signal_3dBwidth;
            app.S20dB.Value = signal_20dBwidth;
            app.D3dB.Value = decoy_3dBwidth;
            app.D20dB.Value = decoy_20dBwidth;
            app.V3dB.Value = vaccum_3dBwidth;
            app.V20dB.Value = vaccum_20dBwidth;  
            app.S0p46dB.Value = signal_0p46dBwidth;
            app.S10dB.Value = signal_10dBwidth;
            
            %% 变异系数
            app.Cov_S.Value = Cov_S;
            app.Cov_D.Value = Cov_D;
            app.Cov_V.Value = Cov_V;
                
            SDV_plot(app,app.UIAxes, time0, counts0, signal, signal_loc, ...
                     decoy, decoy_loc, ER_SD, ER_SD_int, ...
                     vaccum, vaccum_loc, ER_SV, ER_SV_int,  ...
                     app.SDV_Slider.Value, app.SDVLinearButton.Value,'AM Rand')
        end

        % Value changed function: PNRL_dataname
        function PNRL_datanameValueChanged2(app, event)
            value = app.PNRL_dataname.Value;
            app.ch1_filename = ['ch1-' value '.csv'];
            app.ch2_filename = ['ch2-' value '.csv'];
            app.ch3_filename = ['ch3-' value '.csv'];
            app.ch4_filename = ['ch4-' value '.csv'];            
        end

        % Value changed function: PNRL_Fre
        function PNRL_FreValueChanged(app, event)
            freq = app.PNRL_Fre.Value;
            app.PNRL_T = 1000/freq; %ps            
        end

        % Button pushed function: PNRLButton
        function PNRLButtonPushed(app, event)
            PNRLImportfile(app);
            PNRL_timebin = app.filech1(1,2)-app.filech1(1,1); %ps    
            time1 = app.filech1(1,:);
            counts1 = app.filech1(2,:)/app.ch1eff.Value;
            time2 = app.filech2(1,:);
            counts2 = app.filech2(2,:)/app.ch2eff.Value;
            time3 = app.filech3(1,:);
            counts3 = app.filech3(2,:)/app.ch3eff.Value;
            time4 = app.filech4(1,:);
            counts4 = app.filech4(2,:)/app.ch4eff.Value;
        
            % 存储原始数据
            app.PNRL_time1_data = time1;
            app.PNRL_counts1_data = counts1;
            app.PNRL_time2_data = time2;
            app.PNRL_counts2_data = counts2;
            app.PNRL_time3_data = time3;
            app.PNRL_counts3_data = counts3;
            app.PNRL_time4_data = time4;
            app.PNRL_counts4_data = counts4;

            if strcmp(app.four_term,'手动输入') == 1
                bins = app.PNRL_Binsnum.Value;
            elseif strcmp(app.four_term,'自动最优') == 1
                PNRL_timebin = app.filech1(1,2)-app.filech1(1,1); %ps   
                [binslist,width,errorate_P,errorate_N,errorate_L,errorate_R,errorate_ave, ...
                P_sumratio_list,N_sumratio_list,R_sumratio_list,L_sumratio_list,all_sumratio_list] ...
                =PNRL_binwidth_analysis(app,app.PNRL_T,PNRL_timebin,time1,counts1,time2,counts2,time3,counts3,time4,counts4);
                PNRL_binwidth_analysis_plot(app,width,errorate_P,errorate_N,errorate_L,errorate_R,errorate_ave, ...
                P_sumratio_list,N_sumratio_list,R_sumratio_list,L_sumratio_list,all_sumratio_list);
                [~,bin_pos] = min(errorate_ave);
                bins =  binslist(bin_pos);
                app.PNRL_Binsnum.Value = bins;
            end
            [PNRL_timebin, ...
            P_PC1_ch1, locs1_P, P_PC1_ch2, locs2_P, ER_P, ER_P_int, P_PC1_ch1_3dBwidth, P_PC1_ch1_20dBwidth, Pstablemax, Pstablemin, ...
            N_PC1_ch1, locs1_N, N_PC1_ch2, locs2_N, ER_N, ER_N_int, N_PC1_ch2_3dBwidth, N_PC1_ch2_20dBwidth, Nstablemax, Nstablemin, ...
            R_PC2_ch3, locs1_R, R_PC2_ch4, locs2_R, ER_R, ER_R_int, R_PC2_ch3_3dBwidth, R_PC2_ch3_20dBwidth, Rstablemax, Rstablemin, ...
            L_PC2_ch3, locs1_L, L_PC2_ch4, locs2_L, ER_L, ER_L_int, L_PC2_ch4_3dBwidth, L_PC2_ch4_20dBwidth, Lstablemax, Lstablemin, ...
            peakerrorrate, threerrorrate, peakextratio, threextratio, PNpeakextratio, PNthreextratio, RLpeakextratio, RLthreextratio, ...
            P_sum, N_sum, R_sum, L_sum, all_sum, PDL, theta_rad_CH12, theta_rad_CH34 ...
            ] = PNRL_analysis(app,app.Ppeakmax.Value,app.Ppeakmin.Value,app.Npeakmax.Value,app.Npeakmin.Value,app.Rpeakmax.Value,app.Rpeakmin.Value,app.Lpeakmax.Value,app.Lpeakmin.Value, ...
                                    app.PNRL_T,bins,time1,counts1,time2,counts2,time3,counts3,time4,counts4,PNRL_timebin,app.PNRL_MINPEAKDISTANCE.Value); 

            %% 存储分析结果
            % P态数据
            app.PNRL_P_PC1_ch1 = P_PC1_ch1;
            app.PNRL_locs1_P = locs1_P;
            app.PNRL_P_PC1_ch2 = P_PC1_ch2;
            app.PNRL_locs2_P = locs2_P;
            app.PNRL_ER_P = ER_P;
            app.PNRL_ER_P_int = ER_P_int;
            
            % N态数据
            app.PNRL_N_PC1_ch1 = N_PC1_ch1;
            app.PNRL_locs1_N = locs1_N;
            app.PNRL_N_PC1_ch2 = N_PC1_ch2;
            app.PNRL_locs2_N = locs2_N;
            app.PNRL_ER_N = ER_N;
            app.PNRL_ER_N_int = ER_N_int;
            
            % R态数据
            app.PNRL_R_PC2_ch3 = R_PC2_ch3;
            app.PNRL_locs1_R = locs1_R;
            app.PNRL_R_PC2_ch4 = R_PC2_ch4;
            app.PNRL_locs2_R = locs2_R;
            app.PNRL_ER_R = ER_R;
            app.PNRL_ER_R_int = ER_R_int;
            
            % L态数据
            app.PNRL_L_PC2_ch3 = L_PC2_ch3;
            app.PNRL_locs1_L = locs1_L;
            app.PNRL_L_PC2_ch4 = L_PC2_ch4;
            app.PNRL_locs2_L = locs2_L;
            app.PNRL_ER_L = ER_L;
            app.PNRL_ER_L_int = ER_L_int;

            app.P3dB.Value = P_PC1_ch1_3dBwidth;
            app.P20dB.Value = P_PC1_ch1_20dBwidth;
            app.Pstablemax.Value = Pstablemax;
            app.Pstablemin.Value = Pstablemin;
            
            app.N3dB.Value = N_PC1_ch2_3dBwidth;
            app.N20dB.Value = N_PC1_ch2_20dBwidth;
            app.Nstablemax.Value = Nstablemax;
            app.Nstablemin.Value = Nstablemin;
            
            app.R3dB.Value = R_PC2_ch3_3dBwidth;
            app.R20dB.Value = R_PC2_ch3_20dBwidth;
            app.Rstablemax.Value = Rstablemax;
            app.Rstablemin.Value = Rstablemin;
            
            app.L3dB.Value = L_PC2_ch4_3dBwidth;
            app.L20dB.Value = L_PC2_ch4_20dBwidth;
            app.Lstablemax.Value = Lstablemax;
            app.Lstablemin.Value = Lstablemin; 
            
            app.peakerrorrate.Value = peakerrorrate;
            app.threerrorrate.Value = threerrorrate;
            app.peakextratio.Value = peakextratio;
            app.threextratio.Value = threextratio;
            app.PNpeakextratio.Value = PNpeakextratio;
            app.PNthreextratio.Value = PNthreextratio;
            app.RLpeakextratio.Value = RLpeakextratio;
            app.RLthreextratio.Value = RLthreextratio;
            app.PDL.Value = PDL;
            
            PNRL_plot(app,app.UIAxes2_P,app.UIAxes2_N,app.UIAxes2_R,app.UIAxes2_L, ...
                      time1,counts1,time2,counts2,time3,counts3,time4,counts4, ...
                      P_PC1_ch1, locs1_P, P_PC1_ch2, locs2_P, ER_P, ER_P_int, ...
                      N_PC1_ch1, locs1_N, N_PC1_ch2, locs2_N, ER_N, ER_N_int, ...
                      R_PC2_ch3, locs1_R, R_PC2_ch4, locs2_R, ER_R, ER_R_int, ...
                      L_PC2_ch3, locs1_L, L_PC2_ch4, locs2_L, ER_L, ER_L_int, ...
                      app.PNRL_Slider.Value, app.PNRLLinearButton.Value, 'P State', 'N State', 'R State', 'L State');
                  
            % 清除并准备第一个坐标轴 - 0°在3点钟方向
            cla(app.UIAxes8);
            hold(app.UIAxes8, 'on');
            
            % 画单位圆
            t = linspace(0, 2*pi, 100);
            plot(app.UIAxes8, cos(t), sin(t), 'k-', 'LineWidth', 1);
            
            % 批量画线段（从原点出发）
            x_CH12 = cos(theta_rad_CH12);
            y_CH12 = sin(theta_rad_CH12);
            
            % 将所有线段组合成带NaN的序列，一次性绘制
            x_segments = zeros(1, 3*length(x_CH12));
            y_segments = zeros(1, 3*length(y_CH12));
            for i = 1:length(x_CH12)
                x_segments(3*i-2:3*i) = [0, x_CH12(i), NaN];
                y_segments(3*i-2:3*i) = [0, y_CH12(i), NaN];
            end
            plot(app.UIAxes8, x_segments, y_segments, 'b-', 'LineWidth', 1);
            
            % 在端点画点
            plot(app.UIAxes8, x_CH12, y_CH12, 'bo', 'MarkerSize', 6, 'MarkerFaceColor', 'b', 'MarkerEdgeColor', 'k');
            
            % 添加角度刻度（每隔30度）
            theta_ticks = 0:30:330;
            tick_rad = deg2rad(theta_ticks);
            tick_x = 1.15 * cos(tick_rad);
            tick_y = 1.15 * sin(tick_rad);
            for i = 1:length(theta_ticks)
                text(app.UIAxes8, tick_x(i), tick_y(i), sprintf('%d°', theta_ticks(i)), ...
                    'HorizontalAlignment', 'center', 'FontSize', 10);
            end
            
            % 设置坐标轴
            axis(app.UIAxes8, 'equal');
            grid(app.UIAxes8, 'on');
            xlim(app.UIAxes8, [-1.3, 1.3]);
            ylim(app.UIAxes8, [-1.3, 1.3]);
            title(app.UIAxes8, sprintf('CH12 Theta向量 (n=%d)', length(theta_rad_CH12)));
            hold(app.UIAxes8, 'off');
            
            % ------------------------------------------------------------------------
            % 清除并准备第二个坐标轴 - 0°在12点钟方向，逆时针递增
            cla(app.UIAxes8_2);
            hold(app.UIAxes8_2, 'on');
            
            % 画单位圆 - 0°在顶部，逆时针
            t = linspace(0, 2*pi, 100);
            plot(app.UIAxes8_2, sin(t), cos(t), 'k-', 'LineWidth', 1);
            
            % 批量画线段 - 坐标映射：x = sin(θ), y = cos(θ)
            x_CH34 = -sin(theta_rad_CH34);
            y_CH34 = cos(theta_rad_CH34);
            
            % 将所有线段组合成带NaN的序列
            x_segments = zeros(1, 3*length(x_CH34));
            y_segments = zeros(1, 3*length(y_CH34));
            for i = 1:length(x_CH34)
                x_segments(3*i-2:3*i) = [0, x_CH34(i), NaN];
                y_segments(3*i-2:3*i) = [0, y_CH34(i), NaN];
            end
            plot(app.UIAxes8_2, x_segments, y_segments, 'r-', 'LineWidth', 1);
            
            % 在端点画点
            plot(app.UIAxes8_2, x_CH34, y_CH34, 'ro', 'MarkerSize', 6, 'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'k');
            
            % 添加角度刻度 - 0°在顶部，逆时针递增
            theta_ticks = 0:30:330;
            tick_rad = deg2rad(theta_ticks);
            tick_x = 1.15 * -sin(tick_rad);
            tick_y = 1.15 * cos(tick_rad);
            for i = 1:length(theta_ticks)
                text(app.UIAxes8_2, tick_x(i), tick_y(i), sprintf('%d°', theta_ticks(i)), ...
                    'HorizontalAlignment', 'center', 'FontSize', 10);
            end
            
            % 设置坐标轴
            axis(app.UIAxes8_2, 'equal');
            grid(app.UIAxes8_2, 'on');
            xlim(app.UIAxes8_2, [-1.3, 1.3]);
            ylim(app.UIAxes8_2, [-1.3, 1.3]);
            title(app.UIAxes8_2, sprintf('CH34 Theta向量 (n=%d)', length(theta_rad_CH34)));
            hold(app.UIAxes8_2, 'off');
        end

        % Value changed function: Ppeakmax
        function PpeakmaxValueChanged(app, event)
            app.Ppeakmax.Value = event.Value;
        end

        % Value changed function: Ppeakmin
        function PpeakminValueChanged(app, event)
            app.Ppeakmin.Value = event.Value;            
        end

        % Value changed function: Npeakmax
        function NpeakmaxValueChanged(app, event)
            app.npeakmax.Value = event.Value;           
        end

        % Value changed function: Npeakmin
        function NpeakminValueChanged(app, event)
            app.npeakmin.Value = event.Value;            
        end

        % Value changed function: Rpeakmax
        function RpeakmaxValueChanged(app, event)
            app.lpeakmax.Value = event.Value;
        end

        % Value changed function: Rpeakmin
        function RpeakminValueChanged(app, event)
            app.lpeakmin.Value = event.Value;
        end

        % Value changed function: Lpeakmax
        function LpeakmaxValueChanged(app, event)
            app.rpeakmax.Value = event.Value;
        end

        % Value changed function: Lpeakmin
        function LpeakminValueChanged(app, event)
            app.rpeakmin.Value = event.Value;
        end

        % Value changed function: PNRL_Binsnum
        function PNRL_BinsnumValueChanged(app, event)
            app.PNRL_Binsnum.Value = event.Value;           
        end

        % Value changed function: ch2delay
        function ch2delayValueChanged(app, event)
            app.ch2delay.Value = event.Value;      
        end

        % Value changed function: ch4delay
        function ch4delayValueChanged(app, event)
            app.ch4delay.Value = event.Value;
            
        end

        % Callback function
        function PDL_LOSSValueChanged(app, event)
            app.PDL_LOSS.Value = event.Value;
        end

        % Callback function
        function PDL_KValueChanged(app, event)
            app.PDL_K.Value = event.Value;
        end

        % Value changed function: LD_Fre
        function LD_FreValueChanged(app, event)
            freq = app.LD_Fre.Value;
            app.LD_T = 1000/freq; %ps                
        end

        % Button pushed function: LDButton
        function LDButtonPushed(app, event)
            LDImportfile(app);
            [signal, signal_loc, Fremeasured, Cov_Fre, signal_3dBwidth, signal_20dBwidth] = LD_analysis(app);
            
            % 保存分析数据供后续使用
            app.LD_signal_data = signal;
            app.LD_signal_loc_data = signal_loc;
            
            % 更新UI显示
            app.LD3dB.Value = signal_3dBwidth;
            app.LD20dB.Value = signal_20dBwidth;
            app.LD_Fremeasured.Value = Fremeasured;
            app.LD_Cov_Fre.Value = Cov_Fre;
            
            % 绘图
            LD_plot(app, signal, signal_loc);
        end

        % Value changed function: LD_dataneme
        function LD_datanemeValueChanged(app, event)
            value = app.LD_dataneme.Value;
            app.LD_filename = ['LD-' value '.csv'];            
        end

        % Value changed function: PNRL_Slider
        function PNRL_SliderValueChanged(app, event)
            if ~isempty(app.PNRL_time1_data)
                % 如果是Linear模式，直接修改四个坐标轴的X轴范围
                if app.PNRLLinearButton.Value
                    set(app.UIAxes2_P, 'XLim', [0, app.PNRL_Slider.Value]);
                    set(app.UIAxes2_N, 'XLim', [0, app.PNRL_Slider.Value]);
                    set(app.UIAxes2_R, 'XLim', [0, app.PNRL_Slider.Value]);
                    set(app.UIAxes2_L, 'XLim', [0, app.PNRL_Slider.Value]);
                else
                    % 如果是Log模式，需要重新绘图来更新文本位置
                    PNRL_plot(app, app.UIAxes2_P, app.UIAxes2_N, app.UIAxes2_R, app.UIAxes2_L, ...
                              app.PNRL_time1_data, app.PNRL_counts1_data, ...
                              app.PNRL_time2_data, app.PNRL_counts2_data, ...
                              app.PNRL_time3_data, app.PNRL_counts3_data, ...
                              app.PNRL_time4_data, app.PNRL_counts4_data, ...
                              app.PNRL_P_PC1_ch1, app.PNRL_locs1_P, app.PNRL_P_PC1_ch2, app.PNRL_locs2_P, ...
                              app.PNRL_ER_P, app.PNRL_ER_P_int, ...
                              app.PNRL_N_PC1_ch1, app.PNRL_locs1_N, app.PNRL_N_PC1_ch2, app.PNRL_locs2_N, ...
                              app.PNRL_ER_N, app.PNRL_ER_N_int, ...
                              app.PNRL_R_PC2_ch3, app.PNRL_locs1_R, app.PNRL_R_PC2_ch4, app.PNRL_locs2_R, ...
                              app.PNRL_ER_R, app.PNRL_ER_R_int, ...
                              app.PNRL_L_PC2_ch3, app.PNRL_locs1_L, app.PNRL_L_PC2_ch4, app.PNRL_locs2_L, ...
                              app.PNRL_ER_L, app.PNRL_ER_L_int, ...
                              app.PNRL_Slider.Value, app.PNRLLinearButton.Value, 'P State', 'N State', 'R State', 'L State');
                end
            end
        end

        % Callback function
        function Button_7Pushed(app, event)
            cla (app.UIAxes5_3,'reset')
            PNRL_binwidth_photonnum_plot(app);
        end

        % Value changed function: SDV_Slider
        function SDV_SliderValueChanged(app, event)
            if ~isempty(app.SDV_signal_data) && ~isempty(app.SDV_time_data)
                % 如果是Linear模式，直接修改X轴范围
                if app.SDVLinearButton.Value
                    set(app.UIAxes, 'XLim', [1, app.SDV_Slider.Value]);
                else
                    % 如果是Log模式，需要重新绘图来更新文本位置
                    SDV_plot(app, app.UIAxes, app.SDV_time_data, app.SDV_counts_data, ...
                             app.SDV_signal_data, app.SDV_signal_loc, ...
                             app.SDV_decoy_data, app.SDV_decoy_loc, ...
                             app.SDV_ER_SD, app.SDV_ER_SD_int, ...
                             app.SDV_vaccum_data, app.SDV_vaccum_loc, ...
                             app.SDV_ER_SV, app.SDV_ER_SV_int, ...
                             app.SDV_Slider.Value, app.SDVLinearButton.Value, 'AM Rand');
                end
            end
        end

        % Value changed function: SDV_dataname
        function SDV_datanameValueChanged(app, event)
            value = app.SDV_dataname.Value;
            app.SDV_filename = ['SDV-' value '.csv'];
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            app.four_term = app.DropDown_2.Value;
        end

        % Value changed function: LD_Slider
        function LD_SliderValueChanged(app, event)
            if ~isempty(app.LD_signal_data) && ~isempty(app.fileLD)
                % 直接修改X轴范围
                set(app.UIAxes7, 'XLim', [1, app.LD_Slider.Value]);
            end
        end

        % Button pushed function: SDVPNRLButton
        function SDVPNRLButtonPushed(app, event)
            SDVPNRLImportfile(app);
            bins = app.SDVPNRL_Binsnum.Value; 
            SDVPNRL_timebin = app.file_IP_ch1(1,2)-app.file_IP_ch1(1,1); %ps    
            time1 = app.file_IP_ch1(1,:);
            counts1 = app.file_IP_ch1(2,:)/app.SDVPNRL_ch1eff.Value;
            time2 = app.file_IP_ch2(1,:);
            counts2 = app.file_IP_ch2(2,:)/app.SDVPNRL_ch2eff.Value;
            time3 = app.file_IP_ch3(1,:);
            counts3 = app.file_IP_ch3(2,:)/app.SDVPNRL_ch3eff.Value;
            time4 = app.file_IP_ch4(1,:);
            counts4 = app.file_IP_ch4(2,:)/app.SDVPNRL_ch4eff.Value;
            
            if app.SDVPNRL_ch2delay.Value > 0 
                ch2delay_step = round(app.SDVPNRL_ch2delay.Value/SDVPNRL_timebin);
                time2 = time2(1:end-ch2delay_step);
                counts2 = counts2(ch2delay_step+1:end);
                time1 = time1(1:end-ch2delay_step);
                counts1 = counts1(1:end-ch2delay_step);
            elseif app.SDVPNRL_ch2delay.Value < 0 
                ch2delay_step = -round(app.SDVPNRL_ch2delay.Value/SDVPNRL_timebin);
                time1 = time1(1:end-ch2delay_step);
                counts1 = counts1(ch2delay_step+1:end);
                time2 = time2(1:end-ch2delay_step);
                counts2 = counts2(1:end-ch2delay_step);
            end
                        
            if app.SDVPNRL_ch4delay.Value > 0 
                ch4delay_step = round(app.SDVPNRL_ch4delay.Value/SDVPNRL_timebin);
                time4 = time4(1:end-ch4delay_step);
                counts4 = counts4(ch4delay_step+1:end);
                time3 = time3(1:end-ch4delay_step);
                counts3 = counts3(1:end-ch4delay_step);                
            elseif app.SDVPNRL_ch4delay.Value < 0
                ch4delay_step = -round(app.SDVPNRL_ch4delay.Value/SDVPNRL_timebin);
                time3 = time3(1:end-ch4delay_step);
                counts3 = counts3(ch4delay_step+1:end);
                time4 = time4(1:end-ch4delay_step);
                counts4 = counts4(1:end-ch4delay_step);    
            end
            
            %%
            [time1,time2,time3,time4,counts1,counts2,counts3,counts4,time12,counts12,time34,counts34] = ...
                SDVPNRL_delayamend(app,time1,counts1,time2,counts2,time3,counts3,time4,counts4,SDVPNRL_timebin);

            %% 存储原始数据
            app.SDVPNRL_time1_data = time1;
            app.SDVPNRL_counts1_data = counts1;
            app.SDVPNRL_time2_data = time2;
            app.SDVPNRL_counts2_data = counts2;
            app.SDVPNRL_time3_data = time3;
            app.SDVPNRL_counts3_data = counts3;
            app.SDVPNRL_time4_data = time4;
            app.SDVPNRL_counts4_data = counts4;
            
            app.SDVPNRL_time12_data = time12;
            app.SDVPNRL_counts12_data = counts12;
            app.SDVPNRL_time34_data = time34;
            app.SDVPNRL_counts34_data = counts34;

            
            [signal_PN, signal_loc_PN, Cov_S_PN, signal_3dBwidth_PN, signal_20dBwidth_PN, signal_0p46dBwidth_PN, signal_10dBwidth_PN, ...
             decoy_PN, decoy_loc_PN, Cov_D_PN, decoy_3dBwidth_PN, decoy_20dBwidth_PN, ER_SD_PN, ER_SD_int_PN, ...
             vaccum_PN, vaccum_loc_PN, Cov_V_PN, vaccum_3dBwidth_PN, vaccum_20dBwidth_PN, ER_SV_PN, ER_SV_int_PN, ...
             signal_sum_PN, decoy_sum_PN, vaccum_sum_PN, all_sum_PN ...
            ] = SDV_analysis(app,app.Speakmin_Z.Value,app.Vpeakmax_Z.Value,app.SDVPNRL_T,bins,time12,counts12,SDVPNRL_timebin,app.SDVPNRL_MINPEAKDISTANCE.Value);
            
            [signal_RL, signal_loc_RL, Cov_S_RL, signal_3dBwidth_RL, signal_20dBwidth_RL, signal_0p46dBwidth_RL, signal_10dBwidth_RL, ...
             decoy_RL, decoy_loc_RL, Cov_D_RL, decoy_3dBwidth_RL, decoy_20dBwidth_RL, ER_SD_RL, ER_SD_int_RL, ...
             vaccum_RL, vaccum_loc_RL, Cov_V_RL, vaccum_3dBwidth_RL, vaccum_20dBwidth_RL, ER_SV_RL, ER_SV_int_RL, ...
             signal_sum_RL, decoy_sum_RL, vaccum_sum_RL, all_sum_RL ...
            ] = SDV_analysis(app,app.Speakmin_X.Value,app.Vpeakmax_X.Value,app.SDVPNRL_T,bins,time34,counts34,SDVPNRL_timebin,app.SDVPNRL_MINPEAKDISTANCE.Value);

            %% 存储SDV分析结果
            % SDV_PN
            app.SDVPNRL_signal_PN_data = signal_PN;
            app.SDVPNRL_signal_loc_PN = signal_loc_PN;
            app.SDVPNRL_decoy_PN_data = decoy_PN;
            app.SDVPNRL_decoy_loc_PN = decoy_loc_PN;
            app.SDVPNRL_vaccum_PN_data = vaccum_PN;
            app.SDVPNRL_vaccum_loc_PN = vaccum_loc_PN;
            app.SDVPNRL_ER_SD_PN = ER_SD_PN;
            app.SDVPNRL_ER_SD_int_PN = ER_SD_int_PN;
            app.SDVPNRL_ER_SV_PN = ER_SV_PN;
            app.SDVPNRL_ER_SV_int_PN = ER_SV_int_PN;
            
            % SDV_RL
            app.SDVPNRL_signal_RL_data = signal_RL;
            app.SDVPNRL_signal_loc_RL = signal_loc_RL;
            app.SDVPNRL_decoy_RL_data = decoy_RL;
            app.SDVPNRL_decoy_loc_RL = decoy_loc_RL;
            app.SDVPNRL_vaccum_RL_data = vaccum_RL;
            app.SDVPNRL_vaccum_loc_RL = vaccum_loc_RL;
            app.SDVPNRL_ER_SD_RL = ER_SD_RL;
            app.SDVPNRL_ER_SD_int_RL = ER_SD_int_RL;
            app.SDVPNRL_ER_SV_RL = ER_SV_RL;
            app.SDVPNRL_ER_SV_int_RL = ER_SV_int_RL;
            
            %% 变异系数
            app.SDVPN_Cov_S.Value = Cov_S_PN;
            app.SDVPN_Cov_D.Value = Cov_D_PN;
            app.SDVPN_Cov_V.Value = Cov_V_PN;
            app.SDVRL_Cov_S.Value = Cov_S_RL;
            app.SDVRL_Cov_D.Value = Cov_D_RL;
            app.SDVRL_Cov_V.Value = Cov_V_RL;
            
            %%
            [pks1,locs1] = findpeaks(counts1,'MINPEAKHEIGHT',0,'MINPEAKDISTANCE',app.SDVPNRL_MINPEAKDISTANCE.Value);  %PC1 ch1通道数据寻峰
            [pks2_temp, locs2_temp] = findpeaks(counts2, 'MINPEAKHEIGHT', 0, 'MINPEAKDISTANCE', app.SDVPNRL_MINPEAKDISTANCE.Value);
            
            pks2 = zeros(1, length(locs1));
            locs2 = zeros(1, length(locs1));
            match_tol = 5;
            
            for i = 1:length(locs1)
                distances = abs(locs2_temp - locs1(i));
                [min_dist, idx] = min(distances);
                
                if min_dist <= match_tol && ~isempty(locs2_temp)
                    locs2(i) = locs2_temp(idx);
                    pks2(i) = pks2_temp(idx);
                    locs2_temp(idx) = [];
                    pks2_temp(idx) = [];
                else
                    locs2(i) = locs1(i);  % 补位：使用locs1的位置
                    pks2(i) = 0;          % 补位：峰值设为0
                end
            end    
            
            [pks3,locs3] = findpeaks(counts3,'MINPEAKHEIGHT',0,'MINPEAKDISTANCE',app.SDVPNRL_MINPEAKDISTANCE.Value);  %PC2 ch3通道数据寻峰
            [pks4_temp, locs4_temp] = findpeaks(counts4, 'MINPEAKHEIGHT', 0, 'MINPEAKDISTANCE', app.SDVPNRL_MINPEAKDISTANCE.Value);
            
            pks4 = zeros(1, length(locs3));
            locs4 = zeros(1, length(locs3));
            match_tol = 5;
            
            for i = 1:length(locs3)
                distances = abs(locs4_temp - locs3(i));
                [min_dist, idx] = min(distances);
                
                if min_dist <= match_tol && ~isempty(locs4_temp)
                    locs4(i) = locs4_temp(idx);
                    pks4(i) = pks4_temp(idx);
                    locs4_temp(idx) = [];
                    pks4_temp(idx) = [];
                else
                    locs4(i) = locs3(i);  % 补位：使用locs3的位置
                    pks4(i) = 0;          % 补位：峰值设为0或NaN
                end
            end
            
            
            ch1_int = zeros(1,length(locs1)-2); %  ch1通道中脉冲半高宽积分
            ch2_int = zeros(1,length(locs2)-2); %  ch2通道中脉冲半高宽积分
            ch3_int = zeros(1,length(locs3)-2); %  ch3通道中脉冲半高宽积分
            ch4_int = zeros(1,length(locs4)-2); %  ch4通道中脉冲半高宽积分
            for i = 1:length(ch1_int)
                ch1_int(i) = sum(counts1(locs1(i+1)-bins/2:locs1(i+1)+bins/2));   
                ch2_int(i) = sum(counts2(locs2(i+1)-bins/2:locs2(i+1)+bins/2));
            end
            
            for i = 1:length(ch3_int)
                ch3_int(i) = sum(counts3(locs3(i+1)-bins/2:locs3(i+1)+bins/2));   
                ch4_int(i) = sum(counts4(locs4(i+1)-bins/2:locs4(i+1)+bins/2)); 
            end 
            
            locs_P = find(ch1_int./ch2_int > 10^(app.SDVPNRL_P_thre.Value/10))+1;
            P_PC1_ch1 = pks1(locs_P); 
            P_PC1_ch2 = pks2(locs_P); 
            ER_P = 10*log10(mean(P_PC1_ch1)/mean(P_PC1_ch2));
            ER_P_int = 10*log10(mean(ch1_int(locs_P-1))/mean(ch2_int(locs_P-1)));
            
            locs_N = find(ch2_int./ch1_int > 10^(app.SDVPNRL_N_thre.Value/10))+1;
            N_PC1_ch1 = pks1(locs_N); 
            N_PC1_ch2 = pks2(locs_N); 
            ER_N = 10*log10(mean(N_PC1_ch2)/mean(N_PC1_ch1));
            ER_N_int = 10*log10(mean(ch2_int(locs_N-1))/mean(ch1_int(locs_N-1)));      
            
            locs_R = find(ch3_int./ch4_int > 10^(app.SDVPNRL_R_thre.Value/10))+1;
            R_PC2_ch3 = pks3(locs_R); 
            R_PC2_ch4 = pks4(locs_R);    
            ER_R = 10*log10(mean(R_PC2_ch3)/mean(R_PC2_ch4));
            ER_R_int = 10*log10(mean(ch3_int(locs_R-1))/mean(ch4_int(locs_R-1))); 
            
            locs_L = find(ch4_int./ch3_int > 10^(app.SDVPNRL_L_thre.Value/10))+1;
            L_PC2_ch3 = pks3(locs_L); 
            L_PC2_ch4 = pks4(locs_L);  
            ER_L = 10*log10(mean(L_PC2_ch4)/mean(L_PC2_ch3));
            ER_L_int = 10*log10(mean(ch4_int(locs_L-1))/mean(ch3_int(locs_L-1))); 

            %% 存储PNRL分析结果
            % P态
            app.SDVPNRL_P_PC1_ch1 = P_PC1_ch1;
            app.SDVPNRL_locs1_P = locs1(locs_P);
            app.SDVPNRL_P_PC1_ch2 = P_PC1_ch2;
            app.SDVPNRL_locs2_P = locs1(locs_P);
            app.SDVPNRL_ER_P = ER_P;
            app.SDVPNRL_ER_P_int = ER_P_int;
            
            % N态
            app.SDVPNRL_N_PC1_ch1 = N_PC1_ch1;
            app.SDVPNRL_locs1_N = locs1(locs_N);
            app.SDVPNRL_N_PC1_ch2 = N_PC1_ch2;
            app.SDVPNRL_locs2_N = locs1(locs_N);
            app.SDVPNRL_ER_N = ER_N;
            app.SDVPNRL_ER_N_int = ER_N_int;
            
            % R态
            app.SDVPNRL_R_PC2_ch3 = R_PC2_ch3;
            app.SDVPNRL_locs1_R = locs3(locs_R);
            app.SDVPNRL_R_PC2_ch4 = R_PC2_ch4;
            app.SDVPNRL_locs2_R = locs3(locs_R);
            app.SDVPNRL_ER_R = ER_R;
            app.SDVPNRL_ER_R_int = ER_R_int;
            
            % L态
            app.SDVPNRL_L_PC2_ch3 = L_PC2_ch3;
            app.SDVPNRL_locs1_L = locs3(locs_L);
            app.SDVPNRL_L_PC2_ch4 = L_PC2_ch4;
            app.SDVPNRL_locs2_L = locs3(locs_L);
            app.SDVPNRL_ER_L = ER_L;
            app.SDVPNRL_ER_L_int = ER_L_int;

            
            locs_SP_max = find_close_elements(app,signal_loc_PN, locs1(locs_P), app.SDVPNRL_T/SDVPNRL_timebin/2);
            locs_SP_min = find_close_elements(app,signal_loc_PN, locs2(locs_P), app.SDVPNRL_T/SDVPNRL_timebin/2);
            locs_SN_max = find_close_elements(app,signal_loc_PN, locs2(locs_N), app.SDVPNRL_T/SDVPNRL_timebin/2);
            locs_SN_min = find_close_elements(app,signal_loc_PN, locs1(locs_N), app.SDVPNRL_T/SDVPNRL_timebin/2);
            locs_SR_max = find_close_elements(app,signal_loc_RL, locs3(locs_R), app.SDVPNRL_T/SDVPNRL_timebin/2);
            locs_SR_min = find_close_elements(app,signal_loc_RL, locs4(locs_R), app.SDVPNRL_T/SDVPNRL_timebin/2);
            locs_SL_max = find_close_elements(app,signal_loc_RL, locs4(locs_L), app.SDVPNRL_T/SDVPNRL_timebin/2);
            locs_SL_min = find_close_elements(app,signal_loc_RL, locs3(locs_L), app.SDVPNRL_T/SDVPNRL_timebin/2);
            
            locs_DP_max = find_close_elements(app,decoy_loc_PN, locs1(locs_P), app.SDVPNRL_T/SDVPNRL_timebin/2);
            locs_DP_min = find_close_elements(app,decoy_loc_PN, locs2(locs_P), app.SDVPNRL_T/SDVPNRL_timebin/2);
            locs_DN_max = find_close_elements(app,decoy_loc_PN, locs2(locs_N), app.SDVPNRL_T/SDVPNRL_timebin/2);
            locs_DN_min = find_close_elements(app,decoy_loc_PN, locs1(locs_N), app.SDVPNRL_T/SDVPNRL_timebin/2);
            locs_DR_max = find_close_elements(app,decoy_loc_RL, locs3(locs_R), app.SDVPNRL_T/SDVPNRL_timebin/2);
            locs_DR_min = find_close_elements(app,decoy_loc_RL, locs4(locs_R), app.SDVPNRL_T/SDVPNRL_timebin/2);
            locs_DL_max = find_close_elements(app,decoy_loc_RL, locs4(locs_L), app.SDVPNRL_T/SDVPNRL_timebin/2);
            locs_DL_min = find_close_elements(app,decoy_loc_RL, locs3(locs_L), app.SDVPNRL_T/SDVPNRL_timebin/2);

            SP_pulse = zeros(1,length(locs_SP_max));
            SN_pulse = zeros(1,length(locs_SN_max));
            SR_pulse = zeros(1,length(locs_SR_max));
            SL_pulse = zeros(1,length(locs_SL_max));
            
            DP_pulse = zeros(1,length(locs_DP_max));
            DN_pulse = zeros(1,length(locs_DN_max));
            DR_pulse = zeros(1,length(locs_DR_max));
            DL_pulse = zeros(1,length(locs_DL_max));
            
            for i = 1:length(locs_SP_max)
                SP_pulse(i) = sum(counts1(locs_SP_max(i)-bins/2:locs_SP_max(i)+bins/2))+sum(counts2(locs_SP_min(i)-bins/2:locs_SP_min(i)+bins/2));   
            end
            
            for i = 1:length(locs_SN_max)
                SN_pulse(i) = sum(counts2(locs_SN_max(i)-bins/2:locs_SN_max(i)+bins/2))+sum(counts1(locs_SN_min(i)-bins/2:locs_SN_min(i)+bins/2));   
            end

            for i = 1:length(locs_SR_max)
                SR_pulse(i) = sum(counts3(locs_SR_max(i)-bins/2:locs_SR_max(i)+bins/2)) + sum(counts4(locs_SR_min(i)-bins/2:locs_SR_min(i)+bins/2));   
            end

            for i = 1:length(locs_SL_max)
                SL_pulse(i) = sum(counts4(locs_SL_max(i)-bins/2:locs_SL_max(i)+bins/2)) + sum(counts3(locs_SL_min(i)-bins/2:locs_SL_min(i)+bins/2));   
            end
           
            for i = 1:length(locs_DP_max)
                DP_pulse(i) = sum(counts1(locs_DP_max(i)-bins/2:locs_DP_max(i)+bins/2)) + sum(counts2(locs_DP_min(i)-bins/2:locs_DP_min(i)+bins/2));   
            end
            
            for i = 1:length(locs_DN_max)
                DN_pulse(i) = sum(counts2(locs_DN_max(i)-bins/2:locs_DN_max(i)+bins/2)) + sum(counts1(locs_DN_min(i)-bins/2:locs_DN_min(i)+bins/2));   
            end

            for i = 1:length(locs_DR_max)
                DR_pulse(i) = sum(counts3(locs_DR_max(i)-bins/2:locs_DR_max(i)+bins/2)) + sum(counts4(locs_DR_min(i)-bins/2:locs_DR_min(i)+bins/2));   
            end

            for i = 1:length(locs_DL_max)
                DL_pulse(i) = sum(counts4(locs_DL_max(i)-bins/2:locs_DL_max(i)+bins/2)) + sum(counts3(locs_DL_min(i)-bins/2:locs_DL_min(i)+bins/2));   
            end

            app.SDVPNRL_SP_num.Value = mean(SP_pulse);
            app.SDVPNRL_SN_num.Value = mean(SN_pulse);              
            app.SDVPNRL_SR_num.Value = mean(SR_pulse);
            app.SDVPNRL_SL_num.Value = mean(SL_pulse);
            app.SDVPNRL_DP_num.Value = mean(DP_pulse);
            app.SDVPNRL_DN_num.Value = mean(DN_pulse);
            app.SDVPNRL_DR_num.Value = mean(DR_pulse);
            app.SDVPNRL_DL_num.Value = mean(DL_pulse);            
            
            app.SDVPNRL_SP_cov.Value = sqrt(var(SP_pulse))/mean(SP_pulse);
            app.SDVPNRL_SN_cov.Value = sqrt(var(SN_pulse))/mean(SN_pulse);              
            app.SDVPNRL_SR_cov.Value = sqrt(var(SR_pulse))/mean(SR_pulse);
            app.SDVPNRL_SL_cov.Value = sqrt(var(SL_pulse))/mean(SL_pulse);
            app.SDVPNRL_DP_cov.Value = sqrt(var(DP_pulse))/mean(DP_pulse);
            app.SDVPNRL_DN_cov.Value = sqrt(var(DN_pulse))/mean(DN_pulse);
            app.SDVPNRL_DR_cov.Value = sqrt(var(DR_pulse))/mean(DR_pulse);
            app.SDVPNRL_DL_cov.Value = sqrt(var(DL_pulse))/mean(DL_pulse);      
            

            app.SDVPNRL_peakextratio.Value = (1/10^(ER_P/10)+1/10^(ER_N/10)+1/10^(ER_L/10)+1/10^(ER_R/10))/4*100;
            app.SDVPNRL_peakerrorrate.Value = 10*log10(1/(app.SDVPNRL_peakextratio.Value/100));            
            app.SDVPNRL_threerrorrate.Value = (1/10^(ER_P_int/10)+1/10^(ER_N_int/10)+1/10^(ER_L_int/10)+1/10^(ER_R_int/10))/4*100; 
            app.SDVPNRL_threextratio.Value = 10*log10(1/(app.SDVPNRL_threerrorrate.Value/100));             
            app.SDVPNRL_PNpeakextratio.Value = 10*log10(1/((1/10^(ER_P/10)+1/10^(ER_N/10))/2));
            app.SDVPNRL_PNthreextratio.Value = 10*log10(1/((1/10^(ER_P_int/10)+1/10^(ER_N_int/10))/2));
            app.SDVPNRL_RLpeakextratio.Value = 10*log10(1/((1/10^(ER_R/10)+1/10^(ER_L/10))/2));
            app.SDVPNRL_RLthreextratio.Value = 10*log10(1/((1/10^(ER_R_int/10)+1/10^(ER_L_int/10))/2));                        

            SDV_plot(app,app.UIAxes4_SDV_PN, time12, counts12, signal_PN, signal_loc_PN, ...
                     decoy_PN, decoy_loc_PN, ER_SD_PN, ER_SD_int_PN, ...
                     vaccum_PN, vaccum_loc_PN, ER_SV_PN, ER_SV_int_PN,  ...
                     app.SDVPNRL_Slider_SDV.Value, app.SDVPNRL_LinearButton_SDV.Value, 'Z Basis AM Rand');
            
            if app.SDVPNRL_LogButton_SDV.Value 
                hold(app.UIAxes4_SDV_PN, 'on');
                % 添加标签到app.UIAxes4_SDV_RL坐标轴
                for i = 1:length(locs_P)
                    text(app.UIAxes4_SDV_PN, locs1(locs_P(i))*SDVPNRL_timebin-app.SDVPNRL_T/2, 14000, 'P', ...
                            'Color', get_color_SDVPNRL_SDV(app, ...
                            round(locs1(locs_P(i))*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(signal_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(decoy_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(vaccum_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T)));
                    if locs1(locs_P(i))*SDVPNRL_timebin > app.SDVPNRL_Slider_SDV.Value
                        break;
                    end
                end
                for i = 1:length(locs_N)
                    text(app.UIAxes4_SDV_PN, locs1(locs_N(i))*SDVPNRL_timebin-app.SDVPNRL_T/2, 14000, 'N', ...
                            'Color', get_color_SDVPNRL_SDV(app, ...
                            round(locs1(locs_N(i))*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(signal_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(decoy_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(vaccum_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T)));
                    if locs1(locs_N(i))*SDVPNRL_timebin > app.SDVPNRL_Slider_SDV.Value
                        break;
                    end
                end
                for i = 1:length(locs_R)
                    text(app.UIAxes4_SDV_PN, locs3(locs_R(i))*SDVPNRL_timebin-app.SDVPNRL_T/2, 14000, 'R', ...
                            'Color', get_color_SDVPNRL_SDV(app, ...
                            round(locs3(locs_R(i))*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(signal_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(decoy_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(vaccum_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T)));
                    if locs3(locs_R(i))*SDVPNRL_timebin > app.SDVPNRL_Slider_SDV.Value
                        break;
                    end
                end
                for i = 1:length(locs_L)
                    text(app.UIAxes4_SDV_PN, locs3(locs_L(i))*SDVPNRL_timebin-app.SDVPNRL_T/2, 14000, 'L', ...
                            'Color', get_color_SDVPNRL_SDV(app, ...
                            round(locs3(locs_L(i))*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(signal_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(decoy_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(vaccum_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T)));
                    if locs3(locs_L(i))*SDVPNRL_timebin > app.SDVPNRL_Slider_SDV.Value
                        break;
                    end
                end
                hold(app.UIAxes4_SDV_PN, 'off');
            end
            
           
            SDV_plot(app,app.UIAxes4_SDV_RL, time34, counts34, signal_RL, signal_loc_RL, ...
                     decoy_RL, decoy_loc_RL, ER_SD_RL, ER_SD_int_RL, ...
                     vaccum_RL, vaccum_loc_RL, ER_SV_RL, ER_SV_int_RL,  ...
                     app.SDVPNRL_Slider_SDV.Value, app.SDVPNRL_LinearButton_SDV.Value, 'X Basis AM Rand');   
            if app.SDVPNRL_LogButton_SDV.Value
                hold(app.UIAxes4_SDV_RL, 'on');
                % 添加标签到app.UIAxes4_SDV_RL坐标轴
                for i = 1:length(locs_P)
                    text(app.UIAxes4_SDV_RL, locs1(locs_P(i))*SDVPNRL_timebin-app.SDVPNRL_T/2, 14000, 'P', ...
                            'Color', get_color_SDVPNRL_SDV(app, ...
                            round(locs1(locs_P(i))*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(signal_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(decoy_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(vaccum_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T)));
                    if locs1(locs_P(i))*SDVPNRL_timebin > app.SDVPNRL_Slider_SDV.Value
                        break;
                    end
                end
                for i = 1:length(locs_N)
                    text(app.UIAxes4_SDV_RL, locs1(locs_N(i))*SDVPNRL_timebin-app.SDVPNRL_T/2, 14000, 'N', ...
                            'Color', get_color_SDVPNRL_SDV(app, ...
                            round(locs1(locs_N(i))*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(signal_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(decoy_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(vaccum_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T)));
                    if locs1(locs_N(i))*SDVPNRL_timebin > app.SDVPNRL_Slider_SDV.Value
                        break;
                    end
                end
                for i = 1:length(locs_R)
                    text(app.UIAxes4_SDV_RL, locs3(locs_R(i))*SDVPNRL_timebin-app.SDVPNRL_T/2, 14000, 'R', ...
                            'Color', get_color_SDVPNRL_SDV(app, ...
                            round(locs3(locs_R(i))*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(signal_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(decoy_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(vaccum_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T)));
                    if locs3(locs_R(i))*SDVPNRL_timebin > app.SDVPNRL_Slider_SDV.Value
                        break;
                    end                
                end
                for i = 1:length(locs_L)
                    text(app.UIAxes4_SDV_RL, locs3(locs_L(i))*SDVPNRL_timebin-app.SDVPNRL_T/2, 14000, 'L', ...
                            'Color', get_color_SDVPNRL_SDV(app, ...
                            round(locs3(locs_L(i))*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(signal_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(decoy_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(vaccum_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T)));
                    if locs3(locs_L(i))*SDVPNRL_timebin > app.SDVPNRL_Slider_SDV.Value
                        break;
                    end                 
                end
                hold(app.UIAxes4_SDV_RL, 'off');
            end
            
            
            PNRL_plot(app,app.UIAxes3_P,app.UIAxes3_N,app.UIAxes3_R,app.UIAxes3_L, ...
                   time1,counts1,time2,counts2,time3,counts3,time4,counts4, ...
                   P_PC1_ch1, locs1(locs_P), P_PC1_ch2, locs1(locs_P), ER_P, ER_P_int, ...
                   N_PC1_ch1, locs1(locs_N), N_PC1_ch2, locs1(locs_N), ER_N, ER_N_int, ...
                   R_PC2_ch3, locs3(locs_R), R_PC2_ch4, locs3(locs_R), ER_R, ER_R_int, ...
                   L_PC2_ch3, locs3(locs_L), L_PC2_ch4, locs3(locs_L), ER_L, ER_L_int, ...
                   app.SDVPNRL_Slider_PNRL.Value, app.SDVPNRL_LinearButton_PNRL.Value, 'P State', 'N State', 'R State', 'L State')
            
            if app.SDVPNRL_LogButton_PNRL.Value
                hold(app.UIAxes3_P, 'on');
                % 添加标签到app.UIAxes4_SDV_RL坐标轴
                for i = 1:length(locs_P)
                    text(app.UIAxes3_P, locs1(locs_P(i))*SDVPNRL_timebin-app.SDVPNRL_T/2, 14000, 'P', ...
                            'Color', get_color_SDVPNRL_SDV(app, ...
                            round(locs1(locs_P(i))*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(signal_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(decoy_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(vaccum_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T)));
                    if locs1(locs_P(i))*SDVPNRL_timebin > app.SDVPNRL_Slider_SDV.Value
                        break;
                    end
                end
                for i = 1:length(locs_N)
                    text(app.UIAxes3_P, locs1(locs_N(i))*SDVPNRL_timebin-app.SDVPNRL_T/2, 14000, 'N', ...
                            'Color', get_color_SDVPNRL_SDV(app, ...
                            round(locs1(locs_N(i))*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(signal_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(decoy_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(vaccum_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T)));
                    if locs1(locs_N(i))*SDVPNRL_timebin > app.SDVPNRL_Slider_SDV.Value
                        break;
                    end
                end
                hold(app.UIAxes3_P, 'off');
                
                hold(app.UIAxes3_N, 'on');
                % 添加标签到app.UIAxes4_SDV_RL坐标轴
                for i = 1:length(locs_P)
                    text(app.UIAxes3_N, locs1(locs_P(i))*SDVPNRL_timebin-app.SDVPNRL_T/2, 14000, 'P', ...
                            'Color', get_color_SDVPNRL_SDV(app, ...
                            round(locs1(locs_P(i))*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(signal_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(decoy_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(vaccum_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T)));
                    if locs1(locs_P(i))*SDVPNRL_timebin > app.SDVPNRL_Slider_SDV.Value
                        break;
                    end
                end
                for i = 1:length(locs_N)
                    text(app.UIAxes3_N, locs1(locs_N(i))*SDVPNRL_timebin-app.SDVPNRL_T/2, 14000, 'N', ...
                            'Color', get_color_SDVPNRL_SDV(app, ...
                            round(locs1(locs_N(i))*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(signal_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(decoy_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(vaccum_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T)));
                    if locs1(locs_N(i))*SDVPNRL_timebin > app.SDVPNRL_Slider_SDV.Value
                        break;
                    end
                end
                hold(app.UIAxes3_N, 'off');                

                hold(app.UIAxes3_R, 'on');
                for i = 1:length(locs_R)
                    text(app.UIAxes3_R, locs3(locs_R(i))*SDVPNRL_timebin-app.SDVPNRL_T/2, 14000, 'R', ...
                            'Color', get_color_SDVPNRL_SDV(app, ...
                            round(locs3(locs_R(i))*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(signal_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(decoy_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(vaccum_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T)));
                    if locs3(locs_R(i))*SDVPNRL_timebin > app.SDVPNRL_Slider_SDV.Value
                        break;
                    end                
                end
                for i = 1:length(locs_L)
                    text(app.UIAxes3_R, locs3(locs_L(i))*SDVPNRL_timebin-app.SDVPNRL_T/2, 14000, 'L', ...
                            'Color', get_color_SDVPNRL_SDV(app, ...
                            round(locs3(locs_L(i))*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(signal_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(decoy_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(vaccum_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T)));
                    if locs3(locs_L(i))*SDVPNRL_timebin > app.SDVPNRL_Slider_SDV.Value
                        break;
                    end                 
                end
                hold(app.UIAxes3_R, 'off');    
                
                hold(app.UIAxes3_L, 'on');
                % 添加标签到app.UIAxes4_SDV_RL坐标轴
                for i = 1:length(locs_R)
                    text(app.UIAxes3_L, locs3(locs_R(i))*SDVPNRL_timebin-app.SDVPNRL_T/2, 14000, 'R', ...
                            'Color', get_color_SDVPNRL_SDV(app, ...
                            round(locs3(locs_R(i))*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(signal_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(decoy_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(vaccum_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T)));
                    if locs3(locs_R(i))*SDVPNRL_timebin > app.SDVPNRL_Slider_SDV.Value
                        break;
                    end                
                end
                for i = 1:length(locs_L)
                    text(app.UIAxes3_L, locs3(locs_L(i))*SDVPNRL_timebin-app.SDVPNRL_T/2, 14000, 'L', ...
                            'Color', get_color_SDVPNRL_SDV(app, ...
                            round(locs3(locs_L(i))*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(signal_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(decoy_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T), ...
                            round(vaccum_loc_RL*SDVPNRL_timebin/app.SDVPNRL_T)));
                    if locs3(locs_L(i))*SDVPNRL_timebin > app.SDVPNRL_Slider_SDV.Value
                        break;
                    end                 
                end
                hold(app.UIAxes3_L, 'off');                 
            end
        end

        % Value changed function: SDVPNRL_dataname
        function SDVPNRL_datanameValueChanged(app, event)
            value = app.SDVPNRL_dataname.Value;
            app.IP_ch1_filename = ['ch1-' value '.csv'];
            app.IP_ch2_filename = ['ch2-' value '.csv'];
            app.IP_ch3_filename = ['ch3-' value '.csv'];
            app.IP_ch4_filename = ['ch4-' value '.csv'];        
        end

        % Size changed function: SDVPNRL1Tab
        function SDVPNRL(app, event)
            position = app.SDVPNRL1Tab.Position;
            
        end

        % Value changed function: SDVPNRL_Fre
        function SDVPNRL_FreValueChanged(app, event)
            freq = app.SDVPNRL_Fre.Value;
            app.SDVPNRL_T = 1000/freq; %ps        
        end

        % Button pushed function: SDVPNRLSaveButton
        function SDVPNRLSaveButtonPushed(app, event)
            M12=[app.SDVPNRL_time12_data;app.SDVPNRL_counts12_data];
            M34=[app.SDVPNRL_time34_data;app.SDVPNRL_counts34_data];
            
            % 使用 uiputfile 让用户选择保存文件的位置，并输入基础文件名
            [file, path] = uiputfile('*.csv', '选择保存文件的位置');
            
            if file ~= 0 % 用户没有取消操作
                % 获取基础文件名（去掉后缀）
                baseFileName = file(1:end-4); % 去掉 .csv 后缀
                
                % 使用 csvwrite 保存每个矩阵为不同的 CSV 文件
                csvwrite(fullfile(path, ['SDV-',baseFileName, '-ch12.csv']), M12);
                csvwrite(fullfile(path, ['SDV-',baseFileName, '-ch34.csv']), M34);
            end
        end

        % Selection changed function: Coordinate
        function CoordinateSelectionChanged(app, event)
             % 如果有数据，重新绘图
            if ~isempty(app.LD_signal_data) && ~isempty(app.fileLD)
                LD_plot(app, app.LD_signal_data, app.LD_signal_loc_data);
            end
        end

        % Selection changed function: Coordinate_2
        function Coordinate_2SelectionChanged(app, event)
            % SDV坐标轴类型选择回调
            if ~isempty(app.SDV_signal_data) && ~isempty(app.SDV_time_data)
                % 重新绘图
                SDV_plot(app, app.UIAxes, app.SDV_time_data, app.SDV_counts_data, ...
                         app.SDV_signal_data, app.SDV_signal_loc, ...
                         app.SDV_decoy_data, app.SDV_decoy_loc, ...
                         app.SDV_ER_SD, app.SDV_ER_SD_int, ...
                         app.SDV_vaccum_data, app.SDV_vaccum_loc, ...
                         app.SDV_ER_SV, app.SDV_ER_SV_int, ...
                         app.SDV_Slider.Value, app.SDVLinearButton.Value, 'AM Rand');
            end
        end

        % Selection changed function: Coordinate_3
        function Coordinate_3SelectionChanged(app, event)
            % PNRL坐标轴类型选择回调
            if ~isempty(app.PNRL_time1_data)
                % 重新绘图
                PNRL_plot(app, app.UIAxes2_P, app.UIAxes2_N, app.UIAxes2_R, app.UIAxes2_L, ...
                          app.PNRL_time1_data, app.PNRL_counts1_data, ...
                          app.PNRL_time2_data, app.PNRL_counts2_data, ...
                          app.PNRL_time3_data, app.PNRL_counts3_data, ...
                          app.PNRL_time4_data, app.PNRL_counts4_data, ...
                          app.PNRL_P_PC1_ch1, app.PNRL_locs1_P, app.PNRL_P_PC1_ch2, app.PNRL_locs2_P, ...
                          app.PNRL_ER_P, app.PNRL_ER_P_int, ...
                          app.PNRL_N_PC1_ch1, app.PNRL_locs1_N, app.PNRL_N_PC1_ch2, app.PNRL_locs2_N, ...
                          app.PNRL_ER_N, app.PNRL_ER_N_int, ...
                          app.PNRL_R_PC2_ch3, app.PNRL_locs1_R, app.PNRL_R_PC2_ch4, app.PNRL_locs2_R, ...
                          app.PNRL_ER_R, app.PNRL_ER_R_int, ...
                          app.PNRL_L_PC2_ch3, app.PNRL_locs1_L, app.PNRL_L_PC2_ch4, app.PNRL_locs2_L, ...
                          app.PNRL_ER_L, app.PNRL_ER_L_int, ...
                          app.PNRL_Slider.Value, app.PNRLLinearButton.Value, 'P State', 'N State', 'R State', 'L State');
            end
        end

        % Selection changed function: Coordinate_5
        function Coordinate_5SelectionChanged(app, event)
             % 重新绘图SDV_PN
            if ~isempty(app.SDVPNRL_signal_PN_data)
                SDV_plot(app, app.UIAxes4_SDV_PN, ...
                         app.SDVPNRL_time12_data, app.SDVPNRL_counts12_data, ...
                         app.SDVPNRL_signal_PN_data, app.SDVPNRL_signal_loc_PN, ...
                         app.SDVPNRL_decoy_PN_data, app.SDVPNRL_decoy_loc_PN, ...
                         app.SDVPNRL_ER_SD_PN, app.SDVPNRL_ER_SD_int_PN, ...
                         app.SDVPNRL_vaccum_PN_data, app.SDVPNRL_vaccum_loc_PN, ...
                         app.SDVPNRL_ER_SV_PN, app.SDVPNRL_ER_SV_int_PN, ...
                         app.SDVPNRL_Slider_SDV.Value, app.SDVPNRL_LinearButton_SDV.Value, 'Z Basis AM Rand');
            end
            
            % 重新绘图SDV_RL
            if ~isempty(app.SDVPNRL_signal_RL_data)
                SDV_plot(app, app.UIAxes4_SDV_RL, ...
                         app.SDVPNRL_time34_data, app.SDVPNRL_counts34_data, ...
                         app.SDVPNRL_signal_RL_data, app.SDVPNRL_signal_loc_RL, ...
                         app.SDVPNRL_decoy_RL_data, app.SDVPNRL_decoy_loc_RL, ...
                         app.SDVPNRL_ER_SD_RL, app.SDVPNRL_ER_SD_int_RL, ...
                         app.SDVPNRL_vaccum_RL_data, app.SDVPNRL_vaccum_loc_RL, ...
                         app.SDVPNRL_ER_SV_RL, app.SDVPNRL_ER_SV_int_RL, ...
                         app.SDVPNRL_Slider_SDV.Value, app.SDVPNRL_LinearButton_SDV.Value, 'X Basis AM Rand');
            end          
        end

        % Value changed function: SDVPNRL_Slider_SDV
        function SDVPNRL_Slider_SDVValueChanged(app, event)
            if ~isempty(app.SDVPNRL_signal_PN_data)
                % 更新SDV_PN图
                if app.SDVPNRL_LinearButton_SDV.Value
                    set(app.UIAxes4_SDV_PN, 'XLim', [1, app.SDVPNRL_Slider_SDV.Value]);
                else
                    SDV_plot(app, app.UIAxes4_SDV_PN, ...
                             app.SDVPNRL_time12_data, app.SDVPNRL_counts12_data, ...
                             app.SDVPNRL_signal_PN_data, app.SDVPNRL_signal_loc_PN, ...
                             app.SDVPNRL_decoy_PN_data, app.SDVPNRL_decoy_loc_PN, ...
                             app.SDVPNRL_ER_SD_PN, app.SDVPNRL_ER_SD_int_PN, ...
                             app.SDVPNRL_vaccum_PN_data, app.SDVPNRL_vaccum_loc_PN, ...
                             app.SDVPNRL_ER_SV_PN, app.SDVPNRL_ER_SV_int_PN, ...
                             app.SDVPNRL_Slider_SDV.Value, app.SDVPNRL_LinearButton_SDV.Value, 'Z Basis AM Rand');
                end
            end
            
            if ~isempty(app.SDVPNRL_signal_RL_data)
                % 更新SDV_RL图
                if app.SDVPNRL_LinearButton_SDV.Value
                    set(app.UIAxes4_SDV_RL, 'XLim', [1, app.SDVPNRL_Slider_SDV.Value]);
                else                
                    SDV_plot(app, app.UIAxes4_SDV_RL, ...
                             app.SDVPNRL_time34_data, app.SDVPNRL_counts34_data, ...
                             app.SDVPNRL_signal_RL_data, app.SDVPNRL_signal_loc_RL, ...
                             app.SDVPNRL_decoy_RL_data, app.SDVPNRL_decoy_loc_RL, ...
                             app.SDVPNRL_ER_SD_RL, app.SDVPNRL_ER_SD_int_RL, ...
                             app.SDVPNRL_vaccum_RL_data, app.SDVPNRL_vaccum_loc_RL, ...
                             app.SDVPNRL_ER_SV_RL, app.SDVPNRL_ER_SV_int_RL, ...
                             app.SDVPNRL_Slider_SDV.Value, app.SDVPNRL_LinearButton_SDV.Value, 'X Basis AM Rand');
                end
            end
        end

        % Selection changed function: Coordinate_4
        function Coordinate_4SelectionChanged(app, event)
            if ~isempty(app.SDVPNRL_time1_data)
                PNRL_plot(app, app.UIAxes3_P, app.UIAxes3_N, app.UIAxes3_R, app.UIAxes3_L, ...
                         app.SDVPNRL_time1_data, app.SDVPNRL_counts1_data, ...
                         app.SDVPNRL_time2_data, app.SDVPNRL_counts2_data, ...
                         app.SDVPNRL_time3_data, app.SDVPNRL_counts3_data, ...
                         app.SDVPNRL_time4_data, app.SDVPNRL_counts4_data, ...
                         app.SDVPNRL_P_PC1_ch1, app.SDVPNRL_locs1_P, app.SDVPNRL_P_PC1_ch2, app.SDVPNRL_locs2_P, ...
                         app.SDVPNRL_ER_P, app.SDVPNRL_ER_P_int, ...
                         app.SDVPNRL_N_PC1_ch1, app.SDVPNRL_locs1_N, app.SDVPNRL_N_PC1_ch2, app.SDVPNRL_locs2_N, ...
                         app.SDVPNRL_ER_N, app.SDVPNRL_ER_N_int, ...
                         app.SDVPNRL_R_PC2_ch3, app.SDVPNRL_locs1_R, app.SDVPNRL_R_PC2_ch4, app.SDVPNRL_locs2_R, ...
                         app.SDVPNRL_ER_R, app.SDVPNRL_ER_R_int, ...
                         app.SDVPNRL_L_PC2_ch3, app.SDVPNRL_locs1_L, app.SDVPNRL_L_PC2_ch4, app.SDVPNRL_locs2_L, ...
                         app.SDVPNRL_ER_L, app.SDVPNRL_ER_L_int, ...
                         app.SDVPNRL_Slider_PNRL.Value, app.SDVPNRL_LinearButton_PNRL.Value, 'P State', 'N State', 'R State', 'L State');
            end
        end

        % Value changed function: SDVPNRL_Slider_PNRL
        function SDVPNRL_Slider_PNRLValueChanged(app, event)
            if ~isempty(app.SDVPNRL_time1_data)
                if app.SDVPNRL_LinearButton_PNRL.Value
                    set(app.UIAxes3_P, 'XLim', [0, app.SDVPNRL_Slider_PNRL.Value]);
                    set(app.UIAxes3_N, 'XLim', [0, app.SDVPNRL_Slider_PNRL.Value]);
                    set(app.UIAxes3_R, 'XLim', [0, app.SDVPNRL_Slider_PNRL.Value]);
                    set(app.UIAxes3_L, 'XLim', [0, app.SDVPNRL_Slider_PNRL.Value]);
                else
                    PNRL_plot(app, app.UIAxes3_P, app.UIAxes3_N, app.UIAxes3_R, app.UIAxes3_L, ...
                             app.SDVPNRL_time1_data, app.SDVPNRL_counts1_data, ...
                             app.SDVPNRL_time2_data, app.SDVPNRL_counts2_data, ...
                             app.SDVPNRL_time3_data, app.SDVPNRL_counts3_data, ...
                             app.SDVPNRL_time4_data, app.SDVPNRL_counts4_data, ...
                             app.SDVPNRL_P_PC1_ch1, app.SDVPNRL_locs1_P, app.SDVPNRL_P_PC1_ch2, app.SDVPNRL_locs2_P, ...
                             app.SDVPNRL_ER_P, app.SDVPNRL_ER_P_int, ...
                             app.SDVPNRL_N_PC1_ch1, app.SDVPNRL_locs1_N, app.SDVPNRL_N_PC1_ch2, app.SDVPNRL_locs2_N, ...
                             app.SDVPNRL_ER_N, app.SDVPNRL_ER_N_int, ...
                             app.SDVPNRL_R_PC2_ch3, app.SDVPNRL_locs1_R, app.SDVPNRL_R_PC2_ch4, app.SDVPNRL_locs2_R, ...
                             app.SDVPNRL_ER_R, app.SDVPNRL_ER_R_int, ...
                             app.SDVPNRL_L_PC2_ch3, app.SDVPNRL_locs1_L, app.SDVPNRL_L_PC2_ch4, app.SDVPNRL_locs2_L, ...
                             app.SDVPNRL_ER_L, app.SDVPNRL_ER_L_int, ...
                             app.SDVPNRL_Slider_PNRL.Value, app.SDVPNRL_LinearButton_PNRL.Value, 'P State', 'N State', 'R State', 'L State');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LDTab = uitab(app.TabGroup);
            app.LDTab.Title = 'LD数据处理';
            app.UIAxes7 = uiaxes(app.LDTab);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            app.Panel_9 = uipanel(app.LDTab);
            app.Panel_9.Title = '用户参数输入';
            app.Label_17 = uilabel(app.Panel_9);
            app.Label_17.Text = '频率';
            app.LD_Fre = uieditfield(app.Panel_9, 'numeric');
            app.LD_Fre.ValueChangedFcn = createCallbackFcn(app, @LD_FreValueChanged, true);
            app.GHzLabel_3 = uilabel(app.Panel_9);
            app.GHzLabel_3.Text = 'GHz';
            app.LDButton = uibutton(app.Panel_9, 'push');
            app.LDButton.ButtonPushedFcn = createCallbackFcn(app, @LDButtonPushed, true);
            app.LDButton.Text = '确定';
            app.Label_19 = uilabel(app.Panel_9);
            app.Label_19.Text = '数据名称';
            app.LD_dataneme = uieditfield(app.Panel_9, 'text');
            app.LD_dataneme.ValueChangedFcn = createCallbackFcn(app, @LD_datanemeValueChanged, true);
            app.Label_25 = uilabel(app.Panel_9);
            app.Label_25.Text = '寻峰间距';
            app.LD_MINPEAKDISTANCE = uieditfield(app.Panel_9, 'numeric');
            app.binsLabel_5 = uilabel(app.Panel_9);
            app.binsLabel_5.Text = 'bins';
            app.SLabel_3 = uilabel(app.Panel_9);
            app.SLabel_3.Text = 'S态阈值';
            app.LDpeakmin = uieditfield(app.Panel_9, 'numeric');
            app.LDpeakmin.Value = 1000;
            app.Panel_10 = uipanel(app.LDTab);
            app.Panel_10.Title = '宽度';
            app.psLabel_19 = uilabel(app.Panel_10);
            app.psLabel_19.Text = 'ps';
            app.psLabel_20 = uilabel(app.Panel_10);
            app.psLabel_20.Text = 'ps';
            app.dBLabel = uilabel(app.Panel_10);
            app.dBLabel.Text = '3dB宽度';
            app.LD3dB = uieditfield(app.Panel_10, 'numeric');
            app.dBLabel_2 = uilabel(app.Panel_10);
            app.dBLabel_2.Text = '20dB宽度';
            app.LD20dB = uieditfield(app.Panel_10, 'numeric');
            app.SliderLabel_3 = uilabel(app.LDTab);
            app.SliderLabel_3.Text = 'Slider';
            app.LD_Slider = uislider(app.LDTab);
            app.LD_Slider.Limits = [0 50000];
            app.LD_Slider.ValueChangedFcn = createCallbackFcn(app, @LD_SliderValueChanged, true);
            app.LD_Slider.Value = 50000;
            app.Panel_11 = uipanel(app.LDTab);
            app.Panel_11.Title = '频率';
            app.GHzLabel_4 = uilabel(app.Panel_11);
            app.GHzLabel_4.Text = 'GHz';
            app.GHzLabel_5 = uilabel(app.Panel_11);
            app.GHzLabel_5.Text = 'GHz';
            app.Label_23 = uilabel(app.Panel_11);
            app.Label_23.Text = '测量频率';
            app.LD_Fremeasured = uieditfield(app.Panel_11, 'numeric');
            app.Label_24 = uilabel(app.Panel_11);
            app.Label_24.Text = '频率标准差';
            app.LD_Cov_Fre = uieditfield(app.Panel_11, 'numeric');
            app.Coordinate = uibuttongroup(app.LDTab);
            app.Coordinate.SelectionChangedFcn = createCallbackFcn(app, @CoordinateSelectionChanged, true);
            app.LDLinearButton = uiradiobutton(app.Coordinate);
            app.LDLinearButton.Text = 'Linear';
            app.LDLinearButton.Value = true;
            app.LDLogButton = uiradiobutton(app.Coordinate);
            app.LDLogButton.Text = 'Log';
            app.SDVTab = uitab(app.TabGroup);
            app.SDVTab.Title = 'SDV数据处理';
            app.UIAxes = uiaxes(app.SDVTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Panel = uipanel(app.SDVTab);
            app.Panel.Title = '用户参数输入';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '门宽';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'手动输入', '自动最优'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '手动输入';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '频率';
            app.SDV_Fre = uieditfield(app.Panel, 'numeric');
            app.SDV_Fre.ValueChangedFcn = createCallbackFcn(app, @SDV_FreValueChanged, true);
            app.SDV_Binsnum = uieditfield(app.Panel, 'numeric');
            app.SDV_Binsnum.Value = 20;
            app.GHzLabel = uilabel(app.Panel);
            app.GHzLabel.Text = 'GHz';
            app.SDVButton = uibutton(app.Panel, 'push');
            app.SDVButton.ButtonPushedFcn = createCallbackFcn(app, @SDVButtonPushed, true);
            app.SDVButton.Text = '确定';
            app.binsLabel = uilabel(app.Panel);
            app.binsLabel.Text = 'bins';
            app.Label_20 = uilabel(app.Panel);
            app.Label_20.Text = '数据名称';
            app.SDV_dataname = uieditfield(app.Panel, 'text');
            app.SDV_dataname.ValueChangedFcn = createCallbackFcn(app, @SDV_datanameValueChanged, true);
            app.binsLabel_3 = uilabel(app.Panel);
            app.binsLabel_3.Text = 'bins';
            app.Label_21 = uilabel(app.Panel);
            app.Label_21.Text = '寻峰间距';
            app.SDV_MINPEAKDISTANCE = uieditfield(app.Panel, 'numeric');
            app.SLabel_2 = uilabel(app.Panel);
            app.SLabel_2.Text = 'S态阈值';
            app.Speakmin = uieditfield(app.Panel, 'numeric');
            app.Speakmin.Value = 1000;
            app.VLabel_3 = uilabel(app.Panel);
            app.VLabel_3.Text = 'V态阈值';
            app.Vpeakmax = uieditfield(app.Panel, 'numeric');
            app.Vpeakmax.Value = 200;
            app.Panel_2 = uipanel(app.SDVTab);
            app.Panel_2.Title = '宽度';
            app.psLabel = uilabel(app.Panel_2);
            app.psLabel.Text = 'ps';
            app.psLabel_2 = uilabel(app.Panel_2);
            app.psLabel_2.Text = 'ps';
            app.psLabel_3 = uilabel(app.Panel_2);
            app.psLabel_3.Text = 'ps';
            app.psLabel_4 = uilabel(app.Panel_2);
            app.psLabel_4.Text = 'ps';
            app.psLabel_5 = uilabel(app.Panel_2);
            app.psLabel_5.Text = 'ps';
            app.psLabel_6 = uilabel(app.Panel_2);
            app.psLabel_6.Text = 'ps';
            app.S3dBLabel = uilabel(app.Panel_2);
            app.S3dBLabel.Text = 'S态3dB宽度';
            app.S3dB = uieditfield(app.Panel_2, 'numeric');
            app.S20dBLabel = uilabel(app.Panel_2);
            app.S20dBLabel.Text = 'S态20dB宽度';
            app.S20dB = uieditfield(app.Panel_2, 'numeric');
            app.D3dBLabel = uilabel(app.Panel_2);
            app.D3dBLabel.Text = 'D态3dB宽度';
            app.D3dB = uieditfield(app.Panel_2, 'numeric');
            app.D20dBLabel = uilabel(app.Panel_2);
            app.D20dBLabel.Text = 'D态20dB宽度';
            app.D20dB = uieditfield(app.Panel_2, 'numeric');
            app.V3dBLabel = uilabel(app.Panel_2);
            app.V3dBLabel.Text = 'V态3dB宽度';
            app.V3dB = uieditfield(app.Panel_2, 'numeric');
            app.V20dBLabel = uilabel(app.Panel_2);
            app.V20dBLabel.Text = 'V态20dB宽度';
            app.V20dB = uieditfield(app.Panel_2, 'numeric');
            app.psLabel_21 = uilabel(app.Panel_2);
            app.psLabel_21.Text = 'ps';
            app.psLabel_22 = uilabel(app.Panel_2);
            app.psLabel_22.Text = 'ps';
            app.S046dBLabel = uilabel(app.Panel_2);
            app.S046dBLabel.Text = 'S态0.46dB宽度';
            app.S0p46dB = uieditfield(app.Panel_2, 'numeric');
            app.S10dBLabel = uilabel(app.Panel_2);
            app.S10dBLabel.Text = 'S态10dB宽度';
            app.S10dB = uieditfield(app.Panel_2, 'numeric');
            app.Panel_3 = uipanel(app.SDVTab);
            app.Panel_3.Title = '峰值稳定性';
            app.SLabel = uilabel(app.Panel_3);
            app.SLabel.Text = 'S态变异系数';
            app.Cov_S = uieditfield(app.Panel_3, 'numeric');
            app.DLabel = uilabel(app.Panel_3);
            app.DLabel.Text = 'D态变异系数';
            app.Cov_D = uieditfield(app.Panel_3, 'numeric');
            app.VLabel = uilabel(app.Panel_3);
            app.VLabel.Text = 'V态变异系数';
            app.Cov_V = uieditfield(app.Panel_3, 'numeric');
            app.SliderLabel_2 = uilabel(app.SDVTab);
            app.SliderLabel_2.Text = 'Slider';
            app.SDV_Slider = uislider(app.SDVTab);
            app.SDV_Slider.Limits = [0 50000];
            app.SDV_Slider.ValueChangedFcn = createCallbackFcn(app, @SDV_SliderValueChanged, true);
            app.SDV_Slider.Value = 50000;
            app.Coordinate_2 = uibuttongroup(app.SDVTab);
            app.Coordinate_2.SelectionChangedFcn = createCallbackFcn(app, @Coordinate_2SelectionChanged, true);
            app.SDVLinearButton = uiradiobutton(app.Coordinate_2);
            app.SDVLinearButton.Text = 'Linear';
            app.SDVLinearButton.Value = true;
            app.SDVLogButton = uiradiobutton(app.Coordinate_2);
            app.SDVLogButton.Text = 'Log';
            app.PNRLTab = uitab(app.TabGroup);
            app.PNRLTab.Title = 'PNRL数据处理';
            app.UIAxes2_N = uiaxes(app.PNRLTab);
            title(app.UIAxes2_N, 'Title')
            xlabel(app.UIAxes2_N, 'X')
            ylabel(app.UIAxes2_N, 'Y')
            app.UIAxes2_P = uiaxes(app.PNRLTab);
            title(app.UIAxes2_P, 'Title')
            xlabel(app.UIAxes2_P, 'X')
            ylabel(app.UIAxes2_P, 'Y')
            app.UIAxes2_R = uiaxes(app.PNRLTab);
            title(app.UIAxes2_R, 'Title')
            xlabel(app.UIAxes2_R, 'X')
            ylabel(app.UIAxes2_R, 'Y')
            app.UIAxes2_L = uiaxes(app.PNRLTab);
            title(app.UIAxes2_L, 'Title')
            xlabel(app.UIAxes2_L, 'X')
            ylabel(app.UIAxes2_L, 'Y')
            app.Panel_4 = uipanel(app.PNRLTab);
            app.Panel_4.Title = '用户参数输入';
            app.DropDown_2Label = uilabel(app.Panel_4);
            app.DropDown_2Label.Text = '门宽';
            app.DropDown_2 = uidropdown(app.Panel_4);
            app.DropDown_2.Items = {'手动输入', '自动最优'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '手动输入';
            app.Label_5 = uilabel(app.Panel_4);
            app.Label_5.Text = '频率';
            app.PNRL_Fre = uieditfield(app.Panel_4, 'numeric');
            app.PNRL_Fre.ValueChangedFcn = createCallbackFcn(app, @PNRL_FreValueChanged, true);
            app.PNRL_Binsnum = uieditfield(app.Panel_4, 'numeric');
            app.PNRL_Binsnum.ValueChangedFcn = createCallbackFcn(app, @PNRL_BinsnumValueChanged, true);
            app.PNRL_Binsnum.Value = 20;
            app.GHzLabel_2 = uilabel(app.Panel_4);
            app.GHzLabel_2.Text = 'GHz';
            app.binsLabel_2 = uilabel(app.Panel_4);
            app.binsLabel_2.Text = 'bins';
            app.ch2delay = uieditfield(app.Panel_4, 'numeric');
            app.ch2delay.ValueChangedFcn = createCallbackFcn(app, @ch2delayValueChanged, true);
            app.psLabel_17 = uilabel(app.Panel_4);
            app.psLabel_17.Text = 'ps';
            app.ch4delay = uieditfield(app.Panel_4, 'numeric');
            app.ch4delay.ValueChangedFcn = createCallbackFcn(app, @ch4delayValueChanged, true);
            app.psLabel_18 = uilabel(app.Panel_4);
            app.psLabel_18.Text = 'ps';
            app.PLabel_2 = uilabel(app.Panel_4);
            app.PLabel_2.Text = 'P态大阈值';
            app.Ppeakmax = uieditfield(app.Panel_4, 'numeric');
            app.Ppeakmax.ValueChangedFcn = createCallbackFcn(app, @PpeakmaxValueChanged, true);
            app.Ppeakmax.Value = 2000;
            app.PLabel_3 = uilabel(app.Panel_4);
            app.PLabel_3.Text = 'P态小阈值';
            app.Ppeakmin = uieditfield(app.Panel_4, 'numeric');
            app.Ppeakmin.ValueChangedFcn = createCallbackFcn(app, @PpeakminValueChanged, true);
            app.Ppeakmin.Value = 200;
            app.NLabel_2 = uilabel(app.Panel_4);
            app.NLabel_2.Text = 'N态大阈值';
            app.Npeakmax = uieditfield(app.Panel_4, 'numeric');
            app.Npeakmax.ValueChangedFcn = createCallbackFcn(app, @NpeakmaxValueChanged, true);
            app.Npeakmax.Value = 2000;
            app.NLabel_3 = uilabel(app.Panel_4);
            app.NLabel_3.Text = 'N态小阈值';
            app.Npeakmin = uieditfield(app.Panel_4, 'numeric');
            app.Npeakmin.ValueChangedFcn = createCallbackFcn(app, @NpeakminValueChanged, true);
            app.Npeakmin.Value = 200;
            app.RLabel_4 = uilabel(app.Panel_4);
            app.RLabel_4.Text = 'R态大阈值';
            app.Rpeakmax = uieditfield(app.Panel_4, 'numeric');
            app.Rpeakmax.ValueChangedFcn = createCallbackFcn(app, @RpeakmaxValueChanged, true);
            app.Rpeakmax.Value = 2000;
            app.RLabel_5 = uilabel(app.Panel_4);
            app.RLabel_5.Text = 'R态小阈值';
            app.Rpeakmin = uieditfield(app.Panel_4, 'numeric');
            app.Rpeakmin.ValueChangedFcn = createCallbackFcn(app, @RpeakminValueChanged, true);
            app.Rpeakmin.Value = 200;
            app.LLabel_2 = uilabel(app.Panel_4);
            app.LLabel_2.Text = 'L态大阈值';
            app.Lpeakmax = uieditfield(app.Panel_4, 'numeric');
            app.Lpeakmax.ValueChangedFcn = createCallbackFcn(app, @LpeakmaxValueChanged, true);
            app.Lpeakmax.Value = 2000;
            app.LLabel_3 = uilabel(app.Panel_4);
            app.LLabel_3.Text = 'L态小阈值';
            app.Lpeakmin = uieditfield(app.Panel_4, 'numeric');
            app.Lpeakmin.ValueChangedFcn = createCallbackFcn(app, @LpeakminValueChanged, true);
            app.Lpeakmin.Value = 200;
            app.PNRLButton = uibutton(app.Panel_4, 'push');
            app.PNRLButton.ButtonPushedFcn = createCallbackFcn(app, @PNRLButtonPushed, true);
            app.PNRLButton.Text = '确定';
            app.Label_12 = uilabel(app.Panel_4);
            app.Label_12.Text = '数据名称';
            app.PNRL_dataname = uieditfield(app.Panel_4, 'text');
            app.PNRL_dataname.ValueChangedFcn = createCallbackFcn(app, @PNRL_datanameValueChanged2, true);
            app.ch2DropDownLabel_2 = uilabel(app.Panel_4);
            app.ch2DropDownLabel_2.Text = 'ch2延时';
            app.ch4DropDownLabel_2 = uilabel(app.Panel_4);
            app.ch4DropDownLabel_2.Text = 'ch4延时';
            app.binsLabel_4 = uilabel(app.Panel_4);
            app.binsLabel_4.Text = 'bins';
            app.Label_22 = uilabel(app.Panel_4);
            app.Label_22.Text = '寻峰间距';
            app.PNRL_MINPEAKDISTANCE = uieditfield(app.Panel_4, 'numeric');
            app.ch1Label = uilabel(app.Panel_4);
            app.ch1Label.Text = 'ch1效率';
            app.ch1eff = uieditfield(app.Panel_4, 'numeric');
            app.ch1eff.Value = 1;
            app.ch2Label = uilabel(app.Panel_4);
            app.ch2Label.Text = 'ch2效率';
            app.ch2eff = uieditfield(app.Panel_4, 'numeric');
            app.ch2eff.Value = 1;
            app.ch3Label = uilabel(app.Panel_4);
            app.ch3Label.Text = 'ch3效率';
            app.ch3eff = uieditfield(app.Panel_4, 'numeric');
            app.ch3eff.Value = 1;
            app.ch4Label = uilabel(app.Panel_4);
            app.ch4Label.Text = 'ch4效率';
            app.ch4eff = uieditfield(app.Panel_4, 'numeric');
            app.ch4eff.Value = 1;
            app.Panel_6 = uipanel(app.PNRLTab);
            app.Panel_6.Title = '宽度';
            app.psLabel_9 = uilabel(app.Panel_6);
            app.psLabel_9.Text = 'ps';
            app.psLabel_10 = uilabel(app.Panel_6);
            app.psLabel_10.Text = 'ps';
            app.psLabel_11 = uilabel(app.Panel_6);
            app.psLabel_11.Text = 'ps';
            app.psLabel_12 = uilabel(app.Panel_6);
            app.psLabel_12.Text = 'ps';
            app.psLabel_13 = uilabel(app.Panel_6);
            app.psLabel_13.Text = 'ps';
            app.psLabel_14 = uilabel(app.Panel_6);
            app.psLabel_14.Text = 'ps';
            app.P3dBLabel = uilabel(app.Panel_6);
            app.P3dBLabel.Text = 'P态3dB宽度';
            app.P3dB = uieditfield(app.Panel_6, 'numeric');
            app.P20dBLabel = uilabel(app.Panel_6);
            app.P20dBLabel.Text = 'P态20dB宽度';
            app.P20dB = uieditfield(app.Panel_6, 'numeric');
            app.N3dBLabel = uilabel(app.Panel_6);
            app.N3dBLabel.Text = 'N态3dB宽度';
            app.N3dB = uieditfield(app.Panel_6, 'numeric');
            app.N20dBLabel = uilabel(app.Panel_6);
            app.N20dBLabel.Text = 'N态20dB宽度';
            app.N20dB = uieditfield(app.Panel_6, 'numeric');
            app.psLabel_15 = uilabel(app.Panel_6);
            app.psLabel_15.Text = 'ps';
            app.psLabel_16 = uilabel(app.Panel_6);
            app.psLabel_16.Text = 'ps';
            app.R3dBLabel = uilabel(app.Panel_6);
            app.R3dBLabel.Text = 'R态3dB宽度';
            app.R3dB = uieditfield(app.Panel_6, 'numeric');
            app.R20dBLabel = uilabel(app.Panel_6);
            app.R20dBLabel.Text = 'R态20dB宽度';
            app.R20dB = uieditfield(app.Panel_6, 'numeric');
            app.L3dBLabel = uilabel(app.Panel_6);
            app.L3dBLabel.Text = 'L态3dB宽度';
            app.L3dB = uieditfield(app.Panel_6, 'numeric');
            app.L20dBLabel = uilabel(app.Panel_6);
            app.L20dBLabel.Text = 'L态20dB宽度';
            app.L20dB = uieditfield(app.Panel_6, 'numeric');
            app.Panel_7 = uipanel(app.PNRLTab);
            app.Panel_7.Title = '峰值稳定性';
            app.PLabel = uilabel(app.Panel_7);
            app.PLabel.Text = 'P态变异系数';
            app.Pstablemax = uieditfield(app.Panel_7, 'numeric');
            app.NLabel = uilabel(app.Panel_7);
            app.NLabel.Text = 'N态变异系数';
            app.Nstablemax = uieditfield(app.Panel_7, 'numeric');
            app.LLabel = uilabel(app.Panel_7);
            app.LLabel.Text = 'L态变异系数';
            app.Lstablemax = uieditfield(app.Panel_7, 'numeric');
            app.RLabel = uilabel(app.Panel_7);
            app.RLabel.Text = 'R态变异系数';
            app.Rstablemax = uieditfield(app.Panel_7, 'numeric');
            app.Pstablemin = uieditfield(app.Panel_7, 'numeric');
            app.Nstablemin = uieditfield(app.Panel_7, 'numeric');
            app.Lstablemin = uieditfield(app.Panel_7, 'numeric');
            app.Rstablemin = uieditfield(app.Panel_7, 'numeric');
            app.Label_6 = uilabel(app.Panel_7);
            app.Label_6.Text = '主通道';
            app.Label_7 = uilabel(app.Panel_7);
            app.Label_7.Text = '次通道';
            app.SliderLabel = uilabel(app.PNRLTab);
            app.SliderLabel.Text = 'Slider';
            app.PNRL_Slider = uislider(app.PNRLTab);
            app.PNRL_Slider.Limits = [0 120000];
            app.PNRL_Slider.ValueChangedFcn = createCallbackFcn(app, @PNRL_SliderValueChanged, true);
            app.PNRL_Slider.Value = 50000;
            app.Panel_12 = uipanel(app.PNRLTab);
            app.Panel_12.Title = '主要指标';
            app.Label_10 = uilabel(app.Panel_12);
            app.Label_10.Text = '%';
            app.Label_11 = uilabel(app.Panel_12);
            app.Label_11.Text = '%';
            app.Label_8 = uilabel(app.Panel_12);
            app.Label_8.Text = '门宽误码率';
            app.threerrorrate = uieditfield(app.Panel_12, 'numeric');
            app.Label_9 = uilabel(app.Panel_12);
            app.Label_9.Text = '峰值误码率';
            app.peakerrorrate = uieditfield(app.Panel_12, 'numeric');
            app.dBLabel_4 = uilabel(app.Panel_12);
            app.dBLabel_4.Text = 'dB';
            app.Label_27 = uilabel(app.Panel_12);
            app.Label_27.Text = '门宽对比度';
            app.threextratio = uieditfield(app.Panel_12, 'numeric');
            app.dBLabel_3 = uilabel(app.Panel_12);
            app.dBLabel_3.Text = 'dB';
            app.Label_29 = uilabel(app.Panel_12);
            app.Label_29.Text = '峰值对比度';
            app.peakextratio = uieditfield(app.Panel_12, 'numeric');
            app.dBLabel_6 = uilabel(app.Panel_12);
            app.dBLabel_6.Text = 'dB';
            app.dBLabel_5 = uilabel(app.Panel_12);
            app.dBLabel_5.Text = 'dB';
            app.RLLabel = uilabel(app.Panel_12);
            app.RLLabel.Text = 'RL门宽对比度';
            app.RLthreextratio = uieditfield(app.Panel_12, 'numeric');
            app.PNLabel = uilabel(app.Panel_12);
            app.PNLabel.Text = 'PN门宽对比度';
            app.PNthreextratio = uieditfield(app.Panel_12, 'numeric');
            app.dBLabel_7 = uilabel(app.Panel_12);
            app.dBLabel_7.Text = 'dB';
            app.PDLLabel = uilabel(app.Panel_12);
            app.PDLLabel.Text = '门宽内PDL';
            app.PDL = uieditfield(app.Panel_12, 'numeric');
            app.dBLabel_8 = uilabel(app.Panel_12);
            app.dBLabel_8.Text = 'dB';
            app.PNLabel_2 = uilabel(app.Panel_12);
            app.PNLabel_2.Text = 'PN峰值对比度';
            app.PNpeakextratio = uieditfield(app.Panel_12, 'numeric');
            app.dBLabel_9 = uilabel(app.Panel_12);
            app.dBLabel_9.Text = 'dB';
            app.RLLabel_2 = uilabel(app.Panel_12);
            app.RLLabel_2.Text = 'RL峰值对比度';
            app.RLpeakextratio = uieditfield(app.Panel_12, 'numeric');
            app.Coordinate_3 = uibuttongroup(app.PNRLTab);
            app.Coordinate_3.SelectionChangedFcn = createCallbackFcn(app, @Coordinate_3SelectionChanged, true);
            app.PNRLLinearButton = uiradiobutton(app.Coordinate_3);
            app.PNRLLinearButton.Text = 'Linear';
            app.PNRLLinearButton.Value = true;
            app.PNRLLogButton = uiradiobutton(app.Coordinate_3);
            app.PNRLLogButton.Text = 'Log';
            app.PNRLTab_2 = uitab(app.TabGroup);
            app.PNRLTab_2.Title = 'PNRL投影';
            app.UIAxes8 = uiaxes(app.PNRLTab_2);
            title(app.UIAxes8, 'Refer to P')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            app.UIAxes8_2 = uiaxes(app.PNRLTab_2);
            title(app.UIAxes8_2, 'Refer to R')
            xlabel(app.UIAxes8_2, 'X')
            ylabel(app.UIAxes8_2, 'Y')
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '门宽分析';
            app.UIAxes5 = uiaxes(app.Tab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.UIAxes5_2 = uiaxes(app.Tab);
            title(app.UIAxes5_2, 'Title')
            xlabel(app.UIAxes5_2, 'X')
            ylabel(app.UIAxes5_2, 'Y')
            app.SDVPNRL1Tab = uitab(app.TabGroup);
            app.SDVPNRL1Tab.SizeChangedFcn = createCallbackFcn(app, @SDVPNRL, true);
            app.SDVPNRL1Tab.Title = 'SDVPNRL数据处理1';
            app.UIAxes4_SDV_PN = uiaxes(app.SDVPNRL1Tab);
            title(app.UIAxes4_SDV_PN, 'Title')
            xlabel(app.UIAxes4_SDV_PN, 'X')
            ylabel(app.UIAxes4_SDV_PN, 'Y')
            app.UIAxes4_SDV_RL = uiaxes(app.SDVPNRL1Tab);
            title(app.UIAxes4_SDV_RL, 'Title')
            xlabel(app.UIAxes4_SDV_RL, 'X')
            ylabel(app.UIAxes4_SDV_RL, 'Y')
            app.Panel_13 = uipanel(app.SDVPNRL1Tab);
            app.Panel_13.Title = '用户参数输入';
            app.Label_30 = uilabel(app.Panel_13);
            app.Label_30.Text = '频率';
            app.SDVPNRL_Fre = uieditfield(app.Panel_13, 'numeric');
            app.SDVPNRL_Fre.ValueChangedFcn = createCallbackFcn(app, @SDVPNRL_FreValueChanged, true);
            app.GHzLabel_6 = uilabel(app.Panel_13);
            app.GHzLabel_6.Text = 'GHz';
            app.binsLabel_6 = uilabel(app.Panel_13);
            app.binsLabel_6.Text = 'bins';
            app.SDVPNRLButton = uibutton(app.Panel_13, 'push');
            app.SDVPNRLButton.ButtonPushedFcn = createCallbackFcn(app, @SDVPNRLButtonPushed, true);
            app.SDVPNRLButton.Text = '确定';
            app.Label_31 = uilabel(app.Panel_13);
            app.Label_31.Text = '数据名称';
            app.SDVPNRL_dataname = uieditfield(app.Panel_13, 'text');
            app.SDVPNRL_dataname.ValueChangedFcn = createCallbackFcn(app, @SDVPNRL_datanameValueChanged, true);
            app.binsLabel_7 = uilabel(app.Panel_13);
            app.binsLabel_7.Text = 'bins';
            app.Label_32 = uilabel(app.Panel_13);
            app.Label_32.Text = '寻峰间距';
            app.SDVPNRL_MINPEAKDISTANCE = uieditfield(app.Panel_13, 'numeric');
            app.ch1Label_2 = uilabel(app.Panel_13);
            app.ch1Label_2.Text = 'ch1效率';
            app.SDVPNRL_ch1eff = uieditfield(app.Panel_13, 'numeric');
            app.SDVPNRL_ch1eff.Value = 1;
            app.ch2Label_2 = uilabel(app.Panel_13);
            app.ch2Label_2.Text = 'ch2效率';
            app.SDVPNRL_ch2eff = uieditfield(app.Panel_13, 'numeric');
            app.SDVPNRL_ch2eff.Value = 1;
            app.ch3Label_2 = uilabel(app.Panel_13);
            app.ch3Label_2.Text = 'ch3效率';
            app.SDVPNRL_ch3eff = uieditfield(app.Panel_13, 'numeric');
            app.SDVPNRL_ch3eff.Value = 1;
            app.ch4Label_2 = uilabel(app.Panel_13);
            app.ch4Label_2.Text = 'ch4效率';
            app.SDVPNRL_ch4eff = uieditfield(app.Panel_13, 'numeric');
            app.SDVPNRL_ch4eff.Value = 1;
            app.Label_33 = uilabel(app.Panel_13);
            app.Label_33.Text = '门宽';
            app.SDVPNRL_Binsnum = uieditfield(app.Panel_13, 'numeric');
            app.SDVPNRL_Binsnum.Value = 20;
            app.SDVPNRL_P_thre = uieditfield(app.Panel_13, 'numeric');
            app.SDVPNRL_P_thre.Value = 20;
            app.ch2DropDownLabel_4 = uilabel(app.Panel_13);
            app.ch2DropDownLabel_4.Text = 'P态对比度阈值';
            app.SDVPNRL_N_thre = uieditfield(app.Panel_13, 'numeric');
            app.SDVPNRL_N_thre.Value = 20;
            app.ch2DropDownLabel_5 = uilabel(app.Panel_13);
            app.ch2DropDownLabel_5.Text = 'N态对比度阈值';
            app.SDVPNRL_R_thre = uieditfield(app.Panel_13, 'numeric');
            app.SDVPNRL_R_thre.Value = 20;
            app.ch2DropDownLabel_6 = uilabel(app.Panel_13);
            app.ch2DropDownLabel_6.Text = 'R态对比度阈值';
            app.SDVPNRL_L_thre = uieditfield(app.Panel_13, 'numeric');
            app.SDVPNRL_L_thre.Value = 20;
            app.ch2DropDownLabel_7 = uilabel(app.Panel_13);
            app.ch2DropDownLabel_7.Text = 'L态对比度阈值';
            app.dBLabel_10 = uilabel(app.Panel_13);
            app.dBLabel_10.Text = 'dB';
            app.dBLabel_11 = uilabel(app.Panel_13);
            app.dBLabel_11.Text = 'dB';
            app.dBLabel_12 = uilabel(app.Panel_13);
            app.dBLabel_12.Text = 'dB';
            app.dBLabel_13 = uilabel(app.Panel_13);
            app.dBLabel_13.Text = 'dB';
            app.ZSLabel = uilabel(app.Panel_13);
            app.ZSLabel.Text = 'Z基S态阈值';
            app.Speakmin_Z = uieditfield(app.Panel_13, 'numeric');
            app.Speakmin_Z.Value = 1000;
            app.XSLabel = uilabel(app.Panel_13);
            app.XSLabel.Text = 'X基S态阈值';
            app.Speakmin_X = uieditfield(app.Panel_13, 'numeric');
            app.Speakmin_X.Value = 1000;
            app.ZVLabel = uilabel(app.Panel_13);
            app.ZVLabel.Text = 'Z基V态阈值';
            app.Vpeakmax_Z = uieditfield(app.Panel_13, 'numeric');
            app.Vpeakmax_Z.Value = 200;
            app.XVLabel = uilabel(app.Panel_13);
            app.XVLabel.Text = 'X基V态阈值';
            app.Vpeakmax_X = uieditfield(app.Panel_13, 'numeric');
            app.Vpeakmax_X.Value = 200;
            app.SDVPNRL_ch2delay = uieditfield(app.Panel_13, 'numeric');
            app.SDVPNRL_ch4delay = uieditfield(app.Panel_13, 'numeric');
            app.ch2DropDownLabel_8 = uilabel(app.Panel_13);
            app.ch2DropDownLabel_8.Text = 'ch2延时';
            app.ch4DropDownLabel_3 = uilabel(app.Panel_13);
            app.ch4DropDownLabel_3.Text = 'ch4延时';
            app.psLabel_23 = uilabel(app.Panel_13);
            app.psLabel_23.Text = 'ps';
            app.psLabel_24 = uilabel(app.Panel_13);
            app.psLabel_24.Text = 'ps';
            app.SDVPNRLSaveButton = uibutton(app.Panel_13, 'push');
            app.SDVPNRLSaveButton.ButtonPushedFcn = createCallbackFcn(app, @SDVPNRLSaveButtonPushed, true);
            app.SDVPNRLSaveButton.Text = 'save';
            app.Panel_15 = uipanel(app.SDVPNRL1Tab);
            app.Panel_15.Title = '峰值稳定性';
            app.SLabel_4 = uilabel(app.Panel_15);
            app.SLabel_4.Text = 'S态变异系数';
            app.SDVPN_Cov_S = uieditfield(app.Panel_15, 'numeric');
            app.DLabel_2 = uilabel(app.Panel_15);
            app.DLabel_2.Text = 'D态变异系数';
            app.SDVPN_Cov_D = uieditfield(app.Panel_15, 'numeric');
            app.VLabel_4 = uilabel(app.Panel_15);
            app.VLabel_4.Text = 'V态变异系数';
            app.SDVPN_Cov_V = uieditfield(app.Panel_15, 'numeric');
            app.SDVRL_Cov_S = uieditfield(app.Panel_15, 'numeric');
            app.SDVRL_Cov_D = uieditfield(app.Panel_15, 'numeric');
            app.SDVRL_Cov_V = uieditfield(app.Panel_15, 'numeric');
            app.ZLabel = uilabel(app.Panel_15);
            app.ZLabel.Text = 'Z基';
            app.XLabel = uilabel(app.Panel_15);
            app.XLabel.Text = 'X基';
            app.SliderLabel_4 = uilabel(app.SDVPNRL1Tab);
            app.SliderLabel_4.Text = 'Slider';
            app.SDVPNRL_Slider_SDV = uislider(app.SDVPNRL1Tab);
            app.SDVPNRL_Slider_SDV.Limits = [0 120000];
            app.SDVPNRL_Slider_SDV.ValueChangedFcn = createCallbackFcn(app, @SDVPNRL_Slider_SDVValueChanged, true);
            app.SDVPNRL_Slider_SDV.Value = 50000;
            app.Coordinate_5 = uibuttongroup(app.SDVPNRL1Tab);
            app.Coordinate_5.SelectionChangedFcn = createCallbackFcn(app, @Coordinate_5SelectionChanged, true);
            app.SDVPNRL_LinearButton_SDV = uiradiobutton(app.Coordinate_5);
            app.SDVPNRL_LinearButton_SDV.Text = 'Linear';
            app.SDVPNRL_LinearButton_SDV.Value = true;
            app.SDVPNRL_LogButton_SDV = uiradiobutton(app.Coordinate_5);
            app.SDVPNRL_LogButton_SDV.Text = 'Log';
            app.Panel_16 = uipanel(app.SDVPNRL1Tab);
            app.Panel_16.Title = '门宽内光子数';
            app.SLabel_5 = uilabel(app.Panel_16);
            app.SLabel_5.Text = 'S';
            app.SDVPNRL_SP_num = uieditfield(app.Panel_16, 'numeric');
            app.DLabel_3 = uilabel(app.Panel_16);
            app.DLabel_3.Text = 'D';
            app.SDVPNRL_DP_num = uieditfield(app.Panel_16, 'numeric');
            app.SDVPNRL_SP_cov = uieditfield(app.Panel_16, 'numeric');
            app.SDVPNRL_DP_cov = uieditfield(app.Panel_16, 'numeric');
            app.PLabel_4 = uilabel(app.Panel_16);
            app.PLabel_4.Text = 'P';
            app.NLabel_4 = uilabel(app.Panel_16);
            app.NLabel_4.Text = 'N';
            app.SDVPNRL_SN_num = uieditfield(app.Panel_16, 'numeric');
            app.SDVPNRL_DN_num = uieditfield(app.Panel_16, 'numeric');
            app.SDVPNRL_SN_cov = uieditfield(app.Panel_16, 'numeric');
            app.SDVPNRL_DN_cov = uieditfield(app.Panel_16, 'numeric');
            app.Label_34 = uilabel(app.Panel_16);
            app.Label_34.Text = '总数';
            app.Label_35 = uilabel(app.Panel_16);
            app.Label_35.Text = '变异系数';
            app.Label_36 = uilabel(app.Panel_16);
            app.Label_36.Text = '总数';
            app.Label_37 = uilabel(app.Panel_16);
            app.Label_37.Text = '变异系数';
            app.SDVPNRL_SR_cov = uieditfield(app.Panel_16, 'numeric');
            app.SDVPNRL_DR_cov = uieditfield(app.Panel_16, 'numeric');
            app.RLabel_6 = uilabel(app.Panel_16);
            app.RLabel_6.Text = 'R';
            app.LLabel_4 = uilabel(app.Panel_16);
            app.LLabel_4.Text = 'L';
            app.SDVPNRL_SL_num = uieditfield(app.Panel_16, 'numeric');
            app.SDVPNRL_DL_num = uieditfield(app.Panel_16, 'numeric');
            app.SDVPNRL_SL_cov = uieditfield(app.Panel_16, 'numeric');
            app.SDVPNRL_DL_cov = uieditfield(app.Panel_16, 'numeric');
            app.Label_38 = uilabel(app.Panel_16);
            app.Label_38.Text = '总数';
            app.Label_39 = uilabel(app.Panel_16);
            app.Label_39.Text = '变异系数';
            app.Label_40 = uilabel(app.Panel_16);
            app.Label_40.Text = '总数';
            app.Label_41 = uilabel(app.Panel_16);
            app.Label_41.Text = '变异系数';
            app.SLabel_6 = uilabel(app.Panel_16);
            app.SLabel_6.Text = 'S';
            app.SDVPNRL_SR_num = uieditfield(app.Panel_16, 'numeric');
            app.DLabel_4 = uilabel(app.Panel_16);
            app.DLabel_4.Text = 'D';
            app.SDVPNRL_DR_num = uieditfield(app.Panel_16, 'numeric');
            app.SPanel = uipanel(app.SDVPNRL1Tab);
            app.SPanel.Title = '偏振态指标（对S态）';
            app.Label_42 = uilabel(app.SPanel);
            app.Label_42.Text = '%';
            app.Label_43 = uilabel(app.SPanel);
            app.Label_43.Text = '%';
            app.Label_44 = uilabel(app.SPanel);
            app.Label_44.Text = '门宽误码率';
            app.SDVPNRL_threerrorrate = uieditfield(app.SPanel, 'numeric');
            app.Label_45 = uilabel(app.SPanel);
            app.Label_45.Text = '峰值误码率';
            app.SDVPNRL_peakerrorrate = uieditfield(app.SPanel, 'numeric');
            app.dBLabel_14 = uilabel(app.SPanel);
            app.dBLabel_14.Text = 'dB';
            app.Label_46 = uilabel(app.SPanel);
            app.Label_46.Text = '门宽对比度';
            app.SDVPNRL_threextratio = uieditfield(app.SPanel, 'numeric');
            app.dBLabel_15 = uilabel(app.SPanel);
            app.dBLabel_15.Text = 'dB';
            app.Label_47 = uilabel(app.SPanel);
            app.Label_47.Text = '峰值对比度';
            app.SDVPNRL_peakextratio = uieditfield(app.SPanel, 'numeric');
            app.dBLabel_16 = uilabel(app.SPanel);
            app.dBLabel_16.Text = 'dB';
            app.dBLabel_17 = uilabel(app.SPanel);
            app.dBLabel_17.Text = 'dB';
            app.dBLabel_19 = uilabel(app.SPanel);
            app.dBLabel_19.Text = 'dB';
            app.dBLabel_20 = uilabel(app.SPanel);
            app.dBLabel_20.Text = 'dB';
            app.RLLabel_3 = uilabel(app.SPanel);
            app.RLLabel_3.Text = 'RL门宽对比度';
            app.SDVPNRL_RLthreextratio = uieditfield(app.SPanel, 'numeric');
            app.PNLabel_3 = uilabel(app.SPanel);
            app.PNLabel_3.Text = 'PN门宽对比度';
            app.SDVPNRL_PNthreextratio = uieditfield(app.SPanel, 'numeric');
            app.PNLabel_4 = uilabel(app.SPanel);
            app.PNLabel_4.Text = 'PN峰值对比度';
            app.SDVPNRL_PNpeakextratio = uieditfield(app.SPanel, 'numeric');
            app.RLLabel_4 = uilabel(app.SPanel);
            app.RLLabel_4.Text = 'RL峰值对比度';
            app.SDVPNRL_RLpeakextratio = uieditfield(app.SPanel, 'numeric');
            app.SDVPNRL2Tab = uitab(app.TabGroup);
            app.SDVPNRL2Tab.Title = 'SDVPNRL数据处理2';
            app.UIAxes3_N = uiaxes(app.SDVPNRL2Tab);
            title(app.UIAxes3_N, 'Title')
            xlabel(app.UIAxes3_N, 'X')
            ylabel(app.UIAxes3_N, 'Y')
            app.UIAxes3_P = uiaxes(app.SDVPNRL2Tab);
            title(app.UIAxes3_P, 'Title')
            xlabel(app.UIAxes3_P, 'X')
            ylabel(app.UIAxes3_P, 'Y')
            app.UIAxes3_R = uiaxes(app.SDVPNRL2Tab);
            title(app.UIAxes3_R, 'Title')
            xlabel(app.UIAxes3_R, 'X')
            ylabel(app.UIAxes3_R, 'Y')
            app.UIAxes3_L = uiaxes(app.SDVPNRL2Tab);
            title(app.UIAxes3_L, 'Title')
            xlabel(app.UIAxes3_L, 'X')
            ylabel(app.UIAxes3_L, 'Y')
            app.SDVPNRL_Slider_PNRL = uislider(app.SDVPNRL2Tab);
            app.SDVPNRL_Slider_PNRL.Limits = [0 120000];
            app.SDVPNRL_Slider_PNRL.ValueChangedFcn = createCallbackFcn(app, @SDVPNRL_Slider_PNRLValueChanged, true);
            app.SDVPNRL_Slider_PNRL.Value = 50000;
            app.Coordinate_4 = uibuttongroup(app.SDVPNRL2Tab);
            app.Coordinate_4.SelectionChangedFcn = createCallbackFcn(app, @Coordinate_4SelectionChanged, true);
            app.SDVPNRL_LinearButton_PNRL = uiradiobutton(app.Coordinate_4);
            app.SDVPNRL_LinearButton_PNRL.Text = 'Linear';
            app.SDVPNRL_LinearButton_PNRL.Value = true;
            app.SDVPNRL_LogButton_PNRL = uiradiobutton(app.Coordinate_4);
            app.SDVPNRL_LogButton_PNRL.Text = 'Log';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PBB84analysis_V2p4(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end