classdef PBB84scope_analysis_V1p1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ScopesettingTab                matlab.ui.container.Tab
        TimeTaggersettingPanel         matlab.ui.container.Panel
        capTriggerLevel                matlab.ui.control.Label
        lVTemplate                     matlab.ui.control.Label
        efTriggerLevel1                matlab.ui.control.NumericEditField
        lSync                          matlab.ui.control.Label
        efDeadtime1                    matlab.ui.control.NumericEditField
        capDeadtime                    matlab.ui.control.Label
        lnsTemplate                    matlab.ui.control.Label
        capTriggerLevel_2              matlab.ui.control.Label
        lVTemplate_2                   matlab.ui.control.Label
        efTriggerLevel2                matlab.ui.control.NumericEditField
        lSync_2                        matlab.ui.control.Label
        efDeadtime2                    matlab.ui.control.NumericEditField
        capDeadtime_2                  matlab.ui.control.Label
        lnsTemplate_2                  matlab.ui.control.Label
        capTriggerLevel_3              matlab.ui.control.Label
        lVTemplate_3                   matlab.ui.control.Label
        efTriggerLevel3                matlab.ui.control.NumericEditField
        lSync_3                        matlab.ui.control.Label
        efDeadtime3                    matlab.ui.control.NumericEditField
        capDeadtime_3                  matlab.ui.control.Label
        lnsTemplate_3                  matlab.ui.control.Label
        capTriggerLevel_4              matlab.ui.control.Label
        lVTemplate_4                   matlab.ui.control.Label
        efTriggerLevel4                matlab.ui.control.NumericEditField
        lSync_4                        matlab.ui.control.Label
        efDeadtime4                    matlab.ui.control.NumericEditField
        capDeadtime_4                  matlab.ui.control.Label
        lnsTemplate_4                  matlab.ui.control.Label
        capTriggerLevel_5              matlab.ui.control.Label
        lVTemplate_5                   matlab.ui.control.Label
        efTriggerLevel5                matlab.ui.control.NumericEditField
        lSync_5                        matlab.ui.control.Label
        lSync_6                        matlab.ui.control.Label
        capCountratesync               matlab.ui.control.Label
        lCountratesync                 matlab.ui.control.Label
        TimeTaggerstartPanel           matlab.ui.container.Panel
        Unit_ps                        matlab.ui.control.Label
        Unit_s                         matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        binwidthEditFieldLabel         matlab.ui.control.Label
        binwidthEditField              matlab.ui.control.NumericEditField
        binEditFieldLabel              matlab.ui.control.Label
        binNumEditField                matlab.ui.control.NumericEditField
        AcquisitiontimeEditFieldLabel  matlab.ui.control.Label
        AcquisitiontimeEditField       matlab.ui.control.NumericEditField
        AcquisitiontimeIndicator       matlab.ui.control.Label
        ContinuousRecordModePanel      matlab.ui.container.Panel
        Unit_s_2                       matlab.ui.control.Label
        StartButton_2                  matlab.ui.control.Button
        StopButton_2                   matlab.ui.control.Button
        AcquisitiontimeEditField_2Label  matlab.ui.control.Label
        Acquisitiontime2EditField      matlab.ui.control.NumericEditField
        fileLabel                      matlab.ui.control.Label
        fileNumEditField               matlab.ui.control.NumericEditField
        AcquisitiontimeIndicator2      matlab.ui.control.Label
        CH1CheckBox                    matlab.ui.control.CheckBox
        CH2CheckBox                    matlab.ui.control.CheckBox
        CH3CheckBox                    matlab.ui.control.CheckBox
        CH4CheckBox                    matlab.ui.control.CheckBox
        ScopePNTab                     matlab.ui.container.Tab
        UIAxes_ScopeN                  matlab.ui.control.UIAxes
        UIAxes_ScopeP                  matlab.ui.control.UIAxes
        capCountrate1                  matlab.ui.control.Label
        lCountrate1                    matlab.ui.control.Label
        capCountrate2                  matlab.ui.control.Label
        lCountrate2                    matlab.ui.control.Label
        MaxSpinner_2Label_3            matlab.ui.control.Label
        PNspCountMax                   matlab.ui.control.Spinner
        MinSpinner_2Label_3            matlab.ui.control.Label
        PNspCountMin                   matlab.ui.control.Spinner
        CountLabel_6                   matlab.ui.control.Label
        Unit_ns2_3                     matlab.ui.control.Label
        MaxSpinnerLabel_3              matlab.ui.control.Label
        PNspTimeMax                    matlab.ui.control.Spinner
        Unit_ns_3                      matlab.ui.control.Label
        MinSpinnerLabel_3              matlab.ui.control.Label
        PNspTimeMin                    matlab.ui.control.Spinner
        TimeLabel_3                    matlab.ui.control.Label
        Coordinate_3                   matlab.ui.container.ButtonGroup
        PNLinearButton                 matlab.ui.control.RadioButton
        PNLogButton                    matlab.ui.control.RadioButton
        PNSaveButton                   matlab.ui.control.Button
        DelayPanel_3                   matlab.ui.container.Panel
        ScopeCH1delay                  matlab.ui.control.NumericEditField
        capInputDelay_6                matlab.ui.control.Label
        psLabel_29                     matlab.ui.control.Label
        ScopeCH2delay                  matlab.ui.control.NumericEditField
        capInputDelay_7                matlab.ui.control.Label
        psLabel_30                     matlab.ui.control.Label
        ERCH2Panel                     matlab.ui.container.Panel
        NSLEditField                   matlab.ui.control.NumericEditField
        SLabel_7                       matlab.ui.control.Label
        DLabel_5                       matlab.ui.control.Label
        LLabel_7                       matlab.ui.control.Label
        RLabel_9                       matlab.ui.control.Label
        NSREditField                   matlab.ui.control.NumericEditField
        CountLabel_7                   matlab.ui.control.Label
        dbLabel_4                      matlab.ui.control.Label
        NSCountIndicator               matlab.ui.control.Label
        NSdbIndicator                  matlab.ui.control.Label
        NDLEditField                   matlab.ui.control.NumericEditField
        NDREditField                   matlab.ui.control.NumericEditField
        NDCountIndicator               matlab.ui.control.Label
        NDdbIndicator                  matlab.ui.control.Label
        NVLEditField                   matlab.ui.control.NumericEditField
        VLabel_7                       matlab.ui.control.Label
        NVREditField                   matlab.ui.control.NumericEditField
        NVCountIndicator               matlab.ui.control.Label
        NVdbIndicator                  matlab.ui.control.Label
        ERCH1Panel                     matlab.ui.container.Panel
        PSLEditField                   matlab.ui.control.NumericEditField
        SLabel_8                       matlab.ui.control.Label
        DLabel_6                       matlab.ui.control.Label
        LLabel_8                       matlab.ui.control.Label
        RLabel_10                      matlab.ui.control.Label
        PSREditField                   matlab.ui.control.NumericEditField
        CountLabel_8                   matlab.ui.control.Label
        dbLabel_5                      matlab.ui.control.Label
        PSCountIndicator               matlab.ui.control.Label
        PSdbIndicator                  matlab.ui.control.Label
        PDLEditField                   matlab.ui.control.NumericEditField
        PDREditField                   matlab.ui.control.NumericEditField
        PDCountIndicator               matlab.ui.control.Label
        PDdbIndicator                  matlab.ui.control.Label
        PVLEditField                   matlab.ui.control.NumericEditField
        VLabel_8                       matlab.ui.control.Label
        PVREditField                   matlab.ui.control.NumericEditField
        PVCountIndicator               matlab.ui.control.Label
        PVdbIndicator                  matlab.ui.control.Label
        ScopeRLTab                     matlab.ui.container.Tab
        UIAxes_ScopeL                  matlab.ui.control.UIAxes
        UIAxes_ScopeR                  matlab.ui.control.UIAxes
        Unit_ns_2                      matlab.ui.control.Label
        Unit_ns2_2                     matlab.ui.control.Label
        TimeLabel_2                    matlab.ui.control.Label
        CountLabel_3                   matlab.ui.control.Label
        Coordinate_2                   matlab.ui.container.ButtonGroup
        RLLinearButton                 matlab.ui.control.RadioButton
        RLLogButton                    matlab.ui.control.RadioButton
        DelayPanel_2                   matlab.ui.container.Panel
        ScopeCH3delay                  matlab.ui.control.NumericEditField
        capInputDelay_4                matlab.ui.control.Label
        psLabel_27                     matlab.ui.control.Label
        ScopeCH4delay                  matlab.ui.control.NumericEditField
        capInputDelay_5                matlab.ui.control.Label
        psLabel_28                     matlab.ui.control.Label
        MinSpinnerLabel_2              matlab.ui.control.Label
        RLspTimeMin                    matlab.ui.control.Spinner
        MaxSpinnerLabel_2              matlab.ui.control.Label
        RLspTimeMax                    matlab.ui.control.Spinner
        MinSpinner_2Label_2            matlab.ui.control.Label
        RLspCountMin                   matlab.ui.control.Spinner
        MaxSpinner_2Label_2            matlab.ui.control.Label
        RLspCountMax                   matlab.ui.control.Spinner
        SaveButton_2                   matlab.ui.control.Button
        ERCH4Panel                     matlab.ui.container.Panel
        LSLEditField                   matlab.ui.control.NumericEditField
        SLabel_5                       matlab.ui.control.Label
        DLabel_3                       matlab.ui.control.Label
        LLabel_5                       matlab.ui.control.Label
        RLabel_7                       matlab.ui.control.Label
        LSREditField                   matlab.ui.control.NumericEditField
        CountLabel_4                   matlab.ui.control.Label
        dbLabel_2                      matlab.ui.control.Label
        LSCountIndicator               matlab.ui.control.Label
        LSdbIndicator                  matlab.ui.control.Label
        LDLEditField                   matlab.ui.control.NumericEditField
        LDREditField                   matlab.ui.control.NumericEditField
        LDCountIndicator               matlab.ui.control.Label
        LDdbIndicator                  matlab.ui.control.Label
        LVLEditField                   matlab.ui.control.NumericEditField
        VLabel_5                       matlab.ui.control.Label
        LVREditField                   matlab.ui.control.NumericEditField
        LVCountIndicator               matlab.ui.control.Label
        LVdbIndicator                  matlab.ui.control.Label
        ERCH3Panel                     matlab.ui.container.Panel
        RSLEditField                   matlab.ui.control.NumericEditField
        SLabel_6                       matlab.ui.control.Label
        DLabel_4                       matlab.ui.control.Label
        LLabel_6                       matlab.ui.control.Label
        RLabel_8                       matlab.ui.control.Label
        RSREditField                   matlab.ui.control.NumericEditField
        CountLabel_5                   matlab.ui.control.Label
        dbLabel_3                      matlab.ui.control.Label
        RSCountIndicator               matlab.ui.control.Label
        RSdbIndicator                  matlab.ui.control.Label
        RDLEditField                   matlab.ui.control.NumericEditField
        RDREditField                   matlab.ui.control.NumericEditField
        RDCountIndicator               matlab.ui.control.Label
        RDdbIndicator                  matlab.ui.control.Label
        RVLEditField                   matlab.ui.control.NumericEditField
        VLabel_6                       matlab.ui.control.Label
        RVREditField                   matlab.ui.control.NumericEditField
        RVCountIndicator               matlab.ui.control.Label
        RVdbIndicator                  matlab.ui.control.Label
        capCountrate3                  matlab.ui.control.Label
        lCountrate3                    matlab.ui.control.Label
        capCountrate4                  matlab.ui.control.Label
        lCountrate4                    matlab.ui.control.Label
        LDTab                          matlab.ui.container.Tab
        UIAxes7                        matlab.ui.control.UIAxes
        Panel_9                        matlab.ui.container.Panel
        Label_17                       matlab.ui.control.Label
        LD_Fre                         matlab.ui.control.NumericEditField
        GHzLabel_3                     matlab.ui.control.Label
        LDButton                       matlab.ui.control.Button
        Label_19                       matlab.ui.control.Label
        LD_dataneme                    matlab.ui.control.EditField
        Label_25                       matlab.ui.control.Label
        LD_MINPEAKDISTANCE             matlab.ui.control.NumericEditField
        binsLabel_5                    matlab.ui.control.Label
        SLabel_3                       matlab.ui.control.Label
        LDpeakmin                      matlab.ui.control.NumericEditField
        Panel_10                       matlab.ui.container.Panel
        psLabel_19                     matlab.ui.control.Label
        psLabel_20                     matlab.ui.control.Label
        dBLabel                        matlab.ui.control.Label
        LD3dB                          matlab.ui.control.NumericEditField
        dBLabel_2                      matlab.ui.control.Label
        LD20dB                         matlab.ui.control.NumericEditField
        SliderLabel_3                  matlab.ui.control.Label
        LD_Slider                      matlab.ui.control.Slider
        Panel_11                       matlab.ui.container.Panel
        GHzLabel_4                     matlab.ui.control.Label
        GHzLabel_5                     matlab.ui.control.Label
        Label_23                       matlab.ui.control.Label
        LD_Fremeasured                 matlab.ui.control.NumericEditField
        Label_24                       matlab.ui.control.Label
        LD_Cov_Fre                     matlab.ui.control.NumericEditField
        SDVTab                         matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        Label_2                        matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        Label_3                        matlab.ui.control.Label
        SDV_Fre                        matlab.ui.control.NumericEditField
        SDV_Binsnum                    matlab.ui.control.NumericEditField
        GHzLabel                       matlab.ui.control.Label
        SDVButton                      matlab.ui.control.Button
        binsLabel                      matlab.ui.control.Label
        Label_20                       matlab.ui.control.Label
        SDV_dataname                   matlab.ui.control.EditField
        binsLabel_3                    matlab.ui.control.Label
        Label_21                       matlab.ui.control.Label
        SDV_MINPEAKDISTANCE            matlab.ui.control.NumericEditField
        SLabel_2                       matlab.ui.control.Label
        Speakmin                       matlab.ui.control.NumericEditField
        VLabel_3                       matlab.ui.control.Label
        Vpeakmax                       matlab.ui.control.NumericEditField
        Panel_2                        matlab.ui.container.Panel
        psLabel                        matlab.ui.control.Label
        psLabel_2                      matlab.ui.control.Label
        psLabel_3                      matlab.ui.control.Label
        psLabel_4                      matlab.ui.control.Label
        psLabel_5                      matlab.ui.control.Label
        psLabel_6                      matlab.ui.control.Label
        S3dBLabel                      matlab.ui.control.Label
        S3dB                           matlab.ui.control.NumericEditField
        S20dBLabel                     matlab.ui.control.Label
        S20dB                          matlab.ui.control.NumericEditField
        D3dBLabel                      matlab.ui.control.Label
        D3dB                           matlab.ui.control.NumericEditField
        D20dBLabel                     matlab.ui.control.Label
        D20dB                          matlab.ui.control.NumericEditField
        V3dBLabel                      matlab.ui.control.Label
        V3dB                           matlab.ui.control.NumericEditField
        V20dBLabel                     matlab.ui.control.Label
        V20dB                          matlab.ui.control.NumericEditField
        Panel_3                        matlab.ui.container.Panel
        SLabel                         matlab.ui.control.Label
        Cov_S                          matlab.ui.control.NumericEditField
        DLabel                         matlab.ui.control.Label
        Cov_D                          matlab.ui.control.NumericEditField
        VLabel                         matlab.ui.control.Label
        Cov_V                          matlab.ui.control.NumericEditField
        SliderLabel_2                  matlab.ui.control.Label
        SDV_Slider                     matlab.ui.control.Slider
        PNRLTab                        matlab.ui.container.Tab
        UIAxes2_N                      matlab.ui.control.UIAxes
        UIAxes2_P                      matlab.ui.control.UIAxes
        UIAxes2_R                      matlab.ui.control.UIAxes
        UIAxes2_L                      matlab.ui.control.UIAxes
        Panel_4                        matlab.ui.container.Panel
        DropDown_2Label                matlab.ui.control.Label
        DropDown_2                     matlab.ui.control.DropDown
        Label_5                        matlab.ui.control.Label
        PNRL_Fre                       matlab.ui.control.NumericEditField
        PNRL_Binsnum                   matlab.ui.control.NumericEditField
        GHzLabel_2                     matlab.ui.control.Label
        binsLabel_2                    matlab.ui.control.Label
        ch2delay                       matlab.ui.control.NumericEditField
        psLabel_17                     matlab.ui.control.Label
        ch4delay                       matlab.ui.control.NumericEditField
        psLabel_18                     matlab.ui.control.Label
        PLabel_2                       matlab.ui.control.Label
        Ppeakmax                       matlab.ui.control.NumericEditField
        PLabel_3                       matlab.ui.control.Label
        Ppeakmin                       matlab.ui.control.NumericEditField
        NLabel_2                       matlab.ui.control.Label
        Npeakmax                       matlab.ui.control.NumericEditField
        NLabel_3                       matlab.ui.control.Label
        Npeakmin                       matlab.ui.control.NumericEditField
        RLabel_4                       matlab.ui.control.Label
        Rpeakmax                       matlab.ui.control.NumericEditField
        RLabel_5                       matlab.ui.control.Label
        Rpeakmin                       matlab.ui.control.NumericEditField
        LLabel_2                       matlab.ui.control.Label
        Lpeakmax                       matlab.ui.control.NumericEditField
        LLabel_3                       matlab.ui.control.Label
        Lpeakmin                       matlab.ui.control.NumericEditField
        PNRLButton                     matlab.ui.control.Button
        Label_12                       matlab.ui.control.Label
        PNRL_dataname                  matlab.ui.control.EditField
        ch2DropDownLabel_2             matlab.ui.control.Label
        ch4DropDownLabel_2             matlab.ui.control.Label
        binsLabel_4                    matlab.ui.control.Label
        Label_22                       matlab.ui.control.Label
        PNRL_MINPEAKDISTANCE           matlab.ui.control.NumericEditField
        ch1Label                       matlab.ui.control.Label
        ch1eff                         matlab.ui.control.NumericEditField
        ch2Label                       matlab.ui.control.Label
        ch2eff                         matlab.ui.control.NumericEditField
        ch3Label                       matlab.ui.control.Label
        ch3eff                         matlab.ui.control.NumericEditField
        ch4Label                       matlab.ui.control.Label
        ch4eff                         matlab.ui.control.NumericEditField
        Panel_6                        matlab.ui.container.Panel
        psLabel_9                      matlab.ui.control.Label
        psLabel_10                     matlab.ui.control.Label
        psLabel_11                     matlab.ui.control.Label
        psLabel_12                     matlab.ui.control.Label
        psLabel_13                     matlab.ui.control.Label
        psLabel_14                     matlab.ui.control.Label
        P3dBLabel                      matlab.ui.control.Label
        P3dB                           matlab.ui.control.NumericEditField
        P20dBLabel                     matlab.ui.control.Label
        P20dB                          matlab.ui.control.NumericEditField
        N3dBLabel                      matlab.ui.control.Label
        N3dB                           matlab.ui.control.NumericEditField
        N20dBLabel                     matlab.ui.control.Label
        N20dB                          matlab.ui.control.NumericEditField
        psLabel_15                     matlab.ui.control.Label
        psLabel_16                     matlab.ui.control.Label
        R3dBLabel                      matlab.ui.control.Label
        R3dB                           matlab.ui.control.NumericEditField
        R20dBLabel                     matlab.ui.control.Label
        R20dB                          matlab.ui.control.NumericEditField
        L3dBLabel                      matlab.ui.control.Label
        L3dB                           matlab.ui.control.NumericEditField
        L20dBLabel                     matlab.ui.control.Label
        L20dB                          matlab.ui.control.NumericEditField
        Panel_7                        matlab.ui.container.Panel
        PLabel                         matlab.ui.control.Label
        Pstablemax                     matlab.ui.control.NumericEditField
        NLabel                         matlab.ui.control.Label
        Nstablemax                     matlab.ui.control.NumericEditField
        LLabel                         matlab.ui.control.Label
        Lstablemax                     matlab.ui.control.NumericEditField
        RLabel                         matlab.ui.control.Label
        Rstablemax                     matlab.ui.control.NumericEditField
        Pstablemin                     matlab.ui.control.NumericEditField
        Nstablemin                     matlab.ui.control.NumericEditField
        Lstablemin                     matlab.ui.control.NumericEditField
        Rstablemin                     matlab.ui.control.NumericEditField
        Label_6                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        SliderLabel                    matlab.ui.control.Label
        PNRL_Slider                    matlab.ui.control.Slider
        Panel_12                       matlab.ui.container.Panel
        Label_10                       matlab.ui.control.Label
        Label_11                       matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        threerrorrate                  matlab.ui.control.NumericEditField
        Label_9                        matlab.ui.control.Label
        peakerrorrate                  matlab.ui.control.NumericEditField
        Tab                            matlab.ui.container.Tab
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes5_2                      matlab.ui.control.UIAxes
    end

    properties (Constant)
%         LINEHEIGHT = 30; % line height for each channel
        CHNUM      = 5; % channel number
    end
    
    properties (Access = public)
        timetagger       % Time Tagger object
        histP             % Histogram measurement class
        histN             % Histogram measurement class
        histR             % Histogram measurement class
        histL             % Histogram measurement class
        countrate            % Countrate measurement class
%         sync_ch = 1         % sychronization channel for scope
%         click_ch = 1        % click channel for scope
        updateCountrateTimer % timer which polls current data of the countrate measurement        
        bstop = 0;
        bstop2 = 0;
        histdataP
        histdataN
        histdataR
        histdataL
        PNxmin
        PNxmax
        PNymin
        PNymax
        RLxmin
        RLxmax
        RLymin
        RLymax
        %gui element
        allChannels   % stores the channel numbers of all channels
        efTriggerLevel % edit fields to set the trigger levels
        lCountrate    % labels for the countrate outputs
        cbClick
        cbSync
        efInputDelay
        efDeadtime
        df            % number formatter object, required to add thousands separator for the count rate        
        
        fileLD
        fileSDV
        filech1 
        filech2 
        filech3 
        filech4
        LD_filename
        SDV_filename % Description
        ch1_filename
        ch2_filename
        ch3_filename
        ch4_filename
        LD_T
        SDV_T
        PNRL_T
        ppeakmax
        ppeakmin
        npeakmax
        npeakmin
        lpeakmax
        lpeakmin
        rpeakmax
        rpeakmin
        thre_term = '手动输入'
        four_term = '手动输入'
    end
    
    methods (Access = public)        
        function updateCountrateTimerCallback(app)
        % updateTimerCallback is invoked by the updateTimer and polls the countrate.getData() to visualize the current countrate of each channel
            countrates = app.countrate.getData();
            app.countrate.clear();
            app.lCountrate1.Text = sprintf('%s counts / s', string(app.df.format(round(countrates(1)))));
            app.lCountrate2.Text = sprintf('%s counts / s', string(app.df.format(round(countrates(2)))));
            app.lCountrate3.Text = sprintf('%s counts / s', string(app.df.format(round(countrates(3)))));
            app.lCountrate4.Text = sprintf('%s counts / s', string(app.df.format(round(countrates(4)))));
            app.lCountratesync.Text = sprintf('%s counts / s', string(app.df.format(round(countrates(5)))));
        end        
        
        function updateTriggelLevelElements(app, channelIndex)
        % acquires the current trigger level of the channel passed as a parameter and updates the slider position and the label
            voltage = app.timetagger.getTriggerLevel(app.efTriggerLevel{channelIndex}.UserData);
            app.efTriggerLevel{channelIndex}.Value = voltage;
        end         
        

        function updateHistogramSettings(app)
        % create a new Histogram measurement with the current settings from the gui input fields
            app.histP = TTHistogram(app.timetagger, 1, 5, app.binwidthEditField.Value, app.binNumEditField.Value);
            app.histN = TTHistogram(app.timetagger, 2, 5, app.binwidthEditField.Value, app.binNumEditField.Value);
            app.histR = TTHistogram(app.timetagger, 3, 5, app.binwidthEditField.Value, app.binNumEditField.Value);
            app.histL = TTHistogram(app.timetagger, 4, 5, app.binwidthEditField.Value, app.binNumEditField.Value);
        end   
        
        function updateplotP(app)
            plot(app.UIAxes_ScopeP,double(app.histP.getIndex()),app.histdataP);
            if app.PNLinearButton.Value
                set(app.UIAxes_ScopeP,'YScale','linear');
            elseif app.PNLogButton.Value
                set(app.UIAxes_ScopeP,'YScale','log');
            end
        end 

        function updateplotN(app)
            plot(app.UIAxes_ScopeN,double(app.histN.getIndex()),app.histdataN);
            if app.PNLinearButton.Value
                set(app.UIAxes_ScopeN,'YScale','linear');
            elseif app.PNLogButton.Value
                set(app.UIAxes_ScopeN,'YScale','log');
            end
        end 

        function updateplotR(app)
            plot(app.UIAxes_ScopeR,double(app.histR.getIndex()),app.histdataR);
            if app.RLLinearButton.Value
                set(app.UIAxes_ScopeR,'YScale','linear');
            elseif app.RLLogButton.Value
                set(app.UIAxes_ScopeR,'YScale','log');
            end
        end 
        
        function updateplotL(app)
            plot(app.UIAxes_ScopeL,double(app.histL.getIndex()),app.histdataL);
            if app.RLLinearButton.Value
                set(app.UIAxes_ScopeL,'YScale','linear');
            elseif app.RLLogButton.Value
                set(app.UIAxes_ScopeL,'YScale','log');
            end
        end 
        
        function getDecoy(app)
            PSCount = double(sum(app.histdataP(getIndexFromTickP(app,app.PSLEditField.Value):getIndexFromTickP(app,app.PSREditField.Value))));
            PDCount = double(sum(app.histdataP(getIndexFromTickP(app,app.PDLEditField.Value):getIndexFromTickP(app,app.PDREditField.Value))));
            PVCount = double(sum(app.histdataP(getIndexFromTickP(app,app.PVLEditField.Value):getIndexFromTickP(app,app.PVREditField.Value))));
            app.PSCountIndicator.Text = sprintf('%d', PSCount);               
            app.PDCountIndicator.Text = sprintf('%d', PDCount);               
            app.PVCountIndicator.Text = sprintf('%d', PVCount);               
            app.PSdbIndicator.Text = sprintf('%.2f db', 10*log10(PSCount/PSCount));
            app.PDdbIndicator.Text = sprintf('%.2f db', 10*log10(PDCount/PSCount));
            app.PVdbIndicator.Text = sprintf('%.2f db', 10*log10(PVCount/PSCount));
            
            NSCount = double(sum(app.histdataN(getIndexFromTickN(app,app.NSLEditField.Value):getIndexFromTickN(app,app.NSREditField.Value))));
            NDCount = double(sum(app.histdataN(getIndexFromTickN(app,app.NDLEditField.Value):getIndexFromTickN(app,app.NDREditField.Value))));
            NVCount = double(sum(app.histdataN(getIndexFromTickN(app,app.NVLEditField.Value):getIndexFromTickN(app,app.NVREditField.Value))));
            app.NSCountIndicator.Text = sprintf('%d', NSCount);               
            app.NDCountIndicator.Text = sprintf('%d', NDCount);               
            app.NVCountIndicator.Text = sprintf('%d', NVCount);               
            app.NSdbIndicator.Text = sprintf('%.2f db', 10*log10(NSCount/NSCount));
            app.NDdbIndicator.Text = sprintf('%.2f db', 10*log10(NDCount/NSCount));
            app.NVdbIndicator.Text = sprintf('%.2f db', 10*log10(NVCount/NSCount));
            
            RSCount = double(sum(app.histdataR(getIndexFromTickR(app,app.RSLEditField.Value):getIndexFromTickR(app,app.RSREditField.Value))));
            RDCount = double(sum(app.histdataR(getIndexFromTickR(app,app.RDLEditField.Value):getIndexFromTickR(app,app.RDREditField.Value))));
            RVCount = double(sum(app.histdataR(getIndexFromTickR(app,app.RVLEditField.Value):getIndexFromTickR(app,app.RVREditField.Value))));
            app.RSCountIndicator.Text = sprintf('%d', RSCount);               
            app.RDCountIndicator.Text = sprintf('%d', RDCount);               
            app.RVCountIndicator.Text = sprintf('%d', RVCount);               
            app.RSdbIndicator.Text = sprintf('%.2f db', 10*log10(RSCount/RSCount));
            app.RDdbIndicator.Text = sprintf('%.2f db', 10*log10(RDCount/RSCount));
            app.RVdbIndicator.Text = sprintf('%.2f db', 10*log10(RVCount/RSCount));
            
            LSCount = double(sum(app.histdataL(getIndexFromTickL(app,app.LSLEditField.Value):getIndexFromTickL(app,app.LSREditField.Value))));
            LDCount = double(sum(app.histdataL(getIndexFromTickL(app,app.LDLEditField.Value):getIndexFromTickL(app,app.LDREditField.Value))));
            LVCount = double(sum(app.histdataL(getIndexFromTickL(app,app.LVLEditField.Value):getIndexFromTickL(app,app.LVREditField.Value))));
            app.LSCountIndicator.Text = sprintf('%d', LSCount);               
            app.LDCountIndicator.Text = sprintf('%d', LDCount);               
            app.LVCountIndicator.Text = sprintf('%d', LVCount);               
            app.LSdbIndicator.Text = sprintf('%.2f db', 10*log10(LSCount/LSCount));
            app.LDdbIndicator.Text = sprintf('%.2f db', 10*log10(LDCount/LSCount));
            app.LVdbIndicator.Text = sprintf('%.2f db', 10*log10(LVCount/LSCount));
        end
        
        function index = getIndexFromTickP(app, xTick)
            [~, index] = min(abs(app.histP.getIndex()-xTick));
        end  
        
        function index = getIndexFromTickN(app, xTick)
            [~, index] = min(abs(app.histN.getIndex()-xTick));
        end 
        
        function index = getIndexFromTickR(app, xTick)
            [~, index] = min(abs(app.histR.getIndex()-xTick));
        end 
        
        function index = getIndexFromTickL(app, xTick)
            [~, index] = min(abs(app.histL.getIndex()-xTick));
        end 
        
        function [signal,signal_loc,Fremeasured,Cov_Fre,signal_3dBwidth,signal_20dBwidth] = LD_analysis(app)
            LD_timebin = app.fileLD(1,2)-app.fileLD(1,1); %ps
            time0 = app.fileLD(1,:);
            counts0 = app.fileLD(2,:);
            [pks0,locs0] = findpeaks(counts0,'MINPEAKHEIGHT',1,'MINPEAKDISTANCE',app.LD_MINPEAKDISTANCE.Value);  %数据寻峰

            %% 信号态寻峰
            threshold_s =app.LDpeakmin.Value;
            signal = pks0(find(pks0>threshold_s));
            signal_loc_temp = find(pks0>threshold_s);
            signal_loc = locs0(signal_loc_temp);
            
            %% 频率计算
            Frelist = zeros(1,length(signal_loc)-1);
            Cov_Fre = 0;
            for i = 1:length(Frelist)
                Frelist(i) = 10^12/((signal_loc(i+1)-signal_loc(i))*LD_timebin)*10^(-9);
            end
            Fremeasured = sum(Frelist)/length(Frelist);
            for i = 1:length(Frelist)
                Cov_Fre = Cov_Fre+(Frelist(i)-Fremeasured)^2;
            end
            Cov_Fre = sqrt(Cov_Fre/length(Frelist));
            
            %% 3dB宽度计算(仅强度态)
            signal_3dB = signal/2;
            signal_3dBwidth = zeros(1,length(signal)-2);
            for i = 1:1:length(signal_3dBwidth)
                halfpulse_count = round(app.LD_T/LD_timebin/2);
                leftrange = counts0(signal_loc(i+1)-halfpulse_count:signal_loc(i+1));
                rightrange = counts0(signal_loc(i+1):signal_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-signal_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange-signal_3dB(i+1)));
                signal_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*LD_timebin;
            end
            signal_3dBwidth = mean(signal_3dBwidth);        
            
            %% 20dB宽度计算(仅强度态)
            signal_20dB = signal/100;
            signal_20dBwidth = zeros(1,length(signal)-2);
            for i = 1:1:length(signal_20dBwidth)
                halfpulse_count = round(app.LD_T/LD_timebin/2);
                leftrange = counts0(signal_loc(i+1)-halfpulse_count:signal_loc(i+1));
                rightrange = counts0(signal_loc(i+1):signal_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-signal_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange-signal_20dB(i+1)));
                signal_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*LD_timebin;
            end
            signal_20dBwidth = mean(signal_20dBwidth);            
        
        end
        
        function LD_plot(app,signal,signal_loc)
            delete(allchild(app.UIAxes7));   
            LD_timebin = app.fileLD(1,2)-app.fileLD(1,1); %ps
            time0 = app.fileLD(1,:);
            counts0 = app.fileLD(2,:);

            semilogy(app.UIAxes7,time0,counts0,time0(signal_loc),signal,'or'); % 真作图
            xlim(app.UIAxes7,[1,app.LD_Slider.Value]);
            ylim(app.UIAxes7,[1,100000]);
            grid(app.UIAxes7,'on');
            xlabel(app.UIAxes7,'time/ps','fontsize',18);
            ylabel(app.UIAxes7,'counts/log','fontsize',18);
            title(app.UIAxes7,'LD');
        end
        
        function [SDV_timebin, ...
                  signal, signal_loc, Cov_S, signal_3dBwidth, signal_20dBwidth, ...
                  decoy, decoy_loc, Cov_D, decoy_3dBwidth, decoy_20dBwidth, ER_SD, ER_SD_int, ...
                  vaccum, vaccum_loc, Cov_V, vaccum_3dBwidth, vaccum_20dBwidth, ER_SV, ER_SV_int, ...
                  signal_sum, decoy_sum, vaccum_sum, all_sum ...
                 ] = SDV_analysis(app,bins)
            SDV_timebin = app.fileSDV(1,2)-app.fileSDV(1,1); %ps
            time0 = app.fileSDV(1,:);
            counts0 = app.fileSDV(2,:);
            [pks0,locs0] = findpeaks(counts0,'MINPEAKHEIGHT',1,'MINPEAKDISTANCE',app.SDV_MINPEAKDISTANCE.Value);  %数据寻峰
            
            %% 信号态寻峰
            threshold_s = app.Speakmin.Value;
            signal = pks0(find(pks0>threshold_s));
            signal_loc_temp = find(pks0>threshold_s);
            signal_loc = locs0(signal_loc_temp);
            
            %% 真空态寻峰
            threshold_v = app.Vpeakmax.Value;
            vaccum = pks0(find(pks0<threshold_v));
            vaccum_loc_temp = find(pks0<threshold_v);
            vaccum_loc = locs0(vaccum_loc_temp);
            
            %% 诱骗态寻峰
            decoy1 = pks0(find(pks0<threshold_s));
            decoy1_loc_temp = find(pks0<threshold_s);
            decoy1_loc = locs0(decoy1_loc_temp);
            decoy = decoy1(find(decoy1>threshold_v));
            decoy_loc_temp1 = find(decoy1>threshold_v);
            decoy_loc = decoy1_loc(decoy_loc_temp1);
            
            signal_ave = mean(signal);
            decoy_ave = mean(decoy);
            vaccum_ave = mean(vaccum);
            ER_SV = 10*log10(signal_ave/vaccum_ave);   
            ER_SD = 10*log10(signal_ave/decoy_ave);  
                
            signal_int = zeros(1,length(signal)-2);
            vaccum_int = zeros(1,length(vaccum)-2);
            decoy_int = zeros(1,length(decoy)-2);
            
            for i = 1:1:length(signal_int)
                signal_int(i) = sum(counts0(signal_loc(i+1)-bins/2:signal_loc(i+1)+bins/2));
            end
            for j = 1:1:length(vaccum_int)
                vaccum_int(j) = sum(counts0(vaccum_loc(j+1)-bins/2:vaccum_loc(j+1)+bins/2));
            end
            for k = 1:1:length(decoy_int)
                decoy_int(k) = sum(counts0(decoy_loc(k+1)-bins/2:decoy_loc(k+1)+bins/2));
            end
            signal_sum = sum(signal_int);
            decoy_sum = sum(decoy_int);
            vaccum_sum = sum(vaccum_int);
            all_sum = signal_sum + decoy_sum + vaccum_sum;
            signal_int_ave = mean(signal_int);
            vaccum_int_ave = mean(vaccum_int);
            decoy_int_ave = mean(decoy_int);
            ER_SV_int = 10*log10(signal_int_ave/vaccum_int_ave);
            ER_SD_int = 10*log10(signal_int_ave/decoy_int_ave);
            
            %% 变异系数
            Cov_S = sqrt(var(signal))/mean(signal);
            Cov_D = sqrt(var(decoy))/mean(decoy);
            Cov_V = sqrt(var(vaccum))/mean(vaccum);
            
            %% 3dB宽度计算(仅强度态)
            signal_3dB = signal/2;
            signal_3dBwidth = zeros(1,length(signal)-2);
            for i = 1:1:length(signal_3dBwidth)
                halfpulse_count = round(app.SDV_T/SDV_timebin/2);
                leftrange = counts0(signal_loc(i+1)-halfpulse_count:signal_loc(i+1));
                rightrange = counts0(signal_loc(i+1):signal_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-signal_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange-signal_3dB(i+1)));
                signal_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*SDV_timebin;
            end
            signal_3dBwidth = mean(signal_3dBwidth);
            
            %% 20dB宽度计算(仅强度态)
            signal_20dB = signal/100;
            signal_20dBwidth = zeros(1,length(signal)-2);
            for i = 1:1:length(signal_20dBwidth)
                halfpulse_count = round(app.SDV_T/SDV_timebin/2);
                leftrange = counts0(signal_loc(i+1)-halfpulse_count:signal_loc(i+1));
                rightrange = counts0(signal_loc(i+1):signal_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-signal_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange-signal_20dB(i+1)));
                signal_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*SDV_timebin;
            end
            signal_20dBwidth = mean(signal_20dBwidth);
            
            %% 3dB宽度计算(仅诱骗态)
            decoy_3dB = decoy/2;
            decoy_3dBwidth = zeros(1,length(decoy)-2);
            for i = 1:1:length(decoy_3dBwidth)
                halfpulse_count = round(app.SDV_T/SDV_timebin/2);
                leftrange = counts0(decoy_loc(i+1)-halfpulse_count:decoy_loc(i+1));
                rightrange = counts0(decoy_loc(i+1):decoy_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-decoy_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange-decoy_3dB(i+1)));
                decoy_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*SDV_timebin;
            end
            decoy_3dBwidth = mean(decoy_3dBwidth);
            
            %% 20dB宽度计算(仅诱骗态)
            decoy_20dB = decoy/100;
            decoy_20dBwidth = zeros(1,length(decoy)-2);
            for i = 1:1:length(decoy_20dBwidth)
                halfpulse_count = round(app.SDV_T/SDV_timebin/2);
                leftrange = counts0(decoy_loc(i+1)-halfpulse_count:decoy_loc(i+1));
                rightrange = counts0(decoy_loc(i+1):decoy_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-decoy_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange-decoy_20dB(i+1)));
                decoy_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*SDV_timebin;
            end
            decoy_20dBwidth = mean(decoy_20dBwidth);
            
            %% 3dB宽度计算(仅真空态)
            vaccum_3dB = vaccum/2;
            vaccum_3dBwidth = zeros(1,length(vaccum)-2);
            for i = 1:1:length(vaccum_3dBwidth)
                halfpulse_count = round(app.SDV_T/SDV_timebin/2);
                leftrange = counts0(vaccum_loc(i+1)-halfpulse_count:vaccum_loc(i+1));
                rightrange = counts0(vaccum_loc(i+1):vaccum_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-vaccum_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange-vaccum_3dB(i+1)));
                vaccum_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*SDV_timebin;
            end
            vaccum_3dBwidth = mean(vaccum_3dBwidth);
            
            %% 20dB宽度计算(仅真空态)
            vaccum_20dB = vaccum/100;
            vaccum_20dBwidth = zeros(1,length(vaccum)-2);
            for i = 1:1:length(vaccum_20dBwidth)
                halfpulse_count = round(app.SDV_T/SDV_timebin/2);
                leftrange = counts0(vaccum_loc(i+1)-halfpulse_count:vaccum_loc(i+1));
                rightrange = counts0(vaccum_loc(i+1):vaccum_loc(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange-vaccum_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange-vaccum_20dB(i+1)));
                vaccum_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*SDV_timebin;
            end
            vaccum_20dBwidth = mean(vaccum_20dBwidth);
        end
        
        function SDV_plot(app,signal, signal_loc, ...
                  decoy, decoy_loc, ER_SD, ER_SD_int, ...
                  vaccum, vaccum_loc, ER_SV, ER_SV_int ...
                   )
            delete(allchild(app.UIAxes));   
            SDV_timebin = app.fileSDV(1,2)-app.fileSDV(1,1); %ps
            time0 = app.fileSDV(1,:);
            counts0 = app.fileSDV(2,:);
            
            dB1 = strcat('ave ER of S&V peaks = ',num2str(ER_SV),'dB');
            dB2 = strcat('ave ER of S&V pulses = ',num2str(ER_SV_int),'dB');
            dB3 = strcat('ave ER of S&D peaks = ',num2str(ER_SD),'dB');
            dB4 = strcat('ave ER of S&D pulses = ',num2str(ER_SD_int),'dB');
            semilogy(app.UIAxes,time0,counts0,time0(signal_loc),signal,'or',time0(vaccum_loc),vaccum,'sm',time0(decoy_loc),decoy,'+b'); % 真空态信号态诱骗态作图
            xlim(app.UIAxes,[1,app.SDV_Slider.Value]);
            ylim(app.UIAxes,[1,100000]);
            grid(app.UIAxes,'on');
            xlabel(app.UIAxes,'time/ps','fontsize',18);
            ylabel(app.UIAxes,'counts/log','fontsize',18);
            title(app.UIAxes,'AM Rand');
            text(app.UIAxes,1,80000,dB1);
            text(app.UIAxes,30000*app.SDV_Slider.Value/50000,80000,dB2);
            text(app.UIAxes,1,50000,dB3);
            text(app.UIAxes,30000*app.SDV_Slider.Value/50000,50000,dB4);
        end

        function  [binslist,width,errorate,signal_sumratio_list,decoy_sumratio_list,vaccum_sumratio_list,all_sumratio_list] ...
            = SDV_binwidth_analysis(app,SDV_timebin)
            binslist = 0:round(app.SDV_T/2/SDV_timebin);
            width = binslist*SDV_timebin;
            errorate = zeros(1,length(binslist));
            signal_sum_list = zeros(1,length(binslist));
            decoy_sum_list = zeros(1,length(binslist));
            vaccum_sum_list = zeros(1,length(binslist));
            all_sum_list = zeros(1,length(binslist));
            signal_sumratio_list = zeros(1,length(binslist));
            decoy_sumratio_list = zeros(1,length(binslist));
            vaccum_sumratio_list = zeros(1,length(binslist));
            all_sumratio_list = zeros(1,length(binslist));             
            for i = 1:length(binslist)
                [ SDV_timebin, ...
                  signal, signal_loc, Cov_S, signal_3dBwidth, signal_20dBwidth, ...
                  decoy, decoy_loc, Cov_D, decoy_3dBwidth, decoy_20dBwidth, ER_SD, ER_SD_int, ...
                  vaccum, vaccum_loc, Cov_V, vaccum_3dBwidth, vaccum_20dBwidth, ER_SV, ER_SV_int, ...
                  signal_sum, decoy_sum, vaccum_sum, all_sum ...
                 ] = SDV_analysis(app,binslist(i));
                errorate(i) = 1/10^(ER_SV_int/10)*100;
                signal_sum_list(i) = signal_sum;
                decoy_sum_list(i) = decoy_sum;
                vaccum_sum_list(i) = vaccum_sum;
                all_sum_list(i) = all_sum;
            end
            for i = 1:length(binslist)
                signal_sumratio_list(i) = signal_sum_list(i)/signal_sum_list(length(binslist))*100;
                decoy_sumratio_list(i) = decoy_sum_list(i)/decoy_sum_list(length(binslist))*100;
                vaccum_sumratio_list(i) = vaccum_sum_list(i)/vaccum_sum_list(length(binslist))*100;
                all_sumratio_list(i) = all_sum_list(i)/all_sum_list(length(binslist))*100;
            end
        end
         
        function SDV_binwidth_analysis_plot(app, width, errorate, signal_sumratio_list, decoy_sumratio_list, vaccum_sumratio_list, all_sumratio_list)          
            delete(allchild(app.UIAxes5_2)); 
            yyaxis(app.UIAxes5_2,"left");
            % 左侧Y轴（Extinction Ratio）
            p1 = plot(app.UIAxes5_2,width, errorate, '-*');
            ylabel(app.UIAxes5_2,'Extinction Ratio (%)', 'fontsize', 18, 'FontName', 'Arial');
            
            yyaxis(app.UIAxes5_2,"right");
            % 右侧Y轴（Photon Number Ratio）
            p2 = plot(app.UIAxes5_2,width,signal_sumratio_list,'c-*');
            hold(app.UIAxes5_2,'on');
            p3 = plot(app.UIAxes5_2,width,decoy_sumratio_list,'g-*');
            hold(app.UIAxes5_2,'on');
            p4 = plot(app.UIAxes5_2,width,vaccum_sumratio_list,'m-*');
            hold(app.UIAxes5_2,'on');
            p5 = plot(app.UIAxes5_2,width,all_sumratio_list,'-*');
            ylabel(app.UIAxes5_2,'Photon Number Ratio (%)', 'fontsize', 18, 'FontName', 'Arial');
            
            p1.LineWidth = 1;
            p2.LineWidth = 1;
            p3.LineWidth = 1;
            p4.LineWidth = 1;
            p5.LineWidth = 1;
            
            % 添加图例
            lgd = legend(app.UIAxes5_2,'Extinction Ratio', 'Signal Sum Ratio', 'Decoy Sum Ratio', 'Vaccum Sum Ratio', 'All Sum Ratio','Location', 'southeast');
            lgd.Box = 'off';
            
            % 显示网格
            grid(app.UIAxes5_2,'on');
            
            % 设置坐标轴标签和标题
            xlabel(app.UIAxes5_2,'Bin Width (ps)', 'fontsize', 18, 'FontName', 'Arial');
            title(app.UIAxes5_2,'SDV Bin Width Analysis', 'fontsize', 21, 'FontName', 'Arial');
        end

        
        function [PNRL_timebin, ...
            P_PC1_ch1, locs1_P, P_PC1_ch2, locs2_P, ER_P, ER_P_int, P_PC1_ch1_3dBwidth, P_PC1_ch1_20dBwidth, Pstablemax, Pstablemin, ...
            N_PC1_ch1, locs1_N, N_PC1_ch2, locs2_N, ER_N, ER_N_int, N_PC1_ch2_3dBwidth, N_PC1_ch2_20dBwidth, Nstablemax, Nstablemin, ...
            R_PC2_ch3, locs1_R, R_PC2_ch4, locs2_R, ER_R, ER_R_int, R_PC2_ch3_3dBwidth, R_PC2_ch3_20dBwidth, Rstablemax, Rstablemin, ...
            L_PC2_ch3, locs1_L, L_PC2_ch4, locs2_L, ER_L, ER_L_int, L_PC2_ch4_3dBwidth, L_PC2_ch4_20dBwidth, Lstablemax, Lstablemin, ...
            peakerrorrate, threerrorrate, ...
            P_sum, N_sum, R_sum, L_sum, all_sum ...
            ] = PNRL_analysis(app,bins) 
            
             % 无用的值
            P_sum_fullwidth = 1;
            N_sum_fullwidth = 1;
            R_sum_fullwidth = 1;
            L_sum_fullwidth = 1;
            
            PNRL_timebin = app.filech1(1,2)-app.filech1(1,1); %ps    
            time1 = app.filech1(1,:);
            counts1 = app.filech1(2,:);
            time2 = app.filech2(1,:);
            counts2 = app.filech2(2,:);
            time3 = app.filech3(1,:);
            counts3 = app.filech3(2,:);
            time4 = app.filech4(1,:);
            counts4 = app.filech4(2,:);
            
            [pks1,locs1] = findpeaks(counts1,'MINPEAKHEIGHT',3,'MINPEAKDISTANCE',app.PNRL_MINPEAKDISTANCE.Value);  %PC1 ch1通道数据寻峰
            [pks2,locs2] = findpeaks(counts2,'MINPEAKHEIGHT',3,'MINPEAKDISTANCE',app.PNRL_MINPEAKDISTANCE.Value);  %PC1 ch2通道数据寻峰
            [pks3,locs3] = findpeaks(counts3,'MINPEAKHEIGHT',3,'MINPEAKDISTANCE',app.PNRL_MINPEAKDISTANCE.Value);  %PC2 ch3通道数据寻峰
            [pks4,locs4] = findpeaks(counts4,'MINPEAKHEIGHT',3,'MINPEAKDISTANCE',app.PNRL_MINPEAKDISTANCE.Value);  %PC2 ch4通道数据寻峰      
            
            % P在PC1 ch1 下
            thr_P_PC1_ch1 = app.Ppeakmax.Value;                       % P态在PC1下ch1通道的阈值
            locs1_P_temp = find(pks1>thr_P_PC1_ch1);     % P态在寻峰序列中的位置       PC1下ch1
            P_PC1_ch1 = pks1(locs1_P_temp);              % P态峰值强度                PC1下ch1
            locs1_P = locs1(locs1_P_temp);               % P态在时间序列中的位置      PC1下ch1
            
            % P在PC1 ch2 下
            thr_P_PC1_ch2 = app.Ppeakmin.Value;                       % P态在PC1下ch2通道的阈值
            locs2_P_temp = find(pks2<thr_P_PC1_ch2);     % P态在寻峰序列中的位置       PC1下ch2
            P_PC1_ch2 = pks2(locs2_P_temp);              % P态峰值强度                PC1下ch2
            locs2_P = locs2(locs2_P_temp);               % P态在时间序列中的位置      PC1下ch2
            
            % P态峰值比
            P_max_ave = mean(P_PC1_ch1)/app.ch1eff.Value;    % 超导效率归一化
            P_min_ave = mean(P_PC1_ch2)/app.ch2eff.Value;
            ER_P = 10*log10(P_max_ave/P_min_ave);          
            
            % 门宽积分比和全宽积分
            P_PC1_ch1_int = zeros(1,length(P_PC1_ch1)-2); %  P态在PC1 ch1通道中脉冲半高宽积分
%             P_PC1_ch1_int_fullwidth = zeros(1,length(P_PC1_ch1)-2);
            for i = 1:length(P_PC1_ch1_int)
                P_PC1_ch1_int(i) = sum(counts1(locs1_P(i+1)-bins/2:locs1_P(i+1)+bins/2));   
%                 P_PC1_ch1_int_fullwidth(i) = sum(counts1(locs1_P(i+1)-round(app.PNRL_T/2/PNRL_timebin):locs1_P(i+1)+round(app.PNRL_T/2/PNRL_timebin)));  
            end
            P_PC1_ch2_int = zeros(1,length(P_PC1_ch2)-2); %  P态在PC1 ch2通道中脉冲半高宽积分
%             P_PC1_ch2_int_fullwidth = zeros(1,length(P_PC1_ch2)-2);
            for i = 1:length(P_PC1_ch2_int)
                P_PC1_ch2_int(i) = sum(counts2(locs2_P(i+1)-bins/2:locs2_P(i+1)+bins/2));
%                 P_PC1_ch2_int_fullwidth(i) = sum(counts2(locs2_P(i+1)-round(app.PNRL_T/2/PNRL_timebin):locs2_P(i+1)+round(app.PNRL_T/2/PNRL_timebin)));
            end
            P_PC1_ch1_int_ave = mean(P_PC1_ch1_int)/app.ch1eff.Value;    % 超导效率归一化
            P_PC1_ch2_int_ave = mean(P_PC1_ch2_int)/app.ch2eff.Value;
%             P_PC1_ch1_int_fullwidth_ave = mean(P_PC1_ch1_int_fullwidth)/app.ch1eff.Value;
%             P_PC1_ch2_int_fullwidth_ave = mean(P_PC1_ch2_int_fullwidth)/app.ch2eff.Value;
            ER_P_int = 10*log10(P_PC1_ch1_int_ave/P_PC1_ch2_int_ave);
            
            %% 3dB宽度计算(仅P态)
            P_PC1_ch1_3dB = P_PC1_ch1/2;
            P_PC1_ch1_3dBwidth = zeros(1,length(P_PC1_ch1)-2);
            halfpulse_count = round(app.PNRL_T/PNRL_timebin/2);
            for i = 1:1:length(P_PC1_ch1_3dBwidth)
                leftrange = counts1(locs1_P(i+1)-halfpulse_count:locs1_P(i+1));
                rightrange = counts1(locs1_P(i+1):locs1_P(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- P_PC1_ch1_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange- P_PC1_ch1_3dB(i+1)));
                P_PC1_ch1_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            P_PC1_ch1_3dBwidth = mean(P_PC1_ch1_3dBwidth);
            
            %% 20dB宽度计算(仅P态)
            P_PC1_ch1_20dB = P_PC1_ch1/100;
            P_PC1_ch1_20dBwidth = zeros(1,length(P_PC1_ch1)-2);
            halfpulse_count = round(app.PNRL_T/PNRL_timebin/2);
            for i = 1:1:length(P_PC1_ch1_20dBwidth)
                leftrange = counts1(locs1_P(i+1)-halfpulse_count:locs1_P(i+1));
                rightrange = counts1(locs1_P(i+1):locs1_P(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- P_PC1_ch1_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange- P_PC1_ch1_20dB(i+1)));
                P_PC1_ch1_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            P_PC1_ch1_20dBwidth = mean(P_PC1_ch1_20dBwidth);
            
            Pstablemax = sqrt(var(P_PC1_ch1/app.ch1eff.Value))/P_max_ave;
            Pstablemin = sqrt(var(P_PC1_ch2/app.ch2eff.Value))/P_min_ave;
            P_sum = P_PC1_ch1_int_ave+P_PC1_ch2_int_ave;
%             P_sum_fullwidth = P_PC1_ch1_int_fullwidth_ave+P_PC1_ch2_int_fullwidth_ave;     
            
            % N在PC1 ch1 下
            thr_N_PC1_ch1 = app.Npeakmin.Value;                         % N态在PC1下ch1通道的阈值
            locs1_N_temp = find(pks1<thr_N_PC1_ch1);     % N态在寻峰序列中的位置       PC1下ch1
            N_PC1_ch1 = pks1(locs1_N_temp);              % N态峰值强度                PC1下ch1
            locs1_N = locs1(locs1_N_temp);               % N态在时间序列中的位置       PC1下ch1
            
            % N在PC1 ch2 下
            thr_N_PC1_ch2 = app.Npeakmax.Value;                        % N态在PC1下ch2通道的阈值
            locs2_N_temp = find(pks2>thr_N_PC1_ch2);     % N态在寻峰序列中的位置       PC1下ch2
            N_PC1_ch2 = pks2(locs2_N_temp);              % N态峰值强度                PC1下ch2
            locs2_N = locs2(locs2_N_temp);               % N态在时间序列中的位置      PC1下ch2
            
            % N态峰值比
            N_max_ave = mean(N_PC1_ch2)/app.ch2eff.Value;    % 超导效率归一化
            N_min_ave = mean(N_PC1_ch1)/app.ch1eff.Value;
            ER_N = 10*log10(N_max_ave/N_min_ave);       
            
            % 门宽积分比和全宽积分
            N_PC1_ch1_int = zeros(1,length(N_PC1_ch1)-2); %  N态在PC1 ch1通道中脉冲半高宽积分
%             N_PC1_ch1_int_fullwidth = zeros(1,length(N_PC1_ch1)-2);
            for i = 1:length(N_PC1_ch1_int)
                N_PC1_ch1_int(i) = sum(counts1(locs1_N(i+1)-bins/2:locs1_N(i+1)+bins/2));
%                 N_PC1_ch1_int_fullwidth(i) = sum(counts1(locs1_N(i+1)-round(app.PNRL_T/2/PNRL_timebin):locs1_N(i+1)+round(app.PNRL_T/2/PNRL_timebin)));
            end
            N_PC1_ch2_int = zeros(1,length(N_PC1_ch2)-2); %  N态在PC1 ch2通道中脉冲半高宽积分
%             N_PC1_ch2_int_fullwidth = zeros(1,length(N_PC1_ch2)-2);
            for i = 1:length(N_PC1_ch2_int)
                N_PC1_ch2_int(i) = sum(counts2(locs2_N(i+1)-bins/2:locs2_N(i+1)+bins/2));
%                 N_PC1_ch2_int_fullwidth(i) = sum(counts2(locs2_N(i+1)-round(app.PNRL_T/2/PNRL_timebin):locs2_N(i+1)+round(app.PNRL_T/2/PNRL_timebin)));
            end
            N_PC1_ch1_int_ave = mean(N_PC1_ch1_int)/app.ch1eff.Value;    % 超导效率归一化
            N_PC1_ch2_int_ave = mean(N_PC1_ch2_int)/app.ch2eff.Value;
%             N_PC1_ch1_int_fullwidth_ave = mean(N_PC1_ch1_int_fullwidth)/app.ch1eff.Value; 
%             N_PC1_ch2_int_fullwidth_ave = mean(N_PC1_ch2_int_fullwidth)/app.ch2eff.Value;
            ER_N_int = 10*log10(N_PC1_ch2_int_ave/N_PC1_ch1_int_ave);
            
            %% 3dB宽度计算(仅N态)
            N_PC1_ch2_3dB = N_PC1_ch2/2;
            N_PC1_ch2_3dBwidth = zeros(1,length(N_PC1_ch2)-2);
            halfpulse_count = round(app.PNRL_T/PNRL_timebin/2);
            for i = 1:1:length(N_PC1_ch2_3dBwidth)
                leftrange = counts2(locs2_N(i+1)-halfpulse_count:locs2_N(i+1));
                rightrange = counts2(locs2_N(i+1):locs2_N(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- N_PC1_ch2_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange- N_PC1_ch2_3dB(i+1)));
                N_PC1_ch2_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            N_PC1_ch2_3dBwidth = mean(N_PC1_ch2_3dBwidth);
            
            %% 20dB宽度计算(仅N态)
            N_PC1_ch2_20dB = N_PC1_ch2/100;
            N_PC1_ch2_20dBwidth = zeros(1,length(N_PC1_ch2)-2);
            halfpulse_count = round(app.PNRL_T/PNRL_timebin/2);
            for i = 1:1:length(N_PC1_ch2_20dBwidth)
                leftrange = counts2(locs2_N(i+1)-halfpulse_count:locs2_N(i+1));
                rightrange = counts2(locs2_N(i+1):locs2_N(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- N_PC1_ch2_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange- N_PC1_ch2_20dB(i+1)));
                N_PC1_ch2_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            N_PC1_ch2_20dBwidth = mean(N_PC1_ch2_20dBwidth);
            
            Nstablemax = sqrt(var(N_PC1_ch2/app.ch2eff.Value))/N_max_ave;
            Nstablemin = sqrt(var(N_PC1_ch1/app.ch1eff.Value))/N_min_ave;
            N_sum = N_PC1_ch1_int_ave+N_PC1_ch2_int_ave;
%             N_sum_fullwidth = N_PC1_ch1_int_fullwidth_ave+N_PC1_ch2_int_fullwidth_ave;
            
            % R在PC2 ch3 下
            thr_R_PC2_ch3 = app.Rpeakmax.Value;                       % R态在PC2下ch3通道的阈值
            locs1_R_temp = find(pks3>thr_R_PC2_ch3);     % R态在寻峰序列中的位置       PC2下ch3
            R_PC2_ch3 = pks3(locs1_R_temp);              % R态峰值强度                PC2下ch3
            locs1_R = locs3(locs1_R_temp);               % R态在时间序列中的位置      PC2下ch3
            
            % R在PC2 ch4 下
            thr_R_PC2_ch4 = app.Rpeakmin.Value;                       % R态在PC2下ch4通道的阈值
            locs2_R_temp = find(pks4<thr_R_PC2_ch4);     % R态在寻峰序列中的位置       PC2下ch4
            R_PC2_ch4 = pks4(locs2_R_temp);              % R态峰值强度                PC2下ch4
            locs2_R = locs4(locs2_R_temp);               % R态在时间序列中的位置      PC2下ch4
            
            % R态峰值比
            R_max_ave = mean(R_PC2_ch3)/app.ch3eff.Value;    % 超导效率归一化
            R_min_ave = mean(R_PC2_ch4)/app.ch4eff.Value;
            ER_R = 10*log10(R_max_ave/R_min_ave);          
            
            % 门宽积分比和全宽积分
            R_PC2_ch3_int = zeros(1,length(R_PC2_ch3)-2); %  R态在PC2 ch3通道中脉冲半高宽积分
%             R_PC2_ch3_int_fullwidth = zeros(1,length(R_PC2_ch3)-2);
            for i = 1:length(R_PC2_ch3_int)
                R_PC2_ch3_int(i) = sum(counts3(locs1_R(i+1)-bins/2:locs1_R(i+1)+bins/2));
%                 R_PC2_ch3_int_fullwidth(i) = sum(counts3(locs1_R(i+1)-round(app.PNRL_T/2/PNRL_timebin):locs1_R(i+1)+round(app.PNRL_T/2/PNRL_timebin)));
            end
            R_PC2_ch4_int = zeros(1,length(R_PC2_ch4)-2); %  R态在PC2 ch4通道中脉冲半高宽积分
%             R_PC2_ch4_int_fullwidth = zeros(1,length(R_PC2_ch4)-2);
            for i = 1:length(R_PC2_ch4_int)
                R_PC2_ch4_int(i) = sum(counts4(locs2_R(i+1)-bins/2:locs2_R(i+1)+bins/2));
%                 R_PC2_ch4_int_fullwidth(i) = sum(counts4(locs2_R(i+1)-round(app.PNRL_T/2/PNRL_timebin):locs2_R(i+1)+round(app.PNRL_T/2/PNRL_timebin)));
            end
            R_PC2_ch3_int_ave = mean(R_PC2_ch3_int)/app.ch3eff.Value;    % 超导效率归一化
            R_PC2_ch4_int_ave = mean(R_PC2_ch4_int)/app.ch4eff.Value;
%             R_PC2_ch3_int_fullwidth_ave = mean(R_PC2_ch3_int_fullwidth)/app.ch3eff.Value;   
%             R_PC2_ch4_int_fullwidth_ave = mean(R_PC2_ch4_int_fullwidth)/app.ch4eff.Value;
            ER_R_int = 10*log10(R_PC2_ch3_int_ave/R_PC2_ch4_int_ave);
            
            %% 3dB宽度计算(仅R态)
            R_PC2_ch3_3dB = R_PC2_ch3/2;
            R_PC2_ch3_3dBwidth = zeros(1,length(R_PC2_ch3)-2);
            halfpulse_count = round(app.PNRL_T/PNRL_timebin/2);
            for i = 1:1:length(R_PC2_ch3_3dBwidth)
                leftrange = counts3(locs1_R(i+1)-halfpulse_count:locs1_R(i+1));
                rightrange = counts3(locs1_R(i+1):locs1_R(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- R_PC2_ch3_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange- R_PC2_ch3_3dB(i+1)));
                R_PC2_ch3_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            R_PC2_ch3_3dBwidth = mean(R_PC2_ch3_3dBwidth);
            
            %% 20dB宽度计算(仅R态)
            R_PC2_ch3_20dB = R_PC2_ch3/100;
            R_PC2_ch3_20dBwidth = zeros(1,length(R_PC2_ch3)-2);
            halfpulse_count = round(app.PNRL_T/PNRL_timebin/2);
            for i = 1:1:length(R_PC2_ch3_20dBwidth)
                leftrange = counts3(locs1_R(i+1)-halfpulse_count:locs1_R(i+1));
                rightrange = counts3(locs1_R(i+1):locs1_R(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- R_PC2_ch3_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange- R_PC2_ch3_20dB(i+1)));
                R_PC2_ch3_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            R_PC2_ch3_20dBwidth = mean(R_PC2_ch3_20dBwidth);     
            
            Rstablemax = sqrt(var(R_PC2_ch3/app.ch3eff.Value))/R_max_ave;
            Rstablemin = sqrt(var(R_PC2_ch4/app.ch4eff.Value))/R_min_ave;
            R_sum = R_PC2_ch3_int_ave+R_PC2_ch4_int_ave;
%             R_sum_fullwidth = R_PC2_ch3_int_fullwidth_ave+R_PC2_ch4_int_fullwidth_ave;
            
            % L在PC2 ch3 下
            thr_L_PC2_ch3 = app.Lpeakmin.Value;                         % L态在PC2下ch3通道的阈值
            locs1_L_temp = find(pks3<thr_L_PC2_ch3);     % L态在寻峰序列中的位置       PC2下ch3
            L_PC2_ch3 = pks3(locs1_L_temp);              % L态峰值强度                PC2下ch3
            locs1_L = locs3(locs1_L_temp);               % L态在时间序列中的位置       PC2下ch3
            
            % L在PC2 ch4 下
            thr_L_PC2_ch4 = app.Lpeakmax.Value;                        % L态在PC2下ch4通道的阈值
            locs2_L_temp = find(pks4>thr_L_PC2_ch4);     % L态在寻峰序列中的位置       PC2下ch4
            L_PC2_ch4 = pks4(locs2_L_temp);              % L态峰值强度                PC2下ch4
            locs2_L = locs4(locs2_L_temp);               % L态在时间序列中的位置      PC2下ch4
            
            % L态峰值比
            L_max_ave = mean(L_PC2_ch4)/app.ch4eff.Value;    % 超导效率归一化
            L_min_ave = mean(L_PC2_ch3)/app.ch3eff.Value;
            ER_L = 10*log10(L_max_ave/L_min_ave);       
            
            % 门宽积分比和全宽积分
            L_PC2_ch3_int = zeros(1,length(L_PC2_ch3)-2); %  L态在PC2 ch3通道中脉冲半高宽积分
%             L_PC2_ch3_int_fullwidth = zeros(1,length(L_PC2_ch3)-2);
            for i = 1:length(L_PC2_ch3_int)
                L_PC2_ch3_int(i) = sum(counts3(locs1_L(i+1)-bins/2:locs1_L(i+1)+bins/2));
%                 L_PC2_ch3_int_fullwidth(i) = sum(counts3(locs1_L(i+1)-round(app.PNRL_T/2/PNRL_timebin):locs1_L(i+1)+round(app.PNRL_T/2/PNRL_timebin)));
            end
            L_PC2_ch4_int = zeros(1,length(L_PC2_ch4)-2); %  L态在PC2 ch4通道中脉冲半高宽积分
%             L_PC2_ch4_int_fullwidth = zeros(1,length(L_PC2_ch4)-2);
            for i = 1:length(L_PC2_ch4_int)
                L_PC2_ch4_int(i) = sum(counts4(locs2_L(i+1)-bins/2:locs2_L(i+1)+bins/2));
%                 L_PC2_ch4_int_fullwidth(i) = sum(counts4(locs2_L(i+1)-round(app.PNRL_T/2/PNRL_timebin):locs2_L(i+1)+round(app.PNRL_T/2/PNRL_timebin)));
            end
            L_PC2_ch3_int_ave = mean(L_PC2_ch3_int)/app.ch3eff.Value;    % 超导效率归一化
            L_PC2_ch4_int_ave = mean(L_PC2_ch4_int)/app.ch4eff.Value;
%             L_PC2_ch3_int_fullwidth_ave = mean(L_PC2_ch3_int_fullwidth)/app.ch3eff.Value;
%             L_PC2_ch4_int_fullwidth_ave = mean(L_PC2_ch4_int_fullwidth)/app.ch4eff.Value;
            ER_L_int = 10*log10(L_PC2_ch4_int_ave/L_PC2_ch3_int_ave);
            
            %% 3dB宽度计算(仅L态)
            L_PC2_ch4_3dB = L_PC2_ch4/2;
            L_PC2_ch4_3dBwidth = zeros(1,length(L_PC2_ch4)-2);
            halfpulse_count = round(app.PNRL_T/PNRL_timebin/2);
            for i = 1:1:length(L_PC2_ch4_3dBwidth)
                leftrange = counts4(locs2_L(i+1)-halfpulse_count:locs2_L(i+1));
                rightrange = counts4(locs2_L(i+1):locs2_L(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- L_PC2_ch4_3dB(i+1)));
                [~, rightindex] = min(abs(rightrange- L_PC2_ch4_3dB(i+1)));
                L_PC2_ch4_3dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            L_PC2_ch4_3dBwidth = mean(L_PC2_ch4_3dBwidth);
            
            %% 20dB宽度计算(仅L态)
            L_PC2_ch4_20dB = L_PC2_ch4/100;
            L_PC2_ch4_20dBwidth = zeros(1,length(L_PC2_ch4)-2);
            halfpulse_count = round(app.PNRL_T/PNRL_timebin/2);
            for i = 1:1:length(L_PC2_ch4_20dBwidth)
                leftrange = counts4(locs2_L(i+1)-halfpulse_count:locs2_L(i+1));
                rightrange = counts4(locs2_L(i+1):locs2_L(i+1)+halfpulse_count);
                [~, leftindex] = min(abs(leftrange- L_PC2_ch4_20dB(i+1)));
                [~, rightindex] = min(abs(rightrange- L_PC2_ch4_20dB(i+1)));
                L_PC2_ch4_20dBwidth(i) = (halfpulse_count-leftindex+rightindex)*PNRL_timebin;
            end
            L_PC2_ch4_20dBwidth = mean(L_PC2_ch4_20dBwidth);
            
            Lstablemax= sqrt(var(L_PC2_ch4/app.ch4eff.Value))/L_max_ave;
            Lstablemin = sqrt(var(L_PC2_ch3/app.ch3eff.Value))/L_min_ave;
            L_sum = L_PC2_ch3_int_ave+L_PC2_ch4_int_ave;
%             L_sum_fullwidth = L_PC2_ch3_int_fullwidth_ave+L_PC2_ch4_int_fullwidth_ave;
            
            peakerrorrate = (1/10^(ER_P/10)+1/10^(ER_N/10)+1/10^(ER_L/10)+1/10^(ER_R/10))/4*100;
%             peakextratio = 10*log10(1/(peakerrorrate/100));
            threerrorrate = (1/10^(ER_P_int/10)+1/10^(ER_N_int/10)+1/10^(ER_L_int/10)+1/10^(ER_R_int/10))/4*100; 
%             threextratio = 10*log10(1/(threerrorrate/100)); 
%             PNpeakextratio = 10*log10(1/((1/10^(ER_P/10)+1/10^(ER_N/10))/2));
%             PNthreextratio = 10*log10(1/((1/10^(ER_P_int/10)+1/10^(ER_N_int/10))/2));
%             RLpeakextratio = 10*log10(1/((1/10^(ER_R/10)+1/10^(ER_L/10))/2));
%             RLthreextratio = 10*log10(1/((1/10^(ER_R_int/10)+1/10^(ER_L_int/10))/2));
            
            all_sum = P_sum+N_sum+R_sum+L_sum;
%             max_power = max([P_sum, N_sum, R_sum, L_sum]);
%             min_power = min([P_sum, N_sum, R_sum, L_sum]);
            
%             PDL = 10*log10(max_power/min_power);
        end
        
        function PNLR_plot(app, ...
                           P_PC1_ch1, locs1_P, P_PC1_ch2, locs2_P, ER_P, ER_P_int, ...
                           N_PC1_ch1, locs1_N, N_PC1_ch2, locs2_N, ER_N, ER_N_int, ...
                           L_PC2_ch3, locs1_L, L_PC2_ch4, locs2_L, ER_L, ER_L_int, ...
                           R_PC2_ch3, locs1_R, R_PC2_ch4, locs2_R, ER_R, ER_R_int ...
                           )
            delete(allchild(app.UIAxes2_P));          
            delete(allchild(app.UIAxes2_N));  
            delete(allchild(app.UIAxes2_R));  
            delete(allchild(app.UIAxes2_L));  
            
            ch2_delay = app.ch2delay.Value;
            ch4_delay = app.ch4delay.Value;
            
            PNRL_timebin = app.filech1(1,2)-app.filech1(1,1); %ps    
            time1 = app.filech1(1,:);
            counts1 = app.filech1(2,:);
            time2 = app.filech2(1,:);
            counts2 = app.filech2(2,:);
            time3 = app.filech3(1,:);
            counts3 = app.filech3(2,:);
            time4 = app.filech4(1,:);
            counts4 = app.filech4(2,:);
            
            %% P态作图
            dB1 = strcat('ave ER of peaks = ',num2str(ER_P),'dB');
            dB2 = strcat('ave ER of pulses = ',num2str(ER_P_int),'dB');
            H = semilogy(app.UIAxes2_P,time1,counts1,time1(locs1_P),P_PC1_ch1,'or',time2-ch2_delay,counts2,time2(locs2_P)-ch2_delay,P_PC1_ch2,'sk');
            xlim(app.UIAxes2_P,[0,app.PNRL_Slider.Value]);
            ylim(app.UIAxes2_P,[1,100000]);
            grid(app.UIAxes2_P,'on');
            hP = legend(H([2 4]),'P in ch1','P in ch2');
            set(hP,'Orientation','horizon');
            set(hP,'FontName','Times New Roman','FontSize',18,'FontWeight','normal');
            xlabel(app.UIAxes2_P,'time/ps','fontsize',14,'FontName','Arial');
            ylabel(app.UIAxes2_P,'counts/log','fontsize',14,'FontName','Arial');
            title(app.UIAxes2_P,'P state');
            text(app.UIAxes2_P,5,15000,dB1);
            text(app.UIAxes2_P,30000*app.PNRL_Slider.Value/50000,15000,dB2);
            
            %% N态作图
            dB1 = strcat('ave ER of peaks = ',num2str(ER_N),'dB');
            dB2 = strcat('ave ER of pulses = ',num2str(ER_N_int),'dB');
            H = semilogy(app.UIAxes2_N,time1,counts1,time1(locs1_N),N_PC1_ch1,'or',time2-ch2_delay,counts2,time2(locs2_N)-ch2_delay,N_PC1_ch2,'sk');
            xlim(app.UIAxes2_N,[0,app.PNRL_Slider.Value]);
            ylim(app.UIAxes2_N,[1,100000]);
            grid(app.UIAxes2_N,'on');
            hP = legend(H([4 2]),'N in ch2','N in ch1');
            set(hP,'Orientation','horizon');
            set(hP,'FontName','Times New Roman','FontSize',18,'FontWeight','normal');
            xlabel(app.UIAxes2_N,'time/ps','fontsize',14,'FontName','Arial');
            ylabel(app.UIAxes2_N,'counts/log','fontsize',14,'FontName','Arial');
            title(app.UIAxes2_N,'N state');
            text(app.UIAxes2_N,5,15000,dB1);
            text(app.UIAxes2_N,30000*app.PNRL_Slider.Value/50000,15000,dB2);
            
            %% R态作图
            dB1 = strcat('ave ER of peaks = ',num2str(ER_R),'dB');
            dB2 = strcat('ave ER of pulses = ',num2str(ER_R_int),'dB');
            H = semilogy(app.UIAxes2_R,time3,counts3,time3(locs1_R),R_PC2_ch3,'or',time4-ch4_delay,counts4,time4(locs2_R)-ch4_delay,R_PC2_ch4,'sk');
            xlim(app.UIAxes2_R,[0,app.PNRL_Slider.Value]);
            ylim(app.UIAxes2_R,[1,100000]);
            grid(app.UIAxes2_R,'on');
            hP = legend(H([2 4]),'R in ch3','R in ch4');
            set(hP,'Orientation','horizon');
            set(hP,'FontName','Times New Roman','FontSize',18,'FontWeight','normal');
            xlabel(app.UIAxes2_R,'time/ps','fontsize',14,'FontName','Arial');
            ylabel(app.UIAxes2_R,'counts/log','fontsize',14,'FontName','Arial');
            title(app.UIAxes2_R,'R state');
            text(app.UIAxes2_R,5,15000,dB1);
            text(app.UIAxes2_R,30000*app.PNRL_Slider.Value/50000,15000,dB2);  
            
            %% L态作图
            dB1 = strcat('ave ER of peaks = ',num2str(ER_L),'dB');
            dB2 = strcat('ave ER of pulses = ',num2str(ER_L_int),'dB');
            H = semilogy(app.UIAxes2_L,time3,counts3,time3(locs1_L),L_PC2_ch3,'or',time4-ch4_delay,counts4,time4(locs2_L)-ch4_delay,L_PC2_ch4,'sk');
            xlim(app.UIAxes2_L,[0,app.PNRL_Slider.Value]);
            ylim(app.UIAxes2_L,[1,100000]);
            grid(app.UIAxes2_L,'on');
            hP = legend(H([4 2]),'L in ch4','L in ch3');
            set(hP,'Orientation','horizon');
            set(hP,'FontName','Times New Roman','FontSize',18,'FontWeight','normal');
            xlabel(app.UIAxes2_L,'time/ps','fontsize',14,'FontName','Arial');
            ylabel(app.UIAxes2_L,'counts/log','fontsize',14,'FontName','Arial');
            title(app.UIAxes2_L,'L state');
            text(app.UIAxes2_L,5,15000,dB1);
            text(app.UIAxes2_L,30000*app.PNRL_Slider.Value/50000,15000,dB2);   
               
    %             %% 重叠
    %             semilogy(app.UIAxes6,time1(locs1_P),P_PC1_ch1,'or',time2(locs2_N)-ch2_delay,N_PC1_ch2,'sr', ...
    %                 time3(locs1_L),L_PC2_ch3,'xr',time4(locs2_R)-ch4_delay,R_PC2_ch4,'+r');
    %             xlim(app.UIAxes6,[0,50000]);
    %             ylim(app.UIAxes6,[1,100000]);
    %             grid(app.UIAxes6,'on');
            
         end

        function  [binslist,width,errorate_P,errorate_N,errorate_L,errorate_R,errorate_ave, ...
                P_sumratio_list,N_sumratio_list,R_sumratio_list,L_sumratio_list,all_sumratio_list]=PNLR_binwidth_analysis(app,PNRL_timebin)
            binslist = 0:round(app.PNRL_T/2/PNRL_timebin);
            width = binslist*PNRL_timebin;
            errorate_P = zeros(1,length(binslist));
            errorate_N = zeros(1,length(binslist));
            errorate_L = zeros(1,length(binslist));
            errorate_R = zeros(1,length(binslist));
            errorate_ave = zeros(1,length(binslist));
            P_sum_list = zeros(1,length(binslist));
            N_sum_list = zeros(1,length(binslist));
            R_sum_list = zeros(1,length(binslist));
            L_sum_list = zeros(1,length(binslist));
            all_sum_list = zeros(1,length(binslist));
            P_sumratio_list = zeros(1,length(binslist));
            N_sumratio_list = zeros(1,length(binslist));
            R_sumratio_list = zeros(1,length(binslist));
            L_sumratio_list = zeros(1,length(binslist));
            all_sumratio_list = zeros(1,length(binslist));             
            for i = 1:length(binslist)
                [PNRL_timebin, ...
                 P_PC1_ch1, locs1_P, P_PC1_ch2, locs2_P, ER_P, ER_P_int, P_PC1_ch1_3dBwidth, P_PC1_ch1_20dBwidth, Pstablemax, Pstablemin, ...
                 N_PC1_ch1, locs1_N, N_PC1_ch2, locs2_N, ER_N, ER_N_int, N_PC1_ch2_3dBwidth, N_PC1_ch2_20dBwidth, Nstablemax, Nstablemin, ...
                 R_PC2_ch3, locs1_R, R_PC2_ch4, locs2_R, ER_R, ER_R_int, R_PC2_ch3_3dBwidth, R_PC2_ch3_20dBwidth, Rstablemax, Rstablemin, ...
                 L_PC2_ch3, locs1_L, L_PC2_ch4, locs2_L, ER_L, ER_L_int, L_PC2_ch4_3dBwidth, L_PC2_ch4_20dBwidth, Lstablemax, Lstablemin, ...
                 peakerrorrate, threerrorrate, ...
                 P_sum, N_sum, R_sum, L_sum, all_sum ...
                ] = PNRL_analysis(app,binslist(i));
                errorate_P(i) = 1/10^(ER_P_int/10)*100;
                errorate_N(i) = 1/10^(ER_N_int/10)*100;
                errorate_L(i) = 1/10^(ER_L_int/10)*100;
                errorate_R(i) = 1/10^(ER_R_int/10)*100;
                errorate_ave(i) = (errorate_P(i)+errorate_N(i)+errorate_L(i)+errorate_R(i))/4;
                P_sum_list(i) = P_sum;
                N_sum_list(i) = N_sum;
                R_sum_list(i) = R_sum;
                L_sum_list(i) = L_sum;
                all_sum_list(i) = all_sum;
            end
            for i = 1:length(binslist)
                P_sumratio_list(i) = P_sum_list(i)/P_sum_list(length(binslist))*100;
                N_sumratio_list(i) = N_sum_list(i)/N_sum_list(length(binslist))*100;
                R_sumratio_list(i) = R_sum_list(i)/R_sum_list(length(binslist))*100;
                L_sumratio_list(i) = L_sum_list(i)/L_sum_list(length(binslist))*100;
                all_sumratio_list(i) = all_sum_list(i)/all_sum_list(length(binslist))*100;
            end
        end
        
        function PNLR_binwidth_analysis_plot(app,width,errorate_P,errorate_N,errorate_L,errorate_R,errorate_ave, ...
                P_sumratio_list,N_sumratio_list,R_sumratio_list,L_sumratio_list,all_sumratio_list)
            delete(allchild(app.UIAxes5));  
            yyaxis(app.UIAxes5,"left");
            % 左侧Y轴（Extinction Ratio）
            p1 = plot(app.UIAxes5,width,errorate_P,'c-*');
            hold(app.UIAxes5,'on');
            p2 = plot(app.UIAxes5,width,errorate_N,'g-*');
            hold(app.UIAxes5,'on');
            p3 = plot(app.UIAxes5,width,errorate_R,'m-*');
            hold(app.UIAxes5,'on');
            p4 = plot(app.UIAxes5,width,errorate_L,'k-*');
            hold(app.UIAxes5,'on');
            p5 = plot(app.UIAxes5,width,errorate_ave,'-*');
            hold(app.UIAxes5,'on');
            ylabel(app.UIAxes5,'Errorate (%)','fontsize',18,'FontName','Arial');
            
            yyaxis(app.UIAxes5,"right");
            % 右侧Y轴（Photon Number Ratio）
            p6 = plot(app.UIAxes5,width,P_sumratio_list,'c-*');
            hold(app.UIAxes5,'on');
            p7 = plot(app.UIAxes5,width,N_sumratio_list,'g-*');
            hold(app.UIAxes5,'on');
            p8 = plot(app.UIAxes5,width,R_sumratio_list,'m-*');
            hold(app.UIAxes5,'on');
            p9 = plot(app.UIAxes5,width,L_sumratio_list,'k-*');
            hold(app.UIAxes5,'on');
            p10 = plot(app.UIAxes5,width,all_sumratio_list,'-*');
            ylabel(app.UIAxes5,'Photon Number Ratio (%)', 'fontsize', 18, 'FontName', 'Arial');
            
            p1.LineWidth = 1;
            p2.LineWidth = 1;
            p3.LineWidth = 1;
            p4.LineWidth = 1;
            p5.LineWidth = 1;
            p6.LineWidth = 1;
            p7.LineWidth = 1;
            p8.LineWidth = 1;
            p9.LineWidth = 1;
            p10.LineWidth = 1;
            
            % 添加图例
            lgd = legend(app.UIAxes5,'P ER', 'N ER', 'R ER', 'L ER', 'ave ER','P Sum Ratio', 'N Sum Ratio', 'R Sum Ratio', 'L Sum Ratio', 'all Sum Ratio','Location', 'southeast');
            lgd.Box = 'off';
            
            % 显示网格
            grid(app.UIAxes5,'on');
            
            % 设置坐标轴标签和标题
            xlabel(app.UIAxes5,'Bin Width (ps)', 'fontsize', 18, 'FontName', 'Arial');
            title(app.UIAxes5,'PNRL Bin Width Analysis', 'fontsize', 21, 'FontName', 'Arial'); 
        end
        
        
        function LDImportfile(app)
            app.fileLD = importdata(app.LD_filename);  % 导入TimeTagger数据
        end
        
        function SDVImportfile(app)
            app.fileSDV = importdata(app.SDV_filename);  % 导入TimeTagger数据
        end
        
        function PNRLImportfile(app)
            app.filech1 = importdata(app.ch1_filename);  % 导入TimeTagger数据
            app.filech2 = importdata(app.ch2_filename);
            app.filech3 = importdata(app.ch3_filename);
            app.filech4 = importdata(app.ch4_filename);   
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.allChannels = 1:1:app.CHNUM;
            % create a Time Tagger object
            app.timetagger = TimeTagger;
            app.timetagger.reset();

%             % build the gui from the template
%             createUiElementsFromTemplate(app);

            app.timetagger.setInputDelay(1, 0);
            app.timetagger.setInputDelay(2, 0);
            app.timetagger.setInputDelay(3, 0);
            app.timetagger.setInputDelay(4, 0);
            app.ScopeCH1delay.Value = 0;
            app.ScopeCH2delay.Value = 0;
            app.ScopeCH3delay.Value = 0;
            app.ScopeCH4delay.Value = 0;
            for i = 1: app.CHNUM
                if i == app.CHNUM
                    app.timetagger.setTriggerLevel(i, 0.5);
                else
                    app.timetagger.setTriggerLevel(i, 0.07);
                end  
            end
            
            voltage1 = app.timetagger.getTriggerLevel(1);
            app.efTriggerLevel1.Value = voltage1;
            voltage2 = app.timetagger.getTriggerLevel(2);
            app.efTriggerLevel2.Value = voltage2;
            voltage3 = app.timetagger.getTriggerLevel(3);
            app.efTriggerLevel3.Value = voltage3;
            voltage4 = app.timetagger.getTriggerLevel(4);
            app.efTriggerLevel4.Value = voltage4;
            voltage5 = app.timetagger.getTriggerLevel(5);
            app.efTriggerLevel5.Value = voltage5;
            

            % create the countrate measurement
            app.countrate = TTCountrate(app.timetagger, app.allChannels);
            
            % create number formatter object
            pattern = '#,##0.#';
            symbols = java.text.DecimalFormatSymbols();
            symbols.setGroupingSeparator(' ')
            symbols.setDecimalSeparator('.')
            app.df = java.text.DecimalFormat(pattern, symbols);  
            % create and start the poll timer which updates the countrate in the gui
            app.updateCountrateTimer = timer('StartDelay', 1, 'Period', 1, 'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate');
            app.updateCountrateTimer.TimerFcn = @(obj, event)updateCountrateTimerCallback(app);
            start(app.updateCountrateTimer)                   
        end

        % Value changed function: SDV_Fre
        function SDV_FreValueChanged(app, event)
            freq = app.SDV_Fre.Value;
            app.SDV_T = 1000/freq; %ps
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.thre_term = app.DropDown.Value;
        end

        % Button pushed function: SDVButton
        function SDVButtonPushed(app, event)
            SDVImportfile(app);
            if strcmp(app.thre_term,'手动输入') == 1
                bins = app.SDV_Binsnum.Value;
            elseif strcmp(app.thre_term,'自动最优') == 1
                SDV_timebin = app.fileSDV(1,2)-app.fileSDV(1,1); %ps
                [binslist,width,errorate,signal_sumratio_list,decoy_sumratio_list,vaccum_sumratio_list,all_sumratio_list]= SDV_binwidth_analysis(app,SDV_timebin);
                SDV_binwidth_analysis_plot(app, width, errorate, signal_sumratio_list, decoy_sumratio_list, vaccum_sumratio_list, all_sumratio_list);
                [~,bin_pos] = min(errorate);
                bins =  binslist(bin_pos);
                app.SDV_Binsnum.Value = bins;
            end
            [SDV_timebin, ...
             signal, signal_loc, Cov_S, signal_3dBwidth, signal_20dBwidth, ...
             decoy, decoy_loc, Cov_D, decoy_3dBwidth, decoy_20dBwidth, ER_SD, ER_SD_int, ...
             vaccum, vaccum_loc, Cov_V, vaccum_3dBwidth, vaccum_20dBwidth, ER_SV, ER_SV_int, ...
             signal_sum, decoy_sum, vaccum_sum, all_sum ...
             ] = SDV_analysis(app,bins);
            
            %% 宽度
            app.S3dB.Value = signal_3dBwidth;
            app.S20dB.Value = signal_20dBwidth;
            app.D3dB.Value = decoy_3dBwidth;
            app.D20dB.Value = decoy_20dBwidth;
            app.V3dB.Value = vaccum_3dBwidth;
            app.V20dB.Value = vaccum_20dBwidth;   
            
            %% 变异系数
            app.Cov_S.Value = sqrt(var(signal))/mean(signal);
            app.Cov_D.Value = sqrt(var(decoy))/mean(decoy);
            app.Cov_V.Value = sqrt(var(vaccum))/mean(vaccum);
            
            SDV_plot(app,signal, signal_loc, ...
                  decoy, decoy_loc, ER_SD, ER_SD_int, ...
                  vaccum, vaccum_loc, ER_SV, ER_SV_int ...
                   );
        end

        % Value changed function: PNRL_dataname
        function PNRL_datanameValueChanged2(app, event)
            value = app.PNRL_dataname.Value;
            app.ch1_filename = ['ch1-' value '.csv'];
            app.ch2_filename = ['ch2-' value '.csv'];
            app.ch3_filename = ['ch3-' value '.csv'];
            app.ch4_filename = ['ch4-' value '.csv'];            
        end

        % Value changed function: PNRL_Fre
        function PNRL_FreValueChanged(app, event)
            freq = app.PNRL_Fre.Value;
            app.PNRL_T = 1000/freq; %ps            
        end

        % Button pushed function: PNRLButton
        function PNRLButtonPushed(app, event)
            PNRLImportfile(app);
            if strcmp(app.four_term,'手动输入') == 1
                bins = app.PNRL_Binsnum.Value;
            elseif strcmp(app.four_term,'自动最优') == 1
                PNRL_timebin = app.filech1(1,2)-app.filech1(1,1); %ps   
                [binslist,width,errorate_P,errorate_N,errorate_L,errorate_R,errorate_ave, ...
                P_sumratio_list,N_sumratio_list,R_sumratio_list,L_sumratio_list,all_sumratio_list]=PNLR_binwidth_analysis(app,PNRL_timebin);
                PNLR_binwidth_analysis_plot(app,width,errorate_P,errorate_N,errorate_L,errorate_R,errorate_ave, ...
                P_sumratio_list,N_sumratio_list,R_sumratio_list,L_sumratio_list,all_sumratio_list);
                [~,bin_pos] = min(errorate_ave);
                bins =  binslist(bin_pos);
                app.PNRL_Binsnum.Value = bins;
            end
            [PNRL_timebin, ...
             P_PC1_ch1, locs1_P, P_PC1_ch2, locs2_P, ER_P, ER_P_int, P_PC1_ch1_3dBwidth, P_PC1_ch1_20dBwidth, Pstablemax, Pstablemin, ...
             N_PC1_ch1, locs1_N, N_PC1_ch2, locs2_N, ER_N, ER_N_int, N_PC1_ch2_3dBwidth, N_PC1_ch2_20dBwidth, Nstablemax, Nstablemin, ...
             R_PC2_ch3, locs1_R, R_PC2_ch4, locs2_R, ER_R, ER_R_int, R_PC2_ch3_3dBwidth, R_PC2_ch3_20dBwidth, Rstablemax, Rstablemin, ...
             L_PC2_ch3, locs1_L, L_PC2_ch4, locs2_L, ER_L, ER_L_int, L_PC2_ch4_3dBwidth, L_PC2_ch4_20dBwidth, Lstablemax, Lstablemin, ...
             peakerrorrate, threerrorrate, ...
             P_sum, N_sum, R_sum, L_sum, all_sum ...
            ] = PNRL_analysis(app,bins); 
            
            app.P3dB.Value = P_PC1_ch1_3dBwidth;
            app.P20dB.Value = P_PC1_ch1_20dBwidth;
            app.Pstablemax.Value = Pstablemax;
            app.Pstablemin.Value = Pstablemin;
            
            app.N3dB.Value = N_PC1_ch2_3dBwidth;
            app.N20dB.Value = N_PC1_ch2_20dBwidth;
            app.Nstablemax.Value = Nstablemax;
            app.Nstablemin.Value = Nstablemin;
            
            app.R3dB.Value = R_PC2_ch3_3dBwidth;
            app.R20dB.Value = R_PC2_ch3_20dBwidth;
            app.Rstablemax.Value = Rstablemax;
            app.Rstablemin.Value = Rstablemin;
            
            app.L3dB.Value = L_PC2_ch4_3dBwidth;
            app.L20dB.Value = L_PC2_ch4_20dBwidth;
            app.Lstablemax.Value = Lstablemax;
            app.Lstablemin.Value = Lstablemin; 
            
            app.peakerrorrate.Value = peakerrorrate;
            app.threerrorrate.Value = threerrorrate;
            
            PNLR_plot(app, ...
                       P_PC1_ch1, locs1_P, P_PC1_ch2, locs2_P, ER_P, ER_P_int, ...
                       N_PC1_ch1, locs1_N, N_PC1_ch2, locs2_N, ER_N, ER_N_int, ...
                       L_PC2_ch3, locs1_L, L_PC2_ch4, locs2_L, ER_L, ER_L_int, ...
                       R_PC2_ch3, locs1_R, R_PC2_ch4, locs2_R, ER_R, ER_R_int ...
                       );
        end

        % Value changed function: Ppeakmax
        function PpeakmaxValueChanged(app, event)
            app.Ppeakmax.Value = event.Value;
        end

        % Value changed function: Ppeakmin
        function PpeakminValueChanged(app, event)
            app.Ppeakmin.Value = event.Value;            
        end

        % Value changed function: Npeakmax
        function NpeakmaxValueChanged(app, event)
            app.npeakmax.Value = event.Value;           
        end

        % Value changed function: Npeakmin
        function NpeakminValueChanged(app, event)
            app.npeakmin.Value = event.Value;            
        end

        % Value changed function: Rpeakmax
        function RpeakmaxValueChanged(app, event)
            app.lpeakmax.Value = event.Value;
        end

        % Value changed function: Rpeakmin
        function RpeakminValueChanged(app, event)
            app.lpeakmin.Value = event.Value;
        end

        % Value changed function: Lpeakmax
        function LpeakmaxValueChanged(app, event)
            app.rpeakmax.Value = event.Value;
        end

        % Value changed function: Lpeakmin
        function LpeakminValueChanged(app, event)
            app.rpeakmin.Value = event.Value;
        end

        % Value changed function: PNRL_Binsnum
        function PNRL_BinsnumValueChanged(app, event)
            app.PNRL_Binsnum.Value = event.Value;           
        end

        % Value changed function: ch2delay
        function ch2delayValueChanged(app, event)
            app.ch2delay.Value = event.Value;      
        end

        % Value changed function: ch4delay
        function ch4delayValueChanged(app, event)
            app.ch4delay.Value = event.Value;
            
        end

        % Callback function
        function PDL_LOSSValueChanged(app, event)
            app.PDL_LOSS.Value = event.Value;
        end

        % Callback function
        function PDL_KValueChanged(app, event)
            app.PDL_K.Value = event.Value;
        end

        % Value changed function: LD_Fre
        function LD_FreValueChanged(app, event)
            freq = app.LD_Fre.Value;
            app.LD_T = 1000/freq; %ps                
        end

        % Button pushed function: LDButton
        function LDButtonPushed(app, event)
            LDImportfile(app);
            [signal,signal_loc,Fremeasured,Cov_Fre,signal_3dBwidth,signal_20dBwidth] = LD_analysis(app);
            app.LD3dB.Value = signal_3dBwidth;
            app.LD20dB.Value = signal_20dBwidth;
            app.LD_Fremeasured.Value = Fremeasured;
            app.LD_Cov_Fre.Value = Cov_Fre;
            LD_plot(app,signal,signal_loc);
        end

        % Value changed function: LD_dataneme
        function LD_datanemeValueChanged(app, event)
            value = app.LD_dataneme.Value;
            app.LD_filename = ['LD-' value '.csv'];            
        end

        % Value changed function: PNRL_Slider
        function PNRL_SliderValueChanged(app, event)
            value = app.PNRL_Slider.Value;
        end

        % Callback function
        function Button_7Pushed(app, event)
            cla (app.UIAxes5_3,'reset')
            PNLR_binwidth_photonnum_plot(app);
        end

        % Value changed function: SDV_Slider
        function SDV_SliderValueChanged(app, event)
            value = app.SDV_Slider.Value;
        end

        % Value changed function: SDV_dataname
        function SDV_datanameValueChanged(app, event)
            value = app.SDV_dataname.Value;
            app.SDV_filename = ['SDV-' value '.csv'];
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            app.four_term = app.DropDown_2.Value;
        end

        % Value changed function: LD_Slider
        function LD_SliderValueChanged(app, event)
            value = app.LD_Slider.Value;
        end

        % Value changing function: PNspTimeMin
        function PNspTimeMinValueChanging(app, event)
            app.PNxmin = event.Value*1e3;
            xlim(app.UIAxes_ScopeP,[app.PNxmin app.PNxmax]);
            xlim(app.UIAxes_ScopeN,[app.PNxmin app.PNxmax]);
        end

        % Value changing function: PNspTimeMax
        function PNspTimeMaxValueChanging(app, event)
            app.PNxmax = event.Value*1e3;
            xlim(app.UIAxes_ScopeP,[app.PNxmin app.PNxmax]);
            xlim(app.UIAxes_ScopeN,[app.PNxmin app.PNxmax]);
        end

        % Value changing function: PNspCountMin
        function PNspCountMinValueChanging(app, event)
            app.PNymin = event.Value;
            ylim(app.UIAxes_ScopeP,[app.PNymin app.PNymax]);     
            ylim(app.UIAxes_ScopeN,[app.PNymin app.PNymax]); 
        end

        % Value changing function: PNspCountMax
        function PNspCountMaxValueChanging(app, event)
            app.PNymax = event.Value;
            ylim(app.UIAxes_ScopeP,[app.PNymin app.PNymax]);     
            ylim(app.UIAxes_ScopeN,[app.PNymin app.PNymax]);          
        end

        % Value changing function: RLspTimeMin
        function RLspTimeMinValueChanging(app, event)
            app.RLxmin = event.Value*1e3;
            xlim(app.UIAxes_ScopeR,[app.RLxmin app.RLxmax]);
            xlim(app.UIAxes_ScopeL,[app.RLxmin app.RLxmax]);  
        end

        % Value changing function: RLspTimeMax
        function RLspTimeMaxValueChanging(app, event)
            app.RLxmax = event.Value*1e3;
            xlim(app.UIAxes_ScopeR,[app.RLxmin app.RLxmax]);
            xlim(app.UIAxes_ScopeL,[app.RLxmin app.RLxmax]); 
        end

        % Value changing function: RLspCountMin
        function RLspCountMinValueChanging(app, event)
            app.RLymin = event.Value;
            ylim(app.UIAxes_ScopeR,[app.RLymin app.RLymax]);     
            ylim(app.UIAxes_ScopeL,[app.RLymin app.RLymax]); 
        end

        % Value changing function: RLspCountMax
        function RLspCountMaxValueChanging(app, event)
            app.RLymax = event.Value;
            ylim(app.UIAxes_ScopeR,[app.RLymin app.RLymax]);     
            ylim(app.UIAxes_ScopeL,[app.RLymin app.RLymax]); 
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.bstop = 0;
            app.PNxmin = app.PNspTimeMin.Value*1e3;
            app.PNxmax = app.PNspTimeMax.Value*1e3;
            app.PNymin = app.PNspCountMin.Value;
            app.PNymax = app.PNspCountMax.Value;
            app.RLxmin = app.RLspTimeMin.Value*1e3;
            app.RLxmax = app.RLspTimeMax.Value*1e3;
            app.RLymin = app.RLspCountMin.Value;
            app.RLymax = app.RLspCountMax.Value; 
            app.histP = TTHistogram(app.timetagger, 1, 5, app.binwidthEditField.Value, app.binNumEditField.Value);
            app.histN = TTHistogram(app.timetagger, 2, 5, app.binwidthEditField.Value, app.binNumEditField.Value);
            app.histR = TTHistogram(app.timetagger, 3, 5, app.binwidthEditField.Value, app.binNumEditField.Value);
            app.histL = TTHistogram(app.timetagger, 4, 5, app.binwidthEditField.Value, app.binNumEditField.Value);          
            while (~app.bstop)
                app.histP.startFor(app.AcquisitiontimeEditField.Value*1e12, true);
                app.histN.startFor(app.AcquisitiontimeEditField.Value*1e12, true);
                app.histR.startFor(app.AcquisitiontimeEditField.Value*1e12, true);
                app.histL.startFor(app.AcquisitiontimeEditField.Value*1e12, true);
                while (app.histP.isRunning)
                    pause(1)
                    app.AcquisitiontimeIndicator.Text = sprintf('Current aquisition time: %d s', fix(double(app.histP.getCaptureDuration)/1e12));
                end
                app.histdataP = app.histP.getData();
                app.histdataN = app.histN.getData();
                app.histdataR = app.histR.getData();
                app.histdataL = app.histL.getData();
                updateplotP(app);
                updateplotN(app);
                updateplotR(app);
                updateplotL(app);
                getDecoy(app);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.bstop = 1;            
        end

        % Value changed function: efTriggerLevel1
        function efTriggerLevel1ValueChanged(app, event)
            triggerLevel = event.Value;
            app.timetagger.setTriggerLevel(1, triggerLevel);
        end

        % Value changed function: efTriggerLevel2
        function efTriggerLevel2ValueChanged(app, event)
            triggerLevel = event.Value;
            app.timetagger.setTriggerLevel(2, triggerLevel);
        end

        % Value changed function: efTriggerLevel3
        function efTriggerLevel3ValueChanged(app, event)
            triggerLevel = event.Value;
            app.timetagger.setTriggerLevel(3, triggerLevel);
        end

        % Value changed function: efTriggerLevel4
        function efTriggerLevel4ValueChanged(app, event)
            triggerLevel = event.Value;
            app.timetagger.setTriggerLevel(4, triggerLevel);
        end

        % Value changed function: efTriggerLevel5
        function efTriggerLevel5ValueChanged(app, event)
            triggerLevel = event.Value;
            app.timetagger.setTriggerLevel(5, triggerLevel);
        end

        % Value changed function: efDeadtime1
        function efDeadtime1ValueChanged(app, event)
            value = event.Source.Value*1000;    %ps
            app.timetagger.setDeadtime(1,value);  
        end

        % Value changed function: efDeadtime2
        function efDeadtime2ValueChanged(app, event)
            value = event.Source.Value*1000;    %ps
            app.timetagger.setDeadtime(2,value); 
        end

        % Value changed function: efDeadtime3
        function efDeadtime3ValueChanged(app, event)
            value = event.Source.Value*1000;    %ps
            app.timetagger.setDeadtime(3,value);
        end

        % Value changed function: efDeadtime4
        function efDeadtime4ValueChanged(app, event)
            value = event.Source.Value*1000;    %ps
            app.timetagger.setDeadtime(4,value);
        end

        % Value changed function: binwidthEditField
        function binwidthEditFieldValueChanged(app, event)
            updateHistogramSettings(app);
            
        end

        % Value changed function: binNumEditField
        function binNumEditFieldValueChanged(app, event)
            updateHistogramSettings(app);
            
        end

        % Value changed function: ScopeCH1delay
        function ScopeCH1delayValueChanged(app, event)
            value = event.Source.Value;
            app.timetagger.setInputDelay(1, value);    
        end

        % Value changed function: ScopeCH2delay
        function ScopeCH2delayValueChanged(app, event)
            value = event.Source.Value;
            app.timetagger.setInputDelay(2, value);    
        end

        % Value changed function: ScopeCH3delay
        function ScopeCH3delayValueChanged(app, event)
            value = event.Source.Value;
            app.timetagger.setInputDelay(3, value);    
        end

        % Value changed function: ScopeCH4delay
        function ScopeCH4delayValueChanged(app, event)
            value = event.Source.Value;
            app.timetagger.setInputDelay(4, value);    
        end

        % Button pushed function: PNSaveButton
        function PNSaveButtonPushed(app, event)
            MP = [app.histP.getIndex(); app.histdataP];
            MN = [app.histN.getIndex(); app.histdataN];
            MR = [app.histR.getIndex(); app.histdataR];
            ML = [app.histL.getIndex(); app.histdataL];
            
            % 使用 uiputfile 让用户选择保存文件的位置，并输入基础文件名
            [file, path] = uiputfile('*.csv', '选择保存文件的位置');
            
            if file ~= 0 % 用户没有取消操作
                % 获取基础文件名（去掉后缀）
                baseFileName = file(1:end-4); % 去掉 .csv 后缀
                
                % 使用 csvwrite 保存每个矩阵为不同的 CSV 文件
                csvwrite(fullfile(path, ['CH1-', baseFileName, '.csv']), MP);
                csvwrite(fullfile(path, ['CH2-', baseFileName, '.csv']), MN);
                csvwrite(fullfile(path, ['CH3-', baseFileName, '.csv']), MR);
                csvwrite(fullfile(path, ['CH4-', baseFileName, '.csv']), ML);
            end
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            MP = [app.histP.getIndex(); app.histdataP];
            MN = [app.histN.getIndex(); app.histdataN];
            MR = [app.histR.getIndex(); app.histdataR];
            ML = [app.histL.getIndex(); app.histdataL];
            
            % 使用 uiputfile 让用户选择保存文件的位置，并输入基础文件名
            [file, path] = uiputfile('*.csv', '选择保存文件的位置');
            
            if file ~= 0 % 用户没有取消操作
                % 获取基础文件名（去掉后缀）
                baseFileName = file(1:end-4); % 去掉 .csv 后缀
                
                % 使用 csvwrite 保存每个矩阵为不同的 CSV 文件
                csvwrite(fullfile(path, ['CH1-', baseFileName, '.csv']), MP);
                csvwrite(fullfile(path, ['CH2-', baseFileName, '.csv']), MN);
                csvwrite(fullfile(path, ['CH3-', baseFileName, '.csv']), MR);
                csvwrite(fullfile(path, ['CH4-', baseFileName, '.csv']), ML);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.updateCountrateTimer)
                stop(app.updateCountrateTimer)
            end
            % app.timetagger.free();
            clear app.timetagger
            delete(app)    
        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            app.bstop2 = 0;
            app.PNxmin = app.PNspTimeMin.Value*1e3;
            app.PNxmax = app.PNspTimeMax.Value*1e3;
            app.PNymin = app.PNspCountMin.Value;
            app.PNymax = app.PNspCountMax.Value;
            app.RLxmin = app.RLspTimeMin.Value*1e3;
            app.RLxmax = app.RLspTimeMax.Value*1e3;
            app.RLymin = app.RLspCountMin.Value;
            app.RLymax = app.RLspCountMax.Value;
            
            % 根据复选框状态决定创建哪些直方图对象
            if app.CH1CheckBox.Value == 1
                app.histP = TTHistogram(app.timetagger, 1, 5, app.binwidthEditField.Value, app.binNumEditField.Value);
            end
            if app.CH2CheckBox.Value == 1
                app.histN = TTHistogram(app.timetagger, 2, 5, app.binwidthEditField.Value, app.binNumEditField.Value);
            end
            if app.CH3CheckBox.Value == 1
                app.histR = TTHistogram(app.timetagger, 3, 5, app.binwidthEditField.Value, app.binNumEditField.Value);
            end
            if app.CH4CheckBox.Value == 1
                app.histL = TTHistogram(app.timetagger, 4, 5, app.binwidthEditField.Value, app.binNumEditField.Value);
            end            
            
            while (~app.bstop2)
                for i = 1:app.filenumEditField.Value
                    if app.CH1CheckBox.Value == 1
                        app.histP.startFor(app.AcquisitiontimeEditField.Value*1e12, true);
                    end
                    if app.CH2CheckBox.Value == 1
                        app.histN.startFor(app.AcquisitiontimeEditField.Value*1e12, true);
                    end
                    if app.CH3CheckBox.Value == 1
                        app.histR.startFor(app.AcquisitiontimeEditField.Value*1e12, true);
                    end
                    if app.CH4CheckBox.Value == 1
                        app.histL.startFor(app.AcquisitiontimeEditField.Value*1e12, true);
                    end
                    while (app.histP.isRunning)
                        pause(1)
                        app.AcquisitiontimeIndicator2.Text = sprintf('Current aquisition time: %d s', fix(double(app.histP.getCaptureDuration)/1e12));
                    end
                    
                    % 获取数据并保存
                    if app.CH1CheckBox.Value == 1
                        app.histdataP = app.histP.getData();
                        % 保存CH1数据到单独文件
                        M1 = [app.hist.getIndex(); app.histdataP];
                        filename1 = sprintf('SDV-1024random40s_CH1-%d.csv', i); % CH1第i个文件
                        csvwrite(filename1, M1);
                    end
                    
                    if app.CH2CheckBox.Value == 1
                        app.histdataN = app.histN.getData();
                        % 保存CH2数据到单独文件
                        M2 = [app.hist.getIndex(); app.histdataN];
                        filename2 = sprintf('SDV-1024random40s_CH2-%d.csv', i); % CH2第i个文件
                        csvwrite(filename2, M2);
                    end
                    
                    if app.CH3CheckBox.Value == 1
                        app.histdataR = app.histR.getData();
                        % 保存CH3数据到单独文件
                        M3 = [app.hist.getIndex(); app.histdataR];
                        filename3 = sprintf('SDV-1024random40s_CH3-%d.csv', i); % CH3第i个文件
                        csvwrite(filename3, M3);
                    end
                    
                    if app.CH4CheckBox.Value == 1
                        app.histdataL = app.histL.getData();
                        % 保存CH4数据到单独文件
                        M4 = [app.hist.getIndex(); app.histdataL];
                        filename4 = sprintf('SDV-1024random40s_CH4-%d.csv', i); % CH4第i个文件
                        csvwrite(filename4, M4);
                    end
                    
                    if i == app.filenumEditField.Value
                        app.bstop2 = 1;
                    end
                end
            end        
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            app.bstop2 = 1;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ScopesettingTab = uitab(app.TabGroup);
            app.ScopesettingTab.Title = 'Scope setting';
            app.TimeTaggersettingPanel = uipanel(app.ScopesettingTab);
            app.TimeTaggersettingPanel.Title = 'Time Tagger setting';
            app.capTriggerLevel = uilabel(app.TimeTaggersettingPanel);
            app.capTriggerLevel.Text = 'Trigger Level';
            app.lVTemplate = uilabel(app.TimeTaggersettingPanel);
            app.lVTemplate.Text = 'V';
            app.efTriggerLevel1 = uieditfield(app.TimeTaggersettingPanel, 'numeric');
            app.efTriggerLevel1.Limits = [-2.5 2.5];
            app.efTriggerLevel1.ValueDisplayFormat = '%5.2g';
            app.efTriggerLevel1.ValueChangedFcn = createCallbackFcn(app, @efTriggerLevel1ValueChanged, true);
            app.efTriggerLevel1.Value = 0.5;
            app.lSync = uilabel(app.TimeTaggersettingPanel);
            app.lSync.Text = 'CH1';
            app.efDeadtime1 = uieditfield(app.TimeTaggersettingPanel, 'numeric');
            app.efDeadtime1.ValueChangedFcn = createCallbackFcn(app, @efDeadtime1ValueChanged, true);
            app.efDeadtime1.Value = 50;
            app.capDeadtime = uilabel(app.TimeTaggersettingPanel);
            app.capDeadtime.Text = 'Deadtime';
            app.lnsTemplate = uilabel(app.TimeTaggersettingPanel);
            app.lnsTemplate.Text = 'ns';
            app.capTriggerLevel_2 = uilabel(app.TimeTaggersettingPanel);
            app.capTriggerLevel_2.Text = 'Trigger Level';
            app.lVTemplate_2 = uilabel(app.TimeTaggersettingPanel);
            app.lVTemplate_2.Text = 'V';
            app.efTriggerLevel2 = uieditfield(app.TimeTaggersettingPanel, 'numeric');
            app.efTriggerLevel2.Limits = [-2.5 2.5];
            app.efTriggerLevel2.ValueDisplayFormat = '%5.2g';
            app.efTriggerLevel2.ValueChangedFcn = createCallbackFcn(app, @efTriggerLevel2ValueChanged, true);
            app.efTriggerLevel2.Value = 0.5;
            app.lSync_2 = uilabel(app.TimeTaggersettingPanel);
            app.lSync_2.Text = 'CH2';
            app.efDeadtime2 = uieditfield(app.TimeTaggersettingPanel, 'numeric');
            app.efDeadtime2.ValueChangedFcn = createCallbackFcn(app, @efDeadtime2ValueChanged, true);
            app.efDeadtime2.Value = 50;
            app.capDeadtime_2 = uilabel(app.TimeTaggersettingPanel);
            app.capDeadtime_2.Text = 'Deadtime';
            app.lnsTemplate_2 = uilabel(app.TimeTaggersettingPanel);
            app.lnsTemplate_2.Text = 'ns';
            app.capTriggerLevel_3 = uilabel(app.TimeTaggersettingPanel);
            app.capTriggerLevel_3.Text = 'Trigger Level';
            app.lVTemplate_3 = uilabel(app.TimeTaggersettingPanel);
            app.lVTemplate_3.Text = 'V';
            app.efTriggerLevel3 = uieditfield(app.TimeTaggersettingPanel, 'numeric');
            app.efTriggerLevel3.Limits = [-2.5 2.5];
            app.efTriggerLevel3.ValueDisplayFormat = '%5.2g';
            app.efTriggerLevel3.ValueChangedFcn = createCallbackFcn(app, @efTriggerLevel3ValueChanged, true);
            app.efTriggerLevel3.Value = 0.5;
            app.lSync_3 = uilabel(app.TimeTaggersettingPanel);
            app.lSync_3.Text = 'CH3';
            app.efDeadtime3 = uieditfield(app.TimeTaggersettingPanel, 'numeric');
            app.efDeadtime3.ValueChangedFcn = createCallbackFcn(app, @efDeadtime3ValueChanged, true);
            app.efDeadtime3.Value = 50;
            app.capDeadtime_3 = uilabel(app.TimeTaggersettingPanel);
            app.capDeadtime_3.Text = 'Deadtime';
            app.lnsTemplate_3 = uilabel(app.TimeTaggersettingPanel);
            app.lnsTemplate_3.Text = 'ns';
            app.capTriggerLevel_4 = uilabel(app.TimeTaggersettingPanel);
            app.capTriggerLevel_4.Text = 'Trigger Level';
            app.lVTemplate_4 = uilabel(app.TimeTaggersettingPanel);
            app.lVTemplate_4.Text = 'V';
            app.efTriggerLevel4 = uieditfield(app.TimeTaggersettingPanel, 'numeric');
            app.efTriggerLevel4.Limits = [-2.5 2.5];
            app.efTriggerLevel4.ValueDisplayFormat = '%5.2g';
            app.efTriggerLevel4.ValueChangedFcn = createCallbackFcn(app, @efTriggerLevel4ValueChanged, true);
            app.efTriggerLevel4.Value = 0.5;
            app.lSync_4 = uilabel(app.TimeTaggersettingPanel);
            app.lSync_4.Text = 'CH4';
            app.efDeadtime4 = uieditfield(app.TimeTaggersettingPanel, 'numeric');
            app.efDeadtime4.ValueChangedFcn = createCallbackFcn(app, @efDeadtime4ValueChanged, true);
            app.efDeadtime4.Value = 50;
            app.capDeadtime_4 = uilabel(app.TimeTaggersettingPanel);
            app.capDeadtime_4.Text = 'Deadtime';
            app.lnsTemplate_4 = uilabel(app.TimeTaggersettingPanel);
            app.lnsTemplate_4.Text = 'ns';
            app.capTriggerLevel_5 = uilabel(app.TimeTaggersettingPanel);
            app.capTriggerLevel_5.Text = 'Trigger Level';
            app.lVTemplate_5 = uilabel(app.TimeTaggersettingPanel);
            app.lVTemplate_5.Text = 'V';
            app.efTriggerLevel5 = uieditfield(app.TimeTaggersettingPanel, 'numeric');
            app.efTriggerLevel5.Limits = [-2.5 2.5];
            app.efTriggerLevel5.ValueDisplayFormat = '%5.2g';
            app.efTriggerLevel5.ValueChangedFcn = createCallbackFcn(app, @efTriggerLevel5ValueChanged, true);
            app.efTriggerLevel5.Value = 0.5;
            app.lSync_5 = uilabel(app.TimeTaggersettingPanel);
            app.lSync_5.Text = 'CH5';
            app.lSync_6 = uilabel(app.TimeTaggersettingPanel);
            app.lSync_6.Text = '(SYNC)';
            app.capCountratesync = uilabel(app.TimeTaggersettingPanel);
            app.capCountratesync.Text = 'Count rate';
            app.lCountratesync = uilabel(app.TimeTaggersettingPanel);
            app.lCountratesync.Text = '0 ';
            app.TimeTaggerstartPanel = uipanel(app.ScopesettingTab);
            app.TimeTaggerstartPanel.Title = 'Time Tagger start';
            app.Unit_ps = uilabel(app.TimeTaggerstartPanel);
            app.Unit_ps.Text = 'ps';
            app.Unit_s = uilabel(app.TimeTaggerstartPanel);
            app.Unit_s.Text = 's';
            app.StartButton = uibutton(app.TimeTaggerstartPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.TimeTaggerstartPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.binwidthEditFieldLabel = uilabel(app.TimeTaggerstartPanel);
            app.binwidthEditFieldLabel.Text = 'bin width';
            app.binwidthEditField = uieditfield(app.TimeTaggerstartPanel, 'numeric');
            app.binwidthEditField.ValueChangedFcn = createCallbackFcn(app, @binwidthEditFieldValueChanged, true);
            app.binwidthEditField.Value = 16;
            app.binEditFieldLabel = uilabel(app.TimeTaggerstartPanel);
            app.binEditFieldLabel.Text = '#bin';
            app.binNumEditField = uieditfield(app.TimeTaggerstartPanel, 'numeric');
            app.binNumEditField.ValueChangedFcn = createCallbackFcn(app, @binNumEditFieldValueChanged, true);
            app.binNumEditField.Value = 10000;
            app.AcquisitiontimeEditFieldLabel = uilabel(app.TimeTaggerstartPanel);
            app.AcquisitiontimeEditFieldLabel.Text = 'Acquisition time';
            app.AcquisitiontimeEditField = uieditfield(app.TimeTaggerstartPanel, 'numeric');
            app.AcquisitiontimeEditField.Value = 1;
            app.AcquisitiontimeIndicator = uilabel(app.TimeTaggerstartPanel);
            app.ContinuousRecordModePanel = uipanel(app.ScopesettingTab);
            app.ContinuousRecordModePanel.Title = 'Continuous Record Mode';
            app.Unit_s_2 = uilabel(app.ContinuousRecordModePanel);
            app.Unit_s_2.Text = 's';
            app.StartButton_2 = uibutton(app.ContinuousRecordModePanel, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.StopButton_2 = uibutton(app.ContinuousRecordModePanel, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.AcquisitiontimeEditField_2Label = uilabel(app.ContinuousRecordModePanel);
            app.AcquisitiontimeEditField_2Label.Text = 'Acquisition time';
            app.Acquisitiontime2EditField = uieditfield(app.ContinuousRecordModePanel, 'numeric');
            app.Acquisitiontime2EditField.Value = 1;
            app.fileLabel = uilabel(app.ContinuousRecordModePanel);
            app.fileLabel.Text = '#file';
            app.fileNumEditField = uieditfield(app.ContinuousRecordModePanel, 'numeric');
            app.fileNumEditField.Value = 20;
            app.AcquisitiontimeIndicator2 = uilabel(app.ContinuousRecordModePanel);
            app.CH1CheckBox = uicheckbox(app.ContinuousRecordModePanel);
            app.CH1CheckBox.Text = 'CH1';
            app.CH2CheckBox = uicheckbox(app.ContinuousRecordModePanel);
            app.CH2CheckBox.Text = 'CH2';
            app.CH3CheckBox = uicheckbox(app.ContinuousRecordModePanel);
            app.CH3CheckBox.Text = 'CH3';
            app.CH4CheckBox = uicheckbox(app.ContinuousRecordModePanel);
            app.CH4CheckBox.Text = 'CH4';
            app.ScopePNTab = uitab(app.TabGroup);
            app.ScopePNTab.Title = 'Scope PN';
            app.UIAxes_ScopeN = uiaxes(app.ScopePNTab);
            title(app.UIAxes_ScopeN, '')
            xlabel(app.UIAxes_ScopeN, 'Time/ps')
            ylabel(app.UIAxes_ScopeN, 'Count')
            app.UIAxes_ScopeP = uiaxes(app.ScopePNTab);
            title(app.UIAxes_ScopeP, '')
            xlabel(app.UIAxes_ScopeP, 'Time/ps')
            ylabel(app.UIAxes_ScopeP, 'Count')
            app.capCountrate1 = uilabel(app.ScopePNTab);
            app.capCountrate1.Text = 'Count rate';
            app.lCountrate1 = uilabel(app.ScopePNTab);
            app.lCountrate1.Text = '0 ';
            app.capCountrate2 = uilabel(app.ScopePNTab);
            app.capCountrate2.Text = 'Count rate';
            app.lCountrate2 = uilabel(app.ScopePNTab);
            app.lCountrate2.Text = '0 ';
            app.MaxSpinner_2Label_3 = uilabel(app.ScopePNTab);
            app.MaxSpinner_2Label_3.Text = 'Max.';
            app.PNspCountMax = uispinner(app.ScopePNTab);
            app.PNspCountMax.ValueChangingFcn = createCallbackFcn(app, @PNspCountMaxValueChanging, true);
            app.PNspCountMax.Limits = [0 Inf];
            app.PNspCountMax.Value = 100;
            app.MinSpinner_2Label_3 = uilabel(app.ScopePNTab);
            app.MinSpinner_2Label_3.Text = 'Min.';
            app.PNspCountMin = uispinner(app.ScopePNTab);
            app.PNspCountMin.ValueChangingFcn = createCallbackFcn(app, @PNspCountMinValueChanging, true);
            app.PNspCountMin.Limits = [0 Inf];
            app.CountLabel_6 = uilabel(app.ScopePNTab);
            app.CountLabel_6.Text = 'Count';
            app.Unit_ns2_3 = uilabel(app.ScopePNTab);
            app.Unit_ns2_3.Text = 'ns';
            app.MaxSpinnerLabel_3 = uilabel(app.ScopePNTab);
            app.MaxSpinnerLabel_3.Text = 'Max.';
            app.PNspTimeMax = uispinner(app.ScopePNTab);
            app.PNspTimeMax.ValueChangingFcn = createCallbackFcn(app, @PNspTimeMaxValueChanging, true);
            app.PNspTimeMax.Limits = [0 Inf];
            app.PNspTimeMax.Value = 100;
            app.Unit_ns_3 = uilabel(app.ScopePNTab);
            app.Unit_ns_3.Text = 'ns';
            app.MinSpinnerLabel_3 = uilabel(app.ScopePNTab);
            app.MinSpinnerLabel_3.Text = 'Min.';
            app.PNspTimeMin = uispinner(app.ScopePNTab);
            app.PNspTimeMin.ValueChangingFcn = createCallbackFcn(app, @PNspTimeMinValueChanging, true);
            app.PNspTimeMin.Limits = [0 Inf];
            app.TimeLabel_3 = uilabel(app.ScopePNTab);
            app.TimeLabel_3.Text = 'Time';
            app.Coordinate_3 = uibuttongroup(app.ScopePNTab);
            app.PNLinearButton = uiradiobutton(app.Coordinate_3);
            app.PNLinearButton.Text = 'Linear';
            app.PNLinearButton.Value = true;
            app.PNLogButton = uiradiobutton(app.Coordinate_3);
            app.PNLogButton.Text = 'Log';
            app.PNSaveButton = uibutton(app.ScopePNTab, 'push');
            app.PNSaveButton.ButtonPushedFcn = createCallbackFcn(app, @PNSaveButtonPushed, true);
            app.PNSaveButton.Text = 'Save';
            app.DelayPanel_3 = uipanel(app.ScopePNTab);
            app.DelayPanel_3.Title = 'Delay';
            app.ScopeCH1delay = uieditfield(app.DelayPanel_3, 'numeric');
            app.ScopeCH1delay.ValueChangedFcn = createCallbackFcn(app, @ScopeCH1delayValueChanged, true);
            app.capInputDelay_6 = uilabel(app.DelayPanel_3);
            app.capInputDelay_6.Text = 'CH1';
            app.psLabel_29 = uilabel(app.DelayPanel_3);
            app.psLabel_29.Text = 'ps';
            app.ScopeCH2delay = uieditfield(app.DelayPanel_3, 'numeric');
            app.ScopeCH2delay.ValueChangedFcn = createCallbackFcn(app, @ScopeCH2delayValueChanged, true);
            app.capInputDelay_7 = uilabel(app.DelayPanel_3);
            app.capInputDelay_7.Text = 'CH2';
            app.psLabel_30 = uilabel(app.DelayPanel_3);
            app.psLabel_30.Text = 'ps';
            app.ERCH2Panel = uipanel(app.ScopePNTab);
            app.ERCH2Panel.Title = 'ER CH2';
            app.NSLEditField = uieditfield(app.ERCH2Panel, 'numeric');
            app.SLabel_7 = uilabel(app.ERCH2Panel);
            app.SLabel_7.Text = 'S';
            app.DLabel_5 = uilabel(app.ERCH2Panel);
            app.DLabel_5.Text = 'D';
            app.LLabel_7 = uilabel(app.ERCH2Panel);
            app.LLabel_7.Text = 'L';
            app.RLabel_9 = uilabel(app.ERCH2Panel);
            app.RLabel_9.Text = 'R';
            app.NSREditField = uieditfield(app.ERCH2Panel, 'numeric');
            app.CountLabel_7 = uilabel(app.ERCH2Panel);
            app.CountLabel_7.Text = 'Count';
            app.dbLabel_4 = uilabel(app.ERCH2Panel);
            app.dbLabel_4.Text = 'db';
            app.NSCountIndicator = uilabel(app.ERCH2Panel);
            app.NSCountIndicator.Text = '0';
            app.NSdbIndicator = uilabel(app.ERCH2Panel);
            app.NSdbIndicator.Text = '0';
            app.NDLEditField = uieditfield(app.ERCH2Panel, 'numeric');
            app.NDREditField = uieditfield(app.ERCH2Panel, 'numeric');
            app.NDCountIndicator = uilabel(app.ERCH2Panel);
            app.NDCountIndicator.Text = '0';
            app.NDdbIndicator = uilabel(app.ERCH2Panel);
            app.NDdbIndicator.Text = '0';
            app.NVLEditField = uieditfield(app.ERCH2Panel, 'numeric');
            app.VLabel_7 = uilabel(app.ERCH2Panel);
            app.VLabel_7.Text = 'V';
            app.NVREditField = uieditfield(app.ERCH2Panel, 'numeric');
            app.NVCountIndicator = uilabel(app.ERCH2Panel);
            app.NVCountIndicator.Text = '0';
            app.NVdbIndicator = uilabel(app.ERCH2Panel);
            app.NVdbIndicator.Text = '0';
            app.ERCH1Panel = uipanel(app.ScopePNTab);
            app.ERCH1Panel.Title = 'ER CH1';
            app.PSLEditField = uieditfield(app.ERCH1Panel, 'numeric');
            app.SLabel_8 = uilabel(app.ERCH1Panel);
            app.SLabel_8.Text = 'S';
            app.DLabel_6 = uilabel(app.ERCH1Panel);
            app.DLabel_6.Text = 'D';
            app.LLabel_8 = uilabel(app.ERCH1Panel);
            app.LLabel_8.Text = 'L';
            app.RLabel_10 = uilabel(app.ERCH1Panel);
            app.RLabel_10.Text = 'R';
            app.PSREditField = uieditfield(app.ERCH1Panel, 'numeric');
            app.CountLabel_8 = uilabel(app.ERCH1Panel);
            app.CountLabel_8.Text = 'Count';
            app.dbLabel_5 = uilabel(app.ERCH1Panel);
            app.dbLabel_5.Text = 'db';
            app.PSCountIndicator = uilabel(app.ERCH1Panel);
            app.PSCountIndicator.Text = '0';
            app.PSdbIndicator = uilabel(app.ERCH1Panel);
            app.PSdbIndicator.Text = '0';
            app.PDLEditField = uieditfield(app.ERCH1Panel, 'numeric');
            app.PDREditField = uieditfield(app.ERCH1Panel, 'numeric');
            app.PDCountIndicator = uilabel(app.ERCH1Panel);
            app.PDCountIndicator.Text = '0';
            app.PDdbIndicator = uilabel(app.ERCH1Panel);
            app.PDdbIndicator.Text = '0';
            app.PVLEditField = uieditfield(app.ERCH1Panel, 'numeric');
            app.VLabel_8 = uilabel(app.ERCH1Panel);
            app.VLabel_8.Text = 'V';
            app.PVREditField = uieditfield(app.ERCH1Panel, 'numeric');
            app.PVCountIndicator = uilabel(app.ERCH1Panel);
            app.PVCountIndicator.Text = '0';
            app.PVdbIndicator = uilabel(app.ERCH1Panel);
            app.PVdbIndicator.Text = '0';
            app.ScopeRLTab = uitab(app.TabGroup);
            app.ScopeRLTab.Title = 'Scope RL';
            app.UIAxes_ScopeL = uiaxes(app.ScopeRLTab);
            title(app.UIAxes_ScopeL, '')
            xlabel(app.UIAxes_ScopeL, 'Time/ps')
            ylabel(app.UIAxes_ScopeL, 'Count')
            app.UIAxes_ScopeR = uiaxes(app.ScopeRLTab);
            title(app.UIAxes_ScopeR, '')
            xlabel(app.UIAxes_ScopeR, 'Time/ps')
            ylabel(app.UIAxes_ScopeR, 'Count')
            app.Unit_ns_2 = uilabel(app.ScopeRLTab);
            app.Unit_ns_2.Text = 'ns';
            app.Unit_ns2_2 = uilabel(app.ScopeRLTab);
            app.Unit_ns2_2.Text = 'ns';
            app.TimeLabel_2 = uilabel(app.ScopeRLTab);
            app.TimeLabel_2.Text = 'Time';
            app.CountLabel_3 = uilabel(app.ScopeRLTab);
            app.CountLabel_3.Text = 'Count';
            app.Coordinate_2 = uibuttongroup(app.ScopeRLTab);
            app.RLLinearButton = uiradiobutton(app.Coordinate_2);
            app.RLLinearButton.Text = 'Linear';
            app.RLLinearButton.Value = true;
            app.RLLogButton = uiradiobutton(app.Coordinate_2);
            app.RLLogButton.Text = 'Log';
            app.DelayPanel_2 = uipanel(app.ScopeRLTab);
            app.DelayPanel_2.Title = 'Delay';
            app.ScopeCH3delay = uieditfield(app.DelayPanel_2, 'numeric');
            app.ScopeCH3delay.ValueChangedFcn = createCallbackFcn(app, @ScopeCH3delayValueChanged, true);
            app.capInputDelay_4 = uilabel(app.DelayPanel_2);
            app.capInputDelay_4.Text = 'CH3';
            app.psLabel_27 = uilabel(app.DelayPanel_2);
            app.psLabel_27.Text = 'ps';
            app.ScopeCH4delay = uieditfield(app.DelayPanel_2, 'numeric');
            app.ScopeCH4delay.ValueChangedFcn = createCallbackFcn(app, @ScopeCH4delayValueChanged, true);
            app.capInputDelay_5 = uilabel(app.DelayPanel_2);
            app.capInputDelay_5.Text = 'CH4';
            app.psLabel_28 = uilabel(app.DelayPanel_2);
            app.psLabel_28.Text = 'ps';
            app.MinSpinnerLabel_2 = uilabel(app.ScopeRLTab);
            app.MinSpinnerLabel_2.Text = 'Min.';
            app.RLspTimeMin = uispinner(app.ScopeRLTab);
            app.RLspTimeMin.ValueChangingFcn = createCallbackFcn(app, @RLspTimeMinValueChanging, true);
            app.RLspTimeMin.Limits = [0 Inf];
            app.MaxSpinnerLabel_2 = uilabel(app.ScopeRLTab);
            app.MaxSpinnerLabel_2.Text = 'Max.';
            app.RLspTimeMax = uispinner(app.ScopeRLTab);
            app.RLspTimeMax.ValueChangingFcn = createCallbackFcn(app, @RLspTimeMaxValueChanging, true);
            app.RLspTimeMax.Limits = [0 Inf];
            app.RLspTimeMax.Value = 100;
            app.MinSpinner_2Label_2 = uilabel(app.ScopeRLTab);
            app.MinSpinner_2Label_2.Text = 'Min.';
            app.RLspCountMin = uispinner(app.ScopeRLTab);
            app.RLspCountMin.ValueChangingFcn = createCallbackFcn(app, @RLspCountMinValueChanging, true);
            app.RLspCountMin.Limits = [0 Inf];
            app.MaxSpinner_2Label_2 = uilabel(app.ScopeRLTab);
            app.MaxSpinner_2Label_2.Text = 'Max.';
            app.RLspCountMax = uispinner(app.ScopeRLTab);
            app.RLspCountMax.ValueChangingFcn = createCallbackFcn(app, @RLspCountMaxValueChanging, true);
            app.RLspCountMax.Limits = [0 Inf];
            app.RLspCountMax.Value = 100;
            app.SaveButton_2 = uibutton(app.ScopeRLTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.ERCH4Panel = uipanel(app.ScopeRLTab);
            app.ERCH4Panel.Title = 'ER CH4';
            app.LSLEditField = uieditfield(app.ERCH4Panel, 'numeric');
            app.SLabel_5 = uilabel(app.ERCH4Panel);
            app.SLabel_5.Text = 'S';
            app.DLabel_3 = uilabel(app.ERCH4Panel);
            app.DLabel_3.Text = 'D';
            app.LLabel_5 = uilabel(app.ERCH4Panel);
            app.LLabel_5.Text = 'L';
            app.RLabel_7 = uilabel(app.ERCH4Panel);
            app.RLabel_7.Text = 'R';
            app.LSREditField = uieditfield(app.ERCH4Panel, 'numeric');
            app.CountLabel_4 = uilabel(app.ERCH4Panel);
            app.CountLabel_4.Text = 'Count';
            app.dbLabel_2 = uilabel(app.ERCH4Panel);
            app.dbLabel_2.Text = 'db';
            app.LSCountIndicator = uilabel(app.ERCH4Panel);
            app.LSCountIndicator.Text = '0';
            app.LSdbIndicator = uilabel(app.ERCH4Panel);
            app.LSdbIndicator.Text = '0';
            app.LDLEditField = uieditfield(app.ERCH4Panel, 'numeric');
            app.LDREditField = uieditfield(app.ERCH4Panel, 'numeric');
            app.LDCountIndicator = uilabel(app.ERCH4Panel);
            app.LDCountIndicator.Text = '0';
            app.LDdbIndicator = uilabel(app.ERCH4Panel);
            app.LDdbIndicator.Text = '0';
            app.LVLEditField = uieditfield(app.ERCH4Panel, 'numeric');
            app.VLabel_5 = uilabel(app.ERCH4Panel);
            app.VLabel_5.Text = 'V';
            app.LVREditField = uieditfield(app.ERCH4Panel, 'numeric');
            app.LVCountIndicator = uilabel(app.ERCH4Panel);
            app.LVCountIndicator.Text = '0';
            app.LVdbIndicator = uilabel(app.ERCH4Panel);
            app.LVdbIndicator.Text = '0';
            app.ERCH3Panel = uipanel(app.ScopeRLTab);
            app.ERCH3Panel.Title = 'ER CH3';
            app.RSLEditField = uieditfield(app.ERCH3Panel, 'numeric');
            app.SLabel_6 = uilabel(app.ERCH3Panel);
            app.SLabel_6.Text = 'S';
            app.DLabel_4 = uilabel(app.ERCH3Panel);
            app.DLabel_4.Text = 'D';
            app.LLabel_6 = uilabel(app.ERCH3Panel);
            app.LLabel_6.Text = 'L';
            app.RLabel_8 = uilabel(app.ERCH3Panel);
            app.RLabel_8.Text = 'R';
            app.RSREditField = uieditfield(app.ERCH3Panel, 'numeric');
            app.CountLabel_5 = uilabel(app.ERCH3Panel);
            app.CountLabel_5.Text = 'Count';
            app.dbLabel_3 = uilabel(app.ERCH3Panel);
            app.dbLabel_3.Text = 'db';
            app.RSCountIndicator = uilabel(app.ERCH3Panel);
            app.RSCountIndicator.Text = '0';
            app.RSdbIndicator = uilabel(app.ERCH3Panel);
            app.RSdbIndicator.Text = '0';
            app.RDLEditField = uieditfield(app.ERCH3Panel, 'numeric');
            app.RDREditField = uieditfield(app.ERCH3Panel, 'numeric');
            app.RDCountIndicator = uilabel(app.ERCH3Panel);
            app.RDCountIndicator.Text = '0';
            app.RDdbIndicator = uilabel(app.ERCH3Panel);
            app.RDdbIndicator.Text = '0';
            app.RVLEditField = uieditfield(app.ERCH3Panel, 'numeric');
            app.VLabel_6 = uilabel(app.ERCH3Panel);
            app.VLabel_6.Text = 'V';
            app.RVREditField = uieditfield(app.ERCH3Panel, 'numeric');
            app.RVCountIndicator = uilabel(app.ERCH3Panel);
            app.RVCountIndicator.Text = '0';
            app.RVdbIndicator = uilabel(app.ERCH3Panel);
            app.RVdbIndicator.Text = '0';
            app.capCountrate3 = uilabel(app.ScopeRLTab);
            app.capCountrate3.Text = 'Count rate';
            app.lCountrate3 = uilabel(app.ScopeRLTab);
            app.lCountrate3.Text = '0 ';
            app.capCountrate4 = uilabel(app.ScopeRLTab);
            app.capCountrate4.Text = 'Count rate';
            app.lCountrate4 = uilabel(app.ScopeRLTab);
            app.lCountrate4.Text = '0 ';
            app.LDTab = uitab(app.TabGroup);
            app.LDTab.Title = 'LD脉冲处理';
            app.UIAxes7 = uiaxes(app.LDTab);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            app.Panel_9 = uipanel(app.LDTab);
            app.Panel_9.Title = '用户参数输入';
            app.Label_17 = uilabel(app.Panel_9);
            app.Label_17.Text = '频率';
            app.LD_Fre = uieditfield(app.Panel_9, 'numeric');
            app.LD_Fre.ValueChangedFcn = createCallbackFcn(app, @LD_FreValueChanged, true);
            app.GHzLabel_3 = uilabel(app.Panel_9);
            app.GHzLabel_3.Text = 'GHz';
            app.LDButton = uibutton(app.Panel_9, 'push');
            app.LDButton.ButtonPushedFcn = createCallbackFcn(app, @LDButtonPushed, true);
            app.LDButton.Text = '确定';
            app.Label_19 = uilabel(app.Panel_9);
            app.Label_19.Text = '数据名称';
            app.LD_dataneme = uieditfield(app.Panel_9, 'text');
            app.LD_dataneme.ValueChangedFcn = createCallbackFcn(app, @LD_datanemeValueChanged, true);
            app.Label_25 = uilabel(app.Panel_9);
            app.Label_25.Text = '寻峰间距';
            app.LD_MINPEAKDISTANCE = uieditfield(app.Panel_9, 'numeric');
            app.binsLabel_5 = uilabel(app.Panel_9);
            app.binsLabel_5.Text = 'bins';
            app.SLabel_3 = uilabel(app.Panel_9);
            app.SLabel_3.Text = 'S态阈值';
            app.LDpeakmin = uieditfield(app.Panel_9, 'numeric');
            app.LDpeakmin.Value = 1000;
            app.Panel_10 = uipanel(app.LDTab);
            app.Panel_10.Title = '宽度';
            app.psLabel_19 = uilabel(app.Panel_10);
            app.psLabel_19.Text = 'ps';
            app.psLabel_20 = uilabel(app.Panel_10);
            app.psLabel_20.Text = 'ps';
            app.dBLabel = uilabel(app.Panel_10);
            app.dBLabel.Text = '3dB宽度';
            app.LD3dB = uieditfield(app.Panel_10, 'numeric');
            app.dBLabel_2 = uilabel(app.Panel_10);
            app.dBLabel_2.Text = '20dB宽度';
            app.LD20dB = uieditfield(app.Panel_10, 'numeric');
            app.SliderLabel_3 = uilabel(app.LDTab);
            app.SliderLabel_3.Text = 'Slider';
            app.LD_Slider = uislider(app.LDTab);
            app.LD_Slider.Limits = [0 50000];
            app.LD_Slider.ValueChangedFcn = createCallbackFcn(app, @LD_SliderValueChanged, true);
            app.LD_Slider.Value = 50000;
            app.Panel_11 = uipanel(app.LDTab);
            app.Panel_11.Title = '频率';
            app.GHzLabel_4 = uilabel(app.Panel_11);
            app.GHzLabel_4.Text = 'GHz';
            app.GHzLabel_5 = uilabel(app.Panel_11);
            app.GHzLabel_5.Text = 'GHz';
            app.Label_23 = uilabel(app.Panel_11);
            app.Label_23.Text = '测量频率';
            app.LD_Fremeasured = uieditfield(app.Panel_11, 'numeric');
            app.Label_24 = uilabel(app.Panel_11);
            app.Label_24.Text = '频率标准差';
            app.LD_Cov_Fre = uieditfield(app.Panel_11, 'numeric');
            app.SDVTab = uitab(app.TabGroup);
            app.SDVTab.Title = 'SDV数据处理';
            app.UIAxes = uiaxes(app.SDVTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Panel = uipanel(app.SDVTab);
            app.Panel.Title = '用户参数输入';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '门宽';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'手动输入', '自动最优'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '手动输入';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '频率';
            app.SDV_Fre = uieditfield(app.Panel, 'numeric');
            app.SDV_Fre.ValueChangedFcn = createCallbackFcn(app, @SDV_FreValueChanged, true);
            app.SDV_Binsnum = uieditfield(app.Panel, 'numeric');
            app.GHzLabel = uilabel(app.Panel);
            app.GHzLabel.Text = 'GHz';
            app.SDVButton = uibutton(app.Panel, 'push');
            app.SDVButton.ButtonPushedFcn = createCallbackFcn(app, @SDVButtonPushed, true);
            app.SDVButton.Text = '确定';
            app.binsLabel = uilabel(app.Panel);
            app.binsLabel.Text = 'bins';
            app.Label_20 = uilabel(app.Panel);
            app.Label_20.Text = '数据名称';
            app.SDV_dataname = uieditfield(app.Panel, 'text');
            app.SDV_dataname.ValueChangedFcn = createCallbackFcn(app, @SDV_datanameValueChanged, true);
            app.binsLabel_3 = uilabel(app.Panel);
            app.binsLabel_3.Text = 'bins';
            app.Label_21 = uilabel(app.Panel);
            app.Label_21.Text = '寻峰间距';
            app.SDV_MINPEAKDISTANCE = uieditfield(app.Panel, 'numeric');
            app.SLabel_2 = uilabel(app.Panel);
            app.SLabel_2.Text = 'S态阈值';
            app.Speakmin = uieditfield(app.Panel, 'numeric');
            app.Speakmin.Value = 1000;
            app.VLabel_3 = uilabel(app.Panel);
            app.VLabel_3.Text = 'V态阈值';
            app.Vpeakmax = uieditfield(app.Panel, 'numeric');
            app.Vpeakmax.Value = 200;
            app.Panel_2 = uipanel(app.SDVTab);
            app.Panel_2.Title = '宽度';
            app.psLabel = uilabel(app.Panel_2);
            app.psLabel.Text = 'ps';
            app.psLabel_2 = uilabel(app.Panel_2);
            app.psLabel_2.Text = 'ps';
            app.psLabel_3 = uilabel(app.Panel_2);
            app.psLabel_3.Text = 'ps';
            app.psLabel_4 = uilabel(app.Panel_2);
            app.psLabel_4.Text = 'ps';
            app.psLabel_5 = uilabel(app.Panel_2);
            app.psLabel_5.Text = 'ps';
            app.psLabel_6 = uilabel(app.Panel_2);
            app.psLabel_6.Text = 'ps';
            app.S3dBLabel = uilabel(app.Panel_2);
            app.S3dBLabel.Text = 'S态3dB宽度';
            app.S3dB = uieditfield(app.Panel_2, 'numeric');
            app.S20dBLabel = uilabel(app.Panel_2);
            app.S20dBLabel.Text = 'S态20dB宽度';
            app.S20dB = uieditfield(app.Panel_2, 'numeric');
            app.D3dBLabel = uilabel(app.Panel_2);
            app.D3dBLabel.Text = 'D态3dB宽度';
            app.D3dB = uieditfield(app.Panel_2, 'numeric');
            app.D20dBLabel = uilabel(app.Panel_2);
            app.D20dBLabel.Text = 'D态20dB宽度';
            app.D20dB = uieditfield(app.Panel_2, 'numeric');
            app.V3dBLabel = uilabel(app.Panel_2);
            app.V3dBLabel.Text = 'V态3dB宽度';
            app.V3dB = uieditfield(app.Panel_2, 'numeric');
            app.V20dBLabel = uilabel(app.Panel_2);
            app.V20dBLabel.Text = 'V态20dB宽度';
            app.V20dB = uieditfield(app.Panel_2, 'numeric');
            app.Panel_3 = uipanel(app.SDVTab);
            app.Panel_3.Title = '峰值稳定性';
            app.SLabel = uilabel(app.Panel_3);
            app.SLabel.Text = 'S态变异系数';
            app.Cov_S = uieditfield(app.Panel_3, 'numeric');
            app.DLabel = uilabel(app.Panel_3);
            app.DLabel.Text = 'D态变异系数';
            app.Cov_D = uieditfield(app.Panel_3, 'numeric');
            app.VLabel = uilabel(app.Panel_3);
            app.VLabel.Text = 'V态变异系数';
            app.Cov_V = uieditfield(app.Panel_3, 'numeric');
            app.SliderLabel_2 = uilabel(app.SDVTab);
            app.SliderLabel_2.Text = 'Slider';
            app.SDV_Slider = uislider(app.SDVTab);
            app.SDV_Slider.Limits = [0 50000];
            app.SDV_Slider.ValueChangedFcn = createCallbackFcn(app, @SDV_SliderValueChanged, true);
            app.SDV_Slider.Value = 50000;
            app.PNRLTab = uitab(app.TabGroup);
            app.PNRLTab.Title = 'PNRL数据处理';
            app.UIAxes2_N = uiaxes(app.PNRLTab);
            title(app.UIAxes2_N, 'Title')
            xlabel(app.UIAxes2_N, 'X')
            ylabel(app.UIAxes2_N, 'Y')
            app.UIAxes2_P = uiaxes(app.PNRLTab);
            title(app.UIAxes2_P, 'Title')
            xlabel(app.UIAxes2_P, 'X')
            ylabel(app.UIAxes2_P, 'Y')
            app.UIAxes2_R = uiaxes(app.PNRLTab);
            title(app.UIAxes2_R, 'Title')
            xlabel(app.UIAxes2_R, 'X')
            ylabel(app.UIAxes2_R, 'Y')
            app.UIAxes2_L = uiaxes(app.PNRLTab);
            title(app.UIAxes2_L, 'Title')
            xlabel(app.UIAxes2_L, 'X')
            ylabel(app.UIAxes2_L, 'Y')
            app.Panel_4 = uipanel(app.PNRLTab);
            app.Panel_4.Title = '用户参数输入';
            app.DropDown_2Label = uilabel(app.Panel_4);
            app.DropDown_2Label.Text = '门宽';
            app.DropDown_2 = uidropdown(app.Panel_4);
            app.DropDown_2.Items = {'手动输入', '自动最优'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '手动输入';
            app.Label_5 = uilabel(app.Panel_4);
            app.Label_5.Text = '频率';
            app.PNRL_Fre = uieditfield(app.Panel_4, 'numeric');
            app.PNRL_Fre.ValueChangedFcn = createCallbackFcn(app, @PNRL_FreValueChanged, true);
            app.PNRL_Binsnum = uieditfield(app.Panel_4, 'numeric');
            app.PNRL_Binsnum.ValueChangedFcn = createCallbackFcn(app, @PNRL_BinsnumValueChanged, true);
            app.PNRL_Binsnum.Value = 20;
            app.GHzLabel_2 = uilabel(app.Panel_4);
            app.GHzLabel_2.Text = 'GHz';
            app.binsLabel_2 = uilabel(app.Panel_4);
            app.binsLabel_2.Text = 'bins';
            app.ch2delay = uieditfield(app.Panel_4, 'numeric');
            app.ch2delay.ValueChangedFcn = createCallbackFcn(app, @ch2delayValueChanged, true);
            app.psLabel_17 = uilabel(app.Panel_4);
            app.psLabel_17.Text = 'ps';
            app.ch4delay = uieditfield(app.Panel_4, 'numeric');
            app.ch4delay.ValueChangedFcn = createCallbackFcn(app, @ch4delayValueChanged, true);
            app.psLabel_18 = uilabel(app.Panel_4);
            app.psLabel_18.Text = 'ps';
            app.PLabel_2 = uilabel(app.Panel_4);
            app.PLabel_2.Text = 'P态大阈值';
            app.Ppeakmax = uieditfield(app.Panel_4, 'numeric');
            app.Ppeakmax.ValueChangedFcn = createCallbackFcn(app, @PpeakmaxValueChanged, true);
            app.Ppeakmax.Value = 2000;
            app.PLabel_3 = uilabel(app.Panel_4);
            app.PLabel_3.Text = 'P态小阈值';
            app.Ppeakmin = uieditfield(app.Panel_4, 'numeric');
            app.Ppeakmin.ValueChangedFcn = createCallbackFcn(app, @PpeakminValueChanged, true);
            app.Ppeakmin.Value = 200;
            app.NLabel_2 = uilabel(app.Panel_4);
            app.NLabel_2.Text = 'N态大阈值';
            app.Npeakmax = uieditfield(app.Panel_4, 'numeric');
            app.Npeakmax.ValueChangedFcn = createCallbackFcn(app, @NpeakmaxValueChanged, true);
            app.Npeakmax.Value = 2000;
            app.NLabel_3 = uilabel(app.Panel_4);
            app.NLabel_3.Text = 'N态小阈值';
            app.Npeakmin = uieditfield(app.Panel_4, 'numeric');
            app.Npeakmin.ValueChangedFcn = createCallbackFcn(app, @NpeakminValueChanged, true);
            app.Npeakmin.Value = 200;
            app.RLabel_4 = uilabel(app.Panel_4);
            app.RLabel_4.Text = 'R态大阈值';
            app.Rpeakmax = uieditfield(app.Panel_4, 'numeric');
            app.Rpeakmax.ValueChangedFcn = createCallbackFcn(app, @RpeakmaxValueChanged, true);
            app.Rpeakmax.Value = 2000;
            app.RLabel_5 = uilabel(app.Panel_4);
            app.RLabel_5.Text = 'R态小阈值';
            app.Rpeakmin = uieditfield(app.Panel_4, 'numeric');
            app.Rpeakmin.ValueChangedFcn = createCallbackFcn(app, @RpeakminValueChanged, true);
            app.Rpeakmin.Value = 200;
            app.LLabel_2 = uilabel(app.Panel_4);
            app.LLabel_2.Text = 'L态大阈值';
            app.Lpeakmax = uieditfield(app.Panel_4, 'numeric');
            app.Lpeakmax.ValueChangedFcn = createCallbackFcn(app, @LpeakmaxValueChanged, true);
            app.Lpeakmax.Value = 2000;
            app.LLabel_3 = uilabel(app.Panel_4);
            app.LLabel_3.Text = 'L态小阈值';
            app.Lpeakmin = uieditfield(app.Panel_4, 'numeric');
            app.Lpeakmin.ValueChangedFcn = createCallbackFcn(app, @LpeakminValueChanged, true);
            app.Lpeakmin.Value = 200;
            app.PNRLButton = uibutton(app.Panel_4, 'push');
            app.PNRLButton.ButtonPushedFcn = createCallbackFcn(app, @PNRLButtonPushed, true);
            app.PNRLButton.Text = '确定';
            app.Label_12 = uilabel(app.Panel_4);
            app.Label_12.Text = '数据名称';
            app.PNRL_dataname = uieditfield(app.Panel_4, 'text');
            app.PNRL_dataname.ValueChangedFcn = createCallbackFcn(app, @PNRL_datanameValueChanged2, true);
            app.ch2DropDownLabel_2 = uilabel(app.Panel_4);
            app.ch2DropDownLabel_2.Text = 'ch2延时';
            app.ch4DropDownLabel_2 = uilabel(app.Panel_4);
            app.ch4DropDownLabel_2.Text = 'ch4延时';
            app.binsLabel_4 = uilabel(app.Panel_4);
            app.binsLabel_4.Text = 'bins';
            app.Label_22 = uilabel(app.Panel_4);
            app.Label_22.Text = '寻峰间距';
            app.PNRL_MINPEAKDISTANCE = uieditfield(app.Panel_4, 'numeric');
            app.ch1Label = uilabel(app.Panel_4);
            app.ch1Label.Text = 'ch1效率';
            app.ch1eff = uieditfield(app.Panel_4, 'numeric');
            app.ch1eff.Value = 1;
            app.ch2Label = uilabel(app.Panel_4);
            app.ch2Label.Text = 'ch2效率';
            app.ch2eff = uieditfield(app.Panel_4, 'numeric');
            app.ch2eff.Value = 1;
            app.ch3Label = uilabel(app.Panel_4);
            app.ch3Label.Text = 'ch3效率';
            app.ch3eff = uieditfield(app.Panel_4, 'numeric');
            app.ch3eff.Value = 1;
            app.ch4Label = uilabel(app.Panel_4);
            app.ch4Label.Text = 'ch4效率';
            app.ch4eff = uieditfield(app.Panel_4, 'numeric');
            app.ch4eff.Value = 1;
            app.Panel_6 = uipanel(app.PNRLTab);
            app.Panel_6.Title = '宽度';
            app.psLabel_9 = uilabel(app.Panel_6);
            app.psLabel_9.Text = 'ps';
            app.psLabel_10 = uilabel(app.Panel_6);
            app.psLabel_10.Text = 'ps';
            app.psLabel_11 = uilabel(app.Panel_6);
            app.psLabel_11.Text = 'ps';
            app.psLabel_12 = uilabel(app.Panel_6);
            app.psLabel_12.Text = 'ps';
            app.psLabel_13 = uilabel(app.Panel_6);
            app.psLabel_13.Text = 'ps';
            app.psLabel_14 = uilabel(app.Panel_6);
            app.psLabel_14.Text = 'ps';
            app.P3dBLabel = uilabel(app.Panel_6);
            app.P3dBLabel.Text = 'P态3dB宽度';
            app.P3dB = uieditfield(app.Panel_6, 'numeric');
            app.P20dBLabel = uilabel(app.Panel_6);
            app.P20dBLabel.Text = 'P态20dB宽度';
            app.P20dB = uieditfield(app.Panel_6, 'numeric');
            app.N3dBLabel = uilabel(app.Panel_6);
            app.N3dBLabel.Text = 'N态3dB宽度';
            app.N3dB = uieditfield(app.Panel_6, 'numeric');
            app.N20dBLabel = uilabel(app.Panel_6);
            app.N20dBLabel.Text = 'N态20dB宽度';
            app.N20dB = uieditfield(app.Panel_6, 'numeric');
            app.psLabel_15 = uilabel(app.Panel_6);
            app.psLabel_15.Text = 'ps';
            app.psLabel_16 = uilabel(app.Panel_6);
            app.psLabel_16.Text = 'ps';
            app.R3dBLabel = uilabel(app.Panel_6);
            app.R3dBLabel.Text = 'R态3dB宽度';
            app.R3dB = uieditfield(app.Panel_6, 'numeric');
            app.R20dBLabel = uilabel(app.Panel_6);
            app.R20dBLabel.Text = 'R态20dB宽度';
            app.R20dB = uieditfield(app.Panel_6, 'numeric');
            app.L3dBLabel = uilabel(app.Panel_6);
            app.L3dBLabel.Text = 'L态3dB宽度';
            app.L3dB = uieditfield(app.Panel_6, 'numeric');
            app.L20dBLabel = uilabel(app.Panel_6);
            app.L20dBLabel.Text = 'L态20dB宽度';
            app.L20dB = uieditfield(app.Panel_6, 'numeric');
            app.Panel_7 = uipanel(app.PNRLTab);
            app.Panel_7.Title = '峰值稳定性';
            app.PLabel = uilabel(app.Panel_7);
            app.PLabel.Text = 'P态变异系数';
            app.Pstablemax = uieditfield(app.Panel_7, 'numeric');
            app.NLabel = uilabel(app.Panel_7);
            app.NLabel.Text = 'N态变异系数';
            app.Nstablemax = uieditfield(app.Panel_7, 'numeric');
            app.LLabel = uilabel(app.Panel_7);
            app.LLabel.Text = 'L态变异系数';
            app.Lstablemax = uieditfield(app.Panel_7, 'numeric');
            app.RLabel = uilabel(app.Panel_7);
            app.RLabel.Text = 'R态变异系数';
            app.Rstablemax = uieditfield(app.Panel_7, 'numeric');
            app.Pstablemin = uieditfield(app.Panel_7, 'numeric');
            app.Nstablemin = uieditfield(app.Panel_7, 'numeric');
            app.Lstablemin = uieditfield(app.Panel_7, 'numeric');
            app.Rstablemin = uieditfield(app.Panel_7, 'numeric');
            app.Label_6 = uilabel(app.Panel_7);
            app.Label_6.Text = '主通道';
            app.Label_7 = uilabel(app.Panel_7);
            app.Label_7.Text = '次通道';
            app.SliderLabel = uilabel(app.PNRLTab);
            app.SliderLabel.Text = 'Slider';
            app.PNRL_Slider = uislider(app.PNRLTab);
            app.PNRL_Slider.Limits = [0 50000];
            app.PNRL_Slider.ValueChangedFcn = createCallbackFcn(app, @PNRL_SliderValueChanged, true);
            app.PNRL_Slider.Value = 50000;
            app.Panel_12 = uipanel(app.PNRLTab);
            app.Panel_12.Title = '主要指标';
            app.Label_10 = uilabel(app.Panel_12);
            app.Label_10.Text = '%';
            app.Label_11 = uilabel(app.Panel_12);
            app.Label_11.Text = '%';
            app.Label_8 = uilabel(app.Panel_12);
            app.Label_8.Text = '门宽误码率';
            app.threerrorrate = uieditfield(app.Panel_12, 'numeric');
            app.Label_9 = uilabel(app.Panel_12);
            app.Label_9.Text = '峰值误码率';
            app.peakerrorrate = uieditfield(app.Panel_12, 'numeric');
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '门宽分析';
            app.UIAxes5 = uiaxes(app.Tab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.UIAxes5_2 = uiaxes(app.Tab);
            title(app.UIAxes5_2, 'Title')
            xlabel(app.UIAxes5_2, 'X')
            ylabel(app.UIAxes5_2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PBB84scope_analysis_V1p1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end